classdef Combustion_Toolbox_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_main                   matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        NewMenu                       matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        Excel                         matlab.ui.container.Menu
        viewmenu                      matlab.ui.container.Menu
        view_density                  matlab.ui.container.Menu
        DefaultMenu                   matlab.ui.container.Menu
        MajorMenu                     matlab.ui.container.Menu
        ExtraMenu                     matlab.ui.container.Menu
        CommandWindowbetaMenu         matlab.ui.container.Menu
        ExamplesMenu                  matlab.ui.container.Menu
        helpmenu                      matlab.ui.container.Menu
        tutorial                      matlab.ui.container.Menu
        SummaryMenu                   matlab.ui.container.Menu
        contents                      matlab.ui.container.Menu
        SendfeedbackMenu              matlab.ui.container.Menu
        about                         matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        uipanel21                     matlab.ui.container.Panel
        GridLayout12                  matlab.ui.container.GridLayout
        uipanel11_3                   matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        uitable_R                     matlab.ui.control.Table
        edit_phi                      matlab.ui.control.EditField
        text_phi                      matlab.ui.control.Label
        ReactionPanel                 matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        Reaction                      matlab.ui.control.DropDown
        NewreactantPanel              matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        edit_seeker_3                 matlab.ui.control.EditField
        Reactants                     matlab.ui.control.DropDown
        FuelEditFieldLabel            matlab.ui.control.Label
        FuelEditField                 matlab.ui.control.NumericEditField
        OFEditFieldLabel              matlab.ui.control.Label
        OFEditField                   matlab.ui.control.NumericEditField
        uipanel13                     matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        uitable_P                     matlab.ui.control.Table
        uipanel7_3                    matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        ProblemType                   matlab.ui.control.DropDown
        Calculate                     matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        Clear                         matlab.ui.control.Button
        last_plots                    matlab.ui.control.Button
        uipanel20                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        uipanel8                      matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        text_s                        matlab.ui.control.Label
        edit_sR                       matlab.ui.control.NumericEditField
        edit_sP                       matlab.ui.control.NumericEditField
        text_gamma                    matlab.ui.control.Label
        edit_gammaR                   matlab.ui.control.NumericEditField
        edit_gammaP                   matlab.ui.control.NumericEditField
        text_M                        matlab.ui.control.Label
        edit_MP                       matlab.ui.control.NumericEditField
        edit_MR                       matlab.ui.control.EditField
        text_u                        matlab.ui.control.Label
        edit_uP                       matlab.ui.control.NumericEditField
        edit_uR                       matlab.ui.control.EditField
        text_e                        matlab.ui.control.Label
        edit_eR                       matlab.ui.control.NumericEditField
        edit_eP                       matlab.ui.control.NumericEditField
        text_sound                    matlab.ui.control.Label
        edit_soundR                   matlab.ui.control.NumericEditField
        edit_soundP                   matlab.ui.control.NumericEditField
        text_W                        matlab.ui.control.Label
        edit_WR                       matlab.ui.control.NumericEditField
        edit_WP                       matlab.ui.control.NumericEditField
        text_cp                       matlab.ui.control.Label
        edit_cpR                      matlab.ui.control.NumericEditField
        edit_cpP                      matlab.ui.control.NumericEditField
        text_h                        matlab.ui.control.Label
        edit_hR                       matlab.ui.control.NumericEditField
        edit_hP                       matlab.ui.control.NumericEditField
        text_r                        matlab.ui.control.Label
        edit_rR                       matlab.ui.control.NumericEditField
        edit_rP                       matlab.ui.control.NumericEditField
        text_p                        matlab.ui.control.Label
        edit_pP                       matlab.ui.control.NumericEditField
        edit_pR                       matlab.ui.control.EditField
        text_Products                 matlab.ui.control.Label
        text_Reactans                 matlab.ui.control.Label
        text_T                        matlab.ui.control.Label
        edit_TR                       matlab.ui.control.EditField
        edit_TP                       matlab.ui.control.EditField
        uipanel14                     matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        text_q                        matlab.ui.control.Label
        edit_q                        matlab.ui.control.NumericEditField
        edit_vP_vR                    matlab.ui.control.NumericEditField
        text_vP_vR                    matlab.ui.control.Label
        EpsilonmolesLabel             matlab.ui.control.Label
        edit_error_moles              matlab.ui.control.NumericEditField
        EpsilonHLabel                 matlab.ui.control.Label
        edit_error_problem            matlab.ui.control.NumericEditField
        Tree                          matlab.ui.container.Tree
        Results                       matlab.ui.container.TreeNode
        Image_5                       matlab.ui.control.Image
        ExtendedsettingsTab           matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        uipanel22                     matlab.ui.container.Panel
        edit_seeker                   matlab.ui.control.EditField
        listbox_species               matlab.ui.control.ListBox
        listbox_minor_products        matlab.ui.control.ListBox
        pushbutton1                   matlab.ui.control.Button
        text_list_species             matlab.ui.control.Label
        text_minor_products           matlab.ui.control.Label
        pushbutton2                   matlab.ui.control.Button
        text_list_species_2           matlab.ui.control.Label
        uipanel23                     matlab.ui.container.Panel
        TraceoptionLabel              matlab.ui.control.Label
        TraceoptionEditField          matlab.ui.control.NumericEditField
        DisplaySpeciesEditFieldLabel  matlab.ui.control.Label
        DisplaySpeciesEditField       matlab.ui.control.NumericEditField
        SootfactorEditFieldLabel      matlab.ui.control.Label
        SootfactorEditField           matlab.ui.control.NumericEditField
        EpsilonSootEditFieldLabel     matlab.ui.control.Label
        EpsilonSootEditField          matlab.ui.control.NumericEditField
        uipanel22_2                   matlab.ui.container.Panel
        edit_seeker_2                 matlab.ui.control.EditField
        listbox_species_2             matlab.ui.control.ListBox
        listbox_plotted_species       matlab.ui.control.ListBox
        pushbutton1_2                 matlab.ui.control.Button
        text_list_species_3           matlab.ui.control.Label
        text_minor_products_2         matlab.ui.control.Label
        pushbutton2_2                 matlab.ui.control.Button
        text_list_species_4           matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
        MolarfractionvsEquivalenceratioTab  matlab.ui.container.Tab
        GridLayout13                  matlab.ui.container.GridLayout
        uipanel21_2                   matlab.ui.container.Panel
        GridLayout14                  matlab.ui.container.GridLayout
        uipanel7_2                    matlab.ui.container.Panel
        popupmenu1_2                  matlab.ui.control.DropDown
        SpeciesPanel                  matlab.ui.container.Panel
        O2CheckBox                    matlab.ui.control.CheckBox
        OCheckBox                     matlab.ui.control.CheckBox
        OHCheckBox                    matlab.ui.control.CheckBox
        H2OCheckBox                   matlab.ui.control.CheckBox
        H2CheckBox                    matlab.ui.control.CheckBox
        N2CheckBox                    matlab.ui.control.CheckBox
        NOCheckBox                    matlab.ui.control.CheckBox
        CO2CheckBox                   matlab.ui.control.CheckBox
        COCheckBox                    matlab.ui.control.CheckBox
        uipanel11_2                   matlab.ui.container.Panel
        edit_phi_2                    matlab.ui.control.EditField
        text_phi_2                    matlab.ui.control.Label
        popupmenu2_2                  matlab.ui.control.DropDown
        Lamp_2                        matlab.ui.control.Lamp
        togglebutton1_2               matlab.ui.control.Button
        ReactantsPanel                matlab.ui.container.Panel
        PressurebarLabel              matlab.ui.control.Label
        plotfield2                    matlab.ui.control.NumericEditField
        TemperatureKLabel             matlab.ui.control.Label
        plotfield1                    matlab.ui.control.NumericEditField
        ProductsPanel                 matlab.ui.container.Panel
        PressurebarLabel_2            matlab.ui.control.Label
        plotfield4                    matlab.ui.control.NumericEditField
        TemperatureKLabel_2           matlab.ui.control.Label
        plotfield3                    matlab.ui.control.NumericEditField
        uibuttongroup1_2              matlab.ui.container.ButtonGroup
        Complete                      matlab.ui.control.CheckBox
        Incomplete                    matlab.ui.control.CheckBox
        ValidationsPanel              matlab.ui.container.Panel
        CANTERA                       matlab.ui.control.CheckBox
        legend                        matlab.ui.control.CheckBox
        Image_3                       matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        species              % List of reactants species
        NameSpecies_Master   % List of all species in strMaster
        numelstrMaster       % Number of species in strMaster
        minor_products       % Minor products
        List_fixed_Species   % Fixed species
        List_Compute_Species % Fxied and minor species
        leg                  % legend
        flag                 % New reactant
        selected_Reactive    % Selected reactive
        idx_fixed            % index fixed species
        idx_minor            % index minor products
        idx_all              % index fixed species and minor products
    end
    
    properties (Access = public)
        E                    % Data of the chemical elements
        S                    % Data of the chemical species
        M                    % Data of minors products
        C                    % Constants and tolerances
        Misc                 % Miscelaneous properties
        PD                   % Problem description
        PS                   % Problem solution
        TN                   % Tunning properties
        strMaster            % Master database
        strThProp            % Reduce database
    end
    
    methods (Access = private)

        function myCloseRequestFcn(app)
            cleanup ;       % make sure we remove the listener
            delete(app) ;
        end
        
        function showresults(app,selectedNodes)
            if ~isempty(selectedNodes.NodeData)
                % Show settings
                app.ProblemType.Value = selectedNodes.NodeData.numberProblemType;
                app.Reactants.Value = selectedNodes.NodeData.numberReactants;
%                 if strcmpi(selectedNodes.NodeData.CompleteOrIncomplete,'complete')
%                     app.radiobutton_complete.Value = 1;
%                 else
%                     app.radiobutton_incomplete.Value = 1;
%                 end
                switch selectedNodes.NodeData.reaction
                    case '1'
                        app.Reaction.Value = '1';
                    case '2'
                        app.Reaction.Value = '2';
                    case '3'
                        app.Reaction.Value = '3';
                    case '4'
                        app.Reaction.Value = '4';
                    case '5'
                        app.Reaction.Value = '5';
                end
                % Show results
                app.edit_TR.Value = sprintf('%.4g',selectedNodes.NodeData.strR.T);
                app.edit_pR.Value = sprintf('%.4g',selectedNodes.NodeData.strR.p);
                app.edit_rR.Value = selectedNodes.NodeData.strR.rho;
                app.edit_hR.Value = selectedNodes.NodeData.strR.h/selectedNodes.NodeData.strR.mi;
                app.edit_eR.Value = selectedNodes.NodeData.strR.e/selectedNodes.NodeData.strR.mi;
                app.edit_sR.Value = selectedNodes.NodeData.strR.S;
                app.edit_cpR.Value = selectedNodes.NodeData.strR.cP/selectedNodes.NodeData.strR.mi*1e-3;
                app.edit_WR.Value = selectedNodes.NodeData.strR.W;
                app.edit_soundR.Value = selectedNodes.NodeData.strR.sound;
                app.edit_gammaR.Value = selectedNodes.NodeData.strR.cP/selectedNodes.NodeData.strR.cV;
                
                app.edit_TP.Value = sprintf('%.4g',selectedNodes.NodeData.strP.T);
                app.edit_pP.Value = selectedNodes.NodeData.strP.p;
                app.edit_rP.Value = selectedNodes.NodeData.strP.rho;
                app.edit_hP.Value = selectedNodes.NodeData.strP.h/selectedNodes.NodeData.strP.mi;
                app.edit_eP.Value = selectedNodes.NodeData.strP.e/selectedNodes.NodeData.strP.mi;
                app.edit_sP.Value = selectedNodes.NodeData.strP.S;
                app.edit_cpP.Value = selectedNodes.NodeData.strP.cP/selectedNodes.NodeData.strP.mi*1e-3;
                app.edit_WP.Value = selectedNodes.NodeData.strP.W;
                app.edit_soundP.Value = selectedNodes.NodeData.strP.sound;
                app.edit_gammaP.Value = selectedNodes.NodeData.strP.cP/selectedNodes.NodeData.strP.cV;
                app.edit_q.Value = app.edit_hP.Value - app.edit_hR.Value;
                if any(strcmpi(selectedNodes.NodeData.numberProblemType,{'7','8','9'}))
                    app.edit_uR.Visible = 'on'; app.edit_uR.Value = sprintf('%.4g',selectedNodes.NodeData.strR.u); app.edit_uR.Editable = 'on';
                    app.edit_uP.Visible = 'on'; app.edit_uP.Value = selectedNodes.NodeData.strP.u; app.text_u.Visible = 'on';
                    app.edit_MR.Visible = 'on'; app.edit_MP.Visible = 'on'; app.text_M.Visible = 'on';
                    app.edit_MR.Value = sprintf('%.4g',sscanf(app.edit_uR.Value,'%f')/app.edit_soundR.Value);
                    app.edit_MP.Value = app.edit_uP.Value/app.edit_soundP.Value;
                else
                    app.edit_uR.Visible = 'off'; app.edit_uR.Editable = 'off';
                    app.edit_uP.Visible = 'off'; app.text_u.Visible = 'off';
                    app.edit_MR.Visible = 'off'; app.edit_MP.Visible = 'off'; app.text_M.Visible = 'off';
                end
                app.uitable_P.Data = selectedNodes.NodeData.data_P;
                app.uitable_R.Data = selectedNodes.NodeData.data_R;
                app.OFEditField.Value = selectedNodes.NodeData.OF;
                app.FuelEditField.Value = selectedNodes.NodeData.F;
                if any(strcmp(app.Reactants.Value,{'1','2'}))
                    app.edit_phi.Value = '-';
                else
                    app.edit_phi.Value = sprintf('%.4g',selectedNodes.NodeData.phi);
                end
            end
        end
        
        function addchildren(app,app_parent,ProblemType,results,text,text_value)
            %%% New Children Node: Results -> ProblemType
            % Category 1
            j = 0;
            if ~isempty(app_parent.Children)
                i = 1;
                while i<=length(app_parent.Children)
                    if strcmpi(app_parent.Children(i).Text,ProblemType)
                        category1 = app_parent.Children(i);
                        j = 1;
                        break;
                    end
                    i = i + 1;
                end
            end
            if j == 0
                category1 = uitreenode(app_parent,'Text',ProblemType,'NodeData',[]);
            end
            expand(app_parent,'all');
            %%% New Children Node: ProblemType -> Reactants
            for k=numel(results):-1:1
            % Category 2
            j = 0;
            text_reactants = app.Reactants.Items{sscanf(results(k).numberReactants,'%d')};
            if ~isempty(category1.Children)
                i = 1;
                while i<=length(category1.Children)
                    if strcmpi(category1.Children(i).Text,text_reactants)
                        category2 = category1.Children(i);
                        j = 1;
                        break;
                    end
                    i = i + 1;
                end
            end
            if j == 0
                category2 = uitreenode(category1,'Text',text_reactants,'NodeData',[]);
            end
            expand(category1,'all');
            %%% New Children Node: Reactants -> CompleteOrIncomplete
            % Category 3
            j = 0;
            if ~isempty(category2.Children)
                i = 1;
                while i<=length(category2.Children)
                    if strcmpi(category2.Children(i).Text,results(k).CompleteOrIncomplete)
                        category3 = category2.Children(i);
                        j = 1;
                        break;
                    end
                    i = i + 1;
                end
            end
            if j == 0
                category3 = uitreenode(category2,'Text',results(k).CompleteOrIncomplete,'NodeData',[],'UserData',1);
            end
            expand(category2,'all');
            %%% New Children Node: CompleteOrIncomplete -> Phi
            % Category 4
            j = 0;
%             if any(strcmpi(results.numberReactants,{'1','2'})) % Cases without fuel
                text = 'Case %d'; text_value = category3.UserData;
%             end
            if ~isempty(category3.Children)
                i = 1;
                while i<=length(category3.Children)
                    if strcmpi(category3.Children(i).Text,sprintf(text,text_value))
                        category4 = category3.Children(i);
                        j = 1;
                        break;
                    end
                    i = i + 1;
                end
            end
            if j == 0
                category4 = uitreenode(category3,'Text',sprintf(text,text_value),'NodeData',results(k));
                category3.UserData = category3.UserData + 1;
            end
            expand(category3,'all');
            
            %%%% Recursive way
            %             if length(app_parent.Children)>0
            %                 i = 1;
            %                 while i<=length(app_parent.Children)
            %                     if strcmpi(app_parent.Children(i).Text,ProblemType)
            %                         category1 = app_parent.Children(i);
            %                         j = 1;
            %                         break;
            %                     end
            %                     i = i + 1;
            %                 end
            %             end
            %             if j == 0
            %                 category1 = uitreenode(app_parent,'Text',ProblemType,'NodeData',[]);
            %             end
            %             uitreenode(category1,'Text',sprintf(text,text_value),'NodeData',results);
            %             expand(category1);
            %
            %             addchildren(app,app_parent,ProblemType,results,text,text_value)
            %%%% Original
            %             j = 0;
            %             if length(app.Results.Children)>0
            %                 i = 1;
            %                 while i<=length(app.Results.Children)
            %                     if strcmpi(app.Results.Children(i).Text,ProblemType)
            %                         category1 = app.Results.Children(i);
            %                         j = 1;
            %                         break;
            %                     end
            %                     i = i + 1;
            %                 end
            %             end
            %             if j == 0
            %                 category1 = uitreenode(app.Results,'Text',ProblemType,'NodeData',[]);
            %             end
            %             uitreenode(category1,'Text',sprintf('Phi = %.4f',phi),'NodeData',results);
            %             expand(category1);
            end
        end
        
        function displaysweep2(app,strP_CC,strP_IC,Species_c,phi,display_species)
            color = colours;
            fontsize = 18; linewidth = 1.6;
            %% Plot Molar Fraction vs equivalence ratio
                set(app.UIAxes,'LineWidth',linewidth,'FontSize',fontsize+4,'BoxStyle','full')
%                 app.UIAxes.Box = 'on'; 
                app.UIAxes.NextPlot = 'add'; 
                xlim(app.UIAxes,[phi(1),phi(end)])
                ylim(app.UIAxes,[-inf 1])
%                 xlabel(app.UIAxes,"Equivalence ratio, $\phi [-]$",'FontSize',fontsize+4,'interpreter','latex');
%                 ylabel(app.UIAxes,"Molar fraction, $X_i [-]$",'FontSize',fontsize+4,'interpreter','latex');
                Nstruct = length(strP_CC);
                for j=Nstruct:-1:1
                    Xi_CC(:,j) = strP_CC{1,j}.Xi;
                    Xi_IC(:,j) = strP_IC{1,j}.Xi;
                end
                % n_pass = zeros(4,4);
                n_pass = zeros(1,length(display_species));
                % Find index
                for i=1:1
                    j = 0;
                    for k=1:length(display_species)
                        for n = 1:length(app.S.NameSpecies)
                            if any(strcmpi(display_species{k},app.S.NameSpecies{n}))
                                j=j+1;
                                n_pass(i,j) =  n;
                            end
                        end
                    end
                end
                %% CC
                for j=length(display_species):-1:1
                    if n_pass(i,j) > 0
                        plot(app.UIAxes,phi,Xi_CC(n_pass(i,j),:),'LineWidth',linewidth,...
                            'MarkerFaceColor',color(j,:),'MarkerEdgeColor','black',...
                            'MarkerSize',fontsize-10,'color',color(j,:))
                    end
                end
                %% CI
                for j=length(display_species):-1:1
                    if n_pass(i,j) > 0
                        plot(app.UIAxes,phi,Xi_IC(n_pass(i,j),:),'--','LineWidth',linewidth,...
                            'MarkerFaceColor',color(j,:),'MarkerEdgeColor','black',...
                            'MarkerSize',fontsize-10,'color',color(j,:))
                    end
                    leg1(j)= strcat('IC-',display_species(end-j+1));
                end
                %% CANTERA
                if ~isempty(Species_c)
                    for j=length(display_species):-1:1
                        aux4 = Species_c{1,i};
                        plot(app.UIAxes,phi(1:20:end),aux4(j,1:20:end),'s','LineWidth',linewidth,...
                            'MarkerFaceColor',color(j,:),'MarkerEdgeColor','black',...
                            'MarkerSize',fontsize-10,'color',color(j,:))
                        leg2(j)= strcat('CANTERA-',display_species(end-j+1));
                    end
                end
                if isempty(Species_c)
                    %                             leg(2*(j-1)+1) = strcat(display_species(j),' - CC');
                    %                             leg(2*(j-1)+2) =  strcat(display_species(j),' - IC');
                    header_leg = {'CC','CI'};
                    app.leg = {'CC','CC','CC','CC','CC','CC',leg1{1:end}};
                    legend(app.UIAxes,app.leg,'FontSize',fontsize,'Location','northeastoutside','interpreter','latex','NumColumns',2)
                    if ~app.legend.Value
                        app.UIAxes.Legend.Visible = 'off';
%                         set(app.UIAxes, 'Position', [1,1,1216,462]);
                    end
                else 
                    LegendOptions.Location = 'Northeastoutside';
                    LegendOptions.Box = 'on';
                    LegendOptions.FontSize = fontsize;
                    header_leg = {'CC','CI','CANTERA'};
%                     leg = {'','','','','','','','','','','','','','','','','','',};
%                     leg = {'CC','CC','CC','CC','CC','CC','IC','IC','IC','IC','IC','IC','CANTERA','CANTERA','CANTERA','CANTERA','CANTERA','CANTERA'};
                    app.leg = {'CC','CC','CC','CC','CC','CC','IC','IC','IC','IC','IC','IC',leg2{1:end}};
                    legend(app.UIAxes,app.leg,'FontSize',fontsize,'Location','northeastoutside','interpreter','latex','NumColumns',3)
                    if ~app.legend.Value
                        app.UIAxes.Legend.Visible = 'off';
%                         set(app.UIAxes, 'Position', [1,1,1216,462]);
                    end
                end
                set(app.UIAxes,'yscale','log')
        end
        
        
        function Species_c = calc_CANTERA(app,phi,pR_vector,TP_vector,display_species)
            gas = GRI30;
            % species_unique = cell(length(pR_vector),10);
            Species_namesall = [display_species{1}];
            for i=1:length(phi)
                pR = pR_vector*1e5;
                reaction = strcat('CH4:1,O2:',num2str(2/phi(i)),',N2:',num2str(7.52/phi(i)));
                set(gas,'T',TP_vector,'P',pR,'X',reaction);
                equilibrate(gas,'TP');
                %     indX(i,:) = moleFractions(gas)>1e-5;
                for k=length(display_species):-1:1
                    X_c1(i,k) = moleFraction(gas,display_species{1,k});
                end
                %     for k=length(Species_names{2}):-1:1
                %         X_c2(i,k) = moleFraction(gas,Species_names{1,2}{1,k});
                %     end
                %     for k=length(Species_names{3}):-1:1
                %         X_c3(i,k) = moleFraction(gas,Species_names{1,3}{1,k});
                %     end
                %     for k=length(Species_names{4}):-1:1
                %         X_c4(i,k) = moleFraction(gas,Species_names{1,4}{1,k});
                %     end
                %     X(i,:) = moleFractions(gas);
                %     species(i,:) = speciesNames(gas);
                %     species(i,indX(i,:))
                %     X_c(j).case(i,:) = X(i,indX(i,:));
                %     Species_c(j).case(i,:) = species(i,indX(i,:));
                %     species_unique(i,1:length(unique(species(i,indX(i,:))))) = unique(species(i,indX(i,:)));
            end
            % Species_compare(j,:) = unique(species_unique);
            Species_c{1} = X_c1';
            % Species_c{2} = X_c2';
            % Species_c{3} = X_c3';
            % Species_c{4} = X_c4';
        end

        function resize_app(app,fig)
            %% Resize app
%             screenSize = get(groot,'ScreenSize');
%             screenWidth = screenSize(3);
%             screenHeight = screenSize(4);
%             left = screenWidth*0.1;
%             bottom = screenHeight*0.1;
%             width = 1216;
%             height = 650;
%             setappdata(app.(fig),'Position',[left bottom width height]);
            %% Center app
            movegui(app.(fig),'center');
        end
        
        function Initialize(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % LOAD/CALCULATE TABULATED DATA
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            tic; app.Misc.timer_0 = tic;
            addpath(genpath(pwd))
            %% PROPERTIES DESCRIPTION
            app.E.Description    = "Data of the chemical elements";
            app.S.Description    = "Data of the chemical species";
            app.M.Description    = "Data of minors products";
            app.C.Description    = "Constants and tolerances";
            app.C.A0.Description = "Stoichiometric matrix";
            app.Misc.Description = "Miscelaneous properties";
            app.PD.Description   = "Problem description";
            app.PS.Description   = "Problem solution";
            app.TN.Description   = "Tunning properties";
            % Problem Description (PD):
            app.PD.TR.Description  = "Temperature of the reactive species [K]";
            app.PD.pR.Description  = "Pressure of the reactive species [bar]";
            app.PD.phi.Description = "Equivalence ratio [-]";
            %%
            aux = app.ProblemType.Items{1};
            app.PD.ProblemType = aux(1:2); clear aux;
            [app.E.Elements,~] = set_elements();
            % LOAD/CALCULATE TABULATED DATA
            if exist('strMaster_reduced.mat','file')
                disp('NASA database loaded from main path')
                load('strMaster_reduced.mat');
            elseif exist('strMaster.mat','file')
                disp('NASA database loaded from main path')
                load('strMaster.mat');
            else
                strMaster = ParseThermoInp_test(); % struct with tabulated data of all species.
                strMaster = strMaster_reduced(strMaster);
            end
            app.strMaster = strMaster; clear strMaster;
            % LOAD/CALCULATE SHORT TABULATED DATA
            if exist('strThProp_airnasanoions.mat','file')
                disp('NASA short database loaded from main path')
                load('strThProp_airnasanoions.mat');
            else
                strThProp = GenerateShortDatabase_test(app.strMaster); % struct with tabulated data of selected species.
            end
            app.strThProp = strThProp; clear strThProp;
            %% MISCELANEOUS
            app.Misc.FLAG_FIRST = true;
            app.PD.Fuel.x = 0;
            %% INITIALIZE VALUES
            app.M.display_species = {};
            app.M.minor_products = {};
            % CONTAINED ELEMENTS
            app.S.NameSpecies = fieldnames(app.strThProp);
            app.S.NSpecies = numel(app.S.NameSpecies);
            for k = length(app.S.NameSpecies):-1:1
                Species = app.S.NameSpecies{k};
                % Change uppercase 'L' to  lowercase 'l'
                Species(strfind(Species,'AL')+1)='l';
                Species(strfind(Species,'CL')+1)='l';
                Species(strfind(Species,'TL')+1)='l';
                Species(strfind(Species,'FL')+1)='l';
                % -----------------------------------------------
                Species(Species>='0' & Species<='9') = ' ';
                
                [idx0,idxf] = regexp(Species,"minus"); Species(idx0:idxf) = ' ';
                [idx0,idxf] = regexp(Species,"plus"); Species(idx0:idxf) = ' ';
                
                idx = find([(Species>='A' & Species<='Z'), true]);
                lgt = diff(idx);
                Tmp{k,1} = strtrim(mat2cell(Species, 1, lgt));
            end
            aux = unique(cat(2,Tmp{:}));
            n_pass = [];
            for n=length(aux):-1:1
                if any(strcmp(aux(n),app.E.Elements)) % Check Element existence
                    n_pass = [n_pass, n];
                end
            end
            app.E.Elements = aux(n_pass);
            app.E.NE = numel(app.E.Elements);
            %% INDEX OF EVALUABLE ELEMENTS
            app.E.ind_C = find(strcmp(app.E.Elements,'C'));
            app.E.ind_H = find(strcmp(app.E.Elements,'H'));
            app.E.ind_O = find(strcmp(app.E.Elements,'O'));
            app.E.ind_N = find(strcmp(app.E.Elements,'N'));
            app.E.ind_He = find(strcmp(app.E.Elements,'He'));
            app.E.ind_Ar = find(strcmp(app.E.Elements,'Ar'));
            %% INDEX OF EVALUABLE SPECIES
            app.S.idx_CO2 = find_idx({'CO2'},app.S.NameSpecies);
            app.S.idx_CO  = find_idx({'CO'},app.S.NameSpecies);
            app.S.idx_H2O = find_idx({'H2O'},app.S.NameSpecies);
            app.S.idx_H2  = find_idx({'H2'},app.S.NameSpecies);
            app.S.idx_O2  = find_idx({'O2'},app.S.NameSpecies);
            app.S.idx_N2  = find_idx({'N2'},app.S.NameSpecies);
            app.S.idx_He  = find_idx({'He'},app.S.NameSpecies);
            app.S.idx_Ar  = find_idx({'Ar'},app.S.NameSpecies);
            app.S.idx_Cgr = find_idx({'Cbgrb'},app.S.NameSpecies);
            app.S.List_fixed_Species = {'CO2','CO','H2O','H2','O2','N2','He','Ar','Cbgrb'};
            app.S.Lfixed = length(app.S.List_fixed_Species);
            app.S.idx_fixed = [app.S.idx_CO2,app.S.idx_CO,app.S.idx_H2O,app.S.idx_H2,app.S.idx_O2,app.S.idx_N2,app.S.idx_He,app.S.idx_Ar,app.S.idx_Cgr];
            %% CONSTANTS & TOLERANCES
            app.C.R0 = 8.3144598; % [J/(K mol)]. Universal gas constant
            app.C.MassOrMolar = 'mass';
            app.C.filename = 'output';
            app.C.firstrow = 1;
            app.C.ExcelOutputMatrix = [];
            app.C.mintol_display = app.DisplaySpeciesEditField.Value;
            app.C.mintol = app.TraceoptionEditField.Value;
            app.C.FLAG_FIRST = true;
            %% Element_matrix
            app.C.A0.Value = zeros(app.S.NSpecies,app.E.NE);
            for i=1:app.S.NSpecies
                txFormula = app.strThProp.(app.S.NameSpecies{i,1}).txFormula;
                app.strThProp.(app.S.NameSpecies{i,1}).Element_matrix = set_element_matrix(txFormula,app.E.Elements);
                app.C.A0.Value(i,app.strThProp.(app.S.NameSpecies{i,1}).Element_matrix(1,:)) = app.strThProp.(app.S.NameSpecies{i,1}).Element_matrix(2,:);
            end
            %% M0 matrix
            app.C.M0.Value = zeros(app.S.NSpecies,12);
            %% GUESS INITIAL CALCULATION
            app.TN.guess = [2000,2000,0,1.5,2];
            app.TN.ERRFT = 1e-5;
            app.TN.ERRFU = 1e-5;
            toc;
        end
        
        
        function initialize2(app)
                %% Incomplete combustion
                % First we eliminate from the minor species list those considered major
                % species in case the user has included any
                n_pass = [];
                for n = 1:app.M.L_minor
                    if ~any(strcmp(app.M.minor_products{n},app.S.List_fixed_Species))
                        n_pass = [n_pass, n];
                    end
                end
                app.M.minor_products = app.M.minor_products(n_pass);
                app.C.M0.Value = zeros(app.S.NSpecies,12);
                app.S.List_Compute_Species = [app.S.List_fixed_Species,app.M.minor_products];
                %% Compute alpha, beta, gamma, omega and index of minor products
                if app.Misc.FLAG_FIRST
                    app.M.idx_minor = [];
                    for n=app.M.L_minor:-1:1
                        % Properties of other minor species under consideration, which can
                        % be written in the generic form C_alpha H_beta O_gamma N_omega
                        % Find index minor species
                        app.M.idx_minor(n) = find(strcmp(app.S.NameSpecies,app.M.minor_products{n}));
                        app.Misc.FLAG_FIRST = false;
                    end
                    app.C.alpha = app.C.A0.Value(app.M.idx_minor,app.E.ind_C)';
                    app.C.beta  = app.C.A0.Value(app.M.idx_minor,app.E.ind_H)';
                    app.C.gamma = app.C.A0.Value(app.M.idx_minor,app.E.ind_O)';
                    app.C.omega = app.C.A0.Value(app.M.idx_minor,app.E.ind_N)';
                end
                app.S.idx_all = [app.S.idx_fixed,app.M.idx_minor];
            end
        
        function app_edit(app)
            switch app.ProblemType.Value
                case '1' % * TP: Equilibrium composition at defined T and p
                    app.PD.ProblemType = 'TP';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '300'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '1'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'on'; app.edit_TP.Value = '2500'; app.edit_TP.FontWeight = 'bold';
                    app.edit_pP.Editable = 'off'; app.edit_pP.Value = 0; app.edit_pP.FontWeight = 'normal';
                    app.edit_uR.Visible = 'off'; app.edit_uR.Value = '0'; app.edit_uR.Editable = 'off';
                    app.edit_uP.Visible = 'off'; app.edit_uP.Value = 0; app.text_u.Visible = 'off';
                    app.edit_MR.Visible = 'off'; app.edit_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.edit_vP_vR.Visible = 'off'; app.edit_vP_vR.Enable = 'off'; app.text_vP_vR.Visible = 'off';
                case '2' % * HP: Adiabatic T and composition at constant p
                    app.PD.ProblemType = 'HP';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '300'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '1'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'off'; app.edit_TP.Value = '0'; app.edit_TP.FontWeight = 'normal';
                    app.edit_pP.Editable = 'off'; app.edit_pP.Value = sscanf(app.edit_pR.Value,'%f'); app.edit_pP.FontWeight = 'normal';
                    app.edit_uR.Visible = 'off'; app.edit_uR.Value = '0'; app.edit_uR.Editable = 'off';
                    app.edit_uP.Visible = 'off'; app.edit_uP.Value = 0; app.text_u.Visible = 'off';
                    app.edit_MR.Visible = 'off'; app.edit_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.edit_vP_vR.Visible = 'off'; app.edit_vP_vR.Enable = 'off'; app.text_vP_vR.Visible = 'off';
                case '3' % * SP: Isentropic (i.e., adiabatic) compression/expansion to a specified p
                    app.PD.ProblemType = 'SP';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '300'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '20'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'off'; app.edit_TP.Value = '0'; app.edit_TP.FontWeight = 'normal';
                    app.edit_pP.Editable = 'on'; app.edit_pP.Value = 20; app.edit_pP.FontWeight = 'bold';
                    app.edit_uR.Visible = 'off'; app.edit_uR.Value = '0'; app.edit_uR.Editable = 'off';
                    app.edit_uP.Visible = 'off'; app.edit_uP.Value = 0; app.text_u.Visible = 'off';
                    app.edit_MR.Visible = 'off'; app.edit_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.edit_vP_vR.Visible = 'off'; app.edit_vP_vR.Enable = 'off'; app.text_vP_vR.Visible = 'off';
                case '4' % * TV: Equilibrium composition at defined T and constant v
                    app.PD.ProblemType = 'TV';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '300'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '1'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'on'; app.edit_TP.Value = '2500'; app.edit_TP.FontWeight = 'bold';
                    app.edit_pP.Editable = 'off'; app.edit_pP.Value = sscanf(app.edit_pR.Value,'%f'); app.edit_pP.FontWeight = 'normal';
                    app.edit_uR.Visible = 'off'; app.edit_uR.Value = '0'; app.edit_uR.Editable = 'off';
                    app.edit_uP.Visible = 'off'; app.edit_uP.Value = 0; app.text_u.Visible = 'off';
                    app.edit_MR.Visible = 'off'; app.edit_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.edit_vP_vR.Visible = 'off'; app.edit_vP_vR.Enable = 'off'; app.text_vP_vR.Visible = 'off';
                case '5' % * EV: Equilibrium composition at Adiabatic T and constant v
                    app.PD.ProblemType = 'EV';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '1000'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '1'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'off'; app.edit_TP.Value = '0'; app.edit_TP.FontWeight = 'normal';
                    app.edit_pP.Editable = 'off'; app.edit_pP.Value = sscanf(app.edit_pR.Value,'%f'); app.edit_pP.FontWeight = 'normal';
                    app.edit_uR.Visible = 'off'; app.edit_uR.Value = '0'; app.edit_uR.Editable = 'off';
                    app.edit_uP.Visible = 'off'; app.edit_uP.Value = 0; app.text_u.Visible = 'off';
                    app.edit_MR.Visible = 'off'; app.edit_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.edit_vP_vR.Visible = 'off'; app.edit_vP_vR.Enable = 'off'; app.text_vP_vR.Visible = 'off';
                case '6' % * SV: Isentropic (i.e., fast adiabatic) compression/expansion to a specified v
                    app.PD.ProblemType = 'SV';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '300'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '1'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'off'; app.edit_TP.Value = '0'; app.edit_TP.FontWeight = 'normal';
                    app.edit_pP.Editable = 'off'; app.edit_pP.Value = sscanf(app.edit_pR.Value,'%f'); app.edit_pP.FontWeight = 'normal';
                    app.edit_uR.Visible = 'off'; app.edit_uR.Value = '0'; app.edit_uR.Editable = 'off';
                    app.edit_uP.Visible = 'off'; app.edit_uP.Value = 0; app.text_u.Visible = 'off';
                    app.edit_MR.Visible = 'off'; app.edit_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.edit_vP_vR.Visible = 'on'; app.edit_vP_vR.Enable = 'on'; app.edit_vP_vR.Value = 0.5; app.text_vP_vR.Visible = 'on';
                case '7' % * SHOCK_I: CALCULATE PLANAR INCIDENT SHOCK WAVE
                    app.PD.ProblemType = 'SHOCK_I';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '300'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '1'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'off'; app.edit_TP.Value = '0'; app.edit_TP.FontWeight = 'normal';
                    app.edit_pP.Editable = 'off'; app.edit_pP.Value = 0; app.edit_pP.FontWeight = 'normal';
                    app.edit_uR.Visible = 'on'; app.edit_uR.Value = '500'; app.edit_uR.Editable = 'on'; app.edit_uR.FontWeight = 'bold';
                    app.edit_uP.Visible = 'on'; app.edit_uP.Value = 0; app.text_u.Visible = 'on';
                    app.edit_MR.Visible = 'on'; app.edit_MR.Editable = 'on'; app.edit_MR.FontWeight = 'bold';
                    app.edit_MP.Visible = 'on'; app.text_M.Visible = 'on';
                    app.edit_vP_vR.Visible = 'off'; app.edit_vP_vR.Enable = 'off'; app.text_vP_vR.Visible = 'off';
                    %%%% GET TEMPERATURE REACTIVES
                    TR = app.edit_TR.Value;
                    if TR(1) == '['
                        TR = sscanf(TR, '[%f:%f:%f]');
                        TR = TR(1);
                    else
                        TR = sscanf(TR,'%f');
                    end
                    %%%% GET PRESSURE REACTIVES
                    pR = app.edit_pR.Value;
                    if pR(1) == '['
                        pR = sscanf(pR, '[%f:%f:%f]');
                        pR = pR(1);
                    else
                        pR = sscanf(pR,'%f');
                    end
                    %%%% SELECTION REACTANTS
                    numberReactants = app.Reactants.Value;
                    %%%% SELECT REACTANT
                    switch numberReactants
                        case '1'
                            app.PD.R_Fuel = 0;
                            app.PD.S_Oxidizer = {'O2'};
                            app.PD.N_Oxidizer = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                            app.PD.R_Oxidizer = SetSpecies(app.C.M0.Value,app.PD.S_Oxidizer,app.PD.N_Oxidizer,TR,find_idx(app.PD.S_Oxidizer,app.S.NameSpecies),app.strThProp);
                            app.PD.S_Inert = {'N2','Ar'};
                            app.PD.N_Inert = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                            app.PD.R_Inert = SetSpecies(app.C.M0.Value,app.PD.S_Inert,app.PD.N_Inert,TR,find_idx(app.PD.S_Inert,app.S.NameSpecies),app.strThProp);
                            %%%% COMPUTE PROPERTIES OF REACTANTS
                            app.PD.R = app.PD.R_Fuel + app.PD.R_Oxidizer + app.PD.R_Inert;
                            app.PS.strR{1} = ComputeProperties(app.C.A0.Value,app.PD.R,pR,TR,app.E.ind_C,app.E.ind_H);
                            app.edit_MR.Value = sprintf('%.4g',sscanf(app.edit_uR.Value,'%f')/app.PS.strR{1}.sound);
                        case '2'
                            app.PD.R_Fuel = 0;
                            app.PD.S_Oxidizer = {'O2'};
                            app.PD.N_Oxidizer = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                            app.PD.R_Oxidizer = SetSpecies(app.C.M0.Value,app.PD.S_Oxidizer,app.PD.N_Oxidizer,TR,find_idx(app.PD.S_Oxidizer,app.S.NameSpecies),app.strThProp);
                            app.PD.S_Inert = {'N2','Ar'};
                            app.PD.N_Inert = [app.uitable_R.Data{strcmpi(app.species,'N2'),2},app.uitable_R.Data{strcmpi(app.species,'Ar'),2}];
                            app.PD.R_Inert = SetSpecies(app.C.M0.Value,app.PD.S_Inert,app.PD.N_Inert,TR,find_idx(app.PD.S_Inert,app.S.NameSpecies),app.strThProp);
                            %%%% COMPUTE PROPERTIES OF REACTANTS
                            app.PD.R = app.PD.R_Fuel + app.PD.R_Oxidizer + app.PD.R_Inert;
                            app.PS.strR{1} = ComputeProperties(app.C.A0.Value,app.PD.R,pR,TR,app.E.ind_C,app.E.ind_H);
                            app.edit_MR.Value = sprintf('%.4g',sscanf(app.edit_uR.Value,'%f')/app.PS.strR{1}.sound);
                        otherwise
                    end
                case '8' % * SHOCK_R: CALCULATE PLANAR POST-REFLECTED SHOCK STATE
                    app.PD.ProblemType = 'SHOCK_R';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '300'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '1'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'off'; app.edit_TP.Value = '0'; app.edit_TP.FontWeight = 'normal';
                    app.edit_pP.Editable = 'off'; app.edit_pP.Value = 0; app.edit_pP.FontWeight = 'normal';
                    app.edit_uR.Visible = 'on'; app.edit_uR.Value = '500'; app.edit_uR.Editable = 'on'; app.edit_uR.FontWeight = 'bold';
                    app.edit_uP.Visible = 'on'; app.edit_uP.Value = 0; app.text_u.Visible = 'on';
                    app.edit_MR.Visible = 'on'; app.edit_MR.Editable = 'on'; app.edit_MR.FontWeight = 'bold';
                    app.edit_MP.Visible = 'on'; app.text_M.Visible = 'on';
                    app.edit_vP_vR.Visible = 'off'; app.edit_vP_vR.Enable = 'off'; app.text_vP_vR.Visible = 'off';
                    %%%% GET TEMPERATURE REACTIVES
                    TR = app.edit_TR.Value;
                    if TR(1) == '['
                        TR = sscanf(TR, '[%f:%f:%f]');
                        TR = TR(1);
                    else
                        TR = sscanf(TR,'%f');
                    end
                    %%%% GET PRESSURE REACTIVES
                    pR = app.edit_pR.Value;
                    if pR(1) == '['
                        pR = sscanf(pR, '[%f:%f:%f]');
                        pR = pR(1);
                    else
                        pR = sscanf(pR,'%f');
                    end
                    %%%% SELECTION REACTANTS
                    species = app.species;
                    numberReactants = app.Reactants.Value;
                    %%%% SELECT REACTANT
                    switch numberReactants
                        case '1'
                            app.PD.R_Fuel = 0;
                            app.PD.S_Oxidizer = {'O2'};
                            app.PD.N_Oxidizer = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                            app.PD.R_Oxidizer = SetSpecies(app.C.M0.Value,app.PD.S_Oxidizer,app.PD.N_Oxidizer,TR,find_idx(app.PD.S_Oxidizer,app.S.NameSpecies),app.strThProp);
                            app.PD.S_Inert = {'N2','Ar'};
                            app.PD.N_Inert = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                            app.PD.R_Inert = SetSpecies(app.C.M0.Value,app.PD.S_Inert,app.PD.N_Inert,TR,find_idx(app.PD.S_Inert,app.S.NameSpecies),app.strThProp);
                            %%%% COMPUTE PROPERTIES OF REACTANTS
                            app.PD.R = app.PD.R_Fuel + app.PD.R_Oxidizer + app.PD.R_Inert;
                            app.PS.strR{1} = ComputeProperties(app.C.A0.Value,app.PD.R,pR,TR,app.E.ind_C,app.E.ind_H);
                            app.edit_MR.Value = sprintf('%.4g',sscanf(app.edit_uR.Value,'%f')/app.PS.strR{1}.sound);
                        case '2'
                            app.PD.R_Fuel = 0;
                            app.PD.S_Oxidizer = {'O2'};
                            app.PD.N_Oxidizer = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                            app.PD.R_Oxidizer = SetSpecies(app.C.M0.Value,app.PD.S_Oxidizer,app.PD.N_Oxidizer,TR,find_idx(app.PD.S_Oxidizer,app.S.NameSpecies),app.strThProp);
                            app.PD.S_Inert = {'N2','Ar'};
                            app.PD.N_Inert = [app.uitable_R.Data{strcmpi(app.species,'N2'),2},app.uitable_R.Data{strcmpi(app.species,'Ar'),2}];
                            app.PD.R_Inert = SetSpecies(app.C.M0.Value,app.PD.S_Inert,app.PD.N_Inert,TR,find_idx(app.PD.S_Inert,app.S.NameSpecies),app.strThProp);
                            %%%% COMPUTE PROPERTIES OF REACTANTS
                            app.PD.R = app.PD.R_Fuel + app.PD.R_Oxidizer + app.PD.R_Inert;
                            app.PS.strR{1} = ComputeProperties(app.C.A0.Value,app.PD.R,pR,TR,app.E.ind_C,app.E.ind_H);
                            app.edit_MR.Value = sprintf('%.4g',sscanf(app.edit_uR.Value,'%f')/app.PS.strR{1}.sound);
                        otherwise
                    end
                case '9' % * DET: CALCULATE CHAPMAN-JOUGET STATE (CJ UPPER STATE)
                    app.PD.ProblemType = 'DET';
                    app.edit_TR.Editable = 'on'; app.edit_TR.Value = '300'; app.edit_TR.FontWeight = 'bold';
                    app.edit_pR.Editable = 'on'; app.edit_pR.Value = '1'; app.edit_pR.FontWeight = 'bold';
                    app.edit_TP.Editable = 'off'; app.edit_TP.Value = '0'; app.edit_TP.FontWeight = 'normal';
                    app.edit_pP.Editable = 'off'; app.edit_pP.Value = 0; app.edit_pP.FontWeight = 'normal';
                    app.edit_uR.Visible = 'on'; app.edit_uR.Value = '0'; app.edit_uR.Editable = 'off'; app.edit_uR.FontWeight = 'normal';
                    app.edit_uP.Visible = 'on'; app.edit_uP.Value = 0; app.text_u.Visible = 'on';
                    app.edit_MR.Visible = 'on'; app.edit_MP.Visible = 'on'; app.text_M.Visible = 'on'; app.edit_MR.FontWeight = 'normal'; app.edit_MR.Value = '0'; app.edit_MR.Editable = 'off'; 
                    app.edit_vP_vR.Visible = 'off'; app.edit_vP_vR.Enable = 'off'; app.text_vP_vR.Visible = 'off';
                otherwise
            end
        end
        
        function find_minor_species(app,varargin)
            if nargin == 1
                n_pass = [];
                for n = 1:app.S.NSpecies
                    if ~any(strcmp(app.S.NameSpecies{n},app.S.List_fixed_Species))
                        n_pass = [n_pass, n];
                    end
                end
                if ~isempty(app.S.NameSpecies(n_pass))
                    app.M.minor_products = app.S.NameSpecies(n_pass)';
                    app.M.L_minor = length(app.M.minor_products);
                else
                    app.M.minor_products = {};
                    app.M.L_minor = 0;
                end
            else
                app.M.minor_products = varargin{1,1};
                app.M.L_minor = length(app.M.minor_products);
                n_pass = [];
                for n = 1:app.M.L_minor
                    if ~any(strcmp(app.M.minor_products{n},app.S.List_fixed_Species))
                        n_pass = [n_pass, n];
                    end
                end
                if ~isempty(app.M.minor_products(n_pass))
                    app.M.minor_products = app.M.minor_products(n_pass);
                    app.M.L_minor = length(app.M.minor_products);
                else
                    app.M.minor_products = {};
                    app.M.L_minor = 0;
                end
            end
            if app.Misc.FLAG_FIRST
                app.M.idx_minor = [];
                for n=app.M.L_minor:-1:1
                    % Properties of other minor species under consideration, which can
                    % be written in the generic form C_alpha H_beta O_gamma N_omega
                    % Find index minor species
                    app.M.idx_minor(n) = find(strcmp(app.S.NameSpecies,app.M.minor_products{n}));
                    app.Misc.FLAG_FIRST = false;
                end
                app.C.alpha = app.C.A0.Value(app.M.idx_minor,app.E.ind_C)';
                app.C.beta  = app.C.A0.Value(app.M.idx_minor,app.E.ind_H)';
                app.C.gamma = app.C.A0.Value(app.M.idx_minor,app.E.ind_O)';
                app.C.omega = app.C.A0.Value(app.M.idx_minor,app.E.ind_N)';
            end
            app.S.idx_all = [app.S.idx_fixed,app.M.idx_minor];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Combustion_solver_OpeningFcn(app)
            %%%% INITILIZATION %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            d = uiprogressdlg(app.figure_main,'Title','Please Wait','Message','Opening the application ...');
            fontsize = 18; linewidth = 1.6;
            set(app.UIAxes,'LineWidth',linewidth,'FontSize',fontsize,'BoxStyle','full');
            xlabel(app.UIAxes,"Equivalence ratio, $\phi [-]$",'FontSize',fontsize+4,'interpreter','latex');
            ylabel(app.UIAxes,"Molar fraction, $X_i [-]$",'FontSize',fontsize+4,'interpreter','latex');
            app.uitable_R.ColumnFormat = {[] [] [] {'Fuel', 'Oxidant', 'Inert'}};
            app.flag = false;
            app.selected_Reactive = 1;
            %%%% Resize and centering app
            resize_app(app,'figure_main');
            %%%% Command window
            % MirrorCmdWindow;
            %%%% Load Databases and constants
            d.Value = 1; d.Message = 'Loading NASA Database ...';
            Initialize(app); % Load databases and constants
            d.Message = 'Adjusting parameters ...';
            % Minor products
            n_pass = [];
            for n = 1:length(app.S.NameSpecies)
                if ~any(strcmp(app.S.NameSpecies{n},app.S.List_fixed_Species))
                    n_pass = [n_pass, n];
                end
            end
            if ~isempty(app.S.NameSpecies(n_pass))
                app.M.minor_products = app.S.NameSpecies(n_pass)';
                app.M.L_minor = length(app.M.minor_products);
            else
                app.M.minor_products = {};
                app.M.L_minor = 0;
            end
            for n=app.M.L_minor:-1:1
                % Properties of other minor species under consideration, which can
                % be written in the generic form C_alpha H_beta O_gamma N_omega
                % Find index minor species
                app.M.idx_minor(n) = find(strcmp(app.S.NameSpecies,app.M.minor_products{n}));
                app.Misc.FLAG_FIRST = false;
            end
            app.C.alpha = app.C.A0.Value(app.M.idx_minor,app.E.ind_C)';
            app.C.beta  = app.C.A0.Value(app.M.idx_minor,app.E.ind_H)';
            app.C.gamma = app.C.A0.Value(app.M.idx_minor,app.E.ind_O)';
            app.C.omega = app.C.A0.Value(app.M.idx_minor,app.E.ind_N)';
            app.S.idx_all = [app.S.idx_fixed,app.M.idx_minor];
            %%%% Initialize table data
            data_R = {'N2',0.79/0.21,0.79,'Inert';'O2',1,0.21,'Oxidant'};
            app.species = {'N2';'O2'};
%             data_P = app.species;
            app.uitable_R.Data = data_R;
            app.uitable_P.Data = data_R(:,1);
            %%%% Clear memory
            clear data_R;
            %%%% Initialize Selection of species to be plotted
            app.listbox_species_2.Items = app.S.NameSpecies;
            app.listbox_plotted_species.Items = {'CO2','H2O','N2','O2','CO','H2','H','NO','OH','O','Cbgrb','CH4'};
            %%%% Load species and minor products species listboxes
            fnm = fieldnames(app.strMaster);
%             for i=numel(fnm):-1:1
%                 j(i) = ~any(strcmpi(fnm{i},minor_products));
%             end
%             ind = find(j>0);
            for i=length(fnm):-1:1
                app.NameSpecies_Master{i} = app.strMaster.(fnm{i}).FullName;
            end
            app.listbox_species.Items = app.NameSpecies_Master;
            app.listbox_minor_products.Items = app.M.minor_products;
            app.text_list_species.Text =  ['List of species (',sprintf('%d',numel(app.NameSpecies_Master)),')'];
%             app.radiobutton_incomplete.Text = ['Incomplete (',num2str(length(minor_products)+length(List_fixed_Species)),')'];
            app.Reaction.Items{2} = ['Selected products (',sprintf('%d',app.M.L_minor+length(app.S.List_fixed_Species)),')'];
            app.text_minor_products.Text = ['Minor products (',sprintf('%d',app.M.L_minor),')'];
            app.minor_products = app.species';
            app.C.tolN = app.TraceoptionEditField.Value;
            app.uitable_R.Data(:,5) = {sscanf(app.edit_TR.Value,'%f')};
            % Save data
%             app.minor_products = app.minor_products;
            app.numelstrMaster = numel(fnm);
            
            d.Message = 'Finishing!'; d.Icon = 'success';
            pause (0.3) % Wait - adjusting app
            close(d)
        end

        % Value changed function: edit_phi
        function edit_phi_Callback(app, event)
            phi = app.edit_phi.Value;
            if any(strcmpi(app.Reactants.Value,{'1','2'})) && ~app.flag
                    message = sprintf('There is no fuel! That will not have any effect. \n Consider change reactants.');
                    uialert(app.figure_main,message,'Warning','Icon','warning');
                    %             elseif phi < 0.001
                    %                 message = sprintf('Equivalence ratio too lean. \n Notice that there exist a flammability limit.');
                    %                 uialert(app.figure_main,message,'Warning','Icon','warning');
            elseif phi(1) ~= '[' && ~app.flag
                app.PD.phi.Value = sscanf(phi,'%f');
                switch app.selected_Reactive
                    case 3
                        data_R = {2*(79/21)/app.PD.phi.Value;2/app.PD.phi.Value;1};
                    case 4
                        data_R = {2*(78.09/20.95)/app.PD.phi.Value;2/app.PD.phi.Value;2*(0.96/20.95)/app.PD.phi.Value;1};
                    case 5
                        data_R = {2/app.PD.phi.Value;1};
                    case {6,7}
                        data_R = {3.5*(79/21)/app.PD.phi.Value;3.5/app.PD.phi.Value;1};
                    case {8,9}
                        data_R = {5*(79/21)/app.PD.phi.Value;5/app.PD.phi.Value;1};
                    case {10,11}
                        data_R = {2.5*(79/21)/app.PD.phi.Value;2.5/app.PD.phi.Value;1};
                    case {12,13}
                        data_R = {3*(79/21)/app.PD.phi.Value;3/app.PD.phi.Value;1};
                    case {14,15}
                        data_R = {7.5*(79/21)/app.PD.phi.Value;7.5/app.PD.phi.Value;1};
                    case {16,17}
                        data_R = {12.5*(79/21)/app.PD.phi.Value;12.5/app.PD.phi.Value;1};
                    case {18,19}
                        data_R = {0.5*(79/21)/app.PD.phi.Value;0.5/app.PD.phi.Value;1};
                    case {20,21}
                        data_R = {0.5*(79/21)/app.PD.phi.Value;0.5/app.PD.phi.Value;1};
                    case {22,23}
                        data_R = {1.5*(79/21)/app.PD.phi.Value;1.5/app.PD.phi.Value;1};
                    case {24,25}
                        data_R = {3*(79/21)/app.PD.phi.Value;3/app.PD.phi.Value;1};
                    case {26,27}
                        data_R = {2.3*(79/21)/app.PD.phi.Value;2.3/app.PD.phi.Value;0.85;0.1;0.05};
                    case {28,29}
                        data_R = {(79/21)/app.PD.phi.Value;1/app.PD.phi.Value;0.5;0.5};
                end
                if app.selected_Reactive>5 && mod(app.selected_Reactive,2)
                    data_R = data_R(2:end,:);
                end
                NSpecies_R = numel(app.species);
                Species = string(NSpecies_R);
                for i=NSpecies_R:-1:1
                    Species(i) = app.uitable_R.Data{i,1};
                    Ni(i) = data_R{i};
                end
                N = sum(Ni);
                Xi = Ni/N;
                for i=1:NSpecies_R
                    data_R{i,2} = Xi(i);
                end
                app.uitable_R.Data(:,2:3) = data_R;
            end
        end

        % Value changed function: edit_seeker
        function edit_seeker_Callback(app, event)
            %             seek_specie = app.edit_seeker.Value;
            seek_specie = event.Value;
            list_species = app.NameSpecies_Master;
            for i=numel(list_species):-1:1
                %         j(i) = contains(list_species{i},seek_specie,'IgnoreCase',false);
                j(i) = startsWith(list_species{i},seek_specie,'IgnoreCase',false);
            end
            ind = find(j>0);
            if ~isempty(ind)
                for i=length(ind):-1:1
                    SelectedSpecies_Master{i} = list_species{ind(i)};
                end
                app.listbox_species.Items = SelectedSpecies_Master;
                app.text_list_species.Text =  ['List of species (',num2str(numel(ind)),')'];
            else
                app.listbox_species.Items = {};
                app.text_list_species.Text =  'List of species (0)';
            end
        end

        % Value changed function: ProblemType
        function ProblemType_Callback(app, event)
            % Clear last results
            app.edit_rR.Value = 0; app.edit_hR.Value = 0; app.edit_sR.Value = 0; app.edit_cpR.Value = 0;
            app.edit_WR.Value = 0; app.edit_soundR.Value = 0; app.edit_gammaR.Value = 0;
            app.edit_eR.Value = 0; app.edit_eP.Value = 0; 
            app.edit_rP.Value = 0; app.edit_hP.Value = 0; app.edit_sP.Value = 0; app.edit_cpP.Value = 0;
            app.edit_WP.Value = 0; app.edit_soundP.Value = 0; app.edit_gammaP.Value = 0;
            % Callback edit app 
            app_edit(app);
        end

        % Value changed function: Reactants
        function Reactants_Callback(app, event)
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.Reaction.Value = '1';
            app.edit_phi.Value = '1';
            app.selected_Reactive = sscanf(app.Reactants.Value,'%d');
            if any(strcmp(app.Reactants.Value,{'1','2'}))
                load('strThProp_airnasanoions.mat');
                app.strThProp = strThProp; clear strThProp;
                app.S.NameSpecies = fieldnames(app.strThProp);
                app.PD.Fuel.x = 0;
                if app.Reactants.Value == '1'
                    data_R = {'N2',0.79/0.21,0.79,'Inert';'O2',1,0.21,'Oxidant'};
                    app.uitable_P.Data = data_R(:,1);
                elseif app.Reactants.Value == '2'
                    data_R = {'N2',0.78090/0.20950,0.78090,'Inert';'O2',1,0.20950,'Oxidant';'Ar',0.00960/0.20950,0.00960,'Inert'};
                    app.uitable_P.Data = data_R(:,1);
                else
                    app.uitable_P.Data = app.S.NameSpecies(:);
                end
                app.edit_phi.Value = '-';
            else
                if any(strcmp(app.Reactants.Value,{'18','19'}))
                    load('strThProp_hydrogen_air_complete');
                    app.strThProp = strThProp; clear strThProp;
                else
                    load('strThProp_HC_47');
                    app.strThProp = strThProp; clear strThProp;
                end
                app.S.NameSpecies = fieldnames(app.strThProp);
                if app.selected_Reactive == 3 || ~mod(app.selected_Reactive,2)
                    if app.selected_Reactive ~= 4
                        app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2';'N2'};
                    else
                        app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2';'N2';'Ar'};
                    end
                elseif mod(app.selected_Reactive,2)
                    app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2'};
                else
                    app.uitable_P.Data = app.S.NameSpecies(:);
                end
                switch app.selected_Reactive
                    case 3
                        data_R = {'N2';'O2';'CH4'};
                        data_R(:,2) = {2*(79/21);2;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case 4
                        data_R = {'N2';'O2';'Ar';'CH4'};
                        data_R(:,2) = {2*(78.09/20.95);2;2*(0.96/20.95);1};
                        data_R(:,4) = {'Inert';'Oxidant';'Inert';'Fuel'};
                    case 5
                        data_R = {'O2';'CH4'};
                        data_R(:,2) = {2;1};
                        data_R(:,4) = {'Oxidant';'Fuel'};
                    case {6,7}
                        data_R = {'N2';'O2';'C2H6'};
                        data_R(:,2) = {3.5*(79/21);3.5;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {8,9}
                        data_R = {'N2';'O2';'C3H8'};
                        data_R(:,2) = {5*(79/21);5;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {10,11}
                        data_R = {'N2';'O2';'C2H2_acetylene'};
                        data_R(:,2) = {2.5*(79/21);2.5;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {12,13}
                        data_R = {'N2';'O2';'C2H4'};
                        data_R(:,2) = {3*(79/21);3;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {14,15}
                        data_R = {'N2';'O2';'C6H6'};
                        data_R(:,2) = {7.5*(79/21);7.5;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {16,17}
                        data_R = {'N2';'O2';'C8H18_isooctane'};
                        data_R(:,2) = {12.5*(79/21);12.5;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {18,19}
                        data_R = {'N2';'O2';'H2'};
                        data_R(:,2) = {0.5*(79/21);0.5;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {20,21}
                        data_R = {'N2';'O2';'CO'};
                        data_R(:,2) = {0.5*(79/21);0.5;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {22,23}
                        data_R = {'N2';'O2';'CH3OH'};
                        data_R(:,2) = {1.5*(79/21);1.5;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {24,25}
                        data_R = {'N2';'O2';'C2H5OH'};
                        data_R(:,2) = {3*(79/21);3;1};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {26,27}
                        data_R = {'N2';'O2';'CH4';'C2H6';'C3H8'};
                        data_R(:,2) = {2.3*(79/21);2.3;0.85;0.1;0.05};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel';'Fuel';'Fuel'};
                    case {28,29}
                        data_R = {'N2';'O2';'CO';'H2'};
                        data_R(:,2) = {(79/21);1;0.5;0.5};
                        data_R(:,4) = {'Inert';'Oxidant';'Fuel';'Fuel'};
                end
                if app.selected_Reactive>5 && mod(app.selected_Reactive,2)
                    data_R = data_R(2:end,:);
                end
            end
            app.Misc.FLAG_FIRST = true;
            app.S.NSpecies = length(app.S.NameSpecies);
            app.species = data_R(:,1);
            NSpecies_R = numel(app.species);
            Species = string(NSpecies_R);
            for i=NSpecies_R:-1:1
                [Species(i),Ni(i)] = data_R{i,1:2};
            end
            N = sum(Ni);
            Xi = Ni/N;
            for i=1:NSpecies_R
                data_R{i,3} = Xi(i);
            end
            app.uitable_R.Data = data_R;
            app.flag = false;
            %%%%% 
            if any(strcmp(app.Reactants.Value,{'1','2'}))
                app.Reaction.Items = {'Complete + W-G','Selected products','Dissociated air'};
                app.Reaction.ItemsData = {'1','2','3'};
            else
                app.Reaction.Items = {'Complete + W-G','Selected products','HC/O2/N2','HC/O2/N2 extended','HC/O2/N2 rich'};
                app.Reaction.ItemsData = {'1','2','3','4','5'};
            end
            %%%%%
            % A0 matrix
            app.C.A0.Value = zeros(app.S.NSpecies,app.E.NE);
            for i=1:app.S.NSpecies
                txFormula = app.strThProp.(app.S.NameSpecies{i,1}).txFormula;
                app.strThProp.(app.S.NameSpecies{i,1}).Element_matrix = set_element_matrix(txFormula,app.E.Elements);
                app.C.A0.Value(i,app.strThProp.(app.S.NameSpecies{i,1}).Element_matrix(1,:)) = app.strThProp.(app.S.NameSpecies{i,1}).Element_matrix(2,:);
            end
            % Minor products
            find_minor_species(app);
            %%% Check minor products and Elements
            [app.strThProp,app.E,app.S,app.M,app.C] = check_database(app.strMaster,app.strThProp,app.E,app.S,app.M,app.C);
            %%%
            app.Reaction.Items{2} = ['Selected products (',sprintf('%d',app.M.L_minor+length(app.S.Lfixed)),')'];
            app.text_minor_products.Text = ['Minor products (',sprintf('%d',app.M.L_minor),')'];
            app.listbox_minor_products.Items = app.M.minor_products;
            %%%%%
            TR = app.edit_TR.Value;
            if TR(1) == '['
                TR = sscanf(TR, '[%f:%f:%f]');
                TR = TR(1);
            else
                TR = sscanf(TR,'%f');
            end
            app.uitable_R.Data(:,5) = {TR};    
            %%%%%
        end

        % Menu selected function: view_density
        function view_density_Callback(app, event)
            %             pos_size = app.figure_main.Position;
            %
            %             % Call display_density with the argument 'Position'.
            %             user_response = display_density('Title','Confirm Close');
            %             switch user_response
            %             case 'Compact'
            %             	% Change display density to compact view.
            %                 % NOT YET
            %             case 'Default'
            %             	return;
            %             end
        end

        % Button pushed function: Calculate
        function Calculate_Callback(app, event)
            %%%% STARTING
%             profile on
            %% GUESS INITIAL CALCULATION
            app.TN.guess = [2000,2000,0,1.5,2];
            %% Clear all values
            app.PS.strP = [];
            %% Initilize 2
            initialize2(app);
            %% INDEX OF EVALUABLE SPECIES
            app.S.idx_CO2 = find_idx({'CO2'},app.S.NameSpecies);
            app.S.idx_CO  = find_idx({'CO'},app.S.NameSpecies);
            app.S.idx_H2O = find_idx({'H2O'},app.S.NameSpecies);
            app.S.idx_H2  = find_idx({'H2'},app.S.NameSpecies);
            app.S.idx_O2  = find_idx({'O2'},app.S.NameSpecies);
            app.S.idx_N2  = find_idx({'N2'},app.S.NameSpecies);
            app.S.idx_He  = find_idx({'He'},app.S.NameSpecies);
            app.S.idx_Ar  = find_idx({'Ar'},app.S.NameSpecies);
            app.S.idx_Cgr = find_idx({'Cbgrb'},app.S.NameSpecies);
            app.S.List_fixed_Species = {'CO2','CO','H2O','H2','O2','N2','He','Ar','Cbgrb'};
            app.S.Lfixed = length(app.S.List_fixed_Species);
            app.S.idx_fixed = [app.S.idx_CO2,app.S.idx_CO,app.S.idx_H2O,app.S.idx_H2,app.S.idx_O2,app.S.idx_N2,app.S.idx_He,app.S.idx_Ar,app.S.idx_Cgr];
            app.S.idx_all = [app.S.idx_fixed,app.M.idx_minor];
            %%
            if any(contains(app.M.minor_products,'CH4')) && any(contains(app.M.minor_products,'CH3'))
            app.M.major_CH4 = true;
            app.M.idx_m_CH4 = find_idx({'CH4'},app.M.minor_products);
            app.M.idx_m_CH3 = find_idx({'CH3'},app.M.minor_products);
            app.M.idx_m_H = find_idx({'H'},app.M.minor_products);
%             app.M.idx_m_CH = find_idx({'CH'},app.M.minor_products);
            else
                app.M.major_CH4 = false;
            end
            %% OH major specie
            if any(contains(app.M.minor_products,'OH'))
                app.M.major_OH = true;
                app.M.idx_m_OH = find_idx({'OH'},app.M.minor_products);
            else
                app.M.major_OH = false;
            end
            %%
            app.Lamp.Color = 'Yellow';
            pause(0.005);
            app.C.M0.Value = zeros(app.S.NSpecies,12);
            app.S.List_Compute_Species = [app.S.List_fixed_Species,app.M.minor_products];
            app.C.tolN = app.TraceoptionEditField.Value;
            app.TN.factor_c = app.SootfactorEditField.Value;
            flag = false; flag_TR = false; flag_pR = false; flag_TP = false; flag_pP = false; flag_uR = false;
            aux3 = sscanf(app.Reaction.Value,'%d');
            %%%% MISCELANEOUS
%             fnm = fieldnames(strThProp)';
            for i=app.S.NSpecies:-1:1
                disp_Species_fixed(i)= any(strcmp(app.S.NameSpecies{i},app.S.List_fixed_Species));
            end
            if app.Reaction.Value == '1' || strcmp(app.MajorMenu.Checked,'on')
                if app.selected_Reactive == 1
                    disp_aux = {'N2';'O2'};
                elseif app.selected_Reactive == 2
                    disp_aux = {'N2';'O2';'Ar'};
                elseif app.selected_Reactive == 4
                    disp_aux = {'CO2';'H2O';'CO';'H2';'O2';'N2';'Ar';'Cbgrb'};
                elseif app.selected_Reactive == 3 || ~mod(app.selected_Reactive,2) % any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                    disp_aux = {'CO2';'H2O';'CO';'H2';'O2';'N2';'Cbgrb'};
                elseif mod(app.selected_Reactive,2) % any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                    disp_aux = {'CO2';'H2O';'CO';'H2';'O2';'Cbgrb'};
                end
                for i=app.S.NSpecies:-1:1
                    disp_Species(i)= any(strcmp(app.S.NameSpecies{i},disp_aux));
                end
                disp_fnm = app.S.NameSpecies(disp_Species);
            else
                for i=app.S.NSpecies:-1:1
                    disp_Species(i)= any(strcmp(app.S.NameSpecies{i},app.M.minor_products));
                end
                disp_fnm = [app.S.NameSpecies(disp_Species_fixed);app.S.NameSpecies(disp_Species)];
            end
            %%%% GET EQUIVALENCE RATIO AND TYPE OF REACTION
            app.PD.phi.Value = app.edit_phi.Value;
            if app.PD.phi.Value(1) == '['
                app.PD.phi.Value = sscanf(app.PD.phi.Value, '[%f:%f:%f]');
                app.PD.phi.Value = app.PD.phi.Value(1):app.PD.phi.Value(2):app.PD.phi.Value(3);
                flag = true;
            else
                app.PD.phi.Value = sscanf(app.PD.phi.Value,'%f');
            end
            %%%% SELECTION REACTANTS
            numberReactants = app.Reactants.Value;
            if isempty(app.PD.phi.Value)
                app.PD.phi.Value = 1;
            end
            %%%% GET TEMPERATURE REACTIVES
            TR = app.edit_TR.Value;
            if TR(1) == '['
                TR = sscanf(TR, '[%f:%f:%f]');
                TR_vector = TR(1):TR(2):TR(3);
                flag_TR = true;
                app.PD.phi.Value = app.PD.phi.Value(1)*ones(1,numel(TR_vector));
            else
                TR = sscanf(TR,'%f');
            end
            %%%% GET PRESSURE REACTIVES
            pR = app.edit_pR.Value;
            if pR(1) == '['
                pR = sscanf(pR, '[%f:%f:%f]');
                pR_vector = pR(1):pR(2):pR(3);
                flag_pR = true;
                app.PD.phi.Value = app.PD.phi.Value(1)*ones(1,numel(pR_vector));
            else
                pR = sscanf(pR,'%f');
            end
            %%%% GET TEMPERATURE PRODUCTS
            TP = app.edit_TP.Value;
            if TP(1) == '['
                TP = sscanf(TP, '[%f:%f:%f]');
                TP_vector = TP(1):TP(2):TP(3);
                flag_TP = true;
                app.PD.phi.Value = app.PD.phi.Value(1)*ones(1,numel(TP_vector));
            else
                TP = sscanf(TP,'%f');
            end
            %%%% GET PRESSURE PRODUCTS
%             pP = app.edit_pR.Value;
%             if pP(1) == '['
%                 pP = sscanf(pP, '[%f:%f:%f]');
%                 pP_vector = pP(1):pP(2):pP(3);
%                 flag_pP = true;
%                 app.PD.phi.Value = app.PD.phi.Value(1)*ones(1,numel(pP_vector));
%             else
%                 pP = sscanf(pP,'%f');
%             end
            %%%% GET VELOCITY REACTIVES
            uR = app.edit_uR.Value;
            if uR(1) == '['
                uR = sscanf(uR, '[%f:%f:%f]');
                uR_vector = uR(1):uR(2):uR(3);
                flag_uR = true;
                app.PD.phi.Value = app.PD.phi.Value(1)*ones(1,numel(uR_vector));
            else
                uR = sscanf(uR,'%f');
            end
            %%%% GET MACH REACTIVES
            MR = app.edit_MR.Value;
            if MR(1) == '['
                MR = sscanf(MR, '[%f:%f:%f]');
                MR_vector = MR(1):MR(2):MR(3);
                MR = app.edit_MR.Value;
                %%%% SELECT REACTANT
                switch app.Reactants.Value
                    case '1'
                        R_Fuel = 0;
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                        R_Oxidizer = SetSpecies(app.C.M0.Value,{'O2'},Xi1,TR,app.S.idx_O2,app.strThProp);
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                        R_Inert = SetSpecies(app.C.M0.Value,{'N2'},Xi1,TR,app.S.idx_N2,app.strThProp);
                    case '2'
                        R_Fuel = 0;
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                        R_Oxidizer = SetSpecies(app.C.M0.Value,{'O2'},Xi1,TR,app.S.idx_O2,app.strThProp);
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                        Xi2 = app.uitable_R.Data{strcmpi(app.species,'Ar'),2};
                        R_Inert = SetSpecies(app.C.M0.Value,{'N2','Ar'},[Xi1,Xi2],TR,[app.S.idx_N2,app.S.idx_Ar],app.strThProp);
                    otherwise
                end
                %%%% COMPUTE PROPERTIES OF REACTANTS
                R = R_Fuel + R_Oxidizer + R_Inert;
                strR = ComputeProperties(app.C.A0.Value,R,pR,TR,app.E.ind_C,app.E.ind_H);
                for i=numel(MR_vector):-1:1
                    uR_vector(i) = MR_vector(i)*strR.sound;
                end
                flag_uR = true;
                app.PD.phi.Value = app.PD.phi.Value(1)*ones(1,numel(uR_vector));
            end
            %%%% COMPLETE OR INCOMPLETE
            if app.Reaction.Value == '1'
                app.PD.CompleteOrIncomplete = 'Complete';
            else
                app.PD.CompleteOrIncomplete = 'Incomplete';
            end
            %%%%% START LOOPING
            app.C.l_phi = length(app.PD.phi.Value);
            data_R = app.uitable_R.Data;
            for i=app.C.l_phi:-1:1
                if flag_TR; TR = TR_vector(i); end
                if flag_pR; pR = pR_vector(i); end
                %%%% REACTANTS SPECIES COMPOSITION
                if flag
                    switch app.selected_Reactive
                        case 3
                            data_R(:,2) = {2*(79/21)/app.PD.phi.Value(i);2/app.PD.phi.Value(i);1};
                        case 4
                            data_R(:,2) = {2*(78.09/20.95)/app.PD.phi.Value(i);2/app.PD.phi.Value(i);2*(0.96/20.95)/app.PD.phi.Value(i);1};
                        case 5
                            data_R(:,2) = {2/app.PD.phi.Value(i);1};
                        case {6,7}
                            data_R(:,2) = {3.5*(79/21)/app.PD.phi.Value(i);3.5/app.PD.phi.Value(i);1};
                        case {8,9}
                            data_R(:,2) = {5*(79/21)/app.PD.phi.Value(i);5/app.PD.phi.Value(i);1};
                        case {10,11}
                            data_R(:,2) = {2.5*(79/21)/app.PD.phi.Value(i);2.5/app.PD.phi.Value(i);1};
                        case {12,13}
                            data_R(:,2) = {3*(79/21)/app.PD.phi.Value(i);3/app.PD.phi.Value(i);1};
                        case {14,15}
                            data_R(:,2) = {7.5*(79/21)/app.PD.phi.Value(i);7.5/app.PD.phi.Value(i);1};
                        case {16,17}
                            data_R(:,2) = {12.5*(79/21)/app.PD.phi.Value(i);12.5/app.PD.phi.Value(i);1};
                        case {18,19}
                            data_R(:,2) = {0.5*(79/21)/app.PD.phi.Value(i);0.5/app.PD.phi.Value(i);1};
                        case {20,21}
                            data_R(:,2) = {0.5*(79/21)/app.PD.phi.Value(i);0.5/app.PD.phi.Value(i);1};
                        case {22,23}
                            data_R(:,2) = {1.5*(79/21)/app.PD.phi.Value(i);1.5/app.PD.phi.Value(i);1};
                        case {24,25}
                            data_R(:,2) = {3*(79/21)/app.PD.phi.Value(i);3/app.PD.phi.Value(i);1};
                        case {26,27}
                            data_R(:,2) = {2.3*(79/21)/app.PD.phi.Value(i);2.3/app.PD.phi.Value(i);0.85;0.1;0.05};
                        case {28,29}
                            data_R(:,2) = {(79/21)/app.PD.phi.Value(i);1/app.PD.phi.Value(i);0.5;0.5};
                    end
                    if app.selected_Reactive>5 && mod(app.selected_Reactive,2)
                        data_R = data_R(2:end,:);
                    end
                    NSpecies_R = numel(app.species);
                    Species = string(NSpecies_R);
                    for j=NSpecies_R:-1:1
                        Species(j) = app.uitable_R.Data{j,1};
                        Ni(j) = data_R{j,2};
                    end
                    N = sum(Ni);
                    Xi = Ni/N;
                    for j=1:NSpecies_R
                        data_R{j,3} = Xi(j);
                    end
%                     app.uitable_R.Data(:,2:3) = data_R;
                end
                aux1 = numel(app.species);
                ind_Fuel = strcmp(app.uitable_R.Data(:,4),'Fuel');
                ind_Oxidant = strcmp(app.uitable_R.Data(:,4),'Oxidant');
                if ind_Fuel(aux1)
                    S_Fuel = {regexprep(app.uitable_R.Data{end,1},'\s+','')};
                    R_Fuel = SetSpecies(app.C.M0.Value,S_Fuel,data_R{end,2},TR,find_idx(S_Fuel,app.S.NameSpecies),app.strThProp);
                    R_Oxidizer = zeros(size(R_Fuel));
                    R_Inert  = R_Oxidizer;
                elseif ind_Oxidant(aux1)
                    S_Oxidizer = {regexprep(app.uitable_R.Data{end,1},'\s+','')};
                    R_Oxidizer = SetSpecies(app.C.M0.Value,S_Oxidizer,data_R{end,2},TR,find_idx(S_Oxidizer,app.S.NameSpecies),app.strThProp);
                    R_Fuel = zeros(size(R_Oxidizer));
                    R_Inert = R_Fuel;
                else
                    S_Inert = {regexprep(app.uitable_R.Data{end,1},'\s+','')};
                    R_Inert = SetSpecies(app.C.M0.Value,S_Inert,data_R{end,2},TR,find_idx(S_Inert,app.S.NameSpecies),app.strThProp);
                    R_Fuel = zeros(size(R_Inert));
                    R_Oxidizer = R_Fuel;
                end
                for k=aux1-1:-1:1
                    ind_Fuel(k) = strcmp(app.uitable_R.Data{k,4},'Fuel');
                    ind_Oxidant(k) = strcmp(app.uitable_R.Data{k,4},'Oxidant');
                    if ind_Fuel(k)
                        S_Fuel = {regexprep(app.uitable_R.Data{k,1},'\s+','')};
                        aux = SetSpecies(app.C.M0.Value,S_Fuel,data_R{k,2},TR,find_idx(S_Fuel,app.S.NameSpecies),app.strThProp);
                        R_Fuel = R_Fuel+aux;
                    elseif ind_Oxidant(k)
                        S_Oxidizer = {regexprep(app.uitable_R.Data{k,1},'\s+','')};
                        aux = SetSpecies(app.C.M0.Value,S_Oxidizer,data_R{k,2},TR,find_idx(S_Oxidizer,app.S.NameSpecies),app.strThProp);
                        R_Oxidizer = R_Oxidizer+aux;
                    else
                        S_Inert = {regexprep(app.uitable_R.Data{k,1},'\s+','')};
                        aux = SetSpecies(app.C.M0.Value,S_Inert,data_R{k,2},TR,find_idx(S_Inert,app.S.NameSpecies),app.strThProp);
                        R_Inert = R_Inert+aux;
                    end
                end
                %%%% COMPUTE PROPERTIES OF REACTANTS
                R = R_Fuel + R_Oxidizer + R_Inert;
                app.PS.strR{i} = ComputeProperties(app.C.A0.Value,R,pR,TR,app.E.ind_C,app.E.ind_H); app.PS.strR{i}.phi = app.PD.phi.Value(i);
                if sum(ind_Fuel) > 0
                    strR_Fuel = ComputeProperties(app.C.A0.Value,R_Fuel,pR,TR,app.E.ind_C,app.E.ind_H);
                    app.PD.Fuel.x = strR_Fuel.NatomE(app.E.ind_C);
                    app.PD.Fuel.y = strR_Fuel.NatomE(app.E.ind_H);
                    app.PD.Fuel.z = strR_Fuel.NatomE(app.E.ind_O);
                    app.PD.Fuel.eps = app.EpsilonSootEditField.Value;
                    
                    FO = sum(R_Fuel(:,1))/sum(R_Oxidizer(:,1));
                    FO_st = sum(R_Fuel(:,1))/(app.PD.Fuel.x+app.PD.Fuel.y/4-app.PD.Fuel.z/2);
                    app.PD.phi.Value(i) = round(FO/FO_st,5);
                    app.OFEditField.Value = 1/FO;
                    app.FuelEditField.Value = sum(R_Fuel(:,1))/sum(R(:,1))*100;
                else
                    app.PD.phi.Value(i) = 1;
                    app.OFEditField.Value = 0;
                    app.FuelEditField.Value = 0;
                end
                %%%% SOLVING PROBLEM TYPE
                numberProblemType = app.ProblemType.Value;
                switch numberProblemType
                    case {'1','4'} % * TP or TV
                        if flag_TP; TP = TP_vector(i); end
                        app.PS.strP{i} = SolveProblemTP_TV(app.PS.strR{i},app.PD.phi.Value(i),pR,TP,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                    case '2'% * HP or EV
                        if i==app.C.l_phi
                            app.PS.strP{i} = SolveProblemHP(app.PS.strR{i},app.PD.phi.Value(i),pR,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        else
                            app.PS.strP{i} = SolveProblemHP_EV_fast(app.PS.strR{i},app.PD.phi.Value(i),pR,app.PS.strP{i+1},app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        end 
                    case '3' % * SP
                        app.PS.strP{i} = SolveProblemSP(app.PS.strR{i},app.PD.phi.Value(i),pR,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                    case '5'% * HP or EV
                        if i==app.C.l_phi
                            app.PS.strP{i} = SolveProblemEV(app.PS.strR{i},app.PD.phi.Value(i),pR,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        else
                            app.PS.strP{i} = SolveProblemHP_EV_fast(app.PS.strR{i},app.PD.phi.Value(i),pR,app.PS.strP{i+1},app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        end
                    case '6' % * SV
                        vP_vR = app.edit_vP_vR.Value;
                        app.PS.strP{i} = SolveProblemSV(app.PS.strR{i},app.PD.phi.Value(i),vP_vR*app.PS.strR{i}.v,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                    case '7' % * SHOCK_I
                        if flag_uR; uR = uR_vector(i); end
                        if i==app.C.l_phi
                            [app.PS.strR{i},app.PS.strP{i}] = SolveProblemSHOCK_I(app.PS.strR{i},app.PD.phi.Value(i),pR,TR,uR,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        else
                            [app.PS.strR{i},app.PS.strP{i}] = SolveProblemSHOCK_I_fast(app.PS.strR{i},app.PD.phi.Value(i),pR,TR,uR,app.PS.strP{i+1},app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        end
                    case '8' % * SHOCK_R
                        if flag_uR; uR = uR_vector(i); end
                        if i==app.C.l_phi
                            [app.PS.strR{i},app.PS.str2{i},app.PS.strP{i}] = SolveProblemSHOCK_R(app.PS.strR{i},app.PD.phi.Value(i),pR,TR,uR,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        else
                            [app.PS.strR{i},app.PS.str2{i},app.PS.strP{i}] = SolveProblemSHOCK_R_fast(app.PS.strR{i},app.PD.phi.Value(i),pR,TR,uR,app.PS.str2{i+1},app.PS.strP{i+1},app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        end
                    case '9' % * DET
%                         [app.PS.strR{i},app.PS.strP{i},app.TN.guess] = SolveProblemDET_main(app.PS.strR{i},app.PD.phi.Value(i),pR,TR,app.TN.guess,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                        [app.PS.strR{i},app.PS.strP{i},app.TN.guess] = SolveProblemDET_main_2(app.PS.strR{i},app.PD.phi.Value(i),pR,TR,app.TN.guess,app.E,app.S,app.C,app.M,app.PD,app.TN,app.strThProp);
                    otherwise
                end
                %%%% SHOW RESULTS COMMAND WINDOW
                if strcmpi(app.PD.ProblemType,'SHOCK_R')
                    displayresults(app.PS.strR{i},app.PS.str2{i},app.PS.strP{i},app.PD.ProblemType,app.C.mintol_display,app.S.NameSpecies);
                else
                    displayresults(app.PS.strR{i},app.PS.strP{i},app.PD.ProblemType,app.C.mintol_display,app.S.NameSpecies);
                end
                %%%% SORT PRODUCTS SPECIES COMPOSITION
                if app.flag
                    [Xi,ind_sort] = sort(app.PS.strP{i}.Xi(:),'descend');
                    data_P{i} = table2cell(table(app.S.NameSpecies(ind_sort),Xi.*app.PS.strP{i}.N,Xi));
                else
                    if app.Reaction.Value == '1' || strcmp(app.MajorMenu.Checked,'on')
                        [Xi,ind_sort] = sort(app.PS.strP{i}.Xi(disp_Species),'descend');
                    else
                        [Xi,ind_sort] = sort([app.PS.strP{i}.Xi(disp_Species_fixed);app.PS.strP{i}.Xi(disp_Species)],'descend');
                    end
                    data_P{i} = table2cell(table(disp_fnm(ind_sort),Xi.*app.PS.strP{i}.N,Xi));
                end
                %%%% SHOW RESULTS
                if i==1 % Only show last result
                    app.edit_TP.Value = sprintf('%.4g',app.PS.strP{i}.T);
                    app.edit_pP.Value = app.PS.strP{i}.p;
                    app.edit_rR.Value = app.PS.strR{i}.rho;
                    app.edit_rP.Value = app.PS.strP{i}.rho;
                    app.edit_hR.Value = app.PS.strR{i}.h/app.PS.strR{i}.mi;%[kJ/kg]
                    app.edit_hP.Value = app.PS.strP{i}.h/app.PS.strP{i}.mi;%[kJ/kg]
                    app.edit_eR.Value = app.PS.strR{i}.e/app.PS.strR{i}.mi;%[kJ/kg]
                    app.edit_eP.Value = app.PS.strP{i}.e/app.PS.strP{i}.mi;%[kJ/kg]
                    app.edit_cpR.Value = app.PS.strR{i}.cP/app.PS.strR{i}.mi*1e-3;%[kJ/kg]
                    app.edit_cpP.Value = app.PS.strP{i}.cP/app.PS.strP{i}.mi*1e-3;%[kJ/kg]
                    app.edit_sR.Value = app.PS.strR{i}.S;%[kJ/(kg-K)]
                    app.edit_sP.Value = app.PS.strP{i}.S;%[kJ/(kg-K)]
                    app.edit_gammaR.Value = app.PS.strR{i}.gamma;
                    app.edit_gammaP.Value = app.PS.strP{i}.gamma;
                    app.edit_WR.Value = app.PS.strR{i}.W;
                    app.edit_WP.Value = app.PS.strP{i}.W;
                    app.edit_soundR.Value = app.PS.strR{i}.sound;
                    app.edit_soundP.Value = app.PS.strP{i}.sound;
                    app.edit_q.Value = app.edit_hP.Value - app.edit_hR.Value;
                    if sscanf(app.ProblemType.Value,'%f') > 6
                        app.edit_uR.Value = sprintf('%.4g',app.PS.strR{i}.u);
                        app.edit_uP.Value = app.PS.strP{i}.u;
                        app.edit_MR.Value = sprintf('%.4g',app.PS.strR{i}.u/app.PS.strR{i}.sound);
                        app.edit_MP.Value = app.PS.strP{i}.u/app.PS.strP{i}.sound;
                    end
                    app.uitable_P.Data = data_P{i};
                end
                %%%% SAVE DATA
                results(i).strR = app.PS.strR{i};
                results(i).strP = app.PS.strP{i};
                results(i).numberProblemType = numberProblemType;
                results(i).numberReactants = numberReactants;
                results(i).CompleteOrIncomplete = app.Reaction.Items{aux3}; %results.CompleteOrIncomplete = CompleteOrIncomplete;
                results(i).reaction = app.Reaction.Value;
                results(i).data_P = data_P{i};
%                 results(i).data_R = app.uitable_R.Data;
                results(i).data_R = data_R;
                results(i).F = app.FuelEditField.Value;
                results(i).OF = app.OFEditField.Value;
                results(i).phi = app.PD.phi.Value(i);
                results(i).NameSpecies = app.S.NameSpecies;
                % addchildren(app,app.Results,app.PD.ProblemType,results,'Phi = %.5f',app.PD.phi.Value(i))
                %%%%
            end
            %%%% SAVE ALL THE RESULTS
            addchildren(app,app.Results,app.PD.ProblemType,results,'Phi = %.5f',app.PD.phi.Value(i))
            %%%%
            if ~any(strcmpi(numberReactants,{'1','2'})) && flag
                display_species = app.listbox_plotted_species.Items;
                if isempty(display_species)
                    displaysweepresults(app.PS.strP,app.PD.phi.Value,app.S.NameSpecies,app.C.mintol_display);
                else
                    displaysweepresults(app.PS.strP,app.PD.phi.Value,app.S.NameSpecies,app.C.mintol_display,display_species);
                end
                if ~any(strcmp(app.ProblemType.Value,{'1','4'}))
                    display_Tp_phi(app.PS.strP,app.PD.phi.Value,app.Reactants.Items{sscanf(numberReactants,'%d')},app.Reaction.Items{aux3});
                end
            end
%             Plotting(app,app.listbox_plotted_species.Items)
            app.Lamp.Color = 'Green';
%             profile viewer
        end

        % Callback function
        function ToolTipsMenuSelected(app, event)
            
        end

        % Menu selected function: DefaultMenu
        function DefaultMenuSelected(app, event)
            if strcmp(app.DefaultMenu.Checked,'off')
                app.DefaultMenu.Checked = 'on';
                app.MajorMenu.Checked = 'off';
            end
%             message = sprintf('Not implemented yet!');
%             uialert(app.figure_main,message,'Warning','Icon','warning');
        end

        % Menu selected function: MajorMenu
        function MajorMenuSelected(app, event)
            if strcmp(app.MajorMenu.Checked,'off')
                app.DefaultMenu.Checked = 'off';
                app.MajorMenu.Checked = 'on';
            end
%             message = sprintf('Not implemented yet!');
%             uialert(app.figure_main,message,'Warning','Icon','warning');
        end

        % Menu selected function: about
        function aboutMenuSelected(app, event)
            %             web('https://www.linkedin.com/in/albertocuadralara/','-browser');
            run('about.mlapp');
        end

        % Menu selected function: SendfeedbackMenu
        function SendfeedbackMenuSelected(app, event)
            web('mailto:acuadra@ing.uc3m.es');
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            showresults(app,selectedNodes);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %             uisave;
            %             msg = 'Saving these changes will overwrite previous changes.';
            %             title = 'Confirm Save';
            %             selection = uiconfirm(app.figure_main,msg,title,'Options',{'Overwrite','Save as new','Cancel'},'DefaultOption',2,'CancelOption',3);
            
%             message = sprintf('Not implemented yet!');
%             uialert(app.figure_main,message,'Warning','Icon','warning');
            
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            delete(app.Results.Children);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %             uiopen('load');
            message = sprintf('Not implemented yet!');
            uialert(app.figure_main,message,'Warning','Icon','warning');
        end

        % Menu selected function: contents
        function contentsMenuSelected(app, event)
%             uiopen('main.pdf');
              winopen('Masters Thesis - Alberto Cuadra Lara.pdf')
        end

        % Menu selected function: tutorial
        function tutorialMenuSelected(app, event)
            message = sprintf('In progress. Being updated!');
            uialert(app.figure_main,message,'Info','Icon','Icons\engineer.svg');
        end

        % Cell edit callback: uitable_R
        function uitable_RCellEdit(app, event)
            %%%%%%%%%%%%%%%%%%
            indices = event.Indices;
            %%%%%%%%%%%%%%%%%%
            %%%% GET TEMPERATURE REACTIVES
            TR = app.edit_TR.Value;
            if TR(1) == '['
                TR = sscanf(TR, '[%f:%f:%f]');
                TR_vector = TR(1):TR(2):TR(3);
                flag_TR = true;
                app.PD.phi.Value = app.PD.phi.Value(1)*ones(1,numel(TR_vector));
            else
                TR = sscanf(TR,'%f');
            end
            app.species = app.uitable_R.Data(:,1);
            NSpecies = numel(app.species);
            Species = string(NSpecies);
            Ni = zeros(NSpecies,1);
            for i=1:NSpecies
                Species(i) = app.uitable_R.Data{i,1};
                Ni(i,1) = app.uitable_R.Data{i,2};
            end
            N = sum(Ni);
            Xi = Ni/N;
            for i=1:NSpecies
                app.uitable_R.Data(i,3) = {Xi(i)};
            end
            %%%% EQUIVALENCE RATIO
            aux1 = numel(app.species);
            ind_Fuel = strcmp(app.uitable_R.Data(:,4),'Fuel');
            ind_Oxidant = strcmp(app.uitable_R.Data(:,4),'Oxidant');
            S_specie = {regexprep(app.uitable_R.Data{end,1},'\s+','')};
            if ind_Fuel(aux1)
                R_Fuel = SetSpecies(app.C.M0.Value,S_specie,app.uitable_R.Data{end,2},TR,find_idx(S_specie,app.S.NameSpecies),app.strThProp);
                R_Oxidizer = zeros(size(R_Fuel));
                R_Inert  = R_Oxidizer;
            elseif ind_Oxidant(aux1)
                R_Oxidizer = SetSpecies(app.C.M0.Value,S_specie,app.uitable_R.Data{end,2},TR,find_idx(S_specie,app.S.NameSpecies),app.strThProp);
                R_Fuel = zeros(size(R_Oxidizer));
                R_Inert = R_Fuel;
            else
                R_Inert = SetSpecies(app.C.M0.Value,S_specie,app.uitable_R.Data{end,2},TR,find_idx(S_specie,app.S.NameSpecies),app.strThProp);
                R_Fuel = zeros(size(R_Inert));
                R_Oxidizer = R_Fuel;
            end
            for k=aux1-1:-1:1
                ind_Fuel(k) = strcmp(app.uitable_R.Data{k,4},'Fuel');
                ind_Oxidant(k) = strcmp(app.uitable_R.Data{k,4},'Oxidant');
                S_specie = {regexprep(app.uitable_R.Data{k,1},'\s+','')};
                if ind_Fuel(k)
                    aux = SetSpecies(app.C.M0.Value,S_specie,app.uitable_R.Data{k,2},TR,find_idx(S_specie,app.S.NameSpecies),app.strThProp);
                    R_Fuel = R_Fuel+aux;
                elseif ind_Oxidant(k)
                    aux = SetSpecies(app.C.M0.Value,S_specie,app.uitable_R.Data{k,2},TR,find_idx(S_specie,app.S.NameSpecies),app.strThProp);
                    R_Oxidizer = R_Oxidizer+aux;
                else
                    aux = SetSpecies(app.C.M0.Value,S_specie,app.uitable_R.Data{k,2},TR,find_idx(S_specie,app.S.NameSpecies),app.strThProp);
                    R_Inert = R_Inert+aux;
                end
            end
            %%%% COMPUTE PROPERTIES OF REACTANTS
            R = R_Fuel + R_Oxidizer + R_Inert;
            pR = sscanf(app.edit_pR.Value,'%f');
            strR = ComputeProperties(app.C.A0.Value,R,pR,TR,app.E.ind_C,app.E.ind_H);
            if sum(ind_Fuel) > 0
                strR_Fuel = ComputeProperties(app.C.A0.Value,R_Fuel,pR,TR,app.E.ind_C,app.E.ind_H);
                x = strR_Fuel.NatomE(app.E.ind_C);
                y = strR_Fuel.NatomE(app.E.ind_H);
                z = strR_Fuel.NatomE(app.E.ind_O);
                FO = sum(R_Fuel(:,1))/sum(R_Oxidizer(:,1));
                FO_st = sum(R_Fuel(:,1))/(x+y/4-z/2);
                app.edit_phi.Value = sprintf('%.4g',round(FO/FO_st,5));
                app.OFEditField.Value = 1/FO;
                app.FuelEditField.Value = sum(R_Fuel(:,1))/sum(R(:,1))*100;
            end
            
            %         if indices(2) == 5
            %                 TR = event.NewData;
            for i=numel(app.uitable_R.Data(:,1)):-1:1
                %                 cP_vector(i) = species_cP_new(app.uitable_R.Data{i,1},app.uitable_R.Data{i,5},strThProp)
                cV_vector(i) = species_cV_new(app.uitable_R.Data{i,1},app.uitable_R.Data{i,5},app.strThProp);
                Dh_vector(i) = species_DhT_new(app.uitable_R.Data{i,1},app.uitable_R.Data{i,5},app.strThProp);
%                 M_mass(i) = app.uitable_R.Data{i,2}*strThProp.(app.uitable_R.Data{i,1}).mm*1e-3;
            end
            %             TR_eq = sum(M_mass.*[app.uitable_R.Data{:,5}].*cV_vector./strR.mi/1e-3)/sum(M_mass.*cV_vector./strR.mi/1e-3);
            %             TR_eq = sum(M_mass.*[app.uitable_R.Data{:,5}]./strR.mi/1e-3)/sum(M_mass./strR.mi/1e-3);
            TR_eq = sum([app.uitable_R.Data{:,2}].*[app.uitable_R.Data{:,5}].*cV_vector)/sum([app.uitable_R.Data{:,2}].*cV_vector);
            %             TR_eq = sum([app.uitable_R.Data{:,2}].*[app.uitable_R.Data{:,5}])/sum([app.uitable_R.Data{:,2}])
            
            DeltaT = 1;
            tol = 1e-2;
            Ni = [app.uitable_R.Data{:,2}];
            T = TR_eq;
            while abs(DeltaT) > tol
                for i=numel(app.uitable_R.Data(:,1)):-1:1
                    Dh_vector_T(i) = species_DhT_new(app.uitable_R.Data{i,1},T,app.strThProp);
                end
                FT = sum(Ni.*Dh_vector) - sum(Ni.*Dh_vector_T);
                DT = T*0.02;
                Tper = T + DT;
                for i=numel(app.uitable_R.Data(:,1)):-1:1
                    Dh_vector_Tper(i) = species_DhT_new(app.uitable_R.Data{i,1},Tper,app.strThProp);
                end
                FTX = sum(Ni.*Dh_vector) - sum(Ni.*Dh_vector_Tper);
                DFTDT = (FTX-FT)/DT;
                DeltaT = -DFTDT\FT;
                T = T + DeltaT;
            end
            app.edit_TR.Value = sprintf('%.4g',T);
%         else
        end

        % Menu selected function: CommandWindowbetaMenu
        function CommandWindowbetaMenuSelected(app, event)
            if strcmpi(app.CommandWindowbetaMenu.Checked,'off')
                app.CommandWindowbetaMenu.Checked = 'on';
            end
            MirrorCmdWindow(app);
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            delete(app.Results.Children);
            clear app.PS.strR app.PS.strP app.PD.phi.Value
        end

        % Value changed function: listbox_minor_products
        function listbox_minor_productsValueChanged(app, event)
            %             value = app.listbox_minor_products.Value;
        end

        % Button pushed function: pushbutton1
        function pushbutton1ButtonPushed(app, event)
            add_species = app.listbox_species.Value;
            % Check if the species are already included in the list of minor_products
            n_pass = [];
            for n = length(add_species):-1:1
                if ~any(strcmpi(app.M.minor_products,add_species{n}))
                    n_pass = [n_pass, n];
                end
            end
            app.M.minor_products = [app.M.minor_products,add_species(n_pass)];
            app.M.L_minor = length(app.M.minor_products);
            % Update GUI
            app.Reaction.Items{2} = ['Selected products (',sprintf('%d',app.M.L_minor+length(app.S.List_fixed_Species)),')'];
            app.text_minor_products.Text = ['Minor products (',sprintf('%d',app.M.L_minor),')'];
            app.listbox_minor_products.Items = app.M.minor_products;
            % Update strThProp
%             check_database(add_species);
            [app.strThProp,app.E,app.S,app.M,app.C] = check_database(app.strMaster,app.strThProp,app.E,app.S,app.M,app.C);
        end

        % Value changed function: listbox_species
        function listbox_speciesValueChanged(app, event)
            %             value = app.listbox_species.Value;
        end

        % Button pushed function: pushbutton2
        function pushbutton2ButtonPushed(app, event)
            remove_species = app.listbox_minor_products.Value;
            % Remove the species from the list of minor_products
            n_pass = true(1,app.M.L_minor);
            for n = app.M.L_minor:-1:1
                if any(strcmp(remove_species,app.M.minor_products{n}))
                    n_pass(n) = false;
                end
            end
            app.M.minor_products = app.M.minor_products(n_pass);
            app.M.idx_minor = app.M.idx_minor(n_pass);
            app.M.L_minor = length(app.M.minor_products);
            % Update GUI
            app.Reaction.Items{2} = ['Selected products (',sprintf('%d',app.M.L_minor+length(app.S.List_fixed_Species)),')'];
            app.text_minor_products.Text = ['Minor products (',sprintf('%d',app.M.L_minor),')'];
            app.listbox_minor_products.Items = app.M.minor_products;
        end

        % Value changed function: TraceoptionEditField
        function TraceoptionEditFieldValueChanged(app, event)
            app.C.tolN = app.TraceoptionEditField.Value;
        end

        % Value changed function: FuelEditField
        function FuelEditFieldValueChanged(app, event)
            %             value = app.FuelEditField.Value;   
        end

        % Value changed function: OFEditField
        function OFEditFieldValueChanged(app, event)
            %             value = app.OFEditField.Value;
            
        end

        % Value changed function: DisplaySpeciesEditField
        function DisplaySpeciesEditFieldValueChanged(app, event)
            app.C.mintol_display = app.DisplaySpeciesEditField.Value;
        end

        % Button pushed function: togglebutton1_2
        function togglebutton1_2ButtonPushed(app, event)
            %%%% STARTING
%             constants;
            app.UIAxes.cla;
            app.Lamp_2.Color = 'Yellow';
            pause(0.005);
            %%%% MISCELANEOUS
            Species_c = [];
            %%%% GET DATA
            TR = app.plotfield1.Value;
            pR = app.plotfield2.Value;
            TP = app.plotfield3.Value;
            propP2 = app.plotfield4.Value;
            %%%% GET EQUIVALENCE RATIO AND TYPE OF REACTION
            phi = app.edit_phi_2.Value;
            if phi(1) == '['
                phi = sscanf(phi, '[%f:%f:%f]');
                phi = phi(1):phi(2):phi(3);
            else
                message = sprintf('Equivalence ratio (phi) has not the right format');
                uialert(app.figure_main,message,'Warning');
                return
            end
            if app.Complete.Value
                CompleteOrIncomplete = 'Complete';
            %%%% SELECTION REACTANTS
            numberReactants = app.popupmenu2_2.Value;
            for i=length(phi):-1:1
                switch numberReactants
                    case '1'
                        R_Fuel = SetSpecies('CH4',1,'T',TR);
                        R_Oxidizer = SetSpecies('AirPhi',R_Fuel,phi(i),'T',TR);
                        R_Inert = 0;
                    case '2'
                        R_Fuel = SetSpecies('CH4',1,'T',TR);
                        R_Oxidizer = SetSpecies('O2',2/phi(i),'T',TR);
                        R_Inert = SetSpecies('N2',2*78.09/20.95/phi(i),'Ar',2*0.93/20.95/phi(i),'T',TR);
                    case '3'
                        R_Fuel = SetSpecies('CH4',1,'T',TR);
                        R_Oxidizer = SetSpecies('O2Phi',R_Fuel,phi(i),'T',TR);
                        R_Inert = 0;
                    otherwise
                end
                %%%% COMPUTE PROPERTIES OF REACTANTS
                R = R_Fuel + R_Oxidizer + R_Inert;
                strR{i} = ComputeProperties(R,pR,TR);
                %%%% SOLVING PROBLEM TYPE
                numberProblemType = app.popupmenu1_2.Value;
                switch numberProblemType
                    case '1'
                        ProblemType = 'TP';
                        [strP{i}] = SolveProblemTP_test(strR{i},phi(i),pR,TP);
                    case '2'
                        ProblemType = 'HP';
                        [strP{i}] = SolveProblemHP_test(strR{i},phi(i),pR);
                    case '3'
                        ProblemType = 'SP';
                        [strP{i}] = SolveProblemSP_test(strR{i},phi(i),pR);
                    case '4'
                        ProblemType = 'TV';
                        [strP{i}] = SolveProblemTV_test(strR{i},phi(i),pR,TP);
                    case '5'
                        ProblemType = 'EV';
                        [strP{i}] = SolveProblemEV_test(strR{i},phi(i),pR);
                    case '6'
                        vP_vR = app.edit_vP_vR.Value;
                        ProblemType = 'SV';
                        [strP{i}] = SolveProblemSV_test(strR{i},phi(i),vP_vR*strR{i}.v);
                    case '7'
                        ProblemType = 'DET';
                        [strR{i},strP{i}] = SolveProblemDET_test1_reduced_testtest(strR{i},phi(i),pR,TR);
                    otherwise
                end
                if app.Incomplete.Value
                    strP_CC = strP;
                end
            end
            end
            if app.Incomplete.Value
                CompleteOrIncomplete = 'Incomplete';
                %%%% SELECTION REACTANTS
                numberReactants = app.popupmenu2_2.Value;
                for i=length(phi):-1:1
                    switch numberReactants
                        case '1'
                            R_Fuel = SetSpecies('CH4',1,'T',TR);
                            R_Oxidizer = SetSpecies('AirPhi',R_Fuel,phi(i),'T',TR);
                            R_Inert = 0;
                        case '2'
                            R_Fuel = SetSpecies('CH4',1,'T',TR);
                            R_Oxidizer = SetSpecies('O2',2/phi(i),'T',TR);
                            R_Inert = SetSpecies('N2',2*78.09/20.95/phi(i),'Ar',2*0.93/20.95/phi(i),'T',TR);
                        case '3'
                            R_Fuel = SetSpecies('CH4',1,'T',TR);
                            R_Oxidizer = SetSpecies('O2Phi',R_Fuel,phi(i),'T',TR);
                            R_Inert = 0;
                        otherwise
                    end
                    %%%% COMPUTE PROPERTIES OF REACTANTS
                    R = R_Fuel + R_Oxidizer + R_Inert;
                    strR{i} = ComputeProperties(R,pR,TR);
                    %%%% SOLVING PROBLEM TYPE
                    numberProblemType = app.popupmenu1_2.Value;
                    switch numberProblemType
                        case '1'
                            ProblemType = 'TP';
                            [strP{i}] = SolveProblemTP_test(strR{i},phi(i),pR,TP);
                        case '2'
                            ProblemType = 'HP';
                            [strP{i}] = SolveProblemHP_test(strR{i},phi(i),pR);
                        case '3'
                            ProblemType = 'SP';
                            [strP{i}] = SolveProblemSP_test(strR{i},phi(i),pR);
                        case '4'
                            ProblemType = 'TV';
                            [strP{i}] = SolveProblemTV_test(strR{i},phi(i),pR,TP);
                        case '5'
                            ProblemType = 'EV';
                            [strP{i}] = SolveProblemEV_test(strR{i},phi(i),pR);
                        case '6'
                            vP_vR = app.edit_vP_vR.Value;
                            ProblemType = 'SV';
                            [strP{i}] = SolveProblemSV_test(strR{i},phi(i),vP_vR*strR{i}.v);
                        case '7'
                            ProblemType = 'DET';
                            [strR{i},strP{i}] = SolveProblemDET_test1_reduced_testtest(strR{i},phi(i),pR,TR);
                        otherwise
                    end
                    if app.Complete.Value
                        strP_IC = strP;
                    end
                end
            end
            %%%% CANTERA 
            display_species = {'CO2','CO','H2O','H2','O2','N2'};
            if app.CANTERA.Value
                Species_c = calc_CANTERA(app,phi,pR,TP,display_species);
            end
            %%%% SHOW RESULTS 
            %             if app.CO2CheckBox.Value
%                 display_species = {'CO2'};
%             end
%             if app.COCheckBox.Value
%                 display_species = [display_species,'CO2'];
%             end
%             if app.H2OCheckBox.Value
%                 display_species = [display_species,'H2O'];
%             end
%             display_species = app.listbox_plotted_species.Items;
            if app.Complete.Value && app.Incomplete.Value
                displaysweep2(app,strP_CC,strP_IC,Species_c,phi,display_species);
                if ~any(strcmp(app.popupmenu1_2.Value,{'1','4'}))
                    display_Tp_phi_2(strP_CC,strP_IC,phi,app.popupmenu2_2.Items{sscanf(app.popupmenu2_2.Value,'%d')});
                end
            else
%                 displaysweep1(app,strP,Species_c,phi,display_species);
            end
            %%%%
            app.Lamp_2.Color = 'Green';
        end

        % Value changed function: edit_phi_2
        function edit_phi_2ValueChanged(app, event)
            value = app.edit_phi_2.Value;    
        end

        % Value changed function: legend
        function legendValueChanged(app, event)
            value = app.legend.Value;
            if value && ~isempty(app.UIAxes.Legend)
                app.UIAxes.Legend.Visible = 'on';
            elseif ~isempty(app.UIAxes.Legend)
                app.UIAxes.Legend.Visible = 'off';
%                 set(app.UIAxes, 'Position', [1,1,1216,462])
                %                 app.UIAxes.Position = [1,1,1216,462];
%                 app.UIAxes.DataAspectRatioMode = 'auto';
%                 app.UIAxes.DataAspectRatio = [1,1,1];
                %                 app.UIAxes.PlotBoxAspectRatio = [1,0.32019363762102354,0.32019363762102354];
            end
        end

        % Value changed function: edit_TP
        function edit_MRValueChanged(app, event)
            %%%% GET DATA
            TR = app.edit_TR.Value;
            if TR(1) == '['
                TR = sscanf(TR, '[%f:%f:%f]');
%                 TR = TR(1):TR(2):TR(3);
                TR = TR(1);
            else
                TR = sscanf(TR,'%f');
            end
            pR = app.edit_pR.Value;
            if pR(1) == '['
                pR = sscanf(pR, '[%f:%f:%f]');
%                 pR = pR(1):pR(2):pR(3);
                pR = pR(1);
            else
                pR = sscanf(pR,'%f');
            end
            MR = app.edit_MR.Value;
            if MR(1) == '['
                MR = sscanf(MR, '[%f:%f:%f]');
%                 pR = pR(1):pR(2):pR(3);
                MR = MR(1);
            else
                MR = sscanf(MR,'%f');
            end
            %%%% SELECT REACTANT
                switch app.Reactants.Value
                    case '1'
                        R_Fuel = 0;
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                        R_Oxidizer = SetSpecies(app.C.M0.Value,{'O2'},Xi1,TR,app.S.idx_O2,app.strThProp);
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                        R_Inert = SetSpecies(app.C.M0.Value,{'N2'},Xi1,TR,app.S.idx_N2,app.strThProp);
                    case '2'
                        R_Fuel = 0;
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                        R_Oxidizer = SetSpecies(app.C.M0.Value,{'O2'},Xi1,TR,app.S.idx_O2,app.strThProp);
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                        Xi2 = app.uitable_R.Data{strcmpi(app.species,'Ar'),2};
                        R_Inert = SetSpecies(app.C.M0.Value,{'N2','Ar'},[Xi1,Xi2],TR,[app.S.idx_N2,app.S.idx_Ar],app.strThProp);
                    otherwise
                end
                %%%% COMPUTE PROPERTIES OF REACTANTS
                R = R_Fuel + R_Oxidizer + R_Inert;
                strR = ComputeProperties(app.C.A0.Value,R,pR,TR,app.E.ind_C,app.E.ind_H);
                app.edit_uR.Value = sprintf('%.4g',MR(1)*strR.sound);
        end

        % Value changed function: edit_TR
        function edit_uRValueChanged(app, event)
            %%%% GET DATA
            TR = app.edit_TR.Value;
            if TR(1) == '['
                TR = sscanf(TR, '[%f:%f:%f]');
%                 TR = TR(1):TR(2):TR(3);
                TR = TR(1);
            else
                TR = sscanf(TR,'%f');
            end
            pR = app.edit_pR.Value;
            if pR(1) == '['
                pR = sscanf(pR, '[%f:%f:%f]');
%                 pR = pR(1):pR(2):pR(3);
                pR = pR(1);
            else
                pR = sscanf(pR,'%f');
            end
            %%%% SELECT REACTANT
                switch app.Reactants.Value
                    case '1'
                        R_Fuel = 0;
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                        R_Oxidizer = SetSpecies(app.C.M0.Value,{'O2'},Xi1,TR,app.S.idx_O2,app.strThProp);
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                        R_Inert = SetSpecies(app.C.M0.Value,{'N2'},Xi1,TR,app.S.idx_N2,app.strThProp);
                    case '2'
                        R_Fuel = 0;
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                        R_Oxidizer = SetSpecies(app.C.M0.Value,{'O2'},Xi1,TR,app.S.idx_O2,app.strThProp);
                        Xi1 = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                        Xi2 = app.uitable_R.Data{strcmpi(app.species,'Ar'),2};
                        R_Inert = SetSpecies(app.C.M0.Value,{'N2','Ar'},[Xi1,Xi2],TR,[app.S.idx_N2,app.S.idx_Ar],app.strThProp);
                    otherwise
                end
                %%%% COMPUTE PROPERTIES OF REACTANTS
                R = R_Fuel + R_Oxidizer + R_Inert;
                strR = ComputeProperties(app.C.A0.Value,R,pR,TR,app.E.ind_C,app.E.ind_H);
                app.edit_MR.Value = sprintf('%.4g',sscanf(app.edit_uR.Value,'%f')/strR.sound);
        end

        % Menu selected function: Excel
        function ExcelMenuSelected(app, event)
%             if ~isempty(app.PS.strR) && ~isempty(app.PS.strP) && ~isempty(app.PD.phi.Value)
%                 ExcelOutputMatrix_R = FormattedOutput_test([],app.PD.phi.Value,app.PS.strR);
%                 ExcelOutputMatrix_P = FormattedOutput_test([],app.PD.phi.Value,app.PS.strP);
%                 [file,name,path] = uiputfile('*.xls*','File Selection','output.xls');
%                 if isequal(file,0) || isequal(path,0)
%                     disp('User clicked Cancel.')
%                 else
%                     disp(['User selected ',fullfile(path,file),...
%                         ' and then clicked Save.'])
%                     xlswrite(strcat(name,file),ExcelOutputMatrix_R,'Reactives');
%                     xlswrite(strcat(name,file),ExcelOutputMatrix_P,'Products');
%                 end
%             end
            results = app.Tree.Children;
            sumcases = 0;
            if ~isempty(results.Children)
                child1 = results.Children;
                maxi = numel(child1);
                [file,name,path] = uiputfile('*.xls*','File Selection','output.xls');
                if isequal(file,0) || isequal(path,0)
                    disp('User clicked Cancel.')
                else
                    disp(['User selected ',fullfile(path,file),...
                        ' and then clicked Save.'])
                    for i=maxi:-1:1
                        child2 = child1(i).Children;
                        for j=numel(child2):-1:1
                            child3 = child2(j).Children;
                            for k=numel(child3):-1:1
                                child4 = child3(k).Children;
                                NameSpecies = child4(1).NodeData.NameSpecies;
                                for l=numel(child4):-1:1
                                    strR{l} = child4(l).NodeData.strR;
                                    strP{l} = child4(l).NodeData.strP;
                                    phi(l) = child4(l).NodeData.phi;
                                end
                                sumcases = sumcases + 1;
                                ExcelOutputMatrix_R{sumcases} = FormattedOutput_test([],phi,strR,NameSpecies);
                                ExcelOutputMatrix_P{sumcases} = FormattedOutput_test([],phi,strP,NameSpecies);
                                xlswrite(strcat(name,file),ExcelOutputMatrix_R{sumcases},strcat('R-',child1.Text,'-',sprintf('%d',j),'_',sprintf('%d',k)));
                                xlswrite(strcat(name,file),ExcelOutputMatrix_P{sumcases},strcat('P-',child1.Text,'-',sprintf('%d',j),'_',sprintf('%d',k)));
%                                 text2 = child2.Text; text3 = child3.Text;
%                                 xlswrite(strcat(name,file),ExcelOutputMatrix_R{sumcases},strcat('R-',child1.Text,'-',text2(1:3),'-',text3(1:3),'_',sprintf('%d',k)));
%                                 xlswrite(strcat(name,file),ExcelOutputMatrix_P{sumcases},strcat('P-',child1.Text,'-',text2(1:3),'-',text3(1:3),'_',sprintf('%d',k)));
                            end
                        end
                    end
                end
            end
        end

        % Value changed function: Reaction
        function ReactionValueChanged(app, event)
            if any(strcmp(app.Reactants.Value,{'1','2'}))
                switch app.Reaction.Value
                    case '1'
                        if app.Reactants.Value == '1'
                            app.uitable_P.Data = {'N2';'O2'};   
                        else
                            app.uitable_P.Data = {'N2';'O2';'Ar'};   
                        end
                    case '2'
                        app.uitable_P.Data = [app.S.List_fixed_Species';app.listbox_minor_products.Items'];
                    case '3'
                        if app.Reactants.Value == '1'
                            app.uitable_P.Data = {'N2';'O2';'NO';'O';'N';'N2O';'NO2'};
                        else
                            app.uitable_P.Data = {'N2';'O2';'NO';'O';'N';'N2O';'NO2';'Ar'};
                        end
                end
            else
                switch app.Reaction.Value
                    case '1'
                        if any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                            app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2';'N2'};
                        elseif app.Reactants.Value == '4'
                            app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2';'N2';'Ar'};
                        elseif any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                            app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2'};
                        end
                    case '2'
                        app.uitable_P.Data = [app.S.List_fixed_Species';app.listbox_minor_products.Items'];
                    case '3'
                        if any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                            app.uitable_P.Data = {'N2';'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'NO'};
                        elseif app.Reactants.Value == '4'
                            app.uitable_P.Data = {'N2';'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'NO';'Ar'};
                        elseif any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                            app.uitable_P.Data = {'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2'};
                        end
                    case '4'
                        if any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                            app.uitable_P.Data = {'N2';'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'HO2';'NO';'HCO';'CH4';'CH3';'HO2';'NO2';'NH3';'NH2';'N';'HCN';'CN';'N2O';'C2';'CH'};
                        elseif app.Reactants.Value == '4'
                            app.uitable_P.Data = {'N2';'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'HO2';'NO';'HCO';'CH4';'CH3';'HO2';'NO2';'NH3';'NH2';'N';'HCN';'CN';'N2O';'C2';'CH';'Ar'};
                        elseif any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                            app.uitable_P.Data = {'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'HO2';'HCO';'CH4';'CH3';'HO2';'C2';'CH'};
                        end
                    case '5'
                        if any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                            app.uitable_P.Data = {'N2';'H2O';'CO';'HCN';'H2O';'CO2';'H';'OH';'O';'CN';'NH3';'CH4';'C2H4';'CH3';'NO';'HCO';'NH2';'NH';'N';'CH'};
                        elseif app.Reactants.Value == '4'
                            app.uitable_P.Data = {'N2';'H2O';'CO';'HCN';'H2O';'CO2';'H';'OH';'O';'CN';'NH3';'CH4';'C2H4';'CH3';'NO';'HCO';'NH2';'NH';'N';'CH';'Ar'};
                        elseif any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                            app.uitable_P.Data = {'N2';'H2O';'CO';'H2O';'CO2';'H';'OH';'O';'CH4';'C2H4';'CH3';'HCO';'CH'};
                        end
                 end
            end
            if app.Reaction.Value ~= '1'
                app.Misc.FLAG_FIRST = true;
                app.M.minor_products = app.uitable_P.Data';
                find_minor_species(app,app.M.minor_products);
                app.listbox_minor_products.Items = app.M.minor_products';
                app.Reaction.Items{2} = ['Selected products (',sprintf('%d',app.M.L_minor),')'];
                app.text_minor_products.Text = ['Minor products (',sprintf('%d',app.M.L_minor),')'];
            end
            % Indicate Initialize 2
            app.Misc.FLAG_FIRST = true;
        end

        % Value changed function: edit_seeker_3
        function edit_seeker_3ValueChanged(app, event)
            seek_specie = event.Value;
            list_species = app.NameSpecies_Master;
            for i=numel(list_species):-1:1
                %         j(i) = contains(list_species{i},seek_specie,'IgnoreCase',false);
                j(i) = any(strcmp(list_species{i},seek_specie));
            end
            aux = sum(j);
            if aux > 1
                message = sprintf('Be more specific, there are %d species with the same name. \n',aux);
                uialert(app.figure_main,message,'Warning','Icon','warning');
            elseif aux == 1  
                %%%%% CHECK strThProp
                [app.strThProp,app.E,app.S,app.M,app.C] = check_database(app.strMaster,app.strThProp,app.E,app.S,app.M,app.C,{seek_specie});
                %%%%% Print
                TR = app.edit_TR.Value;
                if TR(1) == '['
                    TR = sscanf(TR, '[%f:%f:%f]');
                    TR = TR(1);
                else
                    TR = sscanf(TR,'%f');
                end
                app.uitable_R.Data(end+1,:) = {seek_specie,0,0,'',TR};
                app.flag = true;
            else
                message = sprintf('The specie is not contained in the database \n');
                uialert(app.figure_main,message,'Warning','Icon','warning');
            end
        end

        % Value changed function: popupmenu1_2
        function popupmenu1_2ValueChanged(app, event)
            value = sscanf(app.popupmenu1_2.Value,'%d');
            switch value
                case {1,4}
                    app.plotfield1.Enable = 'off';
                    app.plotfield2.Enable = 'off';
                    app.plotfield3.Enable = 'on';
                    app.plotfield4.Enable = 'on';
                otherwise
                    app.plotfield1.Enable = 'on';
                    app.plotfield2.Enable = 'on';
                    app.plotfield3.Enable = 'off';
                    app.plotfield4.Enable = 'off';
            end
        end

        % Callback function
        function edit_pRValueChanged(app, event)
            if app.ProblemType.Value == '2'
                app.edit_pP.Value = sscanf(app.edit_pR.Value,'%f');
            end
        end

        % Callback function
        function edit_TRValueChanged(app, event)
            TR = app.edit_TR.Value;
            if TR(1) == '['
                TR = sscanf(TR, '[%f:%f:%f]');
                TR = TR(1);
            else
                TR = sscanf(TR,'%f');
            end
            app.uitable_R.Data(:,5) = {TR};            
        end

        % Value changed function: listbox_plotted_species
        function listbox_plotted_speciesValueChanged(app, event)
            value = app.listbox_plotted_species.Value;
            
        end

        % Button pushed function: pushbutton1_2
        function pushbutton1_2ButtonPushed(app, event)
            add_species = app.listbox_species_2.Value;
            app.M.display_species = [app.M.display_species,add_species];
        end

        % Button pushed function: pushbutton2_2
        function pushbutton2_2ButtonPushed(app, event)
            remove_species = app.listbox_plotted_species.Value;
            app.listbox_species_2.Items = [app.listbox_species_2.Items,remove_species];
        end

        % Image clicked function: Image_2, Image_3, Image_5
        function ImageClicked(app, event)
            web('https://www.uc3m.es/Inicio','-browser');
        end

        % Menu selected function: SummaryMenu
        function SummaryMenuSelected(app, event)
            winopen('poster_msc11.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_main = uifigure('Visible', 'off');
            app.figure_main.Name = 'Combustion-Toolbox';
            app.FileMenu = uimenu(app.figure_main);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Enable = 'off';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.Excel = uimenu(app.SaveMenu);
            app.Excel.MenuSelectedFcn = createCallbackFcn(app, @ExcelMenuSelected, true);
            app.Excel.Text = 'Excel';
            app.viewmenu = uimenu(app.figure_main);
            app.viewmenu.Text = 'View';
            app.view_density = uimenu(app.viewmenu);
            app.view_density.MenuSelectedFcn = createCallbackFcn(app, @view_density_Callback, true);
            app.view_density.Text = 'Display products species';
            app.DefaultMenu = uimenu(app.view_density);
            app.DefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultMenuSelected, true);
            app.DefaultMenu.Checked = 'on';
            app.DefaultMenu.Text = 'Default';
            app.MajorMenu = uimenu(app.view_density);
            app.MajorMenu.MenuSelectedFcn = createCallbackFcn(app, @MajorMenuSelected, true);
            app.MajorMenu.Text = 'Major';
            app.ExtraMenu = uimenu(app.figure_main);
            app.ExtraMenu.Text = 'Extra';
            app.CommandWindowbetaMenu = uimenu(app.ExtraMenu);
            app.CommandWindowbetaMenu.MenuSelectedFcn = createCallbackFcn(app, @CommandWindowbetaMenuSelected, true);
            app.CommandWindowbetaMenu.Enable = 'off';
            app.CommandWindowbetaMenu.Text = 'Command Window (beta)';
            app.ExamplesMenu = uimenu(app.figure_main);
            app.ExamplesMenu.Enable = 'off';
            app.ExamplesMenu.Text = 'Examples';
            app.helpmenu = uimenu(app.figure_main);
            app.helpmenu.Text = 'Help';
            app.tutorial = uimenu(app.helpmenu);
            app.tutorial.MenuSelectedFcn = createCallbackFcn(app, @tutorialMenuSelected, true);
            app.tutorial.Text = 'Getting started';
            app.SummaryMenu = uimenu(app.helpmenu);
            app.SummaryMenu.MenuSelectedFcn = createCallbackFcn(app, @SummaryMenuSelected, true);
            app.SummaryMenu.Text = 'Summary';
            app.contents = uimenu(app.helpmenu);
            app.contents.MenuSelectedFcn = createCallbackFcn(app, @contentsMenuSelected, true);
            app.contents.Text = 'Contents';
            app.SendfeedbackMenu = uimenu(app.helpmenu);
            app.SendfeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @SendfeedbackMenuSelected, true);
            app.SendfeedbackMenu.Separator = 'on';
            app.SendfeedbackMenu.Text = 'Send feedback';
            app.about = uimenu(app.helpmenu);
            app.about.MenuSelectedFcn = createCallbackFcn(app, @aboutMenuSelected, true);
            app.about.Text = 'About...';
            app.TabGroup = uitabgroup(app.figure_main);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.GridLayout = uigridlayout(app.MainTab);
            app.uipanel21 = uipanel(app.GridLayout);
            app.uipanel21.Title = 'Settings';
            app.GridLayout12 = uigridlayout(app.uipanel21);
            app.uipanel11_3 = uipanel(app.GridLayout12);
            app.uipanel11_3.Title = 'Reactants';
            app.GridLayout8 = uigridlayout(app.uipanel11_3);
            app.uitable_R = uitable(app.GridLayout8);
            app.uitable_R.ColumnName = {'Species'; 'Nº Moles'; 'Mole Fraction'; 'Type'; 'Temperature [K]'};
            app.uitable_R.ColumnEditable = true;
            app.uitable_R.CellEditCallback = createCallbackFcn(app, @uitable_RCellEdit, true);
            app.edit_phi = uieditfield(app.GridLayout8, 'text');
            app.edit_phi.ValueChangedFcn = createCallbackFcn(app, @edit_phi_Callback, true);
            app.edit_phi.Value = '-';
            app.text_phi = uilabel(app.GridLayout8);
            app.text_phi.Text = 'Phi';
            app.ReactionPanel = uipanel(app.GridLayout8);
            app.ReactionPanel.Title = 'Reaction';
            app.GridLayout9 = uigridlayout(app.ReactionPanel);
            app.Reaction = uidropdown(app.GridLayout9);
            app.Reaction.Items = {'Complete + W-G', 'Selected products', 'Dissociated air'};
            app.Reaction.ItemsData = {'1', '2', '3'};
            app.Reaction.ValueChangedFcn = createCallbackFcn(app, @ReactionValueChanged, true);
            app.Reaction.Value = '1';
            app.NewreactantPanel = uipanel(app.GridLayout8);
            app.NewreactantPanel.Title = 'New reactant';
            app.GridLayout10 = uigridlayout(app.NewreactantPanel);
            app.edit_seeker_3 = uieditfield(app.GridLayout10, 'text');
            app.edit_seeker_3.ValueChangedFcn = createCallbackFcn(app, @edit_seeker_3ValueChanged, true);
            app.Reactants = uidropdown(app.GridLayout8);
            app.Reactants.Items = {'Air (21% O2, 79% N2)', 'Air (20.95% O2, 78.09% N2, 0.96% Ar)', 'Methane + Air (21% O2, 79% N2)', 'Methane + Air (20.95% O2, 78.09% N2, 0.96% Ar)', 'Methane + O2', 'Ethane + Air (21% O2, 79% N2)', 'Ethane + O2', 'Propane + Air (21% O2, 79% N2)', 'Propane + O2', 'Acetylene + Air (21% O2, 79% N2)', 'Acetylene + O2', 'Ethylene + Air (21% O2, 79% N2)', 'Ethylene + O2', 'Bencene + Air (21% O2, 79% N2)', 'Bencene + O2', 'Iso-octane + Air (21% O2, 79% N2)', 'Iso-octane + O2', 'Hydrogen + Air (21% O2, 79% N2)', 'Hydrogen + O2', 'Carbon monoxide + Air (21% O2, 79% N2)', 'Carbon monoxide + O2', 'Methanol + Air (21% O2, 79% N2)', 'Methanol + O2', 'Ethanol + Air (21% O2, 79% N2)', 'Ethanol + O2', 'Natural Gas + Air (21% O2, 79% N2)', 'Natural Gas + O2', 'Syngas + Air (21% O2, 79% N2)', 'Syngas + O2'};
            app.Reactants.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29'};
            app.Reactants.ValueChangedFcn = createCallbackFcn(app, @Reactants_Callback, true);
            app.Reactants.Value = '1';
            app.FuelEditFieldLabel = uilabel(app.GridLayout8);
            app.FuelEditFieldLabel.Text = '% Fuel';
            app.FuelEditField = uieditfield(app.GridLayout8, 'numeric');
            app.FuelEditField.ValueChangedFcn = createCallbackFcn(app, @FuelEditFieldValueChanged, true);
            app.OFEditFieldLabel = uilabel(app.GridLayout8);
            app.OFEditFieldLabel.Text = 'O/F';
            app.OFEditField = uieditfield(app.GridLayout8, 'numeric');
            app.OFEditField.ValueChangedFcn = createCallbackFcn(app, @OFEditFieldValueChanged, true);
            app.uipanel13 = uipanel(app.GridLayout12);
            app.uipanel13.Title = 'Products';
            app.GridLayout7 = uigridlayout(app.uipanel13);
            app.uitable_P = uitable(app.GridLayout7);
            app.uitable_P.ColumnName = {'Species'; 'Nº Moles'; 'Mole Fraction'};
            app.uitable_P.ColumnEditable = [false false false];
            app.uipanel7_3 = uipanel(app.GridLayout12);
            app.uipanel7_3.Title = 'Problem type';
            app.GridLayout11 = uigridlayout(app.uipanel7_3);
            app.ProblemType = uidropdown(app.GridLayout11);
            app.ProblemType.Items = {'TP:  Equilibrium composition at defined T and P', 'HP: Adiabatic T and composition at constant P', 'SP:  Isentropic compression/expansion to a specified P', 'TV: Equilibrium composition at defined T and constant V', 'EV: Adiabatic T and composition at constant V', 'SV: Isentropic compresion/expansion to a specified V', 'SHOCK_I: Planar incident shock wave', 'SHOCK_R: Planar reflected shock wave', 'DET: Champan-Jouget Detonation (CJ upper state)'};
            app.ProblemType.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ProblemType.ValueChangedFcn = createCallbackFcn(app, @ProblemType_Callback, true);
            app.ProblemType.Value = '1';
            app.Calculate = uibutton(app.GridLayout12, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @Calculate_Callback, true);
            app.Calculate.Text = 'Calculate';
            app.Lamp = uilamp(app.GridLayout12);
            app.Clear = uibutton(app.GridLayout12, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.last_plots = uibutton(app.GridLayout12, 'push');
            app.last_plots.Enable = 'off';
            app.last_plots.Text = 'Last Plots';
            app.uipanel20 = uipanel(app.GridLayout);
            app.uipanel20.Title = 'Output';
            app.GridLayout4 = uigridlayout(app.uipanel20);
            app.uipanel8 = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.uipanel8);
            app.text_s = uilabel(app.GridLayout5);
            app.text_s.Text = 'Entropy [kJ/(kg K)]';
            app.edit_sR = uieditfield(app.GridLayout5, 'numeric');
            app.edit_sR.ValueDisplayFormat = '%.4g';
            app.edit_sR.Editable = 'off';
            app.edit_sP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_sP.ValueDisplayFormat = '%.4g';
            app.edit_sP.Editable = 'off';
            app.text_gamma = uilabel(app.GridLayout5);
            app.text_gamma.Text = 'gamma = cp/cv [-]';
            app.edit_gammaR = uieditfield(app.GridLayout5, 'numeric');
            app.edit_gammaR.ValueDisplayFormat = '%.4g';
            app.edit_gammaR.Editable = 'off';
            app.edit_gammaP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_gammaP.ValueDisplayFormat = '%.4g';
            app.edit_gammaP.Editable = 'off';
            app.text_M = uilabel(app.GridLayout5);
            app.text_M.Visible = 'off';
            app.text_M.Text = 'Mach number [-]';
            app.edit_MP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_MP.ValueDisplayFormat = '%.4g';
            app.edit_MP.Editable = 'off';
            app.edit_MP.Visible = 'off';
            app.edit_MR = uieditfield(app.GridLayout5, 'text');
            app.edit_MR.Visible = 'off';
            app.edit_MR.Value = '0';
            app.text_u = uilabel(app.GridLayout5);
            app.text_u.Visible = 'off';
            app.text_u.Text = 'Shock velocity [m/s]';
            app.edit_uP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_uP.ValueDisplayFormat = '%.4g';
            app.edit_uP.Editable = 'off';
            app.edit_uP.Visible = 'off';
            app.edit_uR = uieditfield(app.GridLayout5, 'text');
            app.edit_uR.Visible = 'off';
            app.edit_uR.Value = '500';
            app.text_e = uilabel(app.GridLayout5);
            app.text_e.Text = 'Internal energy [kJ/kg]';
            app.edit_eR = uieditfield(app.GridLayout5, 'numeric');
            app.edit_eR.ValueDisplayFormat = '%.4g';
            app.edit_eR.Editable = 'off';
            app.edit_eP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_eP.ValueDisplayFormat = '%.4g';
            app.edit_eP.Editable = 'off';
            app.text_sound = uilabel(app.GridLayout5);
            app.text_sound.Text = 'Sound speed [m/s]';
            app.edit_soundR = uieditfield(app.GridLayout5, 'numeric');
            app.edit_soundR.ValueDisplayFormat = '%.4g';
            app.edit_soundR.Editable = 'off';
            app.edit_soundP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_soundP.ValueDisplayFormat = '%.4g';
            app.edit_soundP.Editable = 'off';
            app.text_W = uilabel(app.GridLayout5);
            app.text_W.Text = 'Mean Molecular Weight [g/mol]';
            app.edit_WR = uieditfield(app.GridLayout5, 'numeric');
            app.edit_WR.ValueDisplayFormat = '%.4g';
            app.edit_WR.Editable = 'off';
            app.edit_WP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_WP.ValueDisplayFormat = '%.4g';
            app.edit_WP.Editable = 'off';
            app.text_cp = uilabel(app.GridLayout5);
            app.text_cp.Text = 'cp [kJ/(kg K)]';
            app.edit_cpR = uieditfield(app.GridLayout5, 'numeric');
            app.edit_cpR.ValueDisplayFormat = '%.4g';
            app.edit_cpR.Editable = 'off';
            app.edit_cpP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_cpP.ValueDisplayFormat = '%.4g';
            app.edit_cpP.Editable = 'off';
            app.text_h = uilabel(app.GridLayout5);
            app.text_h.Text = 'Enthalpy [kJ/kg]';
            app.edit_hR = uieditfield(app.GridLayout5, 'numeric');
            app.edit_hR.ValueDisplayFormat = '%.4g';
            app.edit_hR.Editable = 'off';
            app.edit_hP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_hP.ValueDisplayFormat = '%.4g';
            app.edit_hP.Editable = 'off';
            app.text_r = uilabel(app.GridLayout5);
            app.text_r.Text = 'Density [kg/m3]';
            app.edit_rR = uieditfield(app.GridLayout5, 'numeric');
            app.edit_rR.ValueDisplayFormat = '%.4g';
            app.edit_rR.Editable = 'off';
            app.edit_rP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_rP.ValueDisplayFormat = '%.4g';
            app.edit_rP.Editable = 'off';
            app.text_p = uilabel(app.GridLayout5);
            app.text_p.Text = 'Pressure [bar]';
            app.edit_pP = uieditfield(app.GridLayout5, 'numeric');
            app.edit_pP.Limits = [0 Inf];
            app.edit_pP.ValueDisplayFormat = '%.4g';
            app.edit_pP.Editable = 'off';
            app.edit_pR = uieditfield(app.GridLayout5, 'text');
            app.edit_pR.Value = '1';
            app.text_Products = uilabel(app.GridLayout5);
            app.text_Products.Text = 'Products';
            app.text_Reactans = uilabel(app.GridLayout5);
            app.text_Reactans.Text = 'Reactants';
            app.text_T = uilabel(app.GridLayout5);
            app.text_T.Text = 'Temperature [K]';
            app.edit_TR = uieditfield(app.GridLayout5, 'text');
            app.edit_TR.ValueChangedFcn = createCallbackFcn(app, @edit_uRValueChanged, true);
            app.edit_TR.Value = '300';
            app.edit_TP = uieditfield(app.GridLayout5, 'text');
            app.edit_TP.ValueChangedFcn = createCallbackFcn(app, @edit_MRValueChanged, true);
            app.edit_TP.Value = '2500';
            app.uipanel14 = uipanel(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.uipanel14);
            app.text_q = uilabel(app.GridLayout6);
            app.text_q.Text = 'hP - hR [kJ/kg]';
            app.edit_q = uieditfield(app.GridLayout6, 'numeric');
            app.edit_q.ValueDisplayFormat = '%.4g';
            app.edit_q.Editable = 'off';
            app.edit_vP_vR = uieditfield(app.GridLayout6, 'numeric');
            app.edit_vP_vR.Limits = [0 Inf];
            app.edit_vP_vR.ValueDisplayFormat = '%.4g';
            app.edit_vP_vR.Enable = 'off';
            app.edit_vP_vR.Visible = 'off';
            app.text_vP_vR = uilabel(app.GridLayout6);
            app.text_vP_vR.Visible = 'off';
            app.text_vP_vR.Text = {'Compression/Expansion'; ' ratio'};
            app.EpsilonmolesLabel = uilabel(app.GridLayout6);
            app.EpsilonmolesLabel.Visible = 'off';
            app.EpsilonmolesLabel.Text = 'Epsilon (moles)';
            app.edit_error_moles = uieditfield(app.GridLayout6, 'numeric');
            app.edit_error_moles.ValueDisplayFormat = '%.4g';
            app.edit_error_moles.Editable = 'off';
            app.edit_error_moles.Visible = 'off';
            app.EpsilonHLabel = uilabel(app.GridLayout6);
            app.EpsilonHLabel.Visible = 'off';
            app.EpsilonHLabel.Text = 'Epsilon (H)';
            app.edit_error_problem = uieditfield(app.GridLayout6, 'numeric');
            app.edit_error_problem.ValueDisplayFormat = '%.4g';
            app.edit_error_problem.Editable = 'off';
            app.edit_error_problem.Visible = 'off';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.Results = uitreenode(app.Tree);
            app.Results.Text = 'Results';
            app.Image_5 = uiimage(app.GridLayout);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_5.ImageSource = 'logo_uc3m.png';
            app.ExtendedsettingsTab = uitab(app.TabGroup);
            app.ExtendedsettingsTab.Title = 'Extended settings';
            app.GridLayout2 = uigridlayout(app.ExtendedsettingsTab);
            app.uipanel22 = uipanel(app.GridLayout2);
            app.uipanel22.Title = 'Selection of minor species';
            app.edit_seeker = uieditfield(app.uipanel22, 'text');
            app.edit_seeker.ValueChangedFcn = createCallbackFcn(app, @edit_seeker_Callback, true);
            app.listbox_species = uilistbox(app.uipanel22);
            app.listbox_species.Items = {};
            app.listbox_species.Multiselect = 'on';
            app.listbox_species.ValueChangedFcn = createCallbackFcn(app, @listbox_speciesValueChanged, true);
            app.listbox_species.Value = {};
            app.listbox_minor_products = uilistbox(app.uipanel22);
            app.listbox_minor_products.Items = {};
            app.listbox_minor_products.Multiselect = 'on';
            app.listbox_minor_products.ValueChangedFcn = createCallbackFcn(app, @listbox_minor_productsValueChanged, true);
            app.listbox_minor_products.Value = {};
            app.pushbutton1 = uibutton(app.uipanel22, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1ButtonPushed, true);
            app.pushbutton1.Text = 'Add >>';
            app.text_list_species = uilabel(app.uipanel22);
            app.text_list_species.Text = 'List of species';
            app.text_minor_products = uilabel(app.uipanel22);
            app.text_minor_products.Text = 'Minor products';
            app.pushbutton2 = uibutton(app.uipanel22, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2ButtonPushed, true);
            app.pushbutton2.Text = 'Remove <<';
            app.text_list_species_2 = uilabel(app.uipanel22);
            app.text_list_species_2.Text = 'Search Species';
            app.uipanel23 = uipanel(app.GridLayout2);
            app.uipanel23.Title = 'Tolerances';
            app.TraceoptionLabel = uilabel(app.uipanel23);
            app.TraceoptionLabel.Text = 'Trace option';
            app.TraceoptionEditField = uieditfield(app.uipanel23, 'numeric');
            app.TraceoptionEditField.Limits = [0 Inf];
            app.TraceoptionEditField.ValueDisplayFormat = '%.1e';
            app.TraceoptionEditField.ValueChangedFcn = createCallbackFcn(app, @TraceoptionEditFieldValueChanged, true);
            app.TraceoptionEditField.Value = 1e-14;
            app.DisplaySpeciesEditFieldLabel = uilabel(app.uipanel23);
            app.DisplaySpeciesEditFieldLabel.Text = 'Display Species';
            app.DisplaySpeciesEditField = uieditfield(app.uipanel23, 'numeric');
            app.DisplaySpeciesEditField.ValueChangedFcn = createCallbackFcn(app, @DisplaySpeciesEditFieldValueChanged, true);
            app.DisplaySpeciesEditField.Value = 1e-07;
            app.SootfactorEditFieldLabel = uilabel(app.uipanel23);
            app.SootfactorEditFieldLabel.Text = 'Soot factor';
            app.SootfactorEditField = uieditfield(app.uipanel23, 'numeric');
            app.SootfactorEditField.Value = 1;
            app.EpsilonSootEditFieldLabel = uilabel(app.uipanel23);
            app.EpsilonSootEditFieldLabel.Text = 'Epsilon Soot';
            app.EpsilonSootEditField = uieditfield(app.uipanel23, 'numeric');
            app.uipanel22_2 = uipanel(app.GridLayout2);
            app.uipanel22_2.Title = 'Selection of species to be plotted';
            app.edit_seeker_2 = uieditfield(app.uipanel22_2, 'text');
            app.edit_seeker_2.Enable = 'off';
            app.listbox_species_2 = uilistbox(app.uipanel22_2);
            app.listbox_species_2.Items = {};
            app.listbox_species_2.Multiselect = 'on';
            app.listbox_species_2.Enable = 'off';
            app.listbox_species_2.Value = {};
            app.listbox_plotted_species = uilistbox(app.uipanel22_2);
            app.listbox_plotted_species.Items = {};
            app.listbox_plotted_species.ValueChangedFcn = createCallbackFcn(app, @listbox_plotted_speciesValueChanged, true);
            app.listbox_plotted_species.Enable = 'off';
            app.listbox_plotted_species.Value = {};
            app.pushbutton1_2 = uibutton(app.uipanel22_2, 'push');
            app.pushbutton1_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_2ButtonPushed, true);
            app.pushbutton1_2.Enable = 'off';
            app.pushbutton1_2.Text = 'Add >>';
            app.text_list_species_3 = uilabel(app.uipanel22_2);
            app.text_list_species_3.Text = 'List of species';
            app.text_minor_products_2 = uilabel(app.uipanel22_2);
            app.text_minor_products_2.Text = 'Plotted Species';
            app.pushbutton2_2 = uibutton(app.uipanel22_2, 'push');
            app.pushbutton2_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_2ButtonPushed, true);
            app.pushbutton2_2.Enable = 'off';
            app.pushbutton2_2.Text = 'Remove <<';
            app.text_list_species_4 = uilabel(app.uipanel22_2);
            app.text_list_species_4.Text = 'Search Species';
            app.Image_2 = uiimage(app.GridLayout2);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_2.ImageSource = 'logo_uc3m.png';
            app.MolarfractionvsEquivalenceratioTab = uitab(app.TabGroup);
            app.MolarfractionvsEquivalenceratioTab.Title = 'Molar fraction vs Equivalence ratio';
            app.GridLayout13 = uigridlayout(app.MolarfractionvsEquivalenceratioTab);
            app.uipanel21_2 = uipanel(app.GridLayout13);
            app.uipanel21_2.Title = 'Settings';
            app.GridLayout14 = uigridlayout(app.uipanel21_2);
            app.uipanel7_2 = uipanel(app.GridLayout14);
            app.uipanel7_2.Title = 'Problem type';
            app.popupmenu1_2 = uidropdown(app.uipanel7_2);
            app.popupmenu1_2.Items = {'TP:  Equilibrium composition at defined T and P', 'HP: Adiabatic T and composition at constant P', 'SP:  Isentropic compression/expansion to a specified P', 'TV: Equilibrium composition at defined T and constant V', 'EV: Adiabatic T and composition at constant V', 'SV: Isentropic compresion/expansion to a specified V', 'DET: Champan-Jouget Detonation (CJ upper state)'};
            app.popupmenu1_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.popupmenu1_2.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_2ValueChanged, true);
            app.popupmenu1_2.Value = '1';
            app.SpeciesPanel = uipanel(app.GridLayout14);
            app.SpeciesPanel.Title = 'Species';
            app.O2CheckBox = uicheckbox(app.SpeciesPanel);
            app.O2CheckBox.Text = 'O2';
            app.O2CheckBox.Value = true;
            app.OCheckBox = uicheckbox(app.SpeciesPanel);
            app.OCheckBox.Enable = 'off';
            app.OCheckBox.Text = 'O';
            app.OHCheckBox = uicheckbox(app.SpeciesPanel);
            app.OHCheckBox.Enable = 'off';
            app.OHCheckBox.Text = 'OH';
            app.H2OCheckBox = uicheckbox(app.SpeciesPanel);
            app.H2OCheckBox.Text = 'H2O';
            app.H2OCheckBox.Value = true;
            app.H2CheckBox = uicheckbox(app.SpeciesPanel);
            app.H2CheckBox.Text = 'H2';
            app.H2CheckBox.Value = true;
            app.N2CheckBox = uicheckbox(app.SpeciesPanel);
            app.N2CheckBox.Text = 'N2';
            app.N2CheckBox.Value = true;
            app.NOCheckBox = uicheckbox(app.SpeciesPanel);
            app.NOCheckBox.Enable = 'off';
            app.NOCheckBox.Text = 'NO';
            app.CO2CheckBox = uicheckbox(app.SpeciesPanel);
            app.CO2CheckBox.Text = 'CO2';
            app.CO2CheckBox.Value = true;
            app.COCheckBox = uicheckbox(app.SpeciesPanel);
            app.COCheckBox.Text = 'CO';
            app.COCheckBox.Value = true;
            app.uipanel11_2 = uipanel(app.GridLayout14);
            app.uipanel11_2.Title = 'Configuration';
            app.edit_phi_2 = uieditfield(app.uipanel11_2, 'text');
            app.edit_phi_2.ValueChangedFcn = createCallbackFcn(app, @edit_phi_2ValueChanged, true);
            app.edit_phi_2.Value = '[0.3:0.01:2.3]';
            app.text_phi_2 = uilabel(app.uipanel11_2);
            app.text_phi_2.Text = 'Phi';
            app.popupmenu2_2 = uidropdown(app.uipanel11_2);
            app.popupmenu2_2.Items = {'Methane + Air (O2, N2)', 'Methane + Air (O2, N2, Ar)', 'Methane + O2'};
            app.popupmenu2_2.ItemsData = {'1', '2', '3'};
            app.popupmenu2_2.Value = '1';
            app.Lamp_2 = uilamp(app.uipanel11_2);
            app.togglebutton1_2 = uibutton(app.uipanel11_2, 'push');
            app.togglebutton1_2.ButtonPushedFcn = createCallbackFcn(app, @togglebutton1_2ButtonPushed, true);
            app.togglebutton1_2.Enable = 'off';
            app.togglebutton1_2.Text = 'Calculate';
            app.ReactantsPanel = uipanel(app.uipanel11_2);
            app.ReactantsPanel.Title = 'Reactants';
            app.PressurebarLabel = uilabel(app.ReactantsPanel);
            app.PressurebarLabel.Text = 'Pressure [bar]';
            app.plotfield2 = uieditfield(app.ReactantsPanel, 'numeric');
            app.plotfield2.Limits = [0 Inf];
            app.plotfield2.Enable = 'off';
            app.plotfield2.Value = 1;
            app.TemperatureKLabel = uilabel(app.ReactantsPanel);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.plotfield1 = uieditfield(app.ReactantsPanel, 'numeric');
            app.plotfield1.Limits = [0 Inf];
            app.plotfield1.Enable = 'off';
            app.plotfield1.Value = 300;
            app.ProductsPanel = uipanel(app.uipanel11_2);
            app.ProductsPanel.Title = 'Products';
            app.PressurebarLabel_2 = uilabel(app.ProductsPanel);
            app.PressurebarLabel_2.Text = 'Pressure [bar]';
            app.plotfield4 = uieditfield(app.ProductsPanel, 'numeric');
            app.plotfield4.Limits = [0 Inf];
            app.plotfield4.Value = 1;
            app.TemperatureKLabel_2 = uilabel(app.ProductsPanel);
            app.TemperatureKLabel_2.Text = 'Temperature [K]';
            app.plotfield3 = uieditfield(app.ProductsPanel, 'numeric');
            app.plotfield3.Limits = [0 Inf];
            app.plotfield3.Value = 2500;
            app.uibuttongroup1_2 = uibuttongroup(app.uipanel11_2);
            app.uibuttongroup1_2.Title = 'Reaction';
            app.Complete = uicheckbox(app.uibuttongroup1_2);
            app.Complete.Text = 'Complete + W-G';
            app.Complete.Value = true;
            app.Incomplete = uicheckbox(app.uibuttongroup1_2);
            app.Incomplete.Text = 'Incomplete';
            app.Incomplete.Value = true;
            app.ValidationsPanel = uipanel(app.uipanel11_2);
            app.ValidationsPanel.Title = 'Validations';
            app.CANTERA = uicheckbox(app.ValidationsPanel);
            app.CANTERA.Text = 'CANTERA';
            app.CANTERA.Value = true;
            app.legend = uicheckbox(app.uipanel11_2);
            app.legend.ValueChangedFcn = createCallbackFcn(app, @legendValueChanged, true);
            app.legend.Text = 'Legend';
            app.legend.Value = true;
            app.Image_3 = uiimage(app.GridLayout14);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_3.ImageSource = 'logo_uc3m.png';
            app.UIAxes = uiaxes(app.GridLayout13);
            % Show the figure after all components are created
            app.figure_main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Combustion_Toolbox_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_main)

            % Execute the startup function
            runStartupFcn(app, @Combustion_solver_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_main)
        end
    end
end