classdef about < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutUIFigure       matlab.ui.Figure
        fluidosImage        matlab.ui.control.Image
        chueteImage         matlab.ui.control.Image
        mveraImage          matlab.ui.control.Image
        acuadraImage        matlab.ui.control.Image
        chueteLinkedin      matlab.ui.control.Image
        chueteResearch      matlab.ui.control.Image
        mveraLinkedin       matlab.ui.control.Image
        mveraResearch       matlab.ui.control.Image
        acuadraMail         matlab.ui.control.Image
        acuadraLinkedin     matlab.ui.control.Image
        acuadraResearch     matlab.ui.control.Image
        acuadraGithub       matlab.ui.control.Image
        categLabel3         matlab.ui.control.Label
        nameLabel3          matlab.ui.control.Label
        nameLabel2          matlab.ui.control.Label
        categLabel2         matlab.ui.control.Label
        RepositoryButton    matlab.ui.control.Button
        ContributorsButton  matlab.ui.control.Button
        WebsiteButton       matlab.ui.control.Button
        categLabel1         matlab.ui.control.Label
        nameLabel1          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             gui_SnapshotMenuSelected(app.AboutUIFigure);
        end

        % Image clicked function: fluidosImage
        function fluidosWebClicked(app, event)
            system('start http://fluidosuc3m.es/');
        end

        % Image clicked function: acuadraLinkedin
        function acuadraLinkedinClicked(app, event)
            system('start https://www.linkedin.com/in/albertocuadralara/');
        end

        % Image clicked function: mveraLinkedin
        function mveraLinkedinClicked(app, event)
            system('start https://www.linkedin.com/in/marcos-vera-coello-05b3a643/?originalSubdomain=es');
        end

        % Image clicked function: acuadraMail
        function acuadraMailClicked(app, event)
            system('start mailto:acuadra@ing.uc3m.es');
        end

        % Callback function
        function Button_9Pushed(app, event)
            
        end

        % Image clicked function: mveraResearch
        function mveraResearchClicked(app, event)
             system('start https://www.researchgate.net/profile/Marcos_Vera');
        end

        % Image clicked function: acuadraResearch
        function acuadraResearchClicked(app, event)
            system('start https://www.researchgate.net/profile/Alberto_Cuadra_Lara');
        end

        % Image clicked function: acuadraImage
        function acuadraWebClicked(app, event)
            system('start https://acuadralara.com/');
        end

        % Image clicked function: mveraImage
        function mveraWebClicked(app, event)
            system('start http://fluidosuc3m.es/people/mvcoello/');
        end

        % Image clicked function: acuadraGithub
        function acuadraGithubClicked(app, event)
            system('start https://github.com/AlbertoCuadra');
        end

        % Button pushed function: RepositoryButton
        function RepositoryButtonPushed(app, event)
            system('start https://github.com/AlbertoCuadra/combustion_toolbox');
        end

        % Button pushed function: ContributorsButton
        function ContributorsButtonPushed(app, event)
            system('start https://github.com/AlbertoCuadra/combustion_toolbox/blob/master/CONTRIBUTORS.md');
        end

        % Image clicked function: chueteResearch
        function chueteResearchClicked(app, event)
            system('start https://www.researchgate.net/profile/Cesar-Huete');
        end

        % Image clicked function: chueteLinkedin
        function chueteLinkedinClicked(app, event)
            system('start https://www.linkedin.com/in/cesarhuete/');
        end

        % Image clicked function: chueteImage
        function chueteWebClicked(app, event)
            system('start http://fluidosuc3m.es/people/chuete/');
        end

        % Button pushed function: WebsiteButton
        function WebsiteButtonPushed(app, event)
            system('start https://combustion-toolbox-website.readthedocs.io/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AboutUIFigure = uifigure('Visible', 'off');
            app.AboutUIFigure.Name = 'About';
            app.AboutUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'about.png');
            app.nameLabel1 = uilabel(app.AboutUIFigure);
            app.nameLabel1.Text = 'Alberto Cuadra-Lara';
            app.categLabel1 = uilabel(app.AboutUIFigure);
            app.categLabel1.Text = 'Core developer & App Designer';
            app.WebsiteButton = uibutton(app.AboutUIFigure, 'push');
            app.WebsiteButton.ButtonPushedFcn = createCallbackFcn(app, @WebsiteButtonPushed, true);
            app.WebsiteButton.Text = 'Website';
            app.ContributorsButton = uibutton(app.AboutUIFigure, 'push');
            app.ContributorsButton.ButtonPushedFcn = createCallbackFcn(app, @ContributorsButtonPushed, true);
            app.ContributorsButton.Text = 'Contributors';
            app.RepositoryButton = uibutton(app.AboutUIFigure, 'push');
            app.RepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @RepositoryButtonPushed, true);
            app.RepositoryButton.Text = 'Repository';
            app.categLabel2 = uilabel(app.AboutUIFigure);
            app.categLabel2.Text = 'Developer';
            app.nameLabel2 = uilabel(app.AboutUIFigure);
            app.nameLabel2.Text = 'Marcos Vera';
            app.nameLabel3 = uilabel(app.AboutUIFigure);
            app.nameLabel3.Text = 'César Huete';
            app.categLabel3 = uilabel(app.AboutUIFigure);
            app.categLabel3.Text = 'Developer';
            app.acuadraGithub = uiimage(app.AboutUIFigure);
            app.acuadraGithub.ImageClickedFcn = createCallbackFcn(app, @acuadraGithubClicked, true);
            app.acuadraGithub.ImageSource = 'github_icon.svg';
            app.acuadraResearch = uiimage(app.AboutUIFigure);
            app.acuadraResearch.ImageClickedFcn = createCallbackFcn(app, @acuadraResearchClicked, true);
            app.acuadraResearch.ImageSource = 'researchgate_icon.svg';
            app.acuadraLinkedin = uiimage(app.AboutUIFigure);
            app.acuadraLinkedin.ImageClickedFcn = createCallbackFcn(app, @acuadraLinkedinClicked, true);
            app.acuadraLinkedin.ImageSource = 'linkedin_icon.svg';
            app.acuadraMail = uiimage(app.AboutUIFigure);
            app.acuadraMail.ImageClickedFcn = createCallbackFcn(app, @acuadraMailClicked, true);
            app.acuadraMail.ImageSource = 'mail_icon.svg';
            app.mveraResearch = uiimage(app.AboutUIFigure);
            app.mveraResearch.ImageClickedFcn = createCallbackFcn(app, @mveraResearchClicked, true);
            app.mveraResearch.ImageSource = 'researchgate_icon.svg';
            app.mveraLinkedin = uiimage(app.AboutUIFigure);
            app.mveraLinkedin.ImageClickedFcn = createCallbackFcn(app, @mveraLinkedinClicked, true);
            app.mveraLinkedin.ImageSource = 'linkedin_icon.svg';
            app.chueteResearch = uiimage(app.AboutUIFigure);
            app.chueteResearch.ImageClickedFcn = createCallbackFcn(app, @chueteResearchClicked, true);
            app.chueteResearch.ImageSource = 'researchgate_icon.svg';
            app.chueteLinkedin = uiimage(app.AboutUIFigure);
            app.chueteLinkedin.ImageClickedFcn = createCallbackFcn(app, @chueteLinkedinClicked, true);
            app.chueteLinkedin.ImageSource = 'linkedin_icon.svg';
            app.acuadraImage = uiimage(app.AboutUIFigure);
            app.acuadraImage.ImageClickedFcn = createCallbackFcn(app, @acuadraWebClicked, true);
            app.acuadraImage.ImageSource = 'acuadra_image.svg';
            app.mveraImage = uiimage(app.AboutUIFigure);
            app.mveraImage.ImageClickedFcn = createCallbackFcn(app, @mveraWebClicked, true);
            app.mveraImage.ImageSource = 'mvera_image.svg';
            app.chueteImage = uiimage(app.AboutUIFigure);
            app.chueteImage.ImageClickedFcn = createCallbackFcn(app, @chueteWebClicked, true);
            app.chueteImage.ImageSource = 'chuete_image.svg';
            app.fluidosImage = uiimage(app.AboutUIFigure);
            app.fluidosImage.ImageClickedFcn = createCallbackFcn(app, @fluidosWebClicked, true);
            app.fluidosImage.ImageSource = 'fluids_uc3m_icon.svg';
            % Show the figure after all components are created
            app.AboutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = about

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AboutUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AboutUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutUIFigure)
        end
    end
end