classdef uiabout_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIAbout            matlab.ui.Figure
        Panel_bar          matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        Image_logo_1       matlab.ui.control.Image
        Image_logo_2       matlab.ui.control.Image
        Image_bar_1        matlab.ui.control.Image
        Image_bar_2        matlab.ui.control.Image
        Image_bar_3        matlab.ui.control.Image
        Panel_authors      matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        Panel_acuadra      matlab.ui.container.Panel
        GridLayoutacuadra  matlab.ui.container.GridLayout
        acuadraImage       matlab.ui.control.Image
        acuadraMail        matlab.ui.control.Image
        acuadraLinkedin    matlab.ui.control.Image
        acuadraResearch    matlab.ui.control.Image
        acuadraGithub      matlab.ui.control.Image
        categLabel1        matlab.ui.control.Label
        nameLabel1         matlab.ui.control.Label
        Panel_mvera        matlab.ui.container.Panel
        GridLayout_mvera   matlab.ui.container.GridLayout
        mveraImage         matlab.ui.control.Image
        mveraLinkedin      matlab.ui.control.Image
        mveraResearch      matlab.ui.control.Image
        nameLabel2         matlab.ui.control.Label
        categLabel2        matlab.ui.control.Label
        Panel_chuete       matlab.ui.container.Panel
        GridLayout_chuete  matlab.ui.container.GridLayout
        chueteImage        matlab.ui.control.Image
        chueteLinkedin     matlab.ui.control.Image
        chueteResearch     matlab.ui.control.Image
        categLabel3        matlab.ui.control.Label
        nameLabel3         matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function select_action(app, event)
            obj_tag_clicked = event.Source.Tag;
            switch lower(obj_tag_clicked)
                case {'repo', 'repository'}
                    system('start https://github.com/AlbertoCuadra/combustion_toolbox');
                case 'website_ct'
                    system('start https://combustion-toolbox-website.readthedocs.io/');
                case {'docs', 'documentation'}
                    system('start https://combustion-toolbox-website.readthedocs.io/en/latest/documentation');
                case 'publications'
                    system('start https://combustion-toolbox-website.readthedocs.io/en/latest/publications.html');
                case 'website_uc3m'
                    system('start http://fluidosuc3m.es/');
                case 'website_acuadra'
                    system('start https://acuadralara.com/');
                case 'mail_acuadra'
                    system('start mailto:acuadra@ing.uc3m.es');
                case 'github_acuadra'
                    system('start https://github.com/AlbertoCuadra');
                case 'researchgate_acuadra'
                    system('start https://www.researchgate.net/profile/Alberto_Cuadra_Lara');
                case 'linkedin_acuadra'
                    system('start https://www.linkedin.com/in/albertocuadralara/');
                case 'website_mvera'
                    system('start http://fluidosuc3m.es/people/mvcoello/');
                case 'researchgate_mvera'
                    system('start https://www.researchgate.net/profile/Marcos_Vera');
                case 'linkedin_mvera'
                    system('start https://www.linkedin.com/in/marcos-vera-coello-05b3a643/?originalSubdomain=es');
                case 'website_chuete'
                    system('start http://fluidosuc3m.es/people/chuete/');
                case 'researchgate_chuete'
                    system('start https://www.researchgate.net/profile/Cesar-Huete');
                case 'linkedin_chuete'
                    system('start https://www.linkedin.com/in/cesarhuete/');
                case 'contributors'
                    system('start https://github.com/AlbertoCuadra/combustion_toolbox/blob/master/CONTRIBUTORS.md');
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             gui_SnapshotMenuSelected(app.UIAbout);
        end

        % Image clicked function: Image_bar_1, Image_bar_2, Image_bar_3, 
        % ...and 13 other components
        function Image_2Clicked(app, event)
            select_action(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIAbout = uifigure('Visible', 'off');
            app.UIAbout.Name = 'About';
            app.UIAbout.Icon = 'logo_uiabout.png';
            app.Panel_authors = uipanel(app.UIAbout);
            app.GridLayout2 = uigridlayout(app.Panel_authors);
            app.Panel_chuete = uipanel(app.GridLayout2);
            app.GridLayout_chuete = uigridlayout(app.Panel_chuete);
            app.nameLabel3 = uilabel(app.GridLayout_chuete);
            app.nameLabel3.Text = 'César Huete';
            app.categLabel3 = uilabel(app.GridLayout_chuete);
            app.categLabel3.Text = 'Developer';
            app.chueteResearch = uiimage(app.GridLayout_chuete);
            app.chueteResearch.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.chueteResearch.Tag = 'researchgate_chuete';
            app.chueteResearch.ImageSource = 'icon_researchgate.svg';
            app.chueteLinkedin = uiimage(app.GridLayout_chuete);
            app.chueteLinkedin.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.chueteLinkedin.Tag = 'linkedin_chuete';
            app.chueteLinkedin.ImageSource = 'icon_linkedin.svg';
            app.chueteImage = uiimage(app.GridLayout_chuete);
            app.chueteImage.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.chueteImage.Tag = 'website_chuete';
            app.chueteImage.ImageSource = 'profile_chuete.svg';
            app.Panel_mvera = uipanel(app.GridLayout2);
            app.GridLayout_mvera = uigridlayout(app.Panel_mvera);
            app.categLabel2 = uilabel(app.GridLayout_mvera);
            app.categLabel2.Text = 'Developer';
            app.nameLabel2 = uilabel(app.GridLayout_mvera);
            app.nameLabel2.Text = 'Marcos Vera';
            app.mveraResearch = uiimage(app.GridLayout_mvera);
            app.mveraResearch.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.mveraResearch.Tag = 'researchgate_mvera';
            app.mveraResearch.ImageSource = 'icon_researchgate.svg';
            app.mveraLinkedin = uiimage(app.GridLayout_mvera);
            app.mveraLinkedin.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.mveraLinkedin.Tag = 'linkedin_mvera';
            app.mveraLinkedin.ImageSource = 'icon_linkedin.svg';
            app.mveraImage = uiimage(app.GridLayout_mvera);
            app.mveraImage.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.mveraImage.Tag = 'website_mvera';
            app.mveraImage.ImageSource = 'profile_mvera.svg';
            app.Panel_acuadra = uipanel(app.GridLayout2);
            app.GridLayoutacuadra = uigridlayout(app.Panel_acuadra);
            app.nameLabel1 = uilabel(app.GridLayoutacuadra);
            app.nameLabel1.Text = 'Alberto Cuadra-Lara';
            app.categLabel1 = uilabel(app.GridLayoutacuadra);
            app.categLabel1.Text = 'Core developer';
            app.acuadraGithub = uiimage(app.GridLayoutacuadra);
            app.acuadraGithub.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.acuadraGithub.Tag = 'github_acuadra';
            app.acuadraGithub.ImageSource = 'icon_github.svg';
            app.acuadraResearch = uiimage(app.GridLayoutacuadra);
            app.acuadraResearch.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.acuadraResearch.Tag = 'researchgate_acuadra';
            app.acuadraResearch.ImageSource = 'icon_researchgate.svg';
            app.acuadraLinkedin = uiimage(app.GridLayoutacuadra);
            app.acuadraLinkedin.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.acuadraLinkedin.Tag = 'linkedin_acuadra';
            app.acuadraLinkedin.ImageSource = 'icon_linkedin.svg';
            app.acuadraMail = uiimage(app.GridLayoutacuadra);
            app.acuadraMail.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.acuadraMail.Tag = 'mail_acuadra';
            app.acuadraMail.ImageSource = 'icon_mail.svg';
            app.acuadraImage = uiimage(app.GridLayoutacuadra);
            app.acuadraImage.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.acuadraImage.Tag = 'website_acuadra';
            app.acuadraImage.ImageSource = 'profile_acuadra.svg';
            app.Panel_bar = uipanel(app.UIAbout);
            app.GridLayout = uigridlayout(app.Panel_bar);
            app.Image_bar_3 = uiimage(app.GridLayout);
            app.Image_bar_3.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_bar_3.Tag = 'publications';
            app.Image_bar_3.ImageSource = 'bar_publications.svg';
            app.Image_bar_2 = uiimage(app.GridLayout);
            app.Image_bar_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_bar_2.Tag = 'documentation';
            app.Image_bar_2.ImageSource = 'bar_documentation.svg';
            app.Image_bar_1 = uiimage(app.GridLayout);
            app.Image_bar_1.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_bar_1.Tag = 'website_CT';
            app.Image_bar_1.ImageSource = 'bar_home.svg';
            app.Image_logo_2 = uiimage(app.GridLayout);
            app.Image_logo_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_logo_2.Tag = 'website_uc3m';
            app.Image_logo_2.ImageSource = 'logo_fluids_uc3m.svg';
            app.Image_logo_1 = uiimage(app.GridLayout);
            app.Image_logo_1.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_logo_1.Tag = 'website_CT';
            app.Image_logo_1.ImageSource = 'logo_CT_noversion.svg';
            % Show the figure after all components are created
            app.UIAbout.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uiabout_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIAbout)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIAbout)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIAbout)
        end
    end
end