classdef combustion_toolbox_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        xlsMenu                         matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        SnapshotMenu                    matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        TutorialMenu                    matlab.ui.container.Menu
        ExamplesMenu                    matlab.ui.container.Menu
        ValidationsMenu                 matlab.ui.container.Menu
        DiscussionsMenu                 matlab.ui.container.Menu
        CheckforupdatesMenu             matlab.ui.container.Menu
        LicenseMenu                     matlab.ui.container.Menu
        SendfeedbackMenu                matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Lamp                            matlab.ui.control.Lamp
        Label_Console                   matlab.ui.control.Label
        Console_text                    matlab.ui.control.TextArea
        Console                         matlab.ui.control.TextArea
        Tab_lateral_bar                 matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        SelectProblemTypePanel          matlab.ui.container.Panel
        FLAG_IAC                        matlab.ui.control.CheckBox
        IonizedspeciesCheckBox          matlab.ui.control.CheckBox
        FrozenchemistryCheckBox         matlab.ui.control.CheckBox
        ProblemType                     matlab.ui.control.DropDown
        Calculate                       matlab.ui.control.Button
        Clear                           matlab.ui.control.Button
        AdditionalconstraintsPanel      matlab.ui.container.Panel
        text_P2                         matlab.ui.control.Label
        PP3                             matlab.ui.control.EditField
        PP4                             matlab.ui.control.EditField
        text_RP4                        matlab.ui.control.Label
        PR4                             matlab.ui.control.EditField
        text_RP3                        matlab.ui.control.Label
        PR3                             matlab.ui.control.EditField
        text_RP                         matlab.ui.control.Label
        text_R2                         matlab.ui.control.Label
        DefinestateofreactantsandproductsPanel  matlab.ui.container.Panel
        text_RP2_2                      matlab.ui.control.Label
        text_RP1_2                      matlab.ui.control.Label
        text_RP2                        matlab.ui.control.Label
        PR2                             matlab.ui.control.EditField
        text_P1                         matlab.ui.control.Label
        text_R1                         matlab.ui.control.Label
        text_RP1                        matlab.ui.control.Label
        PR1                             matlab.ui.control.EditField
        PP1                             matlab.ui.control.EditField
        PP2                             matlab.ui.control.EditField
        DefinereactantsandspeciestobeconsideredPanel  matlab.ui.container.Panel
        edit_F                          matlab.ui.control.NumericEditField
        ProductsPanel                   matlab.ui.container.Panel
        Products                        matlab.ui.control.DropDown
        FuelEditFieldLabel              matlab.ui.control.Label
        text_phi                        matlab.ui.control.Label
        edit_phi                        matlab.ui.control.EditField
        edit_OF                         matlab.ui.control.NumericEditField
        OFEditFieldLabel                matlab.ui.control.Label
        ListofSpeciesPanel              matlab.ui.container.Panel
        listbox_Products                matlab.ui.control.ListBox
        ReactantsPanel                  matlab.ui.container.Panel
        Reactants                       matlab.ui.control.DropDown
        UITable_R                       matlab.ui.control.Table
        ExtendedsettingsTab             matlab.ui.container.Tab
        ReportPanel                     matlab.ui.container.Panel
        Report_type                     matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        OthersPanel                     matlab.ui.container.Panel
        IdealAirCheckBox                matlab.ui.control.CheckBox
        TuningparametersPanel           matlab.ui.container.Panel
        MaxiterationsSDEditField        matlab.ui.control.NumericEditField
        MaxiterationsSDEditFieldLabel   matlab.ui.control.Label
        RFMT0EditField                  matlab.ui.control.NumericEditField
        RFMT0EditFieldLabel             matlab.ui.control.Label
        RFMT0_LEditField                matlab.ui.control.NumericEditField
        RFMT0_LEditFieldLabel           matlab.ui.control.Label
        RFMT0_REditField                matlab.ui.control.NumericEditField
        RFMT0_REditFieldLabel           matlab.ui.control.Label
        MaxiterationsRFMEditField       matlab.ui.control.NumericEditField
        MaxiterationsRFMEditFieldLabel  matlab.ui.control.Label
        TolerancesPanel                 matlab.ui.container.Panel
        ShocksandDetonationsEditField   matlab.ui.control.NumericEditField
        ShocksandDetonationsEditFieldLabel  matlab.ui.control.Label
        RootFindingMethodEditField      matlab.ui.control.NumericEditField
        RootFindingMethodEditFieldLabel  matlab.ui.control.Label
        DisplaySpeciesEditField         matlab.ui.control.NumericEditField
        DisplaySpeciesEditFieldLabel    matlab.ui.control.Label
        TraceoptionEditField            matlab.ui.control.NumericEditField
        TraceoptionEditFieldLabel       matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        SelectionofspeciesTab           matlab.ui.container.Tab
        listbox_LS                      matlab.ui.control.ListBox
        text_LS                         matlab.ui.control.Label
        RemoveButton1                   matlab.ui.control.Button
        AddButton1                      matlab.ui.control.Button
        listbox_LS_DB                   matlab.ui.control.ListBox
        text_LS_DB                      matlab.ui.control.Label
        edit_seeker_1                   matlab.ui.control.EditField
        text_list_species               matlab.ui.control.Label
        PlottedspeciesTab               matlab.ui.container.Tab
        text_minor_products             matlab.ui.control.Label
        listbox_plotted_species         matlab.ui.control.ListBox
        RemoveButton2                   matlab.ui.control.Button
        AddButton2                      matlab.ui.control.Button
        listbox_species                 matlab.ui.control.ListBox
        text_list_species_3             matlab.ui.control.Label
        edit_seeker_2                   matlab.ui.control.EditField
        text_list_species_2             matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        Node_Results                    matlab.ui.container.TreeNode
        Tab_results                     matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        Panel_parameters                matlab.ui.container.Panel
        text_MR                         matlab.ui.control.NumericEditField
        text_uR                         matlab.ui.control.NumericEditField
        text_TP                         matlab.ui.control.NumericEditField
        text_pR                         matlab.ui.control.NumericEditField
        text_TR                         matlab.ui.control.NumericEditField
        text_T                          matlab.ui.control.Label
        text_Reactans                   matlab.ui.control.Label
        text_Products                   matlab.ui.control.Label
        text_pP                         matlab.ui.control.NumericEditField
        text_p                          matlab.ui.control.Label
        text_rP                         matlab.ui.control.NumericEditField
        text_rR                         matlab.ui.control.NumericEditField
        text_r                          matlab.ui.control.Label
        text_hP                         matlab.ui.control.NumericEditField
        text_hR                         matlab.ui.control.NumericEditField
        text_h                          matlab.ui.control.Label
        text_cpP                        matlab.ui.control.NumericEditField
        text_cpR                        matlab.ui.control.NumericEditField
        text_cp                         matlab.ui.control.Label
        text_WP                         matlab.ui.control.NumericEditField
        text_WR                         matlab.ui.control.NumericEditField
        text_W                          matlab.ui.control.Label
        text_soundP                     matlab.ui.control.NumericEditField
        text_soundR                     matlab.ui.control.NumericEditField
        text_sound                      matlab.ui.control.Label
        text_eP                         matlab.ui.control.NumericEditField
        text_eR                         matlab.ui.control.NumericEditField
        text_e                          matlab.ui.control.Label
        text_uP                         matlab.ui.control.NumericEditField
        text_u                          matlab.ui.control.Label
        text_MP                         matlab.ui.control.NumericEditField
        text_M                          matlab.ui.control.Label
        text_gammaP                     matlab.ui.control.NumericEditField
        text_gammaR                     matlab.ui.control.NumericEditField
        text_gamma                      matlab.ui.control.Label
        text_sP                         matlab.ui.control.NumericEditField
        text_sR                         matlab.ui.control.NumericEditField
        text_s                          matlab.ui.control.Label
        text_error_problem              matlab.ui.control.NumericEditField
        EpsilonmolesLabel_2             matlab.ui.control.Label
        text_error_moles                matlab.ui.control.NumericEditField
        EpsilonmolesLabel               matlab.ui.control.Label
        text_vP_vR                      matlab.ui.control.NumericEditField
        VolumeProductsReactantsLabel    matlab.ui.control.Label
        text_q                          matlab.ui.control.NumericEditField
        hPhRkJkgEditFieldLabel          matlab.ui.control.Label
        ParametersRocketsTab            matlab.ui.container.Tab
        Panel_extra_0                   matlab.ui.container.Panel
        text_Isp_1                      matlab.ui.control.NumericEditField
        text_Isp_2                      matlab.ui.control.NumericEditField
        text_Isp                        matlab.ui.control.Label
        text_Ivac_1                     matlab.ui.control.NumericEditField
        text_Ivac_2                     matlab.ui.control.NumericEditField
        text_Ivac                       matlab.ui.control.Label
        text_Cstar_1                    matlab.ui.control.NumericEditField
        text_Cstar_2                    matlab.ui.control.NumericEditField
        text_Cstar                      matlab.ui.control.Label
        text_Aratio_1                   matlab.ui.control.NumericEditField
        text_Aratio_2                   matlab.ui.control.NumericEditField
        text_Aratio                     matlab.ui.control.Label
        text_MR_2                       matlab.ui.control.NumericEditField
        text_uR_2                       matlab.ui.control.NumericEditField
        text_TP_2                       matlab.ui.control.NumericEditField
        text_pR_2                       matlab.ui.control.NumericEditField
        text_TR_2                       matlab.ui.control.NumericEditField
        text_T_2                        matlab.ui.control.Label
        text_Reactans_2                 matlab.ui.control.Label
        text_Products_2                 matlab.ui.control.Label
        text_pP_2                       matlab.ui.control.NumericEditField
        text_p_2                        matlab.ui.control.Label
        text_rP_2                       matlab.ui.control.NumericEditField
        text_rR_2                       matlab.ui.control.NumericEditField
        text_r_2                        matlab.ui.control.Label
        text_hP_2                       matlab.ui.control.NumericEditField
        text_hR_2                       matlab.ui.control.NumericEditField
        text_h_2                        matlab.ui.control.Label
        text_cpP_2                      matlab.ui.control.NumericEditField
        text_cpR_2                      matlab.ui.control.NumericEditField
        text_cp_2                       matlab.ui.control.Label
        text_WP_2                       matlab.ui.control.NumericEditField
        text_WR_2                       matlab.ui.control.NumericEditField
        text_W_2                        matlab.ui.control.Label
        text_soundP_2                   matlab.ui.control.NumericEditField
        text_soundR_2                   matlab.ui.control.NumericEditField
        text_sound_2                    matlab.ui.control.Label
        text_eP_2                       matlab.ui.control.NumericEditField
        text_eR_2                       matlab.ui.control.NumericEditField
        text_e_2                        matlab.ui.control.Label
        text_uP_2                       matlab.ui.control.NumericEditField
        text_u_2                        matlab.ui.control.Label
        text_MP_2                       matlab.ui.control.NumericEditField
        text_M_2                        matlab.ui.control.Label
        text_gammaP_2                   matlab.ui.control.NumericEditField
        text_gammaR_2                   matlab.ui.control.NumericEditField
        text_gamma_2                    matlab.ui.control.Label
        text_sP_2                       matlab.ui.control.NumericEditField
        text_sR_2                       matlab.ui.control.NumericEditField
        text_s_2                        matlab.ui.control.Label
        Panel_extra_5                   matlab.ui.container.Panel
        text_Isp_5                      matlab.ui.control.NumericEditField
        text_Ivac_5                     matlab.ui.control.NumericEditField
        text_Cstar_5                    matlab.ui.control.NumericEditField
        text_Aratio_5                   matlab.ui.control.NumericEditField
        text_TP_5                       matlab.ui.control.NumericEditField
        text_Products_5                 matlab.ui.control.Label
        text_pP_5                       matlab.ui.control.NumericEditField
        text_rP_5                       matlab.ui.control.NumericEditField
        text_hP_5                       matlab.ui.control.NumericEditField
        text_cpP_5                      matlab.ui.control.NumericEditField
        text_WP_5                       matlab.ui.control.NumericEditField
        text_soundP_5                   matlab.ui.control.NumericEditField
        text_eP_5                       matlab.ui.control.NumericEditField
        text_uP_5                       matlab.ui.control.NumericEditField
        text_MP_5                       matlab.ui.control.NumericEditField
        text_gammaP_5                   matlab.ui.control.NumericEditField
        text_sP_5                       matlab.ui.control.NumericEditField
        Panel_extra_3                   matlab.ui.container.Panel
        text_Isp_3                      matlab.ui.control.NumericEditField
        text_Ivac_3                     matlab.ui.control.NumericEditField
        text_Cstar_3                    matlab.ui.control.NumericEditField
        text_Aratio_3                   matlab.ui.control.NumericEditField
        text_TP_3                       matlab.ui.control.NumericEditField
        text_Products_3                 matlab.ui.control.Label
        text_pP_3                       matlab.ui.control.NumericEditField
        text_rP_3                       matlab.ui.control.NumericEditField
        text_hP_3                       matlab.ui.control.NumericEditField
        text_cpP_3                      matlab.ui.control.NumericEditField
        text_WP_3                       matlab.ui.control.NumericEditField
        text_soundP_3                   matlab.ui.control.NumericEditField
        text_eP_3                       matlab.ui.control.NumericEditField
        text_uP_3                       matlab.ui.control.NumericEditField
        text_MP_3                       matlab.ui.control.NumericEditField
        text_gammaP_3                   matlab.ui.control.NumericEditField
        text_sP_3                       matlab.ui.control.NumericEditField
        Panel_extra_4                   matlab.ui.container.Panel
        text_Isp_4                      matlab.ui.control.NumericEditField
        text_Ivac_4                     matlab.ui.control.NumericEditField
        text_Cstar_4                    matlab.ui.control.NumericEditField
        text_Aratio_4                   matlab.ui.control.NumericEditField
        text_TP_4                       matlab.ui.control.NumericEditField
        text_Products_4                 matlab.ui.control.Label
        text_pP_4                       matlab.ui.control.NumericEditField
        text_rP_4                       matlab.ui.control.NumericEditField
        text_hP_4                       matlab.ui.control.NumericEditField
        text_cpP_4                      matlab.ui.control.NumericEditField
        text_WP_4                       matlab.ui.control.NumericEditField
        text_soundP_4                   matlab.ui.control.NumericEditField
        text_eP_4                       matlab.ui.control.NumericEditField
        text_uP_4                       matlab.ui.control.NumericEditField
        text_MP_4                       matlab.ui.control.NumericEditField
        text_gammaP_4                   matlab.ui.control.NumericEditField
        text_sP_4                       matlab.ui.control.NumericEditField
        MixturecompositionTab           matlab.ui.container.Tab
        text_phi_2                      matlab.ui.control.Label
        edit_phi2                       matlab.ui.control.EditField
        text_R3                         matlab.ui.control.Label
        text_P3                         matlab.ui.control.Label
        UITable_P                       matlab.ui.control.Table
        UITable_R2                      matlab.ui.control.Table
        CustomFiguresTab                matlab.ui.container.Tab
        figure_size                     matlab.ui.control.Button
        figure_clear                    matlab.ui.control.Button
        figure_plot                     matlab.ui.control.Button
        Tree_variable_y                 matlab.ui.container.CheckBoxTree
        Variable_y                      matlab.ui.container.TreeNode
        Tree_variable_x                 matlab.ui.container.CheckBoxTree
        Variable_x                      matlab.ui.container.TreeNode
        Tree_mixtures                   matlab.ui.container.CheckBoxTree
        Mixtures                        matlab.ui.container.TreeNode
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu_CommandWindow       matlab.ui.container.ContextMenu
        MaximizeMenu                    matlab.ui.container.Menu
        MinimizeMenu                    matlab.ui.container.Menu
        ClearCommandWindowMenu          matlab.ui.container.Menu
        ContextMenu_UITree              matlab.ui.container.ContextMenu
        RemoveMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = public)
        E         % Elements
        S         % Species
        C         % Constants
        Misc      % Miscelaneous
        PD        % Problem Description
        PS        % Problem Solution
        TN        % Tunning properties
        DB_master % Master DataBase
        DB        % Reduced DataBase
        fig       % Auxiliary figure
        default   % Struct with default values of some components in the GUI
        N_flags   % Number of flags active
        PR1_vector   % Condition Reactants 1
        PR2_vector   % Condition Reactants 2
        PR3_vector   % Condition Reactants 3
        PP1_vector   % Condition Products 1
        PP2_vector   % Condition Products 2
        PR1_var_name % Variable name for PR1
        PR2_var_name % Variable name for PR2
        PR3_var_name % Variable name for PR3
        PP1_var_name % Variable name for PP1
        PP2_var_name % Variable name for PP2
        flag_PR1     % FLAG for PR1: true-> vector
        flag_PR2     % FLAG for PR2: true-> vector
        flag_PR3     % FLAG for PR3: true-> vector
        flag_PP1     % FLAG for PP1: true-> vector
        flag_PP2     % FLAG for PP2: true-> vector
        flag_phi     % FLAG for phi: true-> vector
        ind_Fuel     % Index position Fuel species
        ind_Oxidizer % Index position Oxidizer species
        ind_Inert    % Index position Inert species
        LS           % List of species considered (reactants + products)
        LS_products  % List of species considered as products
        color_splash       = [0.5098, 0.6039, 0.6745]; % Font color splash
        color_lamp_working = [0.9961, 0.9804, 0.8314]; % Lamp color (rgb): working
        color_lamp_done    = [0.5608, 0.7255, 0.6588]; % Lamp color (rgb): done
        color_lamp_error   = [0.9451, 0.5059, 0.5529]; % Lamp color (rgb): error
        temp_results % Temporal variable that contains the last parametric study
        welcome_message = 'Welcome to Combustion Toolbox %s --- A MATLAB-GUI based open-source tool for solving gaseous combustion problems.';
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load figure in the background
            app.UIFigure.Visible = 'off';
            % Splash
            splash_obj = gui_display_splash('app', app);
            % Initialze app
            app = App(app);
            % Initialize List box species DataBase master
            app.listbox_LS_DB.Items = app.S.LS_DB;
            % Initialize table data
            app.UITable_R.ColumnFormat = {[] [] [] {'Fuel', 'Oxidizer', 'Inert'} []};
            % Initialize for TP
            app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR';
            app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
            % Get tolerances
            app = gui_get_tolerances(app);
            % Save default state GUI
            app.default.data_UITable_R = app.UITable_R.Data;
%             app.UITable_R.ColumnFormat(2:3)  = {'shortE'};
%             app.UITable_R2.ColumnFormat(2:3) = {'shortE'};
            app.UITable_P.ColumnFormat(2:3)  = {'shortE'};
            % Update welcome message in the command window
            app.Console_text.Value = sprintf(app.welcome_message, app.C.release);
            % Make app visible
            app.UIFigure.Visible = 'on';
            % Delete splash
            delete(splash_obj);
        end

        % Callback function
        function LogoClicked(app, event)
            system('start http://uc3m.es/');
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            run('about.mlapp');
        end

        % Value changed function: Console
        function ConsoleValueChanged(app, event)
            gui_ConsoleValueChanged(app, event);
        end

        % Menu selected function: SnapshotMenu
        function SnapshotMenuSelected(app, event)
            gui_SnapshotMenuSelected(app.UIFigure);
        end

        % Menu selected function: MaximizeMenu
        function MaximizeMenuSelected(app, event)
            app.Console_text.Position = [78, 30, 554, 462];
        end

        % Menu selected function: MinimizeMenu
        function MinimizeMenuSelected(app, event)
            app.Console_text.Position = [78, 30, 554 57];
        end

        % Menu selected function: ClearCommandWindowMenu
        function ClearCommandWindowMenuSelected(app, event)
            app.Console_text.Value = '';
        end

        % Value changed function: Reactants
        function ReactantsValueChanged(app, event)
            gui_ReactantsValueChanged(app, event);
            % Compute pre-shock velocity (only for shocks)
            gui_compute_mach_or_velocity(app, 'Mach');
            % Update Listbox (extended settings)
            app.listbox_LS.Items = app.listbox_Products.Items;
        end

        % Button pushed function: Calculate
        function CalculateButtonPushed(app, event)
%             profile on
            gui_CalculateButtonPushed(app, event);
%             profile viewer
        end

        % Menu selected function: SendfeedbackMenu
        function SendfeedbackMenuSelected(app, event)
            run('uifeedback');
        end

        % Cell edit callback: UITable_R
        function UITable_RCellEdit(app, event)
            gui_UITable_RCellEdit(app, event);
        end

        % Value changed function: edit_phi
        function edit_phiValueChanged(app, event)
            gui_edit_phiValueChanged(app, event);
        end

        % Value changed function: PR1
        function PR1ValueChanged(app, event)
            % Update equilibrium temperature of reactants (GUI)
            if ~isempty(app.UITable_R.Data)
                species = app.UITable_R.Data(:, 1);
                temperature = {gui_get_prop(app, [], app.PR1.Value)};
                temperature = {temperature{1}(1)};
                Nspecies = length(app.UITable_R.Data(:, 1));
                [app, temperature, FLAG] = gui_check_temperature_reactants(app, app.DB, species, temperature, Nspecies);
                if FLAG
                    app.UITable_R.Data(:, 5) = temperature;
                    message = sprintf('The species selected as reactants can only be evaluated at its defined temperature.\nThe temperature shown as the temperature of the reactant is a ficticious value! The species are unmixed.');
                    uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                else
                    app.UITable_R.Data(:, 5) = create_cell_ntimes(temperature{1}, Nspecies);
                end
            end
            % Compute pre-shock velocity (only for shocks)
            gui_compute_mach_or_velocity(app, 'Mach');
        end

        % Menu selected function: DiscussionsMenu
        function DiscussionsMenuSelected(app, event)
            % Open default web browser and redirect to Combustion Toolbox
            % discussion forum
            system('start https://github.com/AlbertoCuadra/combustion_toolbox/discussions');
        end

        % Value changed function: ProblemType
        function ProblemTypeValueChanged(app, event)
            % Clear GUI results tab (except UITree) and update GUI items for the problem selected
            gui_ProblemTypeValueChanged(app);
        end

        % Value changed function: PP2
        function PP2ValueChanged(app, event)
            if app.ProblemType.Value(2) == 'P' &&  app.ProblemType.Value(1) ~= 'S'
                app.PR2.Value = event.Value;
            end
        end

        % Value changed function: PR2
        function PR2ValueChanged(app, event)
            if app.ProblemType.Value(2) == 'P' &&  app.ProblemType.Value(1) ~= 'S'
                app.PP2.Value = event.Value;
            end
            % Compute pre-shock velocity (only for shocks)
            gui_compute_mach_or_velocity(app, 'Mach');
        end

        % Value changed function: Products
        function ProductsValueChanged(app, event)
            % Update List of species considered as Products
            gui_ProductsValueChanged(app);
            % Update Listbox (extended settings)
            app.listbox_LS.Items = app.listbox_Products.Items;
        end

        % Value changed function: FrozenchemistryCheckBox
        function FrozenchemistryCheckBoxValueChanged(app, event)
            % Set frozen chemistry
            gui_FrozenchemistryCheckBoxValueChanged(app)
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            website_CT;
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            ClearCommandWindowMenuSelected(app, event);
            cla(app.UIAxes);
            delete(app.Node_Results.Children);
            delete(app.Mixtures.Children);
            delete(app.Variable_x.Children);
            delete(app.Variable_y.Children);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            gui_update_from_uitree(app, selectedNodes);
        end

        % Value changed function: PR4
        function PR4ValueChanged(app, event)
            % Compute pre-shock velocity (only for shocks)
            gui_compute_mach_or_velocity(app, 'Mach');
        end

        % Value changed function: PR3
        function PR3ValueChanged(app, event)
            % Compute pre-shock Mach number (only for shocks)
            gui_compute_mach_or_velocity(app, 'velocity');
        end

        % Value changed function: text_error_moles
        function text_error_molesValueChanged(app, event)
            MAX_ERROR_MOLES = app.TN.tolN;
            gui_fontcolor_error(app, 'text_error_moles', MAX_ERROR_MOLES)
        end

        % Value changed function: text_error_problem
        function text_error_problemValueChanged(app, event)
            switch app.ProblemType.Value
                case {'TP', 'TV'}
                    MAX_ERROR_PROBLEM = app.TN.tolN;
                case {'HP', 'SP', 'EV', 'SV'}
                    MAX_ERROR_PROBLEM = app.TN.tol0;
                case {'SHOCK_I', 'SHOCK_R', 'DET', 'DET_OVERDRIVEN'}
                    MAX_ERROR_PROBLEM = app.TN.tol_shocks;
            end
            gui_fontcolor_error(app, 'text_error_problem', MAX_ERROR_PROBLEM)
        end

        % Menu selected function: xlsMenu
        function xlsMenuSelected(app, event)
            gui_save_results(app, '.xls');
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            gui_save_results(app, '.mat');
        end

        % Value changed function: edit_seeker_1
        function edit_seeker_1ValueChanged(app, event)
            seek_value = gui_seeker_value(app, event, app.listbox_LS_DB.Items);
            % Update Listbox (inputs)
            if ~isempty(seek_value)
                app.listbox_LS_DB.Items = seek_value;
            else
                app.listbox_LS_DB.Items = fieldnames(app.DB_master);
            end
        end

        % Button pushed function: AddButton1
        function AddButton1Pushed(app, event)
            app.listbox_LS.Items = gui_value2list(app, app.listbox_LS_DB.Value, app.listbox_LS.Items, 'add');
            % Update Listbox (inputs)
            app.listbox_Products.Items = app.listbox_LS.Items;
        end

        % Button pushed function: RemoveButton1
        function RemoveButton1Pushed(app, event)
            app.listbox_LS.Items = gui_value2list(app, app.listbox_LS.Value, app.listbox_LS.Items, 'remove');
            % Update Listbox (inputs)
            app.listbox_Products.Items = app.listbox_LS.Items;
        end

        % Menu selected function: ValidationsMenu
        function ValidationsMenuSelected(app, event)
            run('uivalidations.mlapp');
        end

        % Value changed function: FLAG_IAC
        function FLAG_IACValueChanged(app, event)
            if ~app.FLAG_IAC.Value
                app.text_P1.Text = 'FAC (only one option)';
                app.text_P1.Visible = 'on';
                app.text_RP1_2.Visible = 'on'; app.text_RP2_2.Visible = 'on';
                app.PP1.Visible = 'on'; app.PP2.Visible = 'on';
                app.PP1.Value = ''; app.PP2.Value = '';
                app.Panel_extra_5.Visible = 'on';
                app.text_Products_2.Text = 'Injector';
                app.text_Products_3.Text = 'Outlet Chamber';
                app.text_Products_4.Text = 'Throat';
                app.text_Products_5.Text = 'Exit';
            else
                app.text_P1.Text = 'Products';
                app.text_P1.Visible = 'off';
                app.text_RP1_2.Visible = 'off'; app.text_RP2_2.Visible = 'off';
                app.PP1.Visible = 'off'; app.PP2.Visible = 'off';
                app.PP1.Value = '2500'; app.PP2.Value = '1';
                app.Panel_extra_5.Visible = 'off';
                app.text_Products_2.Text = 'Outlet Chamber';
                app.text_Products_3.Text = 'Throat';
                app.text_Products_4.Text = 'Exit';
                app.text_Products_5.Text = 'Exit';
            end
        end

        % Menu selected function: RemoveMenu
        function RemoveMenuSelected(app, event)
            delete(app.Tree.SelectedNodes);
        end

        % Menu selected function: LicenseMenu
        function LicenseMenuSelected(app, event)
            run('uilicense.mlapp');
        end

        % Button pushed function: figure_clear
        function figure_clearButtonPushed(app, event)
            ClearButtonPushed(app, event);
        end

        % Button pushed function: figure_plot
        function figure_plotButtonPushed(app, event)
            gui_plot_custom_figures(app);
        end

        % Button pushed function: figure_size
        function figure_sizeButtonPushed(app, event)
            if contains(app.figure_size.Text, 'Max')
                % Save default values
                app.default.UIFigure_position = app.UIFigure.Position;
                app.default.UIAxes_position = app.UIAxes.Position;
                app.default.Tab_results_position = app.Tab_results.Position;
                % Maximize UIFigure (width)
                app.UIFigure.Position([1,3]) = [0.5 * app.UIFigure.Position(1), 2 * app.UIFigure.Position(3)];
                % Delete UIAxes
                delete(app.UIAxes);
                % Create new UIAxes
                app.UIAxes = uiaxes(app.UIFigure, 'Position', [655,9,609,719]);
                % Change name button
                app.figure_size.Text = 'Minimize';
                % Replot
                figure_plotButtonPushed(app, event);
            else
                app.UIFigure.Position = app.default.UIFigure_position;
                % Delete UIAxes
                delete(app.UIAxes);
                % Create new UIAxes
                app.UIAxes = uiaxes(app.CustomFiguresTab, 'Position', app.default.UIAxes_position);
                % Change name button
                app.figure_size.Text = 'Maximize';
                % Replot
                figure_plotButtonPushed(app, event);
            end
        end

        % Menu selected function: CheckforupdatesMenu
        function CheckforupdatesMenuSelected(app, event)
            check_update(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Combustion Toolbox';
            app.UIFigure.Icon = 'lab.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Enable = 'off';
            app.OpenMenu.Text = 'Open';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Separator = 'on';
            app.SaveasMenu.Text = 'Save as';
            app.xlsMenu = uimenu(app.SaveasMenu);
            app.xlsMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsMenuSelected, true);
            app.xlsMenu.Text = '*.xls';
            app.matMenu = uimenu(app.SaveasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '*.mat';
            app.SnapshotMenu = uimenu(app.FileMenu);
            app.SnapshotMenu.MenuSelectedFcn = createCallbackFcn(app, @SnapshotMenuSelected, true);
            app.SnapshotMenu.Separator = 'on';
            app.SnapshotMenu.Text = 'Snapshot';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.Enable = 'off';
            app.PreferencesMenu.Separator = 'on';
            app.PreferencesMenu.Text = 'Preferences';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.TutorialMenu = uimenu(app.HelpMenu);
            app.TutorialMenu.Enable = 'off';
            app.TutorialMenu.Text = 'Tutorial';
            app.ExamplesMenu = uimenu(app.HelpMenu);
            app.ExamplesMenu.Enable = 'off';
            app.ExamplesMenu.Text = 'Examples';
            app.ValidationsMenu = uimenu(app.HelpMenu);
            app.ValidationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidationsMenuSelected, true);
            app.ValidationsMenu.Text = 'Validations';
            app.DiscussionsMenu = uimenu(app.HelpMenu);
            app.DiscussionsMenu.MenuSelectedFcn = createCallbackFcn(app, @DiscussionsMenuSelected, true);
            app.DiscussionsMenu.Text = 'Discussions';
            app.CheckforupdatesMenu = uimenu(app.HelpMenu);
            app.CheckforupdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforupdatesMenuSelected, true);
            app.CheckforupdatesMenu.Text = 'Check for updates';
            app.LicenseMenu = uimenu(app.HelpMenu);
            app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
            app.LicenseMenu.Separator = 'on';
            app.LicenseMenu.Text = 'License';
            app.SendfeedbackMenu = uimenu(app.HelpMenu);
            app.SendfeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @SendfeedbackMenuSelected, true);
            app.SendfeedbackMenu.Separator = 'on';
            app.SendfeedbackMenu.Text = 'Send feedback';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.Tab_lateral_bar = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.Tab_lateral_bar);
            app.SetupTab.Title = 'Setup';
            app.TabGroup2 = uitabgroup(app.SetupTab);
            app.InputsTab = uitab(app.TabGroup2);
            app.InputsTab.Title = 'Inputs';
            app.DefinereactantsandspeciestobeconsideredPanel = uipanel(app.InputsTab);
            app.DefinereactantsandspeciestobeconsideredPanel.Title = 'Define reactants and species to be considered';
            app.UITable_R = uitable(app.DefinereactantsandspeciestobeconsideredPanel);
            app.UITable_R.ColumnName = {'Species'; 'Nº moles'; 'Mole fraction'; 'Type'; 'Temperature [K]'};
            app.UITable_R.RowName = {};
            app.UITable_R.ColumnSortable = true;
            app.UITable_R.ColumnEditable = [true true false true true];
            app.UITable_R.CellEditCallback = createCallbackFcn(app, @UITable_RCellEdit, true);
            app.ReactantsPanel = uipanel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.ReactantsPanel.Title = 'Reactants';
            app.Reactants = uidropdown(app.ReactantsPanel);
            app.Reactants.Items = {'', 'Air', 'Methane + Air', 'Ethane + Air', 'Propane + Air', 'Acetylene + Air', 'Ethylene + Air', 'Bencene + Air', 'Iso-octane + Air', 'Hydrogen + Air', 'Carbon monoxide + Air', 'Methanol + Air', 'Ethanol + Air', 'Natural Gas + Air', 'Syngas + Air', 'LH2 + LOX', 'RP1 + LOX'};
            app.Reactants.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.Reactants.Editable = 'on';
            app.Reactants.ValueChangedFcn = createCallbackFcn(app, @ReactantsValueChanged, true);
            app.Reactants.Value = 1;
            app.ListofSpeciesPanel = uipanel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.ListofSpeciesPanel.Title = 'List of Species';
            app.listbox_Products = uilistbox(app.ListofSpeciesPanel);
            app.listbox_Products.Items = {};
            app.listbox_Products.Multiselect = 'on';
            app.listbox_Products.Value = {};
            app.OFEditFieldLabel = uilabel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.OFEditFieldLabel.Text = 'O/F';
            app.edit_OF = uieditfield(app.DefinereactantsandspeciestobeconsideredPanel, 'numeric');
            app.edit_phi = uieditfield(app.DefinereactantsandspeciestobeconsideredPanel, 'text');
            app.edit_phi.ValueChangedFcn = createCallbackFcn(app, @edit_phiValueChanged, true);
            app.edit_phi.Value = '-';
            app.text_phi = uilabel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.text_phi.Text = 'Phi';
            app.FuelEditFieldLabel = uilabel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.FuelEditFieldLabel.Text = '% Fuel';
            app.ProductsPanel = uipanel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.ProductsPanel.Title = 'Products';
            app.Products = uidropdown(app.ProductsPanel);
            app.Products.Items = {'', 'Complete reaction', 'HC/O2/N2', 'HC/O2/N2 extended', 'HC/O2/N2 rich', 'HC/O2/N2 Propellants', 'Soot formation', 'Soot formation Extended', 'Hydrogen', 'Hydrogen (L)', 'Hydrogen ions', 'Dissociated air', 'Air ions'};
            app.Products.Editable = 'on';
            app.Products.ValueChangedFcn = createCallbackFcn(app, @ProductsValueChanged, true);
            app.Products.Value = '';
            app.edit_F = uieditfield(app.DefinereactantsandspeciestobeconsideredPanel, 'numeric');
            app.DefinestateofreactantsandproductsPanel = uipanel(app.InputsTab);
            app.DefinestateofreactantsandproductsPanel.Title = 'Define state of reactants and products';
            app.PP2 = uieditfield(app.DefinestateofreactantsandproductsPanel, 'text');
            app.PP2.ValueChangedFcn = createCallbackFcn(app, @PP2ValueChanged, true);
            app.PP2.Value = '1';
            app.PP1 = uieditfield(app.DefinestateofreactantsandproductsPanel, 'text');
            app.PP1.Value = '2500';
            app.PR1 = uieditfield(app.DefinestateofreactantsandproductsPanel, 'text');
            app.PR1.ValueChangedFcn = createCallbackFcn(app, @PR1ValueChanged, true);
            app.PR1.Value = '300';
            app.text_RP1 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_RP1.Text = 'Temperature [K]';
            app.text_R1 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_R1.Text = 'Reactants';
            app.text_P1 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_P1.Text = 'Products';
            app.PR2 = uieditfield(app.DefinestateofreactantsandproductsPanel, 'text');
            app.PR2.ValueChangedFcn = createCallbackFcn(app, @PR2ValueChanged, true);
            app.PR2.Value = '1';
            app.text_RP2 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_RP2.Text = 'Pressure [bar]';
            app.text_RP1_2 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_RP1_2.Visible = 'off';
            app.text_RP1_2.Text = 'Area ratio A_c/A_t';
            app.text_RP2_2 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_RP2_2.Visible = 'off';
            app.text_RP2_2.Text = 'Mass flux [kg/s]';
            app.AdditionalconstraintsPanel = uipanel(app.InputsTab);
            app.AdditionalconstraintsPanel.Title = 'Additional constraints';
            app.AdditionalconstraintsPanel.Visible = 'off';
            app.text_R2 = uilabel(app.AdditionalconstraintsPanel);
            app.text_R2.Text = 'Reactants';
            app.text_RP = uilabel(app.AdditionalconstraintsPanel);
            app.text_RP.Text = 'Products';
            app.PR3 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PR3.ValueChangedFcn = createCallbackFcn(app, @PR3ValueChanged, true);
            app.PR3.Value = '500';
            app.text_RP3 = uilabel(app.AdditionalconstraintsPanel);
            app.text_RP3.Text = 'Constant Enthalpy: hP = hR';
            app.PR4 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PR4.ValueChangedFcn = createCallbackFcn(app, @PR4ValueChanged, true);
            app.PR4.Value = '1';
            app.text_RP4 = uilabel(app.AdditionalconstraintsPanel);
            app.text_RP4.Text = 'Pressure [bar]';
            app.PP4 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PP4.Value = '1';
            app.PP3 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PP3.Value = '2500';
            app.text_P2 = uilabel(app.AdditionalconstraintsPanel);
            app.text_P2.Text = 'Products';
            app.Clear = uibutton(app.InputsTab, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.Calculate = uibutton(app.InputsTab, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Calculate.Text = 'Calculate';
            app.SelectProblemTypePanel = uipanel(app.InputsTab);
            app.SelectProblemTypePanel.Title = 'Select Problem Type';
            app.ProblemType = uidropdown(app.SelectProblemTypePanel);
            app.ProblemType.Items = {'TP:  Equilibrium composition at defined T and P', 'HP: Adiabatic T and composition at constant P', 'SP:  Isentropic compression/expansion to a specified P', 'TV: Equilibrium composition at defined T and constant V', 'EV: Adiabatic T and composition at constant V', 'SV: Isentropic compresion/expansion to a specified V', 'SHOCK_I: Planar incident shock wave', 'SHOCK_R: Planar reflected shock wave', 'DET: Chapman-Jouguet Detonation', 'DET_OVERDRIVEN: Overdriven detonation', 'DET_R: Reflected Chapman-Jouguet detonation', 'DET_OVERDRIVEN_R: Overdriven reflected detonation', 'ROCKET: Rocket propellant  performance'};
            app.ProblemType.ItemsData = {'TP', 'HP', 'SP', 'TV', 'EV', 'SV', 'SHOCK_I', 'SHOCK_R', 'DET', 'DET_OVERDRIVEN', 'DET_R', 'DET_OVERDRIVEN_R', 'ROCKET', ''};
            app.ProblemType.ValueChangedFcn = createCallbackFcn(app, @ProblemTypeValueChanged, true);
            app.ProblemType.Value = 'TP';
            app.FrozenchemistryCheckBox = uicheckbox(app.SelectProblemTypePanel);
            app.FrozenchemistryCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrozenchemistryCheckBoxValueChanged, true);
            app.FrozenchemistryCheckBox.Text = 'Frozen chemistry';
            app.IonizedspeciesCheckBox = uicheckbox(app.SelectProblemTypePanel);
            app.IonizedspeciesCheckBox.Enable = 'off';
            app.IonizedspeciesCheckBox.Visible = 'off';
            app.IonizedspeciesCheckBox.Text = 'Ionized species';
            app.FLAG_IAC = uicheckbox(app.SelectProblemTypePanel);
            app.FLAG_IAC.ValueChangedFcn = createCallbackFcn(app, @FLAG_IACValueChanged, true);
            app.FLAG_IAC.Visible = 'off';
            app.FLAG_IAC.Text = 'IAC model';
            app.FLAG_IAC.Value = true;
            app.ExtendedsettingsTab = uitab(app.TabGroup2);
            app.ExtendedsettingsTab.Title = 'Extended settings';
            app.TabGroup4 = uitabgroup(app.ExtendedsettingsTab);
            app.SelectionofspeciesTab = uitab(app.TabGroup4);
            app.SelectionofspeciesTab.Title = 'Selection of species';
            app.text_list_species = uilabel(app.SelectionofspeciesTab);
            app.text_list_species.Text = 'Search Species';
            app.edit_seeker_1 = uieditfield(app.SelectionofspeciesTab, 'text');
            app.edit_seeker_1.ValueChangedFcn = createCallbackFcn(app, @edit_seeker_1ValueChanged, true);
            app.text_LS_DB = uilabel(app.SelectionofspeciesTab);
            app.text_LS_DB.Text = 'List of species DB';
            app.listbox_LS_DB = uilistbox(app.SelectionofspeciesTab);
            app.listbox_LS_DB.Items = {};
            app.listbox_LS_DB.Multiselect = 'on';
            app.listbox_LS_DB.Value = {};
            app.AddButton1 = uibutton(app.SelectionofspeciesTab, 'push');
            app.AddButton1.ButtonPushedFcn = createCallbackFcn(app, @AddButton1Pushed, true);
            app.AddButton1.Text = 'Add >>';
            app.RemoveButton1 = uibutton(app.SelectionofspeciesTab, 'push');
            app.RemoveButton1.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton1Pushed, true);
            app.RemoveButton1.Text = 'Remove <<';
            app.text_LS = uilabel(app.SelectionofspeciesTab);
            app.text_LS.Text = 'List of species';
            app.listbox_LS = uilistbox(app.SelectionofspeciesTab);
            app.listbox_LS.Items = {};
            app.listbox_LS.Multiselect = 'on';
            app.listbox_LS.Value = {};
            app.PlottedspeciesTab = uitab(app.TabGroup4);
            app.PlottedspeciesTab.Title = 'Plotted species';
            app.text_list_species_2 = uilabel(app.PlottedspeciesTab);
            app.text_list_species_2.Text = 'Search Species';
            app.edit_seeker_2 = uieditfield(app.PlottedspeciesTab, 'text');
            app.text_list_species_3 = uilabel(app.PlottedspeciesTab);
            app.text_list_species_3.Text = 'List of species';
            app.listbox_species = uilistbox(app.PlottedspeciesTab);
            app.listbox_species.Items = {};
            app.listbox_species.Multiselect = 'on';
            app.listbox_species.Value = {};
            app.AddButton2 = uibutton(app.PlottedspeciesTab, 'push');
            app.AddButton2.Enable = 'off';
            app.AddButton2.Text = 'Add >>';
            app.RemoveButton2 = uibutton(app.PlottedspeciesTab, 'push');
            app.RemoveButton2.Enable = 'off';
            app.RemoveButton2.Text = 'Remove <<';
            app.listbox_plotted_species = uilistbox(app.PlottedspeciesTab);
            app.listbox_plotted_species.Items = {};
            app.listbox_plotted_species.Multiselect = 'on';
            app.listbox_plotted_species.Value = {};
            app.text_minor_products = uilabel(app.PlottedspeciesTab);
            app.text_minor_products.Text = 'Plotted species';
            app.TolerancesPanel = uipanel(app.ExtendedsettingsTab);
            app.TolerancesPanel.Title = 'Tolerances';
            app.TraceoptionEditFieldLabel = uilabel(app.TolerancesPanel);
            app.TraceoptionEditFieldLabel.Text = 'Trace option';
            app.TraceoptionEditField = uieditfield(app.TolerancesPanel, 'numeric');
            app.TraceoptionEditField.Limits = [0 Inf];
            app.TraceoptionEditField.ValueDisplayFormat = '%.1e';
            app.TraceoptionEditField.Value = 1e-15;
            app.DisplaySpeciesEditFieldLabel = uilabel(app.TolerancesPanel);
            app.DisplaySpeciesEditFieldLabel.Text = 'Display Species';
            app.DisplaySpeciesEditField = uieditfield(app.TolerancesPanel, 'numeric');
            app.DisplaySpeciesEditField.ValueDisplayFormat = '%.1e';
            app.DisplaySpeciesEditField.Value = 1e-14;
            app.RootFindingMethodEditFieldLabel = uilabel(app.TolerancesPanel);
            app.RootFindingMethodEditFieldLabel.Text = 'Root Finding Method';
            app.RootFindingMethodEditField = uieditfield(app.TolerancesPanel, 'numeric');
            app.RootFindingMethodEditField.Limits = [0 Inf];
            app.RootFindingMethodEditField.ValueDisplayFormat = '%.1e';
            app.RootFindingMethodEditField.Value = 0.001;
            app.ShocksandDetonationsEditFieldLabel = uilabel(app.TolerancesPanel);
            app.ShocksandDetonationsEditFieldLabel.Text = 'Shocks and Detonations';
            app.ShocksandDetonationsEditField = uieditfield(app.TolerancesPanel, 'numeric');
            app.ShocksandDetonationsEditField.Limits = [0 Inf];
            app.ShocksandDetonationsEditField.ValueDisplayFormat = '%.1e';
            app.ShocksandDetonationsEditField.Value = 5e-05;
            app.TuningparametersPanel = uipanel(app.ExtendedsettingsTab);
            app.TuningparametersPanel.Title = 'Tuning parameters';
            app.MaxiterationsRFMEditFieldLabel = uilabel(app.TuningparametersPanel);
            app.MaxiterationsRFMEditFieldLabel.Text = 'Max iterations RFM';
            app.MaxiterationsRFMEditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.MaxiterationsRFMEditField.Limits = [1 Inf];
            app.MaxiterationsRFMEditField.ValueDisplayFormat = '%d';
            app.MaxiterationsRFMEditField.Value = 30;
            app.RFMT0_REditFieldLabel = uilabel(app.TuningparametersPanel);
            app.RFMT0_REditFieldLabel.Text = 'RFM T0_R';
            app.RFMT0_REditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.RFMT0_REditField.Limits = [0 Inf];
            app.RFMT0_REditField.ValueDisplayFormat = '%d K';
            app.RFMT0_REditField.Value = 2000;
            app.RFMT0_LEditFieldLabel = uilabel(app.TuningparametersPanel);
            app.RFMT0_LEditFieldLabel.Text = 'RFM T0_L';
            app.RFMT0_LEditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.RFMT0_LEditField.Limits = [0 Inf];
            app.RFMT0_LEditField.ValueDisplayFormat = '%d K';
            app.RFMT0_LEditField.Value = 1000;
            app.RFMT0EditFieldLabel = uilabel(app.TuningparametersPanel);
            app.RFMT0EditFieldLabel.Text = 'RFM T0';
            app.RFMT0EditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.RFMT0EditField.Limits = [0 Inf];
            app.RFMT0EditField.ValueDisplayFormat = '%d K';
            app.RFMT0EditField.Value = 3000;
            app.MaxiterationsSDEditFieldLabel = uilabel(app.TuningparametersPanel);
            app.MaxiterationsSDEditFieldLabel.Text = 'Max iterations S&D';
            app.MaxiterationsSDEditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.MaxiterationsSDEditField.Limits = [1 Inf];
            app.MaxiterationsSDEditField.ValueDisplayFormat = '%d';
            app.MaxiterationsSDEditField.Value = 50;
            app.OthersPanel = uipanel(app.ExtendedsettingsTab);
            app.OthersPanel.Title = 'Others';
            app.IdealAirCheckBox = uicheckbox(app.OthersPanel);
            app.IdealAirCheckBox.Text = 'Ideal Air';
            app.ReportPanel = uipanel(app.ExtendedsettingsTab);
            app.ReportPanel.Title = 'Report';
            app.TypeDropDownLabel = uilabel(app.ReportPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.Report_type = uidropdown(app.ReportPanel);
            app.Report_type.Items = {'None', 'Auto'};
            app.Report_type.Value = 'Auto';
            app.ResultsTab = uitab(app.Tab_lateral_bar);
            app.ResultsTab.Title = 'Results';
            app.Tab_results = uitabgroup(app.ResultsTab);
            app.ParametersTab = uitab(app.Tab_results);
            app.ParametersTab.Title = 'Parameters';
            app.hPhRkJkgEditFieldLabel = uilabel(app.ParametersTab);
            app.hPhRkJkgEditFieldLabel.Text = 'hP - hR [kJ/kg]';
            app.text_q = uieditfield(app.ParametersTab, 'numeric');
            app.text_q.Editable = 'off';
            app.VolumeProductsReactantsLabel = uilabel(app.ParametersTab);
            app.VolumeProductsReactantsLabel.Text = 'Volume Products/Reactants';
            app.text_vP_vR = uieditfield(app.ParametersTab, 'numeric');
            app.text_vP_vR.Editable = 'off';
            app.EpsilonmolesLabel = uilabel(app.ParametersTab);
            app.EpsilonmolesLabel.Text = 'Epsilon (moles)';
            app.text_error_moles = uieditfield(app.ParametersTab, 'numeric');
            app.text_error_moles.ValueDisplayFormat = '%11.4e';
            app.text_error_moles.ValueChangedFcn = createCallbackFcn(app, @text_error_molesValueChanged, true);
            app.text_error_moles.Editable = 'off';
            app.EpsilonmolesLabel_2 = uilabel(app.ParametersTab);
            app.EpsilonmolesLabel_2.Text = 'Epsilon (method)';
            app.text_error_problem = uieditfield(app.ParametersTab, 'numeric');
            app.text_error_problem.ValueDisplayFormat = '%11.4e';
            app.text_error_problem.ValueChangedFcn = createCallbackFcn(app, @text_error_problemValueChanged, true);
            app.text_error_problem.Editable = 'off';
            app.Panel_parameters = uipanel(app.ParametersTab);
            app.text_s = uilabel(app.Panel_parameters);
            app.text_s.Text = 'Entropy [kJ/(kg K)]';
            app.text_sR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_sR.ValueDisplayFormat = '%.4g';
            app.text_sR.Editable = 'off';
            app.text_sP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_sP.ValueDisplayFormat = '%.4g';
            app.text_sP.Editable = 'off';
            app.text_gamma = uilabel(app.Panel_parameters);
            app.text_gamma.Text = 'Adiabatic index [-]';
            app.text_gammaR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_gammaR.ValueDisplayFormat = '%.4g';
            app.text_gammaR.Editable = 'off';
            app.text_gammaP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_gammaP.ValueDisplayFormat = '%.4g';
            app.text_gammaP.Editable = 'off';
            app.text_M = uilabel(app.Panel_parameters);
            app.text_M.Visible = 'off';
            app.text_M.Text = 'Mach number [-]';
            app.text_MP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_MP.ValueDisplayFormat = '%.4g';
            app.text_MP.Editable = 'off';
            app.text_MP.Visible = 'off';
            app.text_u = uilabel(app.Panel_parameters);
            app.text_u.Visible = 'off';
            app.text_u.Text = 'Flow velocity [m/s]';
            app.text_uP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_uP.ValueDisplayFormat = '%.4g';
            app.text_uP.Editable = 'off';
            app.text_uP.Visible = 'off';
            app.text_e = uilabel(app.Panel_parameters);
            app.text_e.Text = 'Internal energy [kJ/kg]';
            app.text_eR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_eR.ValueDisplayFormat = '%.4g';
            app.text_eR.Editable = 'off';
            app.text_eP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_eP.ValueDisplayFormat = '%.4g';
            app.text_eP.Editable = 'off';
            app.text_sound = uilabel(app.Panel_parameters);
            app.text_sound.Text = 'Sound speed [m/s]';
            app.text_soundR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_soundR.ValueDisplayFormat = '%.4g';
            app.text_soundR.Editable = 'off';
            app.text_soundP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_soundP.ValueDisplayFormat = '%.4g';
            app.text_soundP.Editable = 'off';
            app.text_W = uilabel(app.Panel_parameters);
            app.text_W.Text = 'Mean Molecular Weight [g/mol]';
            app.text_WR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_WR.ValueDisplayFormat = '%.4g';
            app.text_WR.Editable = 'off';
            app.text_WP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_WP.ValueDisplayFormat = '%.4g';
            app.text_WP.Editable = 'off';
            app.text_cp = uilabel(app.Panel_parameters);
            app.text_cp.Text = 'Specific heat cp [kJ/(kg K)]';
            app.text_cpR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_cpR.ValueDisplayFormat = '%.4g';
            app.text_cpR.Editable = 'off';
            app.text_cpP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_cpP.ValueDisplayFormat = '%.4g';
            app.text_cpP.Editable = 'off';
            app.text_h = uilabel(app.Panel_parameters);
            app.text_h.Text = 'Enthalpy [kJ/kg]';
            app.text_hR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_hR.ValueDisplayFormat = '%.4g';
            app.text_hR.Editable = 'off';
            app.text_hP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_hP.ValueDisplayFormat = '%.4g';
            app.text_hP.Editable = 'off';
            app.text_r = uilabel(app.Panel_parameters);
            app.text_r.Text = 'Density [kg/m3]';
            app.text_rR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_rR.ValueDisplayFormat = '%.4g';
            app.text_rR.Editable = 'off';
            app.text_rP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_rP.ValueDisplayFormat = '%.4g';
            app.text_rP.Editable = 'off';
            app.text_p = uilabel(app.Panel_parameters);
            app.text_p.Text = 'Pressure [bar]';
            app.text_pP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_pP.Limits = [0 Inf];
            app.text_pP.ValueDisplayFormat = '%.4g';
            app.text_pP.Editable = 'off';
            app.text_Products = uilabel(app.Panel_parameters);
            app.text_Products.Text = 'Products';
            app.text_Reactans = uilabel(app.Panel_parameters);
            app.text_Reactans.Text = 'Reactants';
            app.text_T = uilabel(app.Panel_parameters);
            app.text_T.Text = 'Temperature [K]';
            app.text_TR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_TR.ValueDisplayFormat = '%.4g';
            app.text_TR.Editable = 'off';
            app.text_pR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_pR.ValueDisplayFormat = '%.4g';
            app.text_pR.Editable = 'off';
            app.text_TP = uieditfield(app.Panel_parameters, 'numeric');
            app.text_TP.ValueDisplayFormat = '%.4g';
            app.text_TP.Editable = 'off';
            app.text_uR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_uR.ValueDisplayFormat = '%.4g';
            app.text_uR.Editable = 'off';
            app.text_uR.Visible = 'off';
            app.text_MR = uieditfield(app.Panel_parameters, 'numeric');
            app.text_MR.ValueDisplayFormat = '%.4g';
            app.text_MR.Editable = 'off';
            app.text_MR.Visible = 'off';
            app.ParametersRocketsTab = uitab(app.Tab_results);
            app.ParametersRocketsTab.Title = 'Parameters - Rockets';
            app.Panel_extra_4 = uipanel(app.ParametersRocketsTab);
            app.text_sP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_sP_4.ValueDisplayFormat = '%.4g';
            app.text_sP_4.Editable = 'off';
            app.text_gammaP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_gammaP_4.ValueDisplayFormat = '%.4g';
            app.text_gammaP_4.Editable = 'off';
            app.text_MP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_MP_4.ValueDisplayFormat = '%.4g';
            app.text_MP_4.Editable = 'off';
            app.text_uP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_uP_4.ValueDisplayFormat = '%.4g';
            app.text_uP_4.Editable = 'off';
            app.text_eP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_eP_4.ValueDisplayFormat = '%.4g';
            app.text_eP_4.Editable = 'off';
            app.text_soundP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_soundP_4.ValueDisplayFormat = '%.4g';
            app.text_soundP_4.Editable = 'off';
            app.text_WP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_WP_4.ValueDisplayFormat = '%.4g';
            app.text_WP_4.Editable = 'off';
            app.text_cpP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_cpP_4.ValueDisplayFormat = '%.4g';
            app.text_cpP_4.Editable = 'off';
            app.text_hP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_hP_4.ValueDisplayFormat = '%.4g';
            app.text_hP_4.Editable = 'off';
            app.text_rP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_rP_4.ValueDisplayFormat = '%.4g';
            app.text_rP_4.Editable = 'off';
            app.text_pP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_pP_4.Limits = [0 Inf];
            app.text_pP_4.ValueDisplayFormat = '%.4g';
            app.text_pP_4.Editable = 'off';
            app.text_Products_4 = uilabel(app.Panel_extra_4);
            app.text_Products_4.Text = 'Exit';
            app.text_TP_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_TP_4.ValueDisplayFormat = '%.4g';
            app.text_TP_4.Editable = 'off';
            app.text_Aratio_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_Aratio_4.ValueDisplayFormat = '%.4g';
            app.text_Aratio_4.Editable = 'off';
            app.text_Cstar_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_Cstar_4.ValueDisplayFormat = '%.4g';
            app.text_Cstar_4.Editable = 'off';
            app.text_Ivac_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_Ivac_4.ValueDisplayFormat = '%.4g';
            app.text_Ivac_4.Editable = 'off';
            app.text_Isp_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_Isp_4.ValueDisplayFormat = '%.4g';
            app.text_Isp_4.Editable = 'off';
            app.Panel_extra_3 = uipanel(app.ParametersRocketsTab);
            app.text_sP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_sP_3.ValueDisplayFormat = '%.4g';
            app.text_sP_3.Editable = 'off';
            app.text_gammaP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_gammaP_3.ValueDisplayFormat = '%.4g';
            app.text_gammaP_3.Editable = 'off';
            app.text_MP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_MP_3.ValueDisplayFormat = '%.4g';
            app.text_MP_3.Editable = 'off';
            app.text_uP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_uP_3.ValueDisplayFormat = '%.4g';
            app.text_uP_3.Editable = 'off';
            app.text_eP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_eP_3.ValueDisplayFormat = '%.4g';
            app.text_eP_3.Editable = 'off';
            app.text_soundP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_soundP_3.ValueDisplayFormat = '%.4g';
            app.text_soundP_3.Editable = 'off';
            app.text_WP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_WP_3.ValueDisplayFormat = '%.4g';
            app.text_WP_3.Editable = 'off';
            app.text_cpP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_cpP_3.ValueDisplayFormat = '%.4g';
            app.text_cpP_3.Editable = 'off';
            app.text_hP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_hP_3.ValueDisplayFormat = '%.4g';
            app.text_hP_3.Editable = 'off';
            app.text_rP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_rP_3.ValueDisplayFormat = '%.4g';
            app.text_rP_3.Editable = 'off';
            app.text_pP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_pP_3.Limits = [0 Inf];
            app.text_pP_3.ValueDisplayFormat = '%.4g';
            app.text_pP_3.Editable = 'off';
            app.text_Products_3 = uilabel(app.Panel_extra_3);
            app.text_Products_3.Text = 'Throat';
            app.text_TP_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_TP_3.ValueDisplayFormat = '%.4g';
            app.text_TP_3.Editable = 'off';
            app.text_Aratio_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_Aratio_3.ValueDisplayFormat = '%.4g';
            app.text_Aratio_3.Editable = 'off';
            app.text_Cstar_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_Cstar_3.ValueDisplayFormat = '%.4g';
            app.text_Cstar_3.Editable = 'off';
            app.text_Ivac_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_Ivac_3.ValueDisplayFormat = '%.4g';
            app.text_Ivac_3.Editable = 'off';
            app.text_Isp_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_Isp_3.ValueDisplayFormat = '%.4g';
            app.text_Isp_3.Editable = 'off';
            app.Panel_extra_5 = uipanel(app.ParametersRocketsTab);
            app.Panel_extra_5.Visible = 'off';
            app.text_sP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_sP_5.ValueDisplayFormat = '%.4g';
            app.text_sP_5.Editable = 'off';
            app.text_gammaP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_gammaP_5.ValueDisplayFormat = '%.4g';
            app.text_gammaP_5.Editable = 'off';
            app.text_MP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_MP_5.ValueDisplayFormat = '%.4g';
            app.text_MP_5.Editable = 'off';
            app.text_uP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_uP_5.ValueDisplayFormat = '%.4g';
            app.text_uP_5.Editable = 'off';
            app.text_eP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_eP_5.ValueDisplayFormat = '%.4g';
            app.text_eP_5.Editable = 'off';
            app.text_soundP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_soundP_5.ValueDisplayFormat = '%.4g';
            app.text_soundP_5.Editable = 'off';
            app.text_WP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_WP_5.ValueDisplayFormat = '%.4g';
            app.text_WP_5.Editable = 'off';
            app.text_cpP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_cpP_5.ValueDisplayFormat = '%.4g';
            app.text_cpP_5.Editable = 'off';
            app.text_hP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_hP_5.ValueDisplayFormat = '%.4g';
            app.text_hP_5.Editable = 'off';
            app.text_rP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_rP_5.ValueDisplayFormat = '%.4g';
            app.text_rP_5.Editable = 'off';
            app.text_pP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_pP_5.Limits = [0 Inf];
            app.text_pP_5.ValueDisplayFormat = '%.4g';
            app.text_pP_5.Editable = 'off';
            app.text_Products_5 = uilabel(app.Panel_extra_5);
            app.text_Products_5.Text = 'Exit';
            app.text_TP_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_TP_5.ValueDisplayFormat = '%.4g';
            app.text_TP_5.Editable = 'off';
            app.text_Aratio_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_Aratio_5.ValueDisplayFormat = '%.4g';
            app.text_Aratio_5.Editable = 'off';
            app.text_Cstar_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_Cstar_5.ValueDisplayFormat = '%.4g';
            app.text_Cstar_5.Editable = 'off';
            app.text_Ivac_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_Ivac_5.ValueDisplayFormat = '%.4g';
            app.text_Ivac_5.Editable = 'off';
            app.text_Isp_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_Isp_5.ValueDisplayFormat = '%.4g';
            app.text_Isp_5.Editable = 'off';
            app.Panel_extra_0 = uipanel(app.ParametersRocketsTab);
            app.text_s_2 = uilabel(app.Panel_extra_0);
            app.text_s_2.Text = 'Entropy [kJ/(kg K)]';
            app.text_sR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_sR_2.ValueDisplayFormat = '%.4g';
            app.text_sR_2.Editable = 'off';
            app.text_sP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_sP_2.ValueDisplayFormat = '%.4g';
            app.text_sP_2.Editable = 'off';
            app.text_gamma_2 = uilabel(app.Panel_extra_0);
            app.text_gamma_2.Text = 'Adibatic index [-]';
            app.text_gammaR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_gammaR_2.ValueDisplayFormat = '%.4g';
            app.text_gammaR_2.Editable = 'off';
            app.text_gammaP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_gammaP_2.ValueDisplayFormat = '%.4g';
            app.text_gammaP_2.Editable = 'off';
            app.text_M_2 = uilabel(app.Panel_extra_0);
            app.text_M_2.Text = 'Mach number [-]';
            app.text_MP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_MP_2.ValueDisplayFormat = '%.4g';
            app.text_MP_2.Editable = 'off';
            app.text_u_2 = uilabel(app.Panel_extra_0);
            app.text_u_2.Text = 'Flow velocity [m/s]';
            app.text_uP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_uP_2.ValueDisplayFormat = '%.4g';
            app.text_uP_2.Editable = 'off';
            app.text_e_2 = uilabel(app.Panel_extra_0);
            app.text_e_2.Text = 'Internal energy [kJ/kg]';
            app.text_eR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_eR_2.ValueDisplayFormat = '%.4g';
            app.text_eR_2.Editable = 'off';
            app.text_eP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_eP_2.ValueDisplayFormat = '%.4g';
            app.text_eP_2.Editable = 'off';
            app.text_sound_2 = uilabel(app.Panel_extra_0);
            app.text_sound_2.Text = 'Sound speed [m/s]';
            app.text_soundR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_soundR_2.ValueDisplayFormat = '%.4g';
            app.text_soundR_2.Editable = 'off';
            app.text_soundP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_soundP_2.ValueDisplayFormat = '%.4g';
            app.text_soundP_2.Editable = 'off';
            app.text_W_2 = uilabel(app.Panel_extra_0);
            app.text_W_2.Text = 'Molecular Weight [g/mol]';
            app.text_WR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_WR_2.ValueDisplayFormat = '%.4g';
            app.text_WR_2.Editable = 'off';
            app.text_WP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_WP_2.ValueDisplayFormat = '%.4g';
            app.text_WP_2.Editable = 'off';
            app.text_cp_2 = uilabel(app.Panel_extra_0);
            app.text_cp_2.Text = 'Specific heat cp [kJ/(kg K)]';
            app.text_cpR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_cpR_2.ValueDisplayFormat = '%.4g';
            app.text_cpR_2.Editable = 'off';
            app.text_cpP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_cpP_2.ValueDisplayFormat = '%.4g';
            app.text_cpP_2.Editable = 'off';
            app.text_h_2 = uilabel(app.Panel_extra_0);
            app.text_h_2.Text = 'Enthalpy [kJ/kg]';
            app.text_hR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_hR_2.ValueDisplayFormat = '%.4g';
            app.text_hR_2.Editable = 'off';
            app.text_hP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_hP_2.ValueDisplayFormat = '%.4g';
            app.text_hP_2.Editable = 'off';
            app.text_r_2 = uilabel(app.Panel_extra_0);
            app.text_r_2.Text = 'Density [kg/m3]';
            app.text_rR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_rR_2.ValueDisplayFormat = '%.4g';
            app.text_rR_2.Editable = 'off';
            app.text_rP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_rP_2.ValueDisplayFormat = '%.4g';
            app.text_rP_2.Editable = 'off';
            app.text_p_2 = uilabel(app.Panel_extra_0);
            app.text_p_2.Text = 'Pressure [bar]';
            app.text_pP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_pP_2.Limits = [0 Inf];
            app.text_pP_2.ValueDisplayFormat = '%.4g';
            app.text_pP_2.Editable = 'off';
            app.text_Products_2 = uilabel(app.Panel_extra_0);
            app.text_Products_2.Text = 'Outlet Chamber';
            app.text_Reactans_2 = uilabel(app.Panel_extra_0);
            app.text_Reactans_2.Text = 'Reactants';
            app.text_T_2 = uilabel(app.Panel_extra_0);
            app.text_T_2.Text = 'Temperature [K]';
            app.text_TR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_TR_2.ValueDisplayFormat = '%.4g';
            app.text_TR_2.Editable = 'off';
            app.text_pR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_pR_2.ValueDisplayFormat = '%.4g';
            app.text_pR_2.Editable = 'off';
            app.text_TP_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_TP_2.ValueDisplayFormat = '%.4g';
            app.text_TP_2.Editable = 'off';
            app.text_uR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_uR_2.ValueDisplayFormat = '%.4g';
            app.text_uR_2.Editable = 'off';
            app.text_MR_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_MR_2.ValueDisplayFormat = '%.4g';
            app.text_MR_2.Editable = 'off';
            app.text_Aratio = uilabel(app.Panel_extra_0);
            app.text_Aratio.Text = 'Area ratio A/A_t [-]';
            app.text_Aratio_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_Aratio_2.ValueDisplayFormat = '%.4g';
            app.text_Aratio_2.Editable = 'off';
            app.text_Aratio_1 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_Aratio_1.ValueDisplayFormat = '%.4g';
            app.text_Aratio_1.Editable = 'off';
            app.text_Cstar = uilabel(app.Panel_extra_0);
            app.text_Cstar.Text = 'Charac. velocity [m/s]';
            app.text_Cstar_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_Cstar_2.ValueDisplayFormat = '%.4g';
            app.text_Cstar_2.Editable = 'off';
            app.text_Cstar_1 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_Cstar_1.ValueDisplayFormat = '%.4g';
            app.text_Cstar_1.Editable = 'off';
            app.text_Ivac = uilabel(app.Panel_extra_0);
            app.text_Ivac.Text = 'Specific impulse vac [s]';
            app.text_Ivac_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_Ivac_2.ValueDisplayFormat = '%.4g';
            app.text_Ivac_2.Editable = 'off';
            app.text_Ivac_1 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_Ivac_1.ValueDisplayFormat = '%.4g';
            app.text_Ivac_1.Editable = 'off';
            app.text_Isp = uilabel(app.Panel_extra_0);
            app.text_Isp.Text = 'Specific impulse [s]';
            app.text_Isp_2 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_Isp_2.ValueDisplayFormat = '%.4g';
            app.text_Isp_2.Editable = 'off';
            app.text_Isp_1 = uieditfield(app.Panel_extra_0, 'numeric');
            app.text_Isp_1.ValueDisplayFormat = '%.4g';
            app.text_Isp_1.Editable = 'off';
            app.MixturecompositionTab = uitab(app.Tab_results);
            app.MixturecompositionTab.Title = 'Mixture composition';
            app.UITable_R2 = uitable(app.MixturecompositionTab);
            app.UITable_R2.ColumnName = {'Species'; 'Nº moles'; 'Mole fraction'};
            app.UITable_R2.RowName = {};
            app.UITable_P = uitable(app.MixturecompositionTab);
            app.UITable_P.ColumnName = {'Species'; 'Nº moles'; 'Mole fraction'};
            app.UITable_P.RowName = {};
            app.text_P3 = uilabel(app.MixturecompositionTab);
            app.text_P3.Text = 'Products';
            app.text_R3 = uilabel(app.MixturecompositionTab);
            app.text_R3.Text = 'Reactants';
            app.edit_phi2 = uieditfield(app.MixturecompositionTab, 'text');
            app.edit_phi2.Editable = 'off';
            app.edit_phi2.Value = '-';
            app.text_phi_2 = uilabel(app.MixturecompositionTab);
            app.text_phi_2.Text = 'Phi';
            app.CustomFiguresTab = uitab(app.Tab_results);
            app.CustomFiguresTab.Title = 'Custom Figures';
            app.UIAxes = uiaxes(app.CustomFiguresTab);
            zlabel(app.UIAxes, 'Z')
            app.Tree_mixtures = uitree(app.CustomFiguresTab, 'checkbox');
            app.Mixtures = uitreenode(app.Tree_mixtures);
            app.Mixtures.Text = 'Mixtures';
            app.Tree_variable_x = uitree(app.CustomFiguresTab, 'checkbox');
            app.Variable_x = uitreenode(app.Tree_variable_x);
            app.Variable_x.Text = 'Variable x';
            app.Tree_variable_y = uitree(app.CustomFiguresTab, 'checkbox');
            app.Variable_y = uitreenode(app.Tree_variable_y);
            app.Variable_y.Text = 'Variable y';
            app.figure_plot = uibutton(app.CustomFiguresTab, 'push');
            app.figure_plot.ButtonPushedFcn = createCallbackFcn(app, @figure_plotButtonPushed, true);
            app.figure_plot.Text = 'Plot';
            app.figure_clear = uibutton(app.CustomFiguresTab, 'push');
            app.figure_clear.ButtonPushedFcn = createCallbackFcn(app, @figure_clearButtonPushed, true);
            app.figure_clear.Text = 'Clear';
            app.figure_size = uibutton(app.CustomFiguresTab, 'push');
            app.figure_size.ButtonPushedFcn = createCallbackFcn(app, @figure_sizeButtonPushed, true);
            app.figure_size.Text = 'Maximize';
            app.Tree = uitree(app.ResultsTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node_Results = uitreenode(app.Tree);
            app.Node_Results.Text = 'Results';
            app.Console = uitextarea(app.UIFigure);
            app.Console.ValueChangedFcn = createCallbackFcn(app, @ConsoleValueChanged, true);
            app.Console_text = uitextarea(app.UIFigure);
            app.Console_text.Value = {'Welcome to Combustion Toolbox vXX.XX.XX --- A MATLAB-GUI based open-source tool for solving gaseous combustion problems.'};
            app.Label_Console = uilabel(app.UIFigure);
            app.Label_Console.Text = '>>';
            app.Lamp = uilamp(app.UIFigure);
            app.ContextMenu_CommandWindow = uicontextmenu(app.UIFigure);
            app.MaximizeMenu = uimenu(app.ContextMenu_CommandWindow);
            app.MaximizeMenu.MenuSelectedFcn = createCallbackFcn(app, @MaximizeMenuSelected, true);
            app.MaximizeMenu.Text = 'Maximize';
            app.MinimizeMenu = uimenu(app.ContextMenu_CommandWindow);
            app.MinimizeMenu.MenuSelectedFcn = createCallbackFcn(app, @MinimizeMenuSelected, true);
            app.MinimizeMenu.Text = 'Minimize';
            app.ClearCommandWindowMenu = uimenu(app.ContextMenu_CommandWindow);
            app.ClearCommandWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCommandWindowMenuSelected, true);
            app.ClearCommandWindowMenu.Text = 'Clear Command Window';
            % Assign app.ContextMenu_CommandWindow
            app.Console.ContextMenu = app.ContextMenu_CommandWindow;
            app.Console_text.ContextMenu = app.ContextMenu_CommandWindow;
            app.Label_Console.ContextMenu = app.ContextMenu_CommandWindow;
            app.Lamp.ContextMenu = app.ContextMenu_CommandWindow;
            app.ContextMenu_UITree = uicontextmenu(app.UIFigure);
            app.RemoveMenu = uimenu(app.ContextMenu_UITree);
            app.RemoveMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveMenuSelected, true);
            app.RemoveMenu.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = combustion_toolbox_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end