classdef feedback_mlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        feedbackUIFigure  matlab.ui.Figure
        ClearButton       matlab.ui.control.Button
        subject           matlab.ui.control.DropDown
        AttachButton      matlab.ui.control.Button
        SendButton        matlab.ui.control.Button
        name              matlab.ui.control.EditField
        email             matlab.ui.control.EditField
        message           matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        attachment        % attachment file
        FLAG_SEND = false % FLAG mail send
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             gui_SnapshotMenuSelected(app.feedbackUIFigure);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            % User input
            source = 'combustiontoolboxbot@gmail.com';      % from address (gmail)
            destination = 'combustiontoolboxbot@gmail.com'; % to address (any mail service)
            myEmailPassword = 'tR4?]3qsz`v%p`:J';           % the password to the 'from' account
            subj = app.subject.Value;                       % subject line
            name = app.name.Value;                          % name of the sender (for contant)
            mail = app.email.Value;                         % email of the sender (for contant)
            msg = app.message.Value;                        % main body of email
            attch = app.attachment;                         % attachment
            %set up SMTP service for Gmail
            setpref('Internet', 'E_mail', source);
            setpref('Internet', 'SMTP_Server', 'smtp.gmail.com');
            setpref('Internet', 'SMTP_Username', source);
            setpref('Internet', 'SMTP_Password', myEmailPassword);
            % Gmail server.
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth', 'true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port', '465');
            % Format message
            msg = [{strcat('Name: ', name)}; {strcat('Email: ', mail)}; {' '}; msg];
            % Send the email
            if ~isempty(mail) && contains(mail, '@') && contains(mail, '.')
                if ~app.FLAG_SEND
                    if ~isempty(msg{4})
                        try
                            if isempty(attch)
                                sendmail(destination, subj, msg);         
                            else
                                sendmail(destination, subj, msg, attch)
                            end
                            app.FLAG_SEND = true;
                            uialert(app.feedbackUIFigure, 'Message sent', 'Info', 'Icon', 'success');
                        catch
                            app.FLAG_SEND = false;
                        end
                    else
                        uialert(app.feedbackUIFigure, 'Empty message', 'Warning', 'Icon', 'warning');
                    end
                else
                    uialert(app.feedbackUIFigure, 'Message already sent', 'Info', 'Icon', 'info');
                end
            else
                uialert(app.feedbackUIFigure, 'Empty email', 'Warning', 'Icon', 'warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.feedbackUIFigure = uifigure('Visible', 'off');
            app.feedbackUIFigure.Name = 'Feedback';
            app.feedbackUIFigure.Icon = 'feedback_icon.svg';
            app.message = uitextarea(app.feedbackUIFigure);
            app.message.Placeholder = 'message';
            app.email = uieditfield(app.feedbackUIFigure, 'text');
            app.email.Placeholder = 'email';
            app.name = uieditfield(app.feedbackUIFigure, 'text');
            app.name.Placeholder = 'name (optional)';
            app.SendButton = uibutton(app.feedbackUIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Icon = 'send_icon.svg';
            app.SendButton.Text = 'Send';
            app.AttachButton = uibutton(app.feedbackUIFigure, 'push');
            app.AttachButton.Icon = 'clip_icon.svg';
            app.AttachButton.Text = 'Attach';
            app.subject = uidropdown(app.feedbackUIFigure);
            app.subject.Items = {'bug', 'enhancement', 'documentation'};
            app.subject.Editable = 'on';
            app.subject.Placeholder = 'subject';
            app.subject.Value = 'bug';
            app.ClearButton = uibutton(app.feedbackUIFigure, 'push');
            app.ClearButton.Icon = 'clear_icon.svg';
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.feedbackUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = feedback_mlapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.feedbackUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.feedbackUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.feedbackUIFigure)
        end
    end
end