classdef uivalidations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ValidationsUIFigure  matlab.ui.Figure
        Lamp                 matlab.ui.control.Lamp
        problems_solved      matlab.ui.control.NumericEditField
        CPUtimesLabel_4      matlab.ui.control.Label
        text_phi_3           matlab.ui.control.Label
        OS                   matlab.ui.control.EditField
        cores                matlab.ui.control.NumericEditField
        CPUtimesLabel_3      matlab.ui.control.Label
        memory               matlab.ui.control.NumericEditField
        CPUtimesLabel_2      matlab.ui.control.Label
        text_phi             matlab.ui.control.Label
        cpu                  matlab.ui.control.EditField
        cputime              matlab.ui.control.NumericEditField
        CPUtimesLabel        matlab.ui.control.Label
        CloseButton          matlab.ui.control.Button
        RunButton            matlab.ui.control.Button
        Tree                 matlab.ui.container.Tree
        CEA                  matlab.ui.container.TreeNode
        SDToolbox            matlab.ui.container.TreeNode
        CANTERA              matlab.ui.container.TreeNode
        TEA                  matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        filename = 'LOG.txt'; % Filename of the log file
        system                % Info of the system
        color_lamp_working = [0.9961, 0.9804, 0.8314]; % Lamp color (rgb): working
        color_lamp_done    = [0.5608, 0.7255, 0.6588]; % Lamp color (rgb): done
        color_lamp_error   = [0.9451, 0.5059, 0.5529]; % Lamp color (rgb): error
    end
    
    methods (Access = private)
        
        function start_log(app)
            if exist(app.filename, 'file') 
                delete(app.filename); 
            end
            diary(app.filename);
            diary on
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load info system
            app.system = cpuinfo();
            % Assign info of the system
            app.OS.Value = [app.system.OSType, ' ', app.system.OSVersion];
            app.cpu.Value = app.system.CPUName;
            app.memory.Value = app.system.TotalMemory * 9.313225746154785e-10; % [GB]
            app.cores.Value = app.system.TotalCores;
            % Include validations in the UITree object
            gui_add_nodes_validations(app, 'CEA');
            gui_add_nodes_validations(app, 'SDToolbox');
            gui_add_nodes_validations(app, 'CANTERA');
            gui_add_nodes_validations(app, 'TEA');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                % Initialization
                close all;
                app.cputime.Value = 0;
                app.problems_solved.Value = 0;
                app.Lamp.Color = app.color_lamp_working;
                % CPU time
                cputime = tic;
                % Run validation
                app.problems_solved.Value = eval(app.Tree.SelectedNodes.Text(1:end-2));
                % CPU time
                app.cputime.Value = toc(cputime);
                % Done!
                app.Lamp.Color = app.color_lamp_done;
            catch ME
                app.Lamp.Color = app.color_lamp_error;
                % Print error
                message = {sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
                ME.stack(1).name, ME.stack(1).line, ME.message)};
                uialert(app.ValidationsUIFigure, message, 'Warning', 'Icon', 'warning');
            end
        end

        % Close request function: ValidationsUIFigure
        function ValidationsUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            ValidationsUIFigureCloseRequest(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ValidationsUIFigure = uifigure('Visible', 'off');
            app.ValidationsUIFigure.Name = 'UIValidations';
            app.ValidationsUIFigure.Icon = 'validation.png';
            app.ValidationsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ValidationsUIFigureCloseRequest, true);
            app.Tree = uitree(app.ValidationsUIFigure);
            app.CEA = uitreenode(app.Tree);
            app.CEA.Text = 'CEA';
            app.SDToolbox = uitreenode(app.Tree);
            app.SDToolbox.Text = 'SDToolbox';
            app.CANTERA = uitreenode(app.Tree);
            app.CANTERA.Text = 'CANTERA';
            app.TEA = uitreenode(app.Tree);
            app.TEA.Text = 'TEA';
            app.RunButton = uibutton(app.ValidationsUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CloseButton = uibutton(app.ValidationsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CPUtimesLabel = uilabel(app.ValidationsUIFigure);
            app.CPUtimesLabel.Text = 'CPU time';
            app.cputime = uieditfield(app.ValidationsUIFigure, 'numeric');
            app.cputime.ValueDisplayFormat = '%11.4g s';
            app.cputime.Editable = 'off';
            app.cpu = uieditfield(app.ValidationsUIFigure, 'text');
            app.cpu.Editable = 'off';
            app.text_phi = uilabel(app.ValidationsUIFigure);
            app.text_phi.Text = 'CPU';
            app.CPUtimesLabel_2 = uilabel(app.ValidationsUIFigure);
            app.CPUtimesLabel_2.Text = 'Total Memory';
            app.memory = uieditfield(app.ValidationsUIFigure, 'numeric');
            app.memory.ValueDisplayFormat = '%11.4g GB';
            app.memory.Editable = 'off';
            app.CPUtimesLabel_3 = uilabel(app.ValidationsUIFigure);
            app.CPUtimesLabel_3.Text = 'Cores';
            app.cores = uieditfield(app.ValidationsUIFigure, 'numeric');
            app.cores.ValueDisplayFormat = '%11.4g s';
            app.cores.Editable = 'off';
            app.OS = uieditfield(app.ValidationsUIFigure, 'text');
            app.OS.Editable = 'off';
            app.text_phi_3 = uilabel(app.ValidationsUIFigure);
            app.text_phi_3.Text = 'OS';
            app.CPUtimesLabel_4 = uilabel(app.ValidationsUIFigure);
            app.CPUtimesLabel_4.Text = 'Problems solved';
            app.problems_solved = uieditfield(app.ValidationsUIFigure, 'numeric');
            app.problems_solved.Editable = 'off';
            app.Lamp = uilamp(app.ValidationsUIFigure);
            % Show the figure after all components are created
            app.ValidationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uivalidations

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ValidationsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ValidationsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ValidationsUIFigure)
        end
    end
end