classdef combustion_toolbox_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_main                     matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        Excel                           matlab.ui.container.Menu
        SnapshotMenu                    matlab.ui.container.Menu
        viewmenu                        matlab.ui.container.Menu
        view_density                    matlab.ui.container.Menu
        DefaultMenu                     matlab.ui.container.Menu
        MajorMenu                       matlab.ui.container.Menu
        DefaultMenu_2                   matlab.ui.container.Menu
        ExamplesMenu                    matlab.ui.container.Menu
        helpmenu                        matlab.ui.container.Menu
        tutorial                        matlab.ui.container.Menu
        SummaryMenu                     matlab.ui.container.Menu
        contents                        matlab.ui.container.Menu
        SendfeedbackMenu                matlab.ui.container.Menu
        about                           matlab.ui.container.Menu
        Logo                            matlab.ui.control.Image
        Console                         matlab.ui.control.TextArea
        Console_2                       matlab.ui.control.TextArea
        Label_Console                   matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        ProblemsetupTab                 matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        uipanel11                       matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        uitable_R                       matlab.ui.control.Table
        edit_phi                        matlab.ui.control.EditField
        text_phi                        matlab.ui.control.Label
        ReactionPanel                   matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        Reaction                        matlab.ui.control.DropDown
        NewreactantPanel                matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        edit_seeker                     matlab.ui.control.EditField
        Reactants                       matlab.ui.control.DropDown
        FuelEditFieldLabel              matlab.ui.control.Label
        FuelEditField                   matlab.ui.control.NumericEditField
        OFEditFieldLabel                matlab.ui.control.Label
        OFEditField                     matlab.ui.control.NumericEditField
        uipanel7                        matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Solver                          matlab.ui.control.DropDown
        ProblemType                     matlab.ui.control.DropDown
        uipanel_State                   matlab.ui.container.Panel
        uipanel8_3                      matlab.ui.container.Panel
        GridLayout5_3                   matlab.ui.container.GridLayout
        text_RP2                        matlab.ui.control.Label
        PR2                             matlab.ui.control.EditField
        text_Products_3                 matlab.ui.control.Label
        text_Reactans_3                 matlab.ui.control.Label
        text_RP1                        matlab.ui.control.Label
        PR1                             matlab.ui.control.EditField
        PP1                             matlab.ui.control.EditField
        PP2                             matlab.ui.control.EditField
        Clear                           matlab.ui.control.Button
        Calculate                       matlab.ui.control.Button
        uipanel_additional_constraints  matlab.ui.container.Panel
        uipanel8_4                      matlab.ui.container.Panel
        GridLayout5_4                   matlab.ui.container.GridLayout
        text_RP4                        matlab.ui.control.Label
        PR4                             matlab.ui.control.EditField
        text_Products_5                 matlab.ui.control.Label
        text_Reactans_5                 matlab.ui.control.Label
        text_RP3                        matlab.ui.control.Label
        PR3                             matlab.ui.control.EditField
        PP3                             matlab.ui.control.EditField
        PP4                             matlab.ui.control.EditField
        text_ReactantsProducts          matlab.ui.control.Label
        ExtendedsettingsTab             matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        SelectionofminorsspeciesTab     matlab.ui.container.Tab
        uipanel22                       matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        text_list_species               matlab.ui.control.Label
        pushbutton2                     matlab.ui.control.Button
        text_minor_products             matlab.ui.control.Label
        text_list_species_2             matlab.ui.control.Label
        pushbutton1                     matlab.ui.control.Button
        listbox_minor_products          matlab.ui.control.ListBox
        listbox_species                 matlab.ui.control.ListBox
        edit_seeker_2                   matlab.ui.control.EditField
        PlottedspeciesTab               matlab.ui.container.Tab
        uipanel22_2                     matlab.ui.container.Panel
        GridLayout13_2                  matlab.ui.container.GridLayout
        text_list_species_3             matlab.ui.control.Label
        pushbutton2_2                   matlab.ui.control.Button
        text_minor_products_2           matlab.ui.control.Label
        text_list_species_4             matlab.ui.control.Label
        pushbutton1_2                   matlab.ui.control.Button
        listbox_plotted_species         matlab.ui.control.ListBox
        listbox_species_2               matlab.ui.control.ListBox
        edit_seeker_3                   matlab.ui.control.EditField
        uipanel23                       matlab.ui.container.Panel
        TraceoptionEditFieldLabel       matlab.ui.control.Label
        TraceoptionEditField            matlab.ui.control.NumericEditField
        DisplaySpeciesEditFieldLabel    matlab.ui.control.Label
        DisplaySpeciesEditField         matlab.ui.control.NumericEditField
        SootfactorEditFieldLabel        matlab.ui.control.Label
        SootfactorEditField             matlab.ui.control.NumericEditField
        EpsilonSootEditFieldLabel       matlab.ui.control.Label
        EpsilonSootEditField            matlab.ui.control.NumericEditField
        ResultsTab                      matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        Node_Results                    matlab.ui.container.TreeNode
        TabGroup3                       matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        uipanel14                       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        text_q                          matlab.ui.control.Label
        edit_q                          matlab.ui.control.NumericEditField
        edit_vP_vR                      matlab.ui.control.NumericEditField
        EpsilonmolesLabel               matlab.ui.control.Label
        edit_error_moles                matlab.ui.control.NumericEditField
        EpsilonHLabel                   matlab.ui.control.Label
        edit_error_problem              matlab.ui.control.NumericEditField
        text_vP_vR                      matlab.ui.control.Label
        uipanel8_2                      matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        text_s                          matlab.ui.control.Label
        text_sR                         matlab.ui.control.NumericEditField
        text_sP                         matlab.ui.control.NumericEditField
        text_gamma                      matlab.ui.control.Label
        text_gammaR                     matlab.ui.control.NumericEditField
        text_gammaP                     matlab.ui.control.NumericEditField
        text_M                          matlab.ui.control.Label
        text_MP                         matlab.ui.control.NumericEditField
        text_u                          matlab.ui.control.Label
        text_uP                         matlab.ui.control.NumericEditField
        text_e                          matlab.ui.control.Label
        text_eR                         matlab.ui.control.NumericEditField
        text_eP                         matlab.ui.control.NumericEditField
        text_sound                      matlab.ui.control.Label
        text_soundR                     matlab.ui.control.NumericEditField
        text_soundP                     matlab.ui.control.NumericEditField
        text_W                          matlab.ui.control.Label
        text_WR                         matlab.ui.control.NumericEditField
        text_WP                         matlab.ui.control.NumericEditField
        text_cp                         matlab.ui.control.Label
        text_cpR                        matlab.ui.control.NumericEditField
        text_cpP                        matlab.ui.control.NumericEditField
        text_h                          matlab.ui.control.Label
        text_hR                         matlab.ui.control.NumericEditField
        text_hP                         matlab.ui.control.NumericEditField
        text_r                          matlab.ui.control.Label
        text_rR                         matlab.ui.control.NumericEditField
        text_rP                         matlab.ui.control.NumericEditField
        text_p                          matlab.ui.control.Label
        text_pP                         matlab.ui.control.NumericEditField
        text_Products_2                 matlab.ui.control.Label
        text_Reactans_2                 matlab.ui.control.Label
        text_T                          matlab.ui.control.Label
        text_TR                         matlab.ui.control.NumericEditField
        text_pR                         matlab.ui.control.NumericEditField
        text_TP                         matlab.ui.control.NumericEditField
        text_uR                         matlab.ui.control.NumericEditField
        text_MR                         matlab.ui.control.NumericEditField
        MixturecompositionTab           matlab.ui.container.Tab
        uitable_P                       matlab.ui.control.Table
        uitable_R2                      matlab.ui.control.Table
        text_Products_4                 matlab.ui.control.Label
        text_Reactans_4                 matlab.ui.control.Label
        edit_phi2                       matlab.ui.control.EditField
        text_phi_2                      matlab.ui.control.Label
        PlotTab                         matlab.ui.container.Tab
        Property1Panel                  matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.EditField
        Property2Panel                  matlab.ui.container.Panel
        GridLayout9_3                   matlab.ui.container.GridLayout
        EditField_2                     matlab.ui.control.EditField
        logscaleCheckBox                matlab.ui.control.CheckBox
        Plot                            matlab.ui.control.Button
        GuidelinePanel                  matlab.ui.container.Panel
        Tree2                           matlab.ui.container.Tree
        StatesNode                      matlab.ui.container.TreeNode
        ReactantsRNode                  matlab.ui.container.TreeNode
        ProductsPNode                   matlab.ui.container.TreeNode
        PropertiesNode                  matlab.ui.container.TreeNode
        EquivalenceratiophiNode         matlab.ui.container.TreeNode
        TemperatureTNode                matlab.ui.container.TreeNode
        PressurepNode                   matlab.ui.container.TreeNode
        DensityrhoNode                  matlab.ui.container.TreeNode
        ExamplesNode                    matlab.ui.container.TreeNode
        EquivalenceratiophiNode_2       matlab.ui.container.TreeNode
        MolarfractionProductsXiPNode    matlab.ui.container.TreeNode
        TemperatureProductsTPNode       matlab.ui.container.TreeNode
        EnthalpyProductshRNode          matlab.ui.container.TreeNode
        PreconfiguredPanel              matlab.ui.container.Panel
        PreconfiguredListBox            matlab.ui.control.ListBox
        Panel_2                         matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        CalculateMenu                   matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        MaximazeMenu                    matlab.ui.container.Menu
        MinimizeMenu                    matlab.ui.container.Menu
        ClearCommandWindowMenu          matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        CalculateMenu_2                 matlab.ui.container.Menu
        ClearMenu_2                     matlab.ui.container.Menu
        RownumberedMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        species           % List of reactants species
        LS_Master         % List of all species in strMaster
        NSstrMaster       % Number of species in strMaster
        leg               % Legend
        selected_Reactive % Selected reactive
        selected_Reaction % Selected reaction
        flag              % New reactant
        flagNew           % New reactant as diluent
        flagNewCounter    % Counter new reactant
        PR1_vector        % Condition Reactants 1
        PR2_vector        % Condition Reactants 2
        PR3_vector        % Condition Reactants 3
        PP1_vector        % Condition Products 1
        PP2_vector        % Condition Products 2
        PR1_var_name      % Variable name for PR1
        PR2_var_name      % Variable name for PR2
        PR3_var_name      % Variable name for PR3
        PP1_var_name      % Variable name for PP1
        PP2_var_name      % Variable name for PP2
        flag_PR1          % FLAG for PR1: true-> vector
        flag_PR2          % FLAG for PR2: true-> vector
        flag_PR3          % FLAG for PR3: true-> vector
        flag_PP1          % FLAG for PP1: true-> vector
        flag_PP2          % FLAG for PP2: true-> vector
        results           % Results
        data_R            % Struct with data of reactants updated per iteration
        data_P            % Struct with data of products updated per iteration
        DS                % Display Species
        DS_fixed          % Display Fixed Species
        DF                % Display Fieldnames
        NR                % Number Reactant Species
        NPT               % numberProblemType
        w0                % Width GUI
        h0                % Height size GUI 
        welcome_message   % Welcome message
        minw0             % Minimum Width GUI
        minh0             % Minimum Height size GUI 
        position0         % Initial position of figure_main
    end
    
    properties (Access = public)
        E % Elements
        S % Species
        M % Minors species
        C % Constants
        Misc % Miscelaneous
        PD % Problem Description
        PS % Problem Solution
        TN % Tunning properties
        strMaster % Master DB
        strThProp % Reduced DB
        fig % Auxiliary figure
    end
    
    methods (Access = private)
        
        
        function showresults(app, Node)
            if ~isempty(Node.NodeData)
                % Show settings
                app.ProblemType.Value = Node.NodeData.numberProblemType;
                app.Reactants.Value = Node.NodeData.numberReactants;

                switch Node.NodeData.reaction
                    case '1'
                        app.Reaction.Value = '1';
                    case '2'
                        app.Reaction.Value = '2';
                    case '3'
                        app.Reaction.Value = '3';
                    case '4'
                        app.Reaction.Value = '4';
                    case '5'
                        app.Reaction.Value = '5';
                end
                % Show results
                app.text_TR.Value = Node.NodeData.strR.T;
                app.text_pR.Value = Node.NodeData.strR.p;
                app.text_rR.Value = Node.NodeData.strR.rho;
                app.text_hR.Value = Node.NodeData.strR.h/Node.NodeData.strR.mi;
                app.text_eR.Value = Node.NodeData.strR.e/Node.NodeData.strR.mi;
                app.text_sR.Value = Node.NodeData.strR.S;
                app.text_cpR.Value = Node.NodeData.strR.cP/Node.NodeData.strR.mi*1e-3;
                app.text_WR.Value = Node.NodeData.strR.W;
                app.text_soundR.Value = Node.NodeData.strR.sound;
                app.text_gammaR.Value = Node.NodeData.strR.cP/Node.NodeData.strR.cV;
                
                app.text_TP.Value = Node.NodeData.strP.T;
                app.text_pP.Value = Node.NodeData.strP.p;
                app.text_rP.Value = Node.NodeData.strP.rho;
                app.text_hP.Value = Node.NodeData.strP.h/Node.NodeData.strP.mi;
                app.text_eP.Value = Node.NodeData.strP.e/Node.NodeData.strP.mi;
                app.text_sP.Value = Node.NodeData.strP.S;
                app.text_cpP.Value = Node.NodeData.strP.cP/Node.NodeData.strP.mi*1e-3;
                app.text_WP.Value = Node.NodeData.strP.W;
                app.text_soundP.Value = Node.NodeData.strP.sound;
                app.text_gammaP.Value = Node.NodeData.strP.cP/Node.NodeData.strP.cV;
                app.edit_q.Value = app.text_hP.Value - app.text_hR.Value;
                if any(strcmpi(Node.NodeData.numberProblemType,{'7','8','9'}))
                    app.text_uR.Visible = 'on'; app.text_uR.Value = Node.NodeData.strR.u; app.text_uR.Editable = 'on';
                    app.text_uP.Visible = 'on'; app.text_uP.Value = Node.NodeData.strP.u; app.text_u.Visible = 'on';
                    app.text_MR.Visible = 'on'; app.text_MP.Visible = 'on'; app.text_M.Visible = 'on';
                    app.text_MR.Value = app.edit_uR.Value/app.text_soundR.Value;
                    app.text_MP.Value = app.text_uP.Value/app.text_soundP.Value;
                else
                    app.text_uR.Visible = 'off'; app.text_uR.Editable = 'off';
                    app.text_uP.Visible = 'off'; app.text_u.Visible = 'off';
                    app.text_MR.Visible = 'off'; app.text_MP.Visible = 'off'; app.text_M.Visible = 'off';
                end
                app.uitable_P.Data = Node.NodeData.data_P;
                app.uitable_R.Data = Node.NodeData.data_R;
                app.uitable_R2.Data = Node.NodeData.data_R(:, 1:3);
                app.OFEditField.Value = Node.NodeData.OF;
                app.FuelEditField.Value = Node.NodeData.F;
                if any(strcmp(app.Reactants.Value,{'1','2'}))
                    app.edit_phi.Value = '-';
                    app.edit_phi2.Value = '-';
                else
                    app.edit_phi.Value = sprintf('%.4g',Node.NodeData.phi);
                    app.edit_phi2.Value = sprintf('%.4g',Node.NodeData.phi);
                end
            end
        end
        
        function addchildren(app,app_parent,ProblemType,results,text,text_value)
            %%% New Children Node: Results -> ProblemType
            % Category 1
            j = 0;
            if ~isempty(app_parent.Children)
                i = 1;
                while i<=length(app_parent.Children)
                    if strcmpi(app_parent.Children(i).Text,ProblemType)
                        category1 = app_parent.Children(i);
                        j = 1;
                        break;
                    end
                    i = i + 1;
                end
            end
            if j == 0
                category1 = uitreenode(app_parent,'Text',ProblemType,'NodeData',[]);
            end
            expand(app_parent,'all');
            %%% New Children Node: ProblemType -> Reactants
            for k=numel(results):-1:1
                % Category 2
                j = 0;
                text_reactants = app.Reactants.Items{sscanf(results(k).numberReactants,'%d')};
                if ~isempty(category1.Children)
                    i = 1;
                    while i<=length(category1.Children)
                        if strcmpi(category1.Children(i).Text,text_reactants)
                            category2 = category1.Children(i);
                            j = 1;
                            break;
                        end
                        i = i + 1;
                    end
                end
                if j == 0
                    category2 = uitreenode(category1,'Text',text_reactants,'NodeData',[]);
                end
                expand(category1,'all');
                %%% New Children Node: Reactants -> CompleteOrIncomplete
                % Category 3
                j = 0;
                if ~isempty(category2.Children)
                    i = 1;
                    while i<=length(category2.Children)
                        if strcmpi(category2.Children(i).Text,results(k).CompleteOrIncomplete)
                            category3 = category2.Children(i);
                            j = 1;
                            break;
                        end
                        i = i + 1;
                    end
                end
                if j == 0
                    category3 = uitreenode(category2,'Text',results(k).CompleteOrIncomplete,'NodeData',[],'UserData',1);
                end
                expand(category2,'all');
                %%% New Children Node: CompleteOrIncomplete -> Phi
                % Category 4
                j = 0;
                %             if any(strcmpi(results.app.NR,{'1','2'})) % Cases without fuel
                text = 'Case %d'; text_value = category3.UserData;
                %             end
                if ~isempty(category3.Children)
                    i = 1;
                    while i<=length(category3.Children)
                        if strcmpi(category3.Children(i).Text,sprintf(text,text_value))
                            category4 = category3.Children(i);
                            j = 1;
                            break;
                        end
                        i = i + 1;
                    end
                end
                if j == 0
                    category4 = uitreenode(category3,'Text',sprintf(text,text_value),'NodeData',results(k));
                    category3.UserData = category3.UserData + 1;
                end
                expand(category3,'all');
            end
        end
        
        function addchildren2(app,app_parent,ProblemType,results,text,text_value)
            %%% New Children Node: Results -> ProblemType
            % Category 1
            j = 0;
            if ~isempty(app_parent.Children)
                i = 1;
                while i<=length(app_parent.Children)
                    if strcmpi(app_parent.Children(i).Text,ProblemType)
                        category1 = app_parent.Children(i);
                        j = 1;
                        break;
                    end
                    i = i + 1;
                end
            end
            if j == 0
                category1 = uitreenode(app_parent,'Text',ProblemType,'NodeData',[]);
            end
            expand(app_parent,'all');
            %%% New Children Node: ProblemType -> Reactants
            for k=numel(results):-1:1
                % Category 2
                j = 0;
                text_reactants = app.Reactants.Items{sscanf(results(k).numberReactants,'%d')};
                if ~isempty(category1.Children)
                    i = 1;
                    while i<=length(category1.Children)
                        if strcmpi(category1.Children(i).Text,text_reactants)
                            category2 = category1.Children(i);
                            j = 1;
                            break;
                        end
                        i = i + 1;
                    end
                end
                if j == 0
                    category2 = uitreenode(category1,'Text',text_reactants,'NodeData',[]);
                end
                expand(category1,'all');
                %%% New Children Node: Reactants -> CompleteOrIncomplete
                % Category 3
                j = 0;
                if ~isempty(category2.Children)
                    i = 1;
                    while i<=length(category2.Children)
                        if strcmpi(category2.Children(i).Text,results(k).CompleteOrIncomplete)
                            category3 = category2.Children(i);
                            j = 1;
                            break;
                        end
                        i = i + 1;
                    end
                end
                if j == 0
                    category3 = uitreenode(category2,'Text',results(k).CompleteOrIncomplete,'NodeData',[],'UserData',1);
                end
                expand(category2,'all');
                %%% New Children Node: CompleteOrIncomplete -> Phi
                % Category 4
%                 j = 0;
                %             if any(strcmpi(results.app.NR,{'1','2'})) % Cases without fuel
                text = 'Case %d'; text_value = category3.UserData;
                %             end
%                 if ~isempty(category3.Children)
%                     i = 1;
%                     while i<=length(category3.Children)
%                         if strcmpi(category3.Children(i).Text,sprintf(text,text_value))
% %                             category4 = category3.Children(i);
%                             j = 1;
%                             break;
%                         end
%                         i = i + 1;
%                     end
%                 end
%                 if j == 0
                    uitreenode(category3,'Text',sprintf(text,text_value),'NodeData',results(k));
                    category3.UserData = category3.UserData + 1;
%                 end
                expand(category3,'all');
            end
        end
        
        function resize_app(app, fig)
            movegui(app.(fig),'center');
        end

        
        function write_results(app, i)
            if i==1 % Only show last result
                app.text_TR.Value = app.PS.strR{i}.T;
                app.text_pR.Value = app.PS.strR{i}.p;
                app.text_TP.Value = app.PS.strP{i}.T;
                app.text_pP.Value = app.PS.strP{i}.p;
                app.text_rR.Value = app.PS.strR{i}.rho;
                app.text_rP.Value = app.PS.strP{i}.rho;
                app.text_hR.Value = app.PS.strR{i}.h/app.PS.strR{i}.mi;%[kJ/kg]
                app.text_hP.Value = app.PS.strP{i}.h/app.PS.strP{i}.mi;%[kJ/kg]
                app.text_eR.Value = app.PS.strR{i}.e/app.PS.strR{i}.mi;%[kJ/kg]
                app.text_eP.Value = app.PS.strP{i}.e/app.PS.strP{i}.mi;%[kJ/kg]
                app.text_cpR.Value = app.PS.strR{i}.cP/app.PS.strR{i}.mi*1e-3;%[kJ/kg]
                app.text_cpP.Value = app.PS.strP{i}.cP/app.PS.strP{i}.mi*1e-3;%[kJ/kg]
                app.text_sR.Value = app.PS.strR{i}.S;%[kJ/(kg-K)]
                app.text_sP.Value = app.PS.strP{i}.S;%[kJ/(kg-K)]
                app.text_gammaR.Value = app.PS.strR{i}.gamma;
                app.text_gammaP.Value = app.PS.strP{i}.gamma;
                app.text_WR.Value = app.PS.strR{i}.W;
                app.text_WP.Value = app.PS.strP{i}.W;
                app.text_soundR.Value = app.PS.strR{i}.sound;
                app.text_soundP.Value = app.PS.strP{i}.sound;
                app.edit_q.Value = app.text_hP.Value - app.text_hR.Value;
                if sscanf(app.ProblemType.Value,'%f') > 6
                    app.text_uR.Value = app.PS.strR{i}.u;
                    app.text_uP.Value = app.PS.strP{i}.u;
                    app.text_MR.Value = app.PS.strR{i}.u/app.PS.strR{i}.sound;
                    app.text_MP.Value = app.PS.strP{i}.u/app.PS.strP{i}.sound;
                end
                app.uitable_P.Data = app.data_P{i};
            end
        end
        
        function save_results(app, i)
            app.results(i).strR = app.PS.strR{i};
            app.results(i).strP = app.PS.strP{i};
            app.results(i).numberProblemType = app.NPT;
            app.results(i).numberReactants = app.NR;
            app.results(i).CompleteOrIncomplete = app.Reaction.Items{app.selected_Reaction};
            app.results(i).reaction = app.Reaction.Value;
            app.results(i).data_P = app.data_P{i};
            app.results(i).data_R = app.data_R;
            app.results(i).F = app.FuelEditField.Value;
            app.results(i).OF = app.OFEditField.Value;
            app.results(i).phi = app.PD.phi.value(i);
            app.results(i).NameSpecies = app.S.LS;
        end
        
        function sort_products(app, i)
            if app.flag
                [Xi,ind_sort] = sort(app.PS.strP{i}.Xi(:),'descend');
                app.data_P{i} = table2cell(table(app.S.LS(ind_sort)',Xi.*app.PS.strP{i}.N,Xi));
            else
                if app.Reaction.Value == '1' || strcmp(app.MajorMenu.Checked,'on')
                    [Xi,ind_sort] = sort(app.PS.strP{i}.Xi(app.DS),'descend');
                else
                    [Xi,ind_sort] = sort([app.PS.strP{i}.Xi(app.DS_fixed);app.PS.strP{i}.Xi(app.DS)],'descend');
                end
                app.data_P{i} = table2cell(table(app.DF(ind_sort)',Xi.*app.PS.strP{i}.N,Xi));
            end
        end
        
        function [prop, flag_prop] = get_prop(app, prop, name)
            if prop(1) == '['
                prop = sscanf(prop, '[%f:%f:%f]');
                prop = prop(1):prop(2):prop(3);
                flag_prop = true;
                app.PD.phi.value = app.PD.phi.value(1)*ones(1,numel(prop));
            else
                prop = sscanf(prop,'%f');
                flag_prop = false;
                app.PD.(name).value = prop;
            end
        end
        
        function app = get_parameters(app)
            %% GET EQUIVALENCE RATIO
            [app.PD.phi.value, app.flag] = get_prop(app, app.edit_phi.Value, 'phi');
            if isempty(app.PD.phi.value)
                app.PD.phi.value = 1;
            end
            app.NR = app.Reactants.Value;
            %% NUMBER PROBLEM TYPE
            app.NPT = app.ProblemType.Value;
            %% GET CONDITIONS
            [app.PR1_vector, app.flag_PR1] = get_prop(app, app.PR1.Value, app.PR1_var_name);
            [app.PR2_vector, app.flag_PR2] = get_prop(app, app.PR2.Value, app.PR2_var_name);
            [app.PP1_vector, app.flag_PP1] = get_prop(app, app.PP1.Value, app.PP1_var_name);
            [app.PP2_vector, app.flag_PP2] = get_prop(app, app.PP2.Value, app.PP2_var_name);
            if strcmpi(app.PD.ProblemType, 'DET_OVERDRIVEN')
                [app.PR3_vector, app.flag_PR3] = get_prop(app, app.PR3.Value, app.PR3_var_name);
            end
        end
        
        function update_listbox_minor_products(app)
            app.listbox_minor_products.Items = app.M.minors_products;
            app.Reaction.Items{2} = ['Minor products (',sprintf('%d',app.M.Lminors),')'];
            app.text_minor_products.Text = ['Minor products (',sprintf('%d',app.M.Lminors),')'];
        end
        
        function setPromptFcn(app, jTextArea, eventData, newPrompt)
           % Prevent overlapping reentry due to prompt replacement
          persistent inProgress
          if isempty(inProgress)
            inProgress = 1;  %#ok unused
          else
            return;
          end
         
          try
            % *** Prompt modification code goes here ***
            cwText = char(jTextArea.getText);
            app.Console.Value = cwText;
            % force prompt-change callback to fizzle-out...
            pause(0.02);
          catch
            % Never mind - ignore errors...
          end
         
          % Enable new callbacks now that the prompt has been modified
          inProgress = [];
         
        end  % setPromptFcn
        
        function init_listener_CW(app)
            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
        end
        
        
        function welcome(app)
            app.Console.Value = app.welcome_message;
        end
                
        function undock_item(app)
            app.fig = uifigure('Position', [489, 343, 450, 400], 'CloseRequestFcn', @dock_item);
            addprop(app.fig, 'figure_main');
            app.fig.figure_main = app.figure_main;
            obj1 = copy_obj(app, app.Console, app.fig); 
            obj2 = copy_obj(app, app.Console_2, app.fig); 
            obj3 = copy_obj(app, app.Label_Console, app.fig);
            visible_CW(app, false);
            app.fig.Children(3,1).Position(4) = 6*app.fig.Children(3,1).Position(4);
        end
        
        function b = copy_obj(app, a, fig)
           b = copyobj(a, fig);
        end
        
        function visible_CW(app, bool)
            app.Panel_CW.Visible = bool;
        end
        
        function get_tolerances(app)
            app.C.mintol_display = app.DisplaySpeciesEditField.Value;
            app.C.tolN = app.TraceoptionEditField.Value;
        end
    end
    
    methods (Access = public)
        function app = app_edit(app)
            % Clear last results
            app.text_rR.Value = 0; app.text_hR.Value = 0; app.text_sR.Value = 0; app.text_cpR.Value = 0;
            app.text_WR.Value = 0; app.text_soundR.Value = 0; app.text_gammaR.Value = 0;
            app.text_eR.Value = 0; app.text_eP.Value = 0;
            app.text_rP.Value = 0; app.text_hP.Value = 0; app.text_sP.Value = 0; app.text_cpP.Value = 0;
            app.text_WP.Value = 0; app.text_soundP.Value = 0; app.text_gammaP.Value = 0;
            switch app.ProblemType.Value
                case '1' % * TP: Equilibrium composition at defined T and p
                    app.PD.ProblemType = 'TP';
                    app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR'; 
                    app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
                    app.PP1.Visible = 'on'; app.PP2.Visible = 'on';
                    
                    app.text_RP1.Text = 'Temperature [K]';
                    app.text_RP2.Text = 'Constant Pressure [bar]';
                    app.uipanel_additional_constraints.Visible = 'off';
                    
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 2500; app.text_TP.FontWeight = 'bold';
                    app.text_pP.Value = 0; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'off'; app.text_uR.Value = 0; app.text_uR.Editable = 'off';
                    app.text_uP.Visible = 'off'; app.text_uP.Value = 0; app.text_u.Visible = 'off';
                    app.text_MR.Visible = 'off'; app.text_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.text_vP_vR.Visible = 'off'; 
                case '2' % * HP: Adiabatic T and composition at constant p
                    app.PD.ProblemType = 'HP';
                    app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR'; 
                    app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
                    app.PP1.Visible = 'off'; app.PP2.Visible = 'on'; 
                    app.PR3.Visible = 'off'; app.PR4.Visible = 'off';
                    
                    app.text_RP1.Text = 'Temperature [K]';
                    app.text_RP2.Text = 'Pressure [bar]';
                    app.uipanel_additional_constraints.Visible = 'on';
                    app.text_ReactantsProducts.Visible = 'on'; app.text_Reactans_5.Visible = 'off';
                    app.text_RP3.Text = 'Constant Enthalpy: hP = hR';
                    app.text_RP4.Visible = 'off'; 
                    
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 0; app.text_TP.FontWeight = 'normal';
                    app.text_pP.Value = app.text_pR.Value; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'off'; app.text_uR.Value = 0; app.text_uR.Editable = 'off';
                    app.text_uP.Visible = 'off'; app.text_uP.Value = 0; app.text_u.Visible = 'off';
                    app.text_MR.Visible = 'off'; app.text_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.text_vP_vR.Visible = 'off'; 
                case '3' % * SP: Isentropic (i.e., adiabatic) compression/expansion to a specified p
                    app.PD.ProblemType = 'SP';
                    app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR'; 
                    app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
                    app.PP1.Visible = 'off'; app.PP2.Visible = 'on';
                    app.PR3.Visible = 'off'; app.PR4.Visible = 'off';
                    
                    app.text_RP1.Text = 'Temperature [K]';
                    app.text_RP2.Text = 'Pressure [bar]';
                    app.uipanel_additional_constraints.Visible = 'on';
                    app.text_ReactantsProducts.Visible = 'on'; app.text_Reactans_5.Visible = 'off';
                    app.text_RP3.Text = 'Constant Entropy: SP = SR';
                    app.text_RP4.Visible = 'off'; 
                    
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 20; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 0; app.text_TP.FontWeight = 'normal';
                    app.text_pP.Value = 20; app.text_pP.FontWeight = 'bold';
                    app.text_uR.Visible = 'off'; app.text_uR.Value = 0; app.text_uR.Editable = 'off';
                    app.text_uP.Visible = 'off'; app.text_uP.Value = 0; app.text_u.Visible = 'off';
                    app.text_MR.Visible = 'off'; app.text_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.text_vP_vR.Visible = 'off'; 
                case '4' % * TV: Equilibrium composition at defined T and constant v
                    app.PD.ProblemType = 'TV';
                    app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR'; 
                    app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
                    app.PP1.Visible = 'on'; app.PP2.Visible = 'off'; 
                    app.PR3.Visible = 'off'; app.PR4.Visible = 'off';
                    
                    app.text_RP1.Text = 'Temperature [K]';
                    app.text_RP2.Text = 'Pressure [bar]';
                    app.uipanel_additional_constraints.Visible = 'on';
                    app.text_ReactantsProducts.Visible = 'on'; app.text_Reactans_5.Visible = 'off';
                    app.text_RP3.Text = 'Constant Volume: vP = vR';
                    app.text_RP4.Visible = 'off'; 
                    
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 2500; app.text_TP.FontWeight = 'bold';
                    app.text_pP.Value = app.text_pR.Value; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'off'; app.text_uR.Value = 0; app.text_uR.Editable = 'off';
                    app.text_uP.Visible = 'off'; app.text_uP.Value = 0; app.text_u.Visible = 'off';
                    app.text_MR.Visible = 'off'; app.text_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.text_vP_vR.Visible = 'off';
                case '5' % * EV: Equilibrium composition at Adiabatic T and constant v
                    app.PD.ProblemType = 'EV';
                    app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR'; 
                    app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
                    app.PP1.Visible = 'off'; app.PP2.Visible = 'on'; 
                    app.PR3.Visible = 'off'; app.PR4.Visible = 'off';
                    
                    app.text_RP1.Text = 'Temperature [K]';
                    app.text_RP2.Text = 'Pressure [bar]';
                    app.uipanel_additional_constraints.Visible = 'on';
                    app.text_ReactantsProducts.Visible = 'on'; app.text_Reactans_5.Visible = 'off';
                    app.text_RP3.Text = 'Constant Internal energy: eP = eR';
                    app.text_RP4.Visible = 'off'; 
                    
                    app.text_TR.Value = 1000; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 0; app.text_TP.FontWeight = 'normal';
                    app.text_pP.Value = app.text_pR.Value; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'off'; app.text_uR.Value = 0; app.text_uR.Editable = 'off';
                    app.text_uP.Visible = 'off'; app.text_uP.Value = 0; app.text_u.Visible = 'off';
                    app.text_MR.Visible = 'off'; app.text_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.text_vP_vR.Visible = 'off';
                case '6' % * SV: Isentropic (i.e., fast adiabatic) compression/expansion to a specified v
                    app.PD.ProblemType = 'SV';
                    app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR'; 
                    app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
                    app.PP1.Visible = 'off'; app.PP2.Visible = 'on'; 
                    app.PR3.Visible = 'off'; app.PR4.Visible = 'off';
                    
                    app.text_RP1.Text = 'Temperature [K]';
                    app.text_RP2.Text = 'Pressure [bar]';
                    app.uipanel_additional_constraints.Visible = 'on';
                    app.text_ReactantsProducts.Visible = 'on'; app.text_Reactans_5.Visible = 'off';
                    app.text_RP3.Text = 'Constant Entropy: SP = SR';
                    app.text_RP4.Visible = 'on'; app.text_RP4.Text = 'Volume Products/Reactants';
                    app.text_RP4.Text = 'Volume Products/Reactants';
                    app.PP4.Visible = 'on'; app.PP4.Value = '0.5';
                    
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 0; app.text_TP.FontWeight = 'normal';
                    app.text_pP.Value = app.text_pR.Value; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'off'; app.text_uR.Value = 0; app.text_uR.Editable = 'off';
                    app.text_uP.Visible = 'off'; app.text_uP.Value = 0; app.text_u.Visible = 'off';
                    app.text_MR.Visible = 'off'; app.text_MP.Visible = 'off'; app.text_M.Visible = 'off';
                    app.text_vP_vR.Visible = 'on';
                case '7' % * SHOCK_I: CALCULATE PLANAR INCIDENT SHOCK WAVE
                    app.PD.ProblemType = 'SHOCK_I';
                    app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR'; 
                    app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
                    app.PP1.Visible = 'off'; app.PP2.Visible = 'off';
                    app.PR3.Visible = 'on'; app.PR4.Visible = 'on';
                    
                    app.text_RP1.Text = 'Temperature [K]';
                    app.text_RP2.Text = 'Pressure [bar]';
                    app.uipanel_additional_constraints.Visible = 'on';
                    app.text_ReactantsProducts.Visible = 'off'; app.text_Reactans_5.Visible = 'on';
                    app.text_RP3.Text = 'Shock velocity [m/s]'; 
                    app.text_RP4.Text = 'Mach number [-]'; app.text_RP4.Visible = 'on'; 
                    
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 0; app.text_TP.FontWeight = 'normal';
                    app.text_pP.Value = 0; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'on'; app.text_uR.Value = 500; app.text_uR.FontWeight = 'bold';
                    app.text_uP.Visible = 'on'; app.text_uP.Value = 0; app.text_u.Visible = 'on';
                    app.text_MR.Visible = 'on'; app.text_MR.FontWeight = 'bold';
                    app.text_MP.Visible = 'on'; app.text_M.Visible = 'on';
                    app.text_vP_vR.Visible = 'off';
                    %%%% GET TEMPERATURE REACTIVES
                    TR = app.PR1.Value;
                    if TR(1) == '['
                        TR = sscanf(TR, '[%f:%f:%f]');
                        TR = TR(1);
                    else
                        TR = sscanf(TR,'%f');
                    end
                    %%%% GET PRESSURE REACTIVES
                    pR = app.PR2.Value;
                    if pR(1) == '['
                        pR = sscanf(pR, '[%f:%f:%f]');
                        pR = pR(1);
                    else
                        pR = sscanf(pR,'%f');
                    end
                    %%%% SELECTION REACTANTS
                    app.NR = app.Reactants.Value;
                    %%%% SELECT REACTANT
                    switch app.NR
                        case '1'
                            app.PD.R_Fuel = 0;
                            app.PD.S_Oxidizer = {'O2'};
                            app.PD.N_Oxidizer = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                            app.PD.R_Oxidizer = SetSpecies(app.C.M0.value,app.PD.S_Oxidizer,app.PD.N_Oxidizer,TR,find_ind(app.PD.S_Oxidizer,app.S.LS),app.strThProp);
                            app.PD.S_Inert = {'N2','Ar'};
                            app.PD.N_Inert = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                            app.PD.R_Inert = SetSpecies(app.C.M0.value,app.PD.S_Inert,app.PD.N_Inert,TR,find_ind(app.PD.S_Inert,app.S.LS),app.strThProp);
                            %%%% COMPUTE PROPERTIES OF REACTANTS
                            app.PD.R = app.PD.R_Fuel + app.PD.R_Oxidizer + app.PD.R_Inert;
                            app.PS.strR{1} = ComputeProperties(app.C.A0.value,app.PD.R,pR,TR,app.E.ind_C,app.E.ind_H);
                            app.PR4.Value = sprintf('%.4g',sscanf(app.PR3.Value,'%f')/app.PS.strR{1}.sound);
                        case '2'
                            app.PD.R_Fuel = 0;
                            app.PD.S_Oxidizer = {'O2'};
                            app.PD.N_Oxidizer = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                            app.PD.R_Oxidizer = SetSpecies(app.C.M0.value,app.PD.S_Oxidizer,app.PD.N_Oxidizer,TR,find_ind(app.PD.S_Oxidizer,app.S.LS),app.strThProp);
                            app.PD.S_Inert = {'N2','Ar'};
                            app.PD.N_Inert = [app.uitable_R.Data{strcmpi(app.species,'N2'),2},app.uitable_R.Data{strcmpi(app.species,'Ar'),2}];
                            app.PD.R_Inert = SetSpecies(app.C.M0.value,app.PD.S_Inert,app.PD.N_Inert,TR,find_ind(app.PD.S_Inert,app.S.LS),app.strThProp);
                            %%%% COMPUTE PROPERTIES OF REACTANTS
                            app.PD.R = app.PD.R_Fuel + app.PD.R_Oxidizer + app.PD.R_Inert;
                            app.PS.strR{1} = ComputeProperties(app.C.A0.value,app.PD.R,pR,TR,app.E.ind_C,app.E.ind_H);
                            app.PR4.Value = sprintf('%.4g',sscanf(app.PR3.Value,'%f')/app.PS.strR{1}.sound);
                        otherwise
                    end
                    app.text_MR.Value = app.text_uR.Value/app.PS.strR{1}.sound;
                case '8' % * SHOCK_R: CALCULATE PLANAR POST-REFLECTED SHOCK STATE
                    app.PD.ProblemType = 'SHOCK_R';
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 0; app.text_TP.FontWeight = 'normal';
                    app.text_pP.Value = 0; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'on'; app.text_uR.Value = 500; app.text_uR.Editable = 'on'; app.text_uR.FontWeight = 'bold';
                    app.text_uP.Visible = 'on'; app.text_uP.Value = 0; app.text_u.Visible = 'on';
                    app.text_MR.Visible = 'on'; app.text_MR.Editable = 'on'; app.text_MR.FontWeight = 'bold';
                    app.text_MP.Visible = 'on'; app.text_M.Visible = 'on';
                    app.text_vP_vR.Visible = 'off';
                    %%%% GET TEMPERATURE REACTIVES
                    TR = app.PR1.Value;
                    if TR(1) == '['
                        TR = sscanf(TR, '[%f:%f:%f]');
                        TR = TR(1);
                    else
                        TR = sscanf(TR,'%f');
                    end
                    %%%% GET PRESSURE REACTIVES
                    pR = app.PR2.Value;
                    if pR(1) == '['
                        pR = sscanf(pR, '[%f:%f:%f]');
                        pR = pR(1);
                    else
                        pR = sscanf(pR,'%f');
                    end
                    %%%% SELECTION REACTANTS
                    species = app.species;
                    app.NR = app.Reactants.Value;
                    %%%% SELECT REACTANT
                    switch app.NR
                        case '1'
                            app.PD.R_Fuel = 0;
                            app.PD.S_Oxidizer = {'O2'};
                            app.PD.N_Oxidizer = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                            app.PD.R_Oxidizer = SetSpecies(app.C.M0.value,app.PD.S_Oxidizer,app.PD.N_Oxidizer,TR,find_ind(app.PD.S_Oxidizer,app.S.LS),app.strThProp);
                            app.PD.S_Inert = {'N2','Ar'};
                            app.PD.N_Inert = app.uitable_R.Data{strcmpi(app.species,'N2'),2};
                            app.PD.R_Inert = SetSpecies(app.C.M0.value,app.PD.S_Inert,app.PD.N_Inert,TR,find_ind(app.PD.S_Inert,app.S.LS),app.strThProp);
                            %%%% COMPUTE PROPERTIES OF REACTANTS
                            app.PD.R = app.PD.R_Fuel + app.PD.R_Oxidizer + app.PD.R_Inert;
                            app.PS.strR{1} = ComputeProperties(app.C.A0.value,app.PD.R,pR,TR,app.E.ind_C,app.E.ind_H);
                            app.text_MR.Value = sprintf('%.4g',sscanf(app.text_uR.Value,'%f')/app.PS.strR{1}.sound);
                        case '2'
                            app.PD.R_Fuel = 0;
                            app.PD.S_Oxidizer = {'O2'};
                            app.PD.N_Oxidizer = app.uitable_R.Data{strcmpi(app.species,'O2'),2};
                            app.PD.R_Oxidizer = SetSpecies(app.C.M0.value,app.PD.S_Oxidizer,app.PD.N_Oxidizer,TR,find_ind(app.PD.S_Oxidizer,app.S.LS),app.strThProp);
                            app.PD.S_Inert = {'N2','Ar'};
                            app.PD.N_Inert = [app.uitable_R.Data{strcmpi(app.species,'N2'),2},app.uitable_R.Data{strcmpi(app.species,'Ar'),2}];
                            app.PD.R_Inert = SetSpecies(app.C.M0.value,app.PD.S_Inert,app.PD.N_Inert,TR,find_ind(app.PD.S_Inert,app.S.LS),app.strThProp);
                            %%%% COMPUTE PROPERTIES OF REACTANTS
                            app.PD.R = app.PD.R_Fuel + app.PD.R_Oxidizer + app.PD.R_Inert;
                            app.PS.strR{1} = ComputeProperties(app.C.A0.value,app.PD.R,pR,TR,app.E.ind_C,app.E.ind_H);
                            app.text_MR.Value = sprintf('%.4g',sscanf(app.text_uR.Value,'%f')/app.PS.strR{1}.sound);
                        otherwise
                    end
                case '9' % * DET: CALCULATE CHAPMAN-JOUGET STATE (CJ UPPER STATE)
                    app.PD.ProblemType = 'DET';
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 0; app.text_TP.FontWeight = 'normal';
                    app.text_pP.Value = 0; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'on'; app.text_uR.Value = 0; app.text_uR.Editable = 'off'; app.text_uR.FontWeight = 'normal';
                    app.text_uP.Visible = 'on'; app.text_uP.Value = 0; app.text_u.Visible = 'on';
                    app.text_MR.Visible = 'on'; app.text_MP.Visible = 'on'; app.text_M.Visible = 'on'; app.text_MR.FontWeight = 'normal'; app.text_MR.Value = '0'; app.text_MR.Editable = 'off';
                    app.text_vP_vR.Visible = 'off';
                case '10' % * DET: CALCULATE OVERDRIVE DETONATION
                    app.PD.ProblemType = 'DET_OVERDRIVEN';
                    app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR'; app.PR3_var_name = 'overdrive';
                    app.text_TR.Value = 300; app.text_TR.FontWeight = 'bold';
                    app.text_pR.Value = 1; app.text_pR.FontWeight = 'bold';
                    app.text_TP.Value = 0; app.text_TP.FontWeight = 'normal';
                    app.text_pP.Value = 0; app.text_pP.FontWeight = 'normal';
                    app.text_uR.Visible = 'off'; app.text_uR.Value = 0; app.text_uR.Editable = 'off'; app.text_uR.FontWeight = 'normal';
                    app.text_uP.Visible = 'off'; app.text_uP.Value = 0; app.text_u.Visible = 'off';
                    app.text_MR.Visible = 'off'; app.text_MP.Visible = 'off'; app.text_M.Visible = 'off'; app.text_MR.FontWeight = 'normal'; app.text_MR.Value = '0'; app.text_MR.Editable = 'off';
                    app.text_vP_vR.Visible = 'off';
                otherwise
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             init_listener_CW(app);
            %%%%
            addpath(genpath(pwd));
            %%%% Resize and centering app
            app.figure_main.Visible = false;
            %resize_app(app,'figure_main');
            app.w0 = app.figure_main.Position(3);
            app.h0 = app.figure_main.Position(4);
            app.minw0 = app.UIAxes.Position(1) + app.UIAxes.Position(3);
            app.minh0 = app.UIAxes.Position(2) + app.UIAxes.Position(4);
            app.position0 = app.figure_main.Position;
            %%%%
            app = App(app);
            app.selected_Reactive = 1;
            app.selected_Reaction = 1;
            app.flag = false;
            app.flagNew = false;
            app.flagNewCounter = 0;
            app.welcome_message = app.Console.Value;
            Initialize(app); % Load databases and constants
            %%%% Initialize for TP
            app.PR1_var_name = 'TR'; app.PR2_var_name = 'pR';
            app.PP1_var_name = 'TP'; app.PP2_var_name = 'pP';
            %%%% Initialize table data
            app.uitable_R.ColumnFormat = {[] [] [] {'Fuel', 'Oxidant', 'Inert'}};
            app.data_R = {'N2', 0.79/0.21, 0.79, 'Inert'; 'O2', 1, 0.21, 'Oxidant'};
            app.species = {'N2'; 'O2'};
            app.uitable_R.Data = app.data_R;
            app.uitable_R2.Data = app.data_R(:, 1:3);
            app.uitable_P.Data = app.data_R(:, 1);
            %%%% Clear memory
            clear data_R;
            %%%% Set up interface I
            set(app.UIAxes,'LineWidth',app.Misc.config.linewidth,'FontSize',app.Misc.config.fontsize,'BoxStyle','full');
            xlabel(app.UIAxes,"Property 1",'FontSize',app.Misc.config.fontsize,'interpreter','latex');
            ylabel(app.UIAxes,"Property 2",'FontSize',app.Misc.config.fontsize,'interpreter','latex');
            app.figure_main.AutoResizeChildren = false;
            %%%% Initialize Selection of species to be plotted
            app.listbox_species_2.Items = app.S.LS;
            app.listbox_plotted_species.Items = {};
            fnm = fieldnames(app.strMaster);
            for i=length(fnm):-1:1
                app.LS_Master{i} = app.strMaster.(fnm{i}).FullName;
            end
            app.listbox_species.Items = app.LS_Master;
            
            app.text_list_species.Text =  ['List of species (',sprintf('%d',numel(app.LS_Master)),')'];
            update_listbox_minor_products(app);
            app.C.tolN = app.TraceoptionEditField.Value;
            app.uitable_R.Data(:,5) = {sscanf(app.PR1.Value,'%f')};
            app.NSstrMaster = numel(fnm);
            %%%% Set up interface II
            pause(3)
            app.figure_main.Visible = true;
        end

        % Callback function: Calculate, CalculateMenu, CalculateMenu_2
        function CalculateButtonPushed(app, event)
            %% DEBUG
            profile on;
            %% START
            app.Lamp.Color = 'Yellow';
            app = App(app, app.uitable_P.Data(:, 1)');
            %% Initilization
            Initialize(app);
            %% MISCELANEOUS
            app.DS = logical(1:app.S.NS_fixed);
            if app.Reaction.Value == '1' || strcmp(app.MajorMenu.Checked,'on')
                if app.selected_Reactive == 1
                    disp_aux = {'N2';'O2'};
                elseif app.selected_Reactive == 2
                    disp_aux = {'N2';'O2';'Ar'};
                elseif app.selected_Reactive == 4
                    disp_aux = {'CO2';'H2O';'CO';'H2';'O2';'N2';'Ar';'Cbgrb'};
                elseif app.selected_Reactive == 3 || ~mod(app.selected_Reactive,2)
                    disp_aux = {'CO2';'H2O';'CO';'H2';'O2';'N2';'Cbgrb'};
                elseif mod(app.selected_Reactive,2) 
                    disp_aux = {'CO2';'H2O';'CO';'H2';'O2';'Cbgrb'};
                end
                for i=app.S.NS:-1:1
                    app.DS(i)= any(strcmp(app.S.LS{i}, disp_aux));
                end
                app.DF = app.S.LS(app.DS);
            else
                for i=app.S.NS:-1:1
                    app.DS(i)= any(strcmp(app.S.LS{i}, app.M.minors_products));
                end
                app.DF = [app.S.LS(app.DS_fixed);app.S.LS(app.DS)];
            end
            %% EXTRACT INITIAL PARAMETERS
            app = get_parameters(app);
            %% Constants
            app.C.l_phi = length(app.PD.phi.value);
            app.data_R = app.uitable_R.Data;
            ind_Fuel = strcmp(app.uitable_R.Data(:,4), 'Fuel');
            ind_Oxidizer = strcmp(app.uitable_R.Data(:,4), 'Oxidant');
            ind_Inert = strcmp(app.uitable_R.Data(:,4),'Inert');
            %% Get tolerances
            get_tolerances(app);
            for i=app.C.l_phi:-1:1 % Evading preallocate struct
                if app.flag_PR1; app.PD.TR.value = app.PR1_vector(i); end
                if app.flag_PR2; app.PD.pR.value = app.PR2_vector(i); end
                if app.flag_PP1; app.PD.TP.value = app.PP1_vector(i); end
                if app.flag_PP2; app.PD.pP.value = app.PP2_vector(i); end
                %if app.flag_PR3 && strcmpi(app.PD.ProblemType, 'DET_OVERDRIVEN'); app.PD.overdriven.value = app.PR3_vector(i); end
                
                if app.flag
                    switch app.selected_Reactive
                        case 3
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {2*(79/21)/app.PD.phi.value(i);2/app.PD.phi.value(i);1};
                        case 4
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {2*(78.09/20.95)/app.PD.phi.value(i);2/app.PD.phi.value(i);2*(0.96/20.95)/app.PD.phi.value(i);1};
                        case 5
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {2/app.PD.phi.value(i);1};
                        case 6
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {3.5*(79/21)/app.PD.phi.value(i);3.5/app.PD.phi.value(i);1};
                        case 7
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {3.5/app.PD.phi.value(i);1};
                        case 8
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {5*(79/21)/app.PD.phi.value(i);5/app.PD.phi.value(i);1};
                        case 9
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {5/app.PD.phi.value(i);1};
                        case 10
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {2.5*(79/21)/app.PD.phi.value(i);2.5/app.PD.phi.value(i);1};
                        case 11
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {2.5/app.PD.phi.value(i);1};
                        case 12
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {3*(79/21)/app.PD.phi.value(i);3/app.PD.phi.value(i);1};
                        case 13
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {3/app.PD.phi.value(i);1};
                        case 14
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {7.5*(79/21)/app.PD.phi.value(i);7.5/app.PD.phi.value(i);1};
                        case 15
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {7.5/app.PD.phi.value(i);1};
                        case 16
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {12.5*(79/21)/app.PD.phi.value(i);12.5/app.PD.phi.value(i);1};
                        case 17
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {12.5/app.PD.phi.value(i);1};
                        case 18
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {0.5*(79/21)/app.PD.phi.value(i);0.5/app.PD.phi.value(i);1};
                        case 19
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {0.5/app.PD.phi.value(i);1};
                        case 20
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {0.5*(79/21)/app.PD.phi.value(i);0.5/app.PD.phi.value(i);1};
                        case 21
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {0.5/app.PD.phi.value(i);1};
                        case 22
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {1.5*(79/21)/app.PD.phi.value(i);1.5/app.PD.phi.value(i);1};
                        case 23
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {1.5/app.PD.phi.value(i);1};
                        case 24
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {3*(79/21)/app.PD.phi.value(i);3/app.PD.phi.value(i);1};
                        case 25
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {3/app.PD.phi.value(i);1};
                        case 26
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {2.3*(79/21)/app.PD.phi.value(i);2.3/app.PD.phi.value(i);0.85;0.1;0.05};
                        case 27
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {2.3/app.PD.phi.value(i);0.85;0.1;0.05};
                        case 28
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {0.5*(79/21)/app.PD.phi.value(i);0.5/app.PD.phi.value(i);0.5;0.5};
                        case 29
                            app.data_R(1:length(app.species)-app.flagNewCounter,2) = {0.5/app.PD.phi.value(i);0.5;0.5};
                    end
                    NSpecies_R = numel(app.species);
                    Species = string(NSpecies_R);
                    for j=NSpecies_R:-1:1
                        Species(j) = app.uitable_R.Data{j,1};
                        Ni(j) = app.data_R{j,2};
                    end
                    N = sum(Ni);
                    Xi = Ni/N;
                    for j=1:NSpecies_R
                        app.data_R{j,3} = Xi(j);
                    end
                end
                
                %% DEFINE FUEL
                if sum(ind_Fuel)
                    app.PD.S_Fuel = regexprep(app.uitable_R.Data(ind_Fuel,1),'\s+','')';
                    app.PD.N_Fuel = app.data_R{ind_Fuel, 2};
                else
                    app.PD.S_Fuel = [];
                end
                app = Define_F(app);
                %% DEFINE OXIDIZER
                if sum(ind_Oxidizer)
                    app.PD.S_Oxidizer = regexprep(app.uitable_R.Data(ind_Oxidizer,1),'\s+','')';
                    app.PD.N_Oxidizer = app.data_R{ind_Oxidizer, 2};
                else
                    app.PD.S_Oxidizer = [];
                end
                app = Define_O(app);
                %% DEFINE INERT
                if sum(ind_Inert)
                    app.PD.S_Inert = regexprep(app.uitable_R.Data(ind_Inert,1),'\s+','')';
                    app.PD.N_Inert = app.data_R{ind_Inert, 2};
                else
                    app.PD.S_Inert = [];
                end
                app = Define_I(app);
                %% COMPUTE PROPERTIES
                app = Define_FOI(app, i);
                %% PROBLEM TYPE
                app = SolveProblem(app, i);
                %% DISPLAY RESULTS COMMAND WINDOW
                results(app, i);
                %% SORT PRODUCTS SPECIES COMPOSITION
                sort_products(app, i);
                %% SHOW RESULTS GUI
                write_results(app, i)
                %% SAVE DATA
                save_results(app, i);
                %%
            end
            %% SAVE ALL THE RESULTS
            addchildren2(app, app.Node_Results, app.PD.ProblemType, app.results, 'Phi = %.5f', app.PD.phi.value(i))
            %% DISPLAY RESULTS (PLOTS)
            closing(app);
            %% FINISH
            app.Lamp.Color = 'Green';
            app.TabGroup2.SelectedTab = app.ResultsTab;
            %% DEBUG
            profile viewer
        end

        % Callback function
        function TraceoptionEditFieldValueChanged(app, event)
            app.C.tolN = app.TraceoptionEditField.Value;
        end

        % Callback function
        function SootfactorEditFieldValueChanged(app, event)
            app.TN.factor_c = app.SootfactorEditField.Value;
        end

        % Value changed function: ProblemType
        function ProblemTypeValueChanged(app, event)
            app_edit(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            showresults(app, selectedNodes);
        end

        % Value changed function: Reactants
        function ReactantsValueChanged(app, event)
            app.Reaction.Value = '1';
            app.edit_phi.Value = '1';
            app.flagNew = false;
            app.flagNewCounter = 0;
            app.selected_Reactive = sscanf(app.Reactants.Value,'%d');
            if app.selected_Reactive < 3
                if app.Reactants.Value == '1'
                    app.data_R = {'N2',0.79/0.21,0.79,'Inert';'O2',1,0.21,'Oxidant'};
                    app.uitable_P.Data = app.data_R(:,1);
                elseif app.Reactants.Value == '2'
                    app.data_R = {'N2',0.78090/0.20950,0.78090,'Inert';'O2',1,0.20950,'Oxidant';'Ar',0.00960/0.20950,0.00960,'Inert'};
                    app.uitable_P.Data = app.data_R(:,1);
                else
                    app.uitable_P.Data = app.S.NameSpecies(:);
                end
                app.edit_phi.Value = '-';
            else
                if app.selected_Reactive == 3 || ~mod(app.selected_Reactive,2)
                    if app.selected_Reactive ~= 4
                        app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2';'N2'};
                    else
                        app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2';'N2';'Ar'};
                    end
                elseif mod(app.selected_Reactive,2)
                    app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2'};
                else
                    app.uitable_P.Data = app.S.NameSpecies(:);
                end
                switch app.selected_Reactive
                    case 3
                        app.data_R = {'N2';'O2';'CH4'};
                        app.data_R(:,2) = {2*(79/21);2;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case 4
                        app.data_R = {'N2';'O2';'Ar';'CH4'};
                        app.data_R(:,2) = {2*(78.09/20.95);2;2*(0.96/20.95);1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Inert';'Fuel'};
                    case 5
                        app.data_R = {'O2';'CH4'};
                        app.data_R(:,2) = {2;1};
                        app.data_R(:,4) = {'Oxidant';'Fuel'};
                    case {6,7}
                        app.data_R = {'N2';'O2';'C2H6'};
                        app.data_R(:,2) = {3.5*(79/21);3.5;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {8,9}
                        app.data_R = {'N2';'O2';'C3H8'};
                        app.data_R(:,2) = {5*(79/21);5;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {10,11}
                        app.data_R = {'N2';'O2';'C2H2_acetylene'};
                        app.data_R(:,2) = {2.5*(79/21);2.5;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {12,13}
                        app.data_R = {'N2';'O2';'C2H4'};
                        app.data_R(:,2) = {3*(79/21);3;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {14,15}
                        app.data_R = {'N2';'O2';'C6H6'};
                        app.data_R(:,2) = {7.5*(79/21);7.5;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {16,17}
                        app.data_R = {'N2';'O2';'C8H18_isooctane'};
                        app.data_R(:,2) = {12.5*(79/21);12.5;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {18,19}
                        app.data_R = {'N2';'O2';'H2'};
                        app.data_R(:,2) = {0.5*(79/21);0.5;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {20,21}
                        app.data_R = {'N2';'O2';'CO'};
                        app.data_R(:,2) = {0.5*(79/21);0.5;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {22,23}
                        app.data_R = {'N2';'O2';'CH3OH'};
                        app.data_R(:,2) = {1.5*(79/21);1.5;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {24,25}
                        app.data_R = {'N2';'O2';'C2H5OH'};
                        app.data_R(:,2) = {3*(79/21);3;1};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel'};
                    case {26,27}
                        app.data_R = {'N2';'O2';'CH4';'C2H6';'C3H8'};
                        app.data_R(:,2) = {2.3*(79/21);2.3;0.85;0.1;0.05};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel';'Fuel';'Fuel'};
                    case {28,29}
                        app.data_R = {'N2';'O2';'CO';'H2'};
                        app.data_R(:,2) = {0.5*(79/21);0.5;0.5;0.5};
                        app.data_R(:,4) = {'Inert';'Oxidant';'Fuel';'Fuel'};
                end
                if app.selected_Reactive>5 && mod(app.selected_Reactive,2)
                    app.data_R = app.data_R(2:end,:);
                end
            end
            app.Misc.FLAG_FIRST = true;
            app.species = app.data_R(:,1);
            NSpecies_R = numel(app.species);
            Species = string(NSpecies_R);
            for i=NSpecies_R:-1:1
                [Species(i), Ni(i)] = app.data_R{i,1:2};
            end
            N = sum(Ni);
            Xi = Ni/N;
            for i=1:NSpecies_R
                app.data_R{i,3} = Xi(i);
            end
            app.uitable_R.Data = app.data_R;
            app.uitable_R2.Data = app.data_R(:, 1:3);
            app.flag = false;
            %%%%%
            if any(strcmp(app.Reactants.Value,{'1','2'}))
                app.Reaction.Items = {'Complete + W-G','Minor products','Dissociated air'};
                app.Reaction.ItemsData = {'1','2','3'};
            else
                app.Reaction.Items = {'Complete + W-G','Minor products','HC/O2/N2','HC/O2/N2 extended','HC/O2/N2 rich'};
                app.Reaction.ItemsData = {'1','2','3','4','5'};
            end
            %% Update listbox minors species
            update_listbox_minor_products(app);
            %% Assign temperature for reactants
            [TR, ~] = get_prop(app, app.PR1.Value, app.PR1_var_name);
            app.uitable_R.Data(:,5) = {TR};
            %%%%%
            app.edit_phi2.Value = app.edit_phi.Value;
        end

        % Callback function: Clear, ClearMenu, ClearMenu_2
        function ClearButtonPushed(app, event)
            delete(app.Node_Results.Children);
            clear app.PS.strR app.PS.strP app.PD.phi.value
        end

        % Image clicked function: Logo
        function LogoImageClicked(app, event)
            web('https://www.uc3m.es/Inicio','-browser');
        end

        % Menu selected function: ClearCommandWindowMenu
        function ClearCommandWindowMenuSelected(app, event)
            app.Console.Value = '';
        end

        % Value changed function: Console_2
        function Console_2ValueChanged(app, event)
            if strcmp('clear', app.Console_2.Value{1,1})
                ClearButtonPushed(app, event);
                output = ' ';
            else
                try
                    output = evalc(app.Console_2.Value{1,1});
                catch error
                    output = error.message;
                end
            end
            if ~isempty(output) || strcmp('clc', app.Console_2.Value{1,1})
                app.Console.Value = output;
            end
            app.Console_2.Value = '';
        end

        % Menu selected function: MaximazeMenu
        function MaximazeMenuSelected(app, event)
            app.Console.Position = [660,35,413,462];
%             app.UIAxes.Position = [607,504,605,529];
        end

        % Close request function: figure_main
        function figure_mainCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: MinimizeMenu
        function MinimizeMenuSelected(app, event)
            app.Console.Position = [660,35,413,62];
%             app.UIAxes.Position = [607,104,605,529];
        end

        % Value changed function: edit_phi
        function edit_phiValueChanged(app, event)
            phi = app.edit_phi.Value; 
            app.edit_phi2.Value = phi;
            if any(strcmpi(app.Reactants.Value,{'1','2'})) && ~app.flag
                message = sprintf('There is no fuel! That will not have any effect. \n Consider change reactants.');
                uialert(app.figure_main,message,'Warning','Icon','warning');
            elseif phi(1) ~= '[' && ~app.flag
                app.PD.phi.value = sscanf(phi,'%f');
                switch app.selected_Reactive
                    case 3
                        app.data_R = {2*(79/21)/app.PD.phi.value;2/app.PD.phi.value;1};
                    case 4
                        app.data_R = {2*(78.09/20.95)/app.PD.phi.value;2/app.PD.phi.value;2*(0.96/20.95)/app.PD.phi.value;1};
                    case 5
                        app.data_R = {2/app.PD.phi.value;1};
                    case {6,7}
                        app.data_R = {3.5*(79/21)/app.PD.phi.value;3.5/app.PD.phi.value;1};
                    case {8,9}
                        app.data_R = {5*(79/21)/app.PD.phi.value;5/app.PD.phi.value;1};
                    case {10,11}
                        app.data_R = {2.5*(79/21)/app.PD.phi.value;2.5/app.PD.phi.value;1};
                    case {12,13}
                        app.data_R = {3*(79/21)/app.PD.phi.value;3/app.PD.phi.value;1};
                    case {14,15}
                        app.data_R = {7.5*(79/21)/app.PD.phi.value;7.5/app.PD.phi.value;1};
                    case {16,17}
                        app.data_R = {12.5*(79/21)/app.PD.phi.value;12.5/app.PD.phi.value;1};
                    case {18,19}
                        app.data_R = {0.5*(79/21)/app.PD.phi.value;0.5/app.PD.phi.value;1};
                    case {20,21}
                        app.data_R = {0.5*(79/21)/app.PD.phi.value;0.5/app.PD.phi.value;1};
                    case {22,23}
                        app.data_R = {1.5*(79/21)/app.PD.phi.value;1.5/app.PD.phi.value;1};
                    case {24,25}
                        app.data_R = {3*(79/21)/app.PD.phi.value;3/app.PD.phi.value;1};
                    case {26,27}
                        app.data_R = {2.3*(79/21)/app.PD.phi.value;2.3/app.PD.phi.value;0.85;0.1;0.05};
                    case {28,29}
                        app.data_R = {(79/21)/app.PD.phi.value;1/app.PD.phi.value;0.5;0.5};
                end
                if app.flagNew
                    for i=app.flagNewCounter:-1:1
                        app.data_R = [app.data_R(:);app.uitable_R.Data{length(app.data_R)+1,2}];
                    end
                end
                if app.selected_Reactive>5 && mod(app.selected_Reactive,2)
                    app.data_R = app.data_R(2:end,:);
                end
                NSpecies_R = numel(app.species);
                Species = string(NSpecies_R);
                for i=NSpecies_R:-1:1
                    Species(i) = app.uitable_R.Data{i,1};
                    Ni(i) = app.data_R{i};
                end
                N = sum(Ni);
                Xi = Ni/N;
                for i=1:NSpecies_R
                    app.data_R{i,2} = Xi(i);
                end
                app.uitable_R.Data(:, 2:3) = app.data_R;
                app.uitable_R2.Data(:, 2:3) = app.data_R;
            end
        end

        % Menu selected function: RownumberedMenu
        function RownumberedMenuSelected(app, event)
            if strcmpi(app.uitable_R2.RowName, 'numbered')
                app.uitable_R2.RowName = '';
                app.uitable_P.RowName = '';
            else
                app.uitable_R2.RowName = 'numbered';
                app.uitable_P.RowName = 'numbered';
            end
        end

        % Size changed function: figure_main
        function figure_mainSizeChanged(app, event)
            tol = 1;
            position = app.figure_main.Position;
            deltaw = position(3) - app.w0;
            deltah = position(4) - app.h0;
            app.TabGroup2.Position = [1,1,593,position(4)];
            cond3 = app.UIAxes.Position(1) + app.UIAxes.Position(3) + deltaw >= app.minw0;
            cond4 = app.UIAxes.Position(2) + app.UIAxes.Position(4) + deltah >= app.minh0;
            if (abs(deltaw) > tol || abs(deltah) > tol) && cond3 && cond4
                app.UIAxes.Position(3) = app.UIAxes.Position(3) + deltaw;
                app.UIAxes.Position(4) = app.UIAxes.Position(4) + deltah;
                app.Logo.Position(1) = app.Logo.Position(1) + deltaw;
                app.Console.Position(3) = app.Console.Position(3) + deltaw;
                app.Console_2.Position(3) = app.Console_2.Position(3) + deltaw;
            end
            app.w0 = position(3);
            app.h0 = position(4);
        end

        % Value changed function: Reaction
        function ReactionValueChanged(app, event)
            app.selected_Reaction = sscanf(app.Reaction.Value,'%d');
            if any(strcmp(app.Reactants.Value,{'1','2'}))
                switch app.Reaction.Value
                    case '1'
                        if app.Reactants.Value == '1'
                            app.uitable_P.Data = {'N2';'O2'};
                        else
                            app.uitable_P.Data = {'N2';'O2';'Ar'};
                        end
                    case '2'
                        app.uitable_P.Data = app.listbox_minor_products.Items';
                    case '3'
                        if app.Reactants.Value == '1'
                            app.uitable_P.Data = {'N2';'O2';'NO';'O';'N';'N2O';'NO2'};
                        else
                            app.uitable_P.Data = {'N2';'O2';'NO';'O';'N';'N2O';'NO2';'Ar'};
                        end
                end
            else
                switch app.Reaction.Value
                    case '1'
                        if any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                            app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2';'N2'};
                        elseif app.Reactants.Value == '4'
                            app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2';'N2';'Ar'};
                        elseif any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                            app.uitable_P.Data = {'CO2';'H2O';'CO';'H2';'O2'};
                        end
                    case '2'
                        app.uitable_P.Data = app.listbox_minor_products.Items';
                    case '3'
                        if any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                            app.uitable_P.Data = {'N2';'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'NO'};
                        elseif app.Reactants.Value == '4'
                            app.uitable_P.Data = {'N2';'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'NO';'Ar'};
                        elseif any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                            app.uitable_P.Data = {'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2'};
                        end
                    case '4'
                        if any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                            app.uitable_P.Data = {'N2';'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'HO2';'NO';'HCO';'CH4';'CH3';'HO2';'NO2';'NH3';'NH2';'N';'HCN';'CN';'N2O';'C2';'CH'};
                        elseif app.Reactants.Value == '4'
                            app.uitable_P.Data = {'N2';'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'HO2';'NO';'HCO';'CH4';'CH3';'HO2';'NO2';'NH3';'NH2';'N';'HCN';'CN';'N2O';'C2';'CH';'Ar'};
                        elseif any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                            app.uitable_P.Data = {'H2O';'CO2';'CO';'O2';'OH';'H';'O';'H2';'HO2';'HCO';'CH4';'CH3';'HO2';'C2';'CH'};
                        end
                    case '5'
                        if any(strcmp(app.Reactants.Value,{'3','6','8','10','12','14','16','18','20','22','24','26','28'}))
                            app.uitable_P.Data = {'N2';'H2O';'CO';'HCN';'H2O';'CO2';'H';'OH';'O';'CN';'NH3';'CH4';'C2H4';'CH3';'NO';'HCO';'NH2';'NH';'N';'CH'};
                        elseif app.Reactants.Value == '4'
                            app.uitable_P.Data = {'N2';'H2O';'CO';'HCN';'H2O';'CO2';'H';'OH';'O';'CN';'NH3';'CH4';'C2H4';'CH3';'NO';'HCO';'NH2';'NH';'N';'CH';'Ar'};
                        elseif any(strcmp(app.Reactants.Value,{'5','7','9','11','13','15','17','19','21','23','25','27','29'}))
                            app.uitable_P.Data = {'N2';'H2O';'CO';'H2O';'CO2';'H';'OH';'O';'CH4';'C2H4';'CH3';'HCO';'CH'};
                        end
                end
            end
            if app.Reaction.Value ~= '1'
                app = App(app, app.uitable_P.Data');
                app.listbox_minor_products.Items = app.M.minors_products';
                app.Reaction.Items{2} = ['Minor products (',sprintf('%d',app.M.Lminors),')'];
                app.text_minor_products.Text = ['Minor products (',sprintf('%d',app.M.Lminors),')'];
            end
        end

        % Menu selected function: SnapshotMenu
        function SnapshotMenuSelected(app, event)
            filter = {'*.pdf';'*.jpg';'*.png';'*.tif'};
            [filename, filepath] = uiputfile(filter);
            if ischar(filename)
                exportapp(app.figure_main, [filepath filename]);
            end
        end

        % Menu selected function: Excel
        function ExcelMenuSelected(app, event)
            results = app.Tree.Children;
            sumcases = 0;
            if ~isempty(results.Children)
                child1 = results.Children;
                maxi = numel(child1);
                [file,name,path] = uiputfile('*.xls','File Selection','output.xls');
                if isequal(file,0) || isequal(path,0)
                    disp('User clicked Cancel.')
                else
                    disp(['User selected ',fullfile(path,file),...
                        ' and then clicked Save.'])
                    for i=maxi:-1:1
                        child2 = child1(i).Children;
                        for j=numel(child2):-1:1
                            child3 = child2(j).Children;
                            for k=numel(child3):-1:1
                                child4 = child3(k).Children;
                                NameSpecies = child4(1).NodeData.NameSpecies;
                                for l=numel(child4):-1:1
                                    strR{l} = child4(l).NodeData.strR;
                                    strP{l} = child4(l).NodeData.strP;
                                    phi(l) = child4(l).NodeData.phi;
                                end
                                sumcases = sumcases + 1;
                                ExcelOutputMatrix_R{sumcases} = FormattedOutput_test([],phi,strR,NameSpecies);
                                ExcelOutputMatrix_P{sumcases} = FormattedOutput_test([],phi,strP,NameSpecies);
                                xlswrite(strcat(name,file),ExcelOutputMatrix_R{sumcases},strcat('R-',child1(i).Text,'-',sprintf('%d',j),'_',sprintf('%d',k)));
                                xlswrite(strcat(name,file),ExcelOutputMatrix_P{sumcases},strcat('P-',child1(i).Text,'-',sprintf('%d',j),'_',sprintf('%d',k)));
                            end
                        end
                    end
                end
            end
        end

        % Menu selected function: DefaultMenu_2
        function DefaultMenu_2Selected(app, event)
            app.figure_main.Position(3:4) = [app.w0; app.h0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_main = uifigure('Visible', 'off');
            app.figure_main.Name = 'Combustion Toolbox';
            app.figure_main.Icon = 'lab.png';
            app.figure_main.CloseRequestFcn = createCallbackFcn(app, @figure_mainCloseRequest, true);
            app.figure_main.SizeChangedFcn = createCallbackFcn(app, @figure_mainSizeChanged, true);
            app.FileMenu = uimenu(app.figure_main);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Enable = 'off';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.Excel = uimenu(app.SaveMenu);
            app.Excel.MenuSelectedFcn = createCallbackFcn(app, @ExcelMenuSelected, true);
            app.Excel.Text = 'Excel';
            app.SnapshotMenu = uimenu(app.FileMenu);
            app.SnapshotMenu.MenuSelectedFcn = createCallbackFcn(app, @SnapshotMenuSelected, true);
            app.SnapshotMenu.Text = 'Snapshot';
            app.viewmenu = uimenu(app.figure_main);
            app.viewmenu.Text = 'View';
            app.view_density = uimenu(app.viewmenu);
            app.view_density.Text = 'Display products species';
            app.DefaultMenu = uimenu(app.view_density);
            app.DefaultMenu.Checked = 'on';
            app.DefaultMenu.Text = 'Default';
            app.MajorMenu = uimenu(app.view_density);
            app.MajorMenu.Text = 'Major';
            app.DefaultMenu_2 = uimenu(app.viewmenu);
            app.DefaultMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DefaultMenu_2Selected, true);
            app.DefaultMenu_2.Text = 'Default';
            app.ExamplesMenu = uimenu(app.figure_main);
            app.ExamplesMenu.Enable = 'off';
            app.ExamplesMenu.Text = 'Examples';
            app.helpmenu = uimenu(app.figure_main);
            app.helpmenu.Text = 'Help';
            app.tutorial = uimenu(app.helpmenu);
            app.tutorial.Text = 'Getting started';
            app.SummaryMenu = uimenu(app.helpmenu);
            app.SummaryMenu.Text = 'Summary';
            app.contents = uimenu(app.helpmenu);
            app.contents.Text = 'Contents';
            app.SendfeedbackMenu = uimenu(app.helpmenu);
            app.SendfeedbackMenu.Separator = 'on';
            app.SendfeedbackMenu.Text = 'Send feedback';
            app.about = uimenu(app.helpmenu);
            app.about.Text = 'About...';
            app.Logo = uiimage(app.figure_main);
            app.Logo.ImageClickedFcn = createCallbackFcn(app, @LogoImageClicked, true);
            app.Logo.ImageSource = 'logo_uc3m.png';
            app.Console = uitextarea(app.figure_main);
            app.Console.Editable = 'off';
            app.Console.Value = {'Welcome to Combustion Toolbox 1.0 --- A MATLAB-GUI based open-source tool for solving combustion problems.'};
            app.Console_2 = uitextarea(app.figure_main);
            app.Console_2.ValueChangedFcn = createCallbackFcn(app, @Console_2ValueChanged, true);
            app.Label_Console = uilabel(app.figure_main);
            app.Label_Console.Text = '>>';
            app.TabGroup2 = uitabgroup(app.figure_main);
            app.ProblemsetupTab = uitab(app.TabGroup2);
            app.ProblemsetupTab.Title = 'Problem setup    ';
            app.TabGroup4 = uitabgroup(app.ProblemsetupTab);
            app.InputsTab = uitab(app.TabGroup4);
            app.InputsTab.Title = 'Inputs';
            app.uipanel11 = uipanel(app.InputsTab);
            app.uipanel11.Title = 'Define Reactants composition';
            app.GridLayout8 = uigridlayout(app.uipanel11);
            app.uitable_R = uitable(app.GridLayout8);
            app.uitable_R.ColumnName = {'Species'; 'Nº Moles'; 'Mole Fraction'; 'Type'; 'Temperature [K]'};
            app.uitable_R.ColumnEditable = [true true true false true];
            app.edit_phi = uieditfield(app.GridLayout8, 'text');
            app.edit_phi.ValueChangedFcn = createCallbackFcn(app, @edit_phiValueChanged, true);
            app.edit_phi.Value = '-';
            app.text_phi = uilabel(app.GridLayout8);
            app.text_phi.Text = 'Phi';
            app.ReactionPanel = uipanel(app.GridLayout8);
            app.ReactionPanel.Title = 'Reaction';
            app.GridLayout9 = uigridlayout(app.ReactionPanel);
            app.Reaction = uidropdown(app.GridLayout9);
            app.Reaction.Items = {'Complete + W-G', 'Minor products', 'Dissociated air'};
            app.Reaction.ItemsData = {'1', '2', '3'};
            app.Reaction.ValueChangedFcn = createCallbackFcn(app, @ReactionValueChanged, true);
            app.Reaction.Value = '1';
            app.NewreactantPanel = uipanel(app.GridLayout8);
            app.NewreactantPanel.Title = 'New reactant';
            app.GridLayout10 = uigridlayout(app.NewreactantPanel);
            app.edit_seeker = uieditfield(app.GridLayout10, 'text');
            app.Reactants = uidropdown(app.GridLayout8);
            app.Reactants.Items = {'Air (21% O2, 79% N2)', 'Air (20.95% O2, 78.09% N2, 0.96% Ar)', 'Methane + Air (21% O2, 79% N2)', 'Methane + Air (20.95% O2, 78.09% N2, 0.96% Ar)', 'Methane + O2', 'Ethane + Air (21% O2, 79% N2)', 'Ethane + O2', 'Propane + Air (21% O2, 79% N2)', 'Propane + O2', 'Acetylene + Air (21% O2, 79% N2)', 'Acetylene + O2', 'Ethylene + Air (21% O2, 79% N2)', 'Ethylene + O2', 'Bencene + Air (21% O2, 79% N2)', 'Bencene + O2', 'Iso-octane + Air (21% O2, 79% N2)', 'Iso-octane + O2', 'Hydrogen + Air (21% O2, 79% N2)', 'Hydrogen + O2', 'Carbon monoxide + Air (21% O2, 79% N2)', 'Carbon monoxide + O2', 'Methanol + Air (21% O2, 79% N2)', 'Methanol + O2', 'Ethanol + Air (21% O2, 79% N2)', 'Ethanol + O2', 'Natural Gas + Air (21% O2, 79% N2)', 'Natural Gas + O2', 'Syngas + Air (21% O2, 79% N2)', 'Syngas + O2'};
            app.Reactants.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29'};
            app.Reactants.ValueChangedFcn = createCallbackFcn(app, @ReactantsValueChanged, true);
            app.Reactants.Value = '1';
            app.FuelEditFieldLabel = uilabel(app.GridLayout8);
            app.FuelEditFieldLabel.Text = '% Fuel';
            app.FuelEditField = uieditfield(app.GridLayout8, 'numeric');
            app.OFEditFieldLabel = uilabel(app.GridLayout8);
            app.OFEditFieldLabel.Text = 'O/F';
            app.OFEditField = uieditfield(app.GridLayout8, 'numeric');
            app.uipanel7 = uipanel(app.InputsTab);
            app.uipanel7.Title = 'Select Problem type and Solver';
            app.GridLayout11 = uigridlayout(app.uipanel7);
            app.Solver = uidropdown(app.GridLayout11);
            app.Solver.Items = {'Segregated', 'Gibbs', 'Gibbs Soot', 'Gibbs Reduced'};
            app.Solver.Value = 'Gibbs';
            app.ProblemType = uidropdown(app.GridLayout11);
            app.ProblemType.Items = {'TP:  Equilibrium composition at defined T and P', 'HP: Adiabatic T and composition at constant P', 'SP:  Isentropic compression/expansion to a specified P', 'TV: Equilibrium composition at defined T and constant V', 'EV: Adiabatic T and composition at constant V', 'SV: Isentropic compresion/expansion to a specified V', 'SHOCK_I: Planar incident shock wave', 'SHOCK_R: Planar reflected shock wave', 'DET: Champan-Jouget Detonation (CJ upper state)', 'DET_OVERDRIVEN: Overdriven detonation'};
            app.ProblemType.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ProblemType.ValueChangedFcn = createCallbackFcn(app, @ProblemTypeValueChanged, true);
            app.ProblemType.Value = '1';
            app.uipanel_State = uipanel(app.InputsTab);
            app.uipanel_State.Title = 'Define the state of reactants and products';
            app.uipanel8_3 = uipanel(app.uipanel_State);
            app.GridLayout5_3 = uigridlayout(app.uipanel8_3);
            app.text_RP2 = uilabel(app.GridLayout5_3);
            app.text_RP2.Text = 'Pressure [bar]';
            app.PR2 = uieditfield(app.GridLayout5_3, 'text');
            app.PR2.Value = '1';
            app.text_Products_3 = uilabel(app.GridLayout5_3);
            app.text_Products_3.Text = 'Products';
            app.text_Reactans_3 = uilabel(app.GridLayout5_3);
            app.text_Reactans_3.Text = 'Reactants';
            app.text_RP1 = uilabel(app.GridLayout5_3);
            app.text_RP1.Text = 'Temperature [K]';
            app.PR1 = uieditfield(app.GridLayout5_3, 'text');
            app.PR1.Value = '300';
            app.PP1 = uieditfield(app.GridLayout5_3, 'text');
            app.PP1.Value = '2500';
            app.PP2 = uieditfield(app.GridLayout5_3, 'text');
            app.PP2.Value = '1';
            app.Clear = uibutton(app.InputsTab, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.Calculate = uibutton(app.InputsTab, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Calculate.Text = 'Calculate';
            app.uipanel_additional_constraints = uipanel(app.InputsTab);
            app.uipanel_additional_constraints.Title = 'Additional constraints';
            app.uipanel_additional_constraints.Visible = 'off';
            app.uipanel8_4 = uipanel(app.uipanel_additional_constraints);
            app.GridLayout5_4 = uigridlayout(app.uipanel8_4);
            app.text_RP4 = uilabel(app.GridLayout5_4);
            app.text_RP4.Visible = 'off';
            app.text_RP4.Text = 'Pressure [bar]';
            app.PR4 = uieditfield(app.GridLayout5_4, 'text');
            app.PR4.Visible = 'off';
            app.PR4.Value = '1';
            app.text_Products_5 = uilabel(app.GridLayout5_4);
            app.text_Products_5.Visible = 'off';
            app.text_Products_5.Text = 'Products';
            app.text_Reactans_5 = uilabel(app.GridLayout5_4);
            app.text_Reactans_5.Visible = 'off';
            app.text_Reactans_5.Text = 'Reactants';
            app.text_RP3 = uilabel(app.GridLayout5_4);
            app.text_RP3.Text = 'Constant Enthalpy, i.e., hP = hR';
            app.PR3 = uieditfield(app.GridLayout5_4, 'text');
            app.PR3.Visible = 'off';
            app.PR3.Value = '500';
            app.PP3 = uieditfield(app.GridLayout5_4, 'text');
            app.PP3.Visible = 'off';
            app.PP3.Value = '2500';
            app.PP4 = uieditfield(app.GridLayout5_4, 'text');
            app.PP4.Visible = 'off';
            app.PP4.Value = '1';
            app.text_ReactantsProducts = uilabel(app.GridLayout5_4);
            app.text_ReactantsProducts.Text = 'Products';
            app.ExtendedsettingsTab = uitab(app.TabGroup4);
            app.ExtendedsettingsTab.Title = 'Extended settings';
            app.TabGroup5 = uitabgroup(app.ExtendedsettingsTab);
            app.SelectionofminorsspeciesTab = uitab(app.TabGroup5);
            app.SelectionofminorsspeciesTab.Title = 'Selection of minors species';
            app.uipanel22 = uipanel(app.SelectionofminorsspeciesTab);
            app.GridLayout13 = uigridlayout(app.uipanel22);
            app.text_list_species = uilabel(app.GridLayout13);
            app.text_list_species.Text = 'Search Species';
            app.pushbutton2 = uibutton(app.GridLayout13, 'push');
            app.pushbutton2.Text = 'Remove <<';
            app.text_minor_products = uilabel(app.GridLayout13);
            app.text_minor_products.Text = 'Minors species';
            app.text_list_species_2 = uilabel(app.GridLayout13);
            app.text_list_species_2.Text = 'List of species';
            app.pushbutton1 = uibutton(app.GridLayout13, 'push');
            app.pushbutton1.Text = 'Add >>';
            app.listbox_minor_products = uilistbox(app.GridLayout13);
            app.listbox_minor_products.Items = {};
            app.listbox_minor_products.Multiselect = 'on';
            app.listbox_minor_products.Value = {};
            app.listbox_species = uilistbox(app.GridLayout13);
            app.listbox_species.Items = {};
            app.listbox_species.Multiselect = 'on';
            app.listbox_species.Value = {};
            app.edit_seeker_2 = uieditfield(app.GridLayout13, 'text');
            app.PlottedspeciesTab = uitab(app.TabGroup5);
            app.PlottedspeciesTab.Title = 'Plotted species';
            app.uipanel22_2 = uipanel(app.PlottedspeciesTab);
            app.GridLayout13_2 = uigridlayout(app.uipanel22_2);
            app.text_list_species_3 = uilabel(app.GridLayout13_2);
            app.text_list_species_3.Text = 'Search Species';
            app.pushbutton2_2 = uibutton(app.GridLayout13_2, 'push');
            app.pushbutton2_2.Enable = 'off';
            app.pushbutton2_2.Text = 'Remove <<';
            app.text_minor_products_2 = uilabel(app.GridLayout13_2);
            app.text_minor_products_2.Text = 'Plotted species';
            app.text_list_species_4 = uilabel(app.GridLayout13_2);
            app.text_list_species_4.Text = 'List of species';
            app.pushbutton1_2 = uibutton(app.GridLayout13_2, 'push');
            app.pushbutton1_2.Enable = 'off';
            app.pushbutton1_2.Text = 'Add >>';
            app.listbox_plotted_species = uilistbox(app.GridLayout13_2);
            app.listbox_plotted_species.Items = {};
            app.listbox_plotted_species.Multiselect = 'on';
            app.listbox_plotted_species.Value = {};
            app.listbox_species_2 = uilistbox(app.GridLayout13_2);
            app.listbox_species_2.Items = {};
            app.listbox_species_2.Multiselect = 'on';
            app.listbox_species_2.Value = {};
            app.edit_seeker_3 = uieditfield(app.GridLayout13_2, 'text');
            app.uipanel23 = uipanel(app.ExtendedsettingsTab);
            app.uipanel23.Title = 'Tolerances';
            app.TraceoptionEditFieldLabel = uilabel(app.uipanel23);
            app.TraceoptionEditFieldLabel.Text = 'Trace option';
            app.TraceoptionEditField = uieditfield(app.uipanel23, 'numeric');
            app.TraceoptionEditField.Limits = [0 Inf];
            app.TraceoptionEditField.ValueDisplayFormat = '%.1e';
            app.TraceoptionEditField.Value = 1e-14;
            app.DisplaySpeciesEditFieldLabel = uilabel(app.uipanel23);
            app.DisplaySpeciesEditFieldLabel.Text = 'Display Species';
            app.DisplaySpeciesEditField = uieditfield(app.uipanel23, 'numeric');
            app.DisplaySpeciesEditField.Value = 1e-07;
            app.SootfactorEditFieldLabel = uilabel(app.uipanel23);
            app.SootfactorEditFieldLabel.Text = 'Soot factor';
            app.SootfactorEditField = uieditfield(app.uipanel23, 'numeric');
            app.SootfactorEditField.Value = 1;
            app.EpsilonSootEditFieldLabel = uilabel(app.uipanel23);
            app.EpsilonSootEditFieldLabel.Text = 'Epsilon Soot';
            app.EpsilonSootEditField = uieditfield(app.uipanel23, 'numeric');
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.Tree = uitree(app.ResultsTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.Node_Results = uitreenode(app.Tree);
            app.Node_Results.Text = 'Results';
            app.TabGroup3 = uitabgroup(app.ResultsTab);
            app.ParametersTab = uitab(app.TabGroup3);
            app.ParametersTab.Title = 'Parameters';
            app.Panel = uipanel(app.ParametersTab);
            app.uipanel14 = uipanel(app.Panel);
            app.GridLayout6 = uigridlayout(app.uipanel14);
            app.text_q = uilabel(app.GridLayout6);
            app.text_q.Text = 'hP - hR [kJ/kg]';
            app.edit_q = uieditfield(app.GridLayout6, 'numeric');
            app.edit_q.ValueDisplayFormat = '%.4g';
            app.edit_q.Editable = 'off';
            app.edit_vP_vR = uieditfield(app.GridLayout6, 'numeric');
            app.edit_vP_vR.Limits = [0 Inf];
            app.edit_vP_vR.ValueDisplayFormat = '%.4g';
            app.edit_vP_vR.Enable = 'off';
            app.edit_vP_vR.Visible = 'off';
            app.EpsilonmolesLabel = uilabel(app.GridLayout6);
            app.EpsilonmolesLabel.Visible = 'off';
            app.EpsilonmolesLabel.Text = 'Epsilon (moles)';
            app.edit_error_moles = uieditfield(app.GridLayout6, 'numeric');
            app.edit_error_moles.ValueDisplayFormat = '%.4g';
            app.edit_error_moles.Editable = 'off';
            app.edit_error_moles.Visible = 'off';
            app.EpsilonHLabel = uilabel(app.GridLayout6);
            app.EpsilonHLabel.Visible = 'off';
            app.EpsilonHLabel.Text = 'Epsilon (H)';
            app.edit_error_problem = uieditfield(app.GridLayout6, 'numeric');
            app.edit_error_problem.ValueDisplayFormat = '%.4g';
            app.edit_error_problem.Editable = 'off';
            app.edit_error_problem.Visible = 'off';
            app.text_vP_vR = uilabel(app.GridLayout6);
            app.text_vP_vR.Visible = 'off';
            app.text_vP_vR.Text = 'Volume Products/Reactants';
            app.uipanel8_2 = uipanel(app.Panel);
            app.GridLayout5_2 = uigridlayout(app.uipanel8_2);
            app.text_s = uilabel(app.GridLayout5_2);
            app.text_s.Text = 'Entropy [kJ/(kg K)]';
            app.text_sR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_sR.ValueDisplayFormat = '%.4g';
            app.text_sR.Editable = 'off';
            app.text_sP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_sP.ValueDisplayFormat = '%.4g';
            app.text_sP.Editable = 'off';
            app.text_gamma = uilabel(app.GridLayout5_2);
            app.text_gamma.Text = 'gamma = cp/cv [-]';
            app.text_gammaR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_gammaR.ValueDisplayFormat = '%.4g';
            app.text_gammaR.Editable = 'off';
            app.text_gammaP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_gammaP.ValueDisplayFormat = '%.4g';
            app.text_gammaP.Editable = 'off';
            app.text_M = uilabel(app.GridLayout5_2);
            app.text_M.Visible = 'off';
            app.text_M.Text = 'Mach number [-]';
            app.text_MP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_MP.ValueDisplayFormat = '%.4g';
            app.text_MP.Editable = 'off';
            app.text_MP.Visible = 'off';
            app.text_u = uilabel(app.GridLayout5_2);
            app.text_u.Visible = 'off';
            app.text_u.Text = 'Shock velocity [m/s]';
            app.text_uP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_uP.ValueDisplayFormat = '%.4g';
            app.text_uP.Editable = 'off';
            app.text_uP.Visible = 'off';
            app.text_e = uilabel(app.GridLayout5_2);
            app.text_e.Text = 'Internal energy [kJ/kg]';
            app.text_eR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_eR.ValueDisplayFormat = '%.4g';
            app.text_eR.Editable = 'off';
            app.text_eP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_eP.ValueDisplayFormat = '%.4g';
            app.text_eP.Editable = 'off';
            app.text_sound = uilabel(app.GridLayout5_2);
            app.text_sound.Text = 'Sound speed [m/s]';
            app.text_soundR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_soundR.ValueDisplayFormat = '%.4g';
            app.text_soundR.Editable = 'off';
            app.text_soundP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_soundP.ValueDisplayFormat = '%.4g';
            app.text_soundP.Editable = 'off';
            app.text_W = uilabel(app.GridLayout5_2);
            app.text_W.Text = 'Mean Molecular Weight [g/mol]';
            app.text_WR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_WR.ValueDisplayFormat = '%.4g';
            app.text_WR.Editable = 'off';
            app.text_WP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_WP.ValueDisplayFormat = '%.4g';
            app.text_WP.Editable = 'off';
            app.text_cp = uilabel(app.GridLayout5_2);
            app.text_cp.Text = 'cp [kJ/(kg K)]';
            app.text_cpR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_cpR.ValueDisplayFormat = '%.4g';
            app.text_cpR.Editable = 'off';
            app.text_cpP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_cpP.ValueDisplayFormat = '%.4g';
            app.text_cpP.Editable = 'off';
            app.text_h = uilabel(app.GridLayout5_2);
            app.text_h.Text = 'Enthalpy [kJ/kg]';
            app.text_hR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_hR.ValueDisplayFormat = '%.4g';
            app.text_hR.Editable = 'off';
            app.text_hP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_hP.ValueDisplayFormat = '%.4g';
            app.text_hP.Editable = 'off';
            app.text_r = uilabel(app.GridLayout5_2);
            app.text_r.Text = 'Density [kg/m3]';
            app.text_rR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_rR.ValueDisplayFormat = '%.4g';
            app.text_rR.Editable = 'off';
            app.text_rP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_rP.ValueDisplayFormat = '%.4g';
            app.text_rP.Editable = 'off';
            app.text_p = uilabel(app.GridLayout5_2);
            app.text_p.Text = 'Pressure [bar]';
            app.text_pP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_pP.Limits = [0 Inf];
            app.text_pP.ValueDisplayFormat = '%.4g';
            app.text_pP.Editable = 'off';
            app.text_Products_2 = uilabel(app.GridLayout5_2);
            app.text_Products_2.Text = 'Products';
            app.text_Reactans_2 = uilabel(app.GridLayout5_2);
            app.text_Reactans_2.Text = 'Reactants';
            app.text_T = uilabel(app.GridLayout5_2);
            app.text_T.Text = 'Temperature [K]';
            app.text_TR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_TR.ValueDisplayFormat = '%.4g';
            app.text_TR.Editable = 'off';
            app.text_pR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_pR.ValueDisplayFormat = '%.4g';
            app.text_pR.Editable = 'off';
            app.text_TP = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_TP.ValueDisplayFormat = '%.4g';
            app.text_TP.Editable = 'off';
            app.text_uR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_uR.ValueDisplayFormat = '%.4g';
            app.text_uR.Editable = 'off';
            app.text_uR.Visible = 'off';
            app.text_MR = uieditfield(app.GridLayout5_2, 'numeric');
            app.text_MR.ValueDisplayFormat = '%.4g';
            app.text_MR.Editable = 'off';
            app.text_MR.Visible = 'off';
            app.MixturecompositionTab = uitab(app.TabGroup3);
            app.MixturecompositionTab.Title = 'Mixture composition';
            app.uitable_P = uitable(app.MixturecompositionTab);
            app.uitable_P.ColumnName = {'Species'; 'Nº Moles'; 'Mole Fraction'};
            app.uitable_P.RowName = {};
            app.uitable_P.ColumnEditable = [false false false];
            app.uitable_R2 = uitable(app.MixturecompositionTab);
            app.uitable_R2.ColumnName = {'Species'; 'Nº Moles'; 'Mole Fraction'};
            app.uitable_R2.RowName = {};
            app.uitable_R2.ColumnEditable = [false false false];
            app.text_Products_4 = uilabel(app.MixturecompositionTab);
            app.text_Products_4.Text = 'Products';
            app.text_Reactans_4 = uilabel(app.MixturecompositionTab);
            app.text_Reactans_4.Text = 'Reactants';
            app.edit_phi2 = uieditfield(app.MixturecompositionTab, 'text');
            app.edit_phi2.Editable = 'off';
            app.edit_phi2.Value = '-';
            app.text_phi_2 = uilabel(app.MixturecompositionTab);
            app.text_phi_2.Text = 'Phi';
            app.PlotTab = uitab(app.TabGroup3);
            app.PlotTab.Title = 'Plot';
            app.Property1Panel = uipanel(app.PlotTab);
            app.Property1Panel.Title = 'Property 1';
            app.GridLayout9_2 = uigridlayout(app.Property1Panel);
            app.EditField = uieditfield(app.GridLayout9_2, 'text');
            app.EditField.Value = 'phi';
            app.Property2Panel = uipanel(app.PlotTab);
            app.Property2Panel.Title = 'Property 2';
            app.GridLayout9_3 = uigridlayout(app.Property2Panel);
            app.EditField_2 = uieditfield(app.GridLayout9_3, 'text');
            app.EditField_2.Value = 'XiP';
            app.logscaleCheckBox = uicheckbox(app.PlotTab);
            app.logscaleCheckBox.Text = 'log scale';
            app.Plot = uibutton(app.PlotTab, 'push');
            app.Plot.Text = 'Plot';
            app.GuidelinePanel = uipanel(app.PlotTab);
            app.GuidelinePanel.Title = 'Guideline';
            app.Tree2 = uitree(app.GuidelinePanel);
            app.StatesNode = uitreenode(app.Tree2);
            app.StatesNode.Text = 'States';
            app.ReactantsRNode = uitreenode(app.StatesNode);
            app.ReactantsRNode.Text = 'Reactants (R)';
            app.ProductsPNode = uitreenode(app.StatesNode);
            app.ProductsPNode.Text = 'Products (P)';
            app.PropertiesNode = uitreenode(app.Tree2);
            app.PropertiesNode.Text = 'Properties';
            app.EquivalenceratiophiNode = uitreenode(app.PropertiesNode);
            app.EquivalenceratiophiNode.Text = 'Equivalence ratio (phi)';
            app.TemperatureTNode = uitreenode(app.PropertiesNode);
            app.TemperatureTNode.Text = 'Temperature (T)';
            app.PressurepNode = uitreenode(app.PropertiesNode);
            app.PressurepNode.Text = 'Pressure (p)';
            app.DensityrhoNode = uitreenode(app.PropertiesNode);
            app.DensityrhoNode.Text = 'Density (rho)';
            app.ExamplesNode = uitreenode(app.Tree2);
            app.ExamplesNode.Text = 'Examples';
            app.EquivalenceratiophiNode_2 = uitreenode(app.ExamplesNode);
            app.EquivalenceratiophiNode_2.Text = 'Equivalence ratio = phi';
            app.MolarfractionProductsXiPNode = uitreenode(app.ExamplesNode);
            app.MolarfractionProductsXiPNode.Text = 'Molar fraction Products = XiP';
            app.TemperatureProductsTPNode = uitreenode(app.ExamplesNode);
            app.TemperatureProductsTPNode.Text = 'Temperature Products = TP';
            app.EnthalpyProductshRNode = uitreenode(app.ExamplesNode);
            app.EnthalpyProductshRNode.Text = 'Enthalpy Products = hR';
            app.PreconfiguredPanel = uipanel(app.PlotTab);
            app.PreconfiguredPanel.Title = 'Preconfigured';
            app.PreconfiguredListBox = uilistbox(app.PreconfiguredPanel);
            app.PreconfiguredListBox.Items = {'Molar fraction Products(phi)', 'Molar fraction Products(TP)', 'Molar fraction Products(pR)', 'Temperature Products(phi)', 'Temperature Products(pR)', 'Enthalpy Products(phi)'};
            app.PreconfiguredListBox.Value = 'Molar fraction Products(phi)';
            app.Panel_2 = uipanel(app.figure_main);
            app.Lamp = uilamp(app.Panel_2);
            app.UIAxes = uiaxes(app.figure_main);
            xlabel(app.UIAxes, 'Property 1')
            ylabel(app.UIAxes, 'Property 2')
            app.ContextMenu = uicontextmenu(app.figure_main);
            % Assign app.ContextMenu
            app.TabGroup2.ContextMenu = app.ContextMenu;
            app.InputsTab.ContextMenu = app.ContextMenu;
            app.uipanel11.ContextMenu = app.ContextMenu;
            app.uipanel7.ContextMenu = app.ContextMenu;
            app.uipanel_State.ContextMenu = app.ContextMenu;
            app.uipanel_additional_constraints.ContextMenu = app.ContextMenu;
            app.ResultsTab.ContextMenu = app.ContextMenu;
            app.Tree.ContextMenu = app.ContextMenu;
            app.Node_Results.ContextMenu = app.ContextMenu;
            app.ParametersTab.ContextMenu = app.ContextMenu;
            app.GridLayout6.ContextMenu = app.ContextMenu;
            app.text_q.ContextMenu = app.ContextMenu;
            app.edit_q.ContextMenu = app.ContextMenu;
            app.edit_vP_vR.ContextMenu = app.ContextMenu;
            app.EpsilonmolesLabel.ContextMenu = app.ContextMenu;
            app.edit_error_moles.ContextMenu = app.ContextMenu;
            app.EpsilonHLabel.ContextMenu = app.ContextMenu;
            app.edit_error_problem.ContextMenu = app.ContextMenu;
            app.text_vP_vR.ContextMenu = app.ContextMenu;
            app.GridLayout5_2.ContextMenu = app.ContextMenu;
            app.MixturecompositionTab.ContextMenu = app.ContextMenu;
            app.uitable_P.ContextMenu = app.ContextMenu;
            app.text_Reactans_4.ContextMenu = app.ContextMenu;
            app.PlotTab.ContextMenu = app.ContextMenu;
            app.CalculateMenu = uimenu(app.ContextMenu);
            app.CalculateMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateMenu.Text = 'Calculate';
            app.ClearMenu = uimenu(app.ContextMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearMenu.Text = 'Clear';
            app.ContextMenu2 = uicontextmenu(app.figure_main);
            % Assign app.ContextMenu2
            app.Console.ContextMenu = app.ContextMenu2;
            app.Console_2.ContextMenu = app.ContextMenu2;
            app.Label_Console.ContextMenu = app.ContextMenu2;
            app.MaximazeMenu = uimenu(app.ContextMenu2);
            app.MaximazeMenu.MenuSelectedFcn = createCallbackFcn(app, @MaximazeMenuSelected, true);
            app.MaximazeMenu.Text = 'Maximaze';
            app.MinimizeMenu = uimenu(app.ContextMenu2);
            app.MinimizeMenu.MenuSelectedFcn = createCallbackFcn(app, @MinimizeMenuSelected, true);
            app.MinimizeMenu.Text = 'Minimize';
            app.ClearCommandWindowMenu = uimenu(app.ContextMenu2);
            app.ClearCommandWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCommandWindowMenuSelected, true);
            app.ClearCommandWindowMenu.Text = 'Clear Command Window';
            app.ContextMenu3 = uicontextmenu(app.figure_main);
            % Assign app.ContextMenu3
            app.uitable_R2.ContextMenu = app.ContextMenu3;
            app.CalculateMenu_2 = uimenu(app.ContextMenu3);
            app.CalculateMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateMenu_2.Text = 'Calculate';
            app.ClearMenu_2 = uimenu(app.ContextMenu3);
            app.ClearMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearMenu_2.Text = 'Clear';
            app.RownumberedMenu = uimenu(app.ContextMenu3);
            app.RownumberedMenu.MenuSelectedFcn = createCallbackFcn(app, @RownumberedMenuSelected, true);
            app.RownumberedMenu.Text = 'Row numbered';
            % Show the figure after all components are created
            app.figure_main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = combustion_toolbox_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure_main)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure_main)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_main)
        end
    end
end