% description: The Combustion Toolbox (CT) is a newly developed open-source thermochemical code designed to solve problems involving chemical equilibrium for both gas- and condensed-phase species. The
% kernel of the code is based on the theoretical framework set forth by NASA's computer program CEA (Chemical Equilibrium with Applications) while incorporating new algorithms that significantly improve
% both convergence rate and robustness. The thermochemical properties are computed under the ideal gas approximation using an up-to-date version of NASA's 9-coefficient polynomial fits. These fits use
% the Third Millennium database, which includes the available values from Active Thermochemical Tables. Combustion Toolbox is programmed in MATLAB with an object-oriented architecture composed of three
% main modules: CT-EQUIL, CT-SD, and CT-ROCKET. The kernel module, CT-EQUIL, minimizes the Gibbs/Helmholtz free energy of the system using the technique of Lagrange multipliers combined with a
% multidimensional Newton-Raphson method, upon the condition that two state functions are used to define the mixture properties (e.g., enthalpy and pressure). CT-SD solves processes involving strong
% changes in dynamic pressure, such as steady shock and detonation waves under normal and oblique incidence angles. Finally, CT-ROCKET estimates rocket engine performance under highly idealized
% conditions. The new tool is equipped with a versatile Graphical User Interface and has been successfully used for teaching and research activities over the last six years. Results are in excellent
% agreement with CEA, Cantera within Caltech's Shock and Detonation Toolbox (SD-Toolbox), and the Thermochemical Equilibrium Abundances (TEA) code. CT is available under an open-source GPLv3 license via
% GitHub https://github.com/CombustionToolbox/combustion_toolbox, and its documentation can be found in https://combustion-toolbox-website.readthedocs.io.
classdef combustion_toolbox_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        xlsMenu                         matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        scriptMenu                      matlab.ui.container.Menu
        SnapshotMenu                    matlab.ui.container.Menu
        PreferencesMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        TutorialMenu                    matlab.ui.container.Menu
        ExamplesMenu                    matlab.ui.container.Menu
        ValidationsMenu                 matlab.ui.container.Menu
        DiscussionsMenu                 matlab.ui.container.Menu
        CheckforupdatesMenu             matlab.ui.container.Menu
        LicenseMenu                     matlab.ui.container.Menu
        SendfeedbackMenu                matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Console_text                    matlab.ui.control.TextArea
        Lamp                            matlab.ui.control.Lamp
        Label_Console                   matlab.ui.control.Label
        Console                         matlab.ui.control.TextArea
        Tab_lateral_bar                 matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        text_RP3_2                      matlab.ui.control.Label
        PP6                             matlab.ui.control.EditField
        TabGroup2                       matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        SelectProblemTypePanel          matlab.ui.container.Panel
        FLAG_IAC                        matlab.ui.control.CheckBox
        IonizedspeciesCheckBox          matlab.ui.control.CheckBox
        FrozenchemistryCheckBox         matlab.ui.control.CheckBox
        ProblemType                     matlab.ui.control.DropDown
        Calculate                       matlab.ui.control.Button
        Clear                           matlab.ui.control.Button
        AdditionalconstraintsPanel      matlab.ui.container.Panel
        PP5                             matlab.ui.control.EditField
        text_RP5                        matlab.ui.control.Label
        PR5                             matlab.ui.control.EditField
        text_P2                         matlab.ui.control.Label
        PP3                             matlab.ui.control.EditField
        PP4                             matlab.ui.control.EditField
        text_RP4                        matlab.ui.control.Label
        PR4                             matlab.ui.control.EditField
        text_RP3                        matlab.ui.control.Label
        PR3                             matlab.ui.control.EditField
        text_RP                         matlab.ui.control.Label
        text_R2                         matlab.ui.control.Label
        DefinestateofreactantsandproductsPanel  matlab.ui.container.Panel
        text_RP2_2                      matlab.ui.control.Label
        text_RP1_2                      matlab.ui.control.Label
        text_RP2                        matlab.ui.control.Label
        PR2                             matlab.ui.control.EditField
        text_P1                         matlab.ui.control.Label
        text_R1                         matlab.ui.control.Label
        text_RP1                        matlab.ui.control.Label
        PR1                             matlab.ui.control.EditField
        PP1                             matlab.ui.control.EditField
        PP2                             matlab.ui.control.EditField
        DefinereactantsandspeciestobeconsideredPanel  matlab.ui.container.Panel
        PeriodicTable_P                 matlab.ui.control.Button
        PeriodicTable_R                 matlab.ui.control.Button
        edit_F                          matlab.ui.control.NumericEditField
        ProductsPanel                   matlab.ui.container.Panel
        Products                        matlab.ui.control.DropDown
        FuelEditFieldLabel              matlab.ui.control.Label
        text_phi                        matlab.ui.control.Label
        edit_phi                        matlab.ui.control.EditField
        edit_OF                         matlab.ui.control.NumericEditField
        OFEditFieldLabel                matlab.ui.control.Label
        ListofSpeciesPanel              matlab.ui.container.Panel
        listbox_Products                matlab.ui.control.ListBox
        ReactantsPanel                  matlab.ui.container.Panel
        Reactants                       matlab.ui.control.DropDown
        UITable_R                       matlab.ui.control.Table
        QuicksettingsTab                matlab.ui.container.Tab
        ReportPanel                     matlab.ui.container.Panel
        PrintresultsCheckBox            matlab.ui.control.CheckBox
        Report_type                     matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        OthersPanel                     matlab.ui.container.Panel
        IdealAirCheckBox                matlab.ui.control.CheckBox
        TuningparametersPanel           matlab.ui.container.Panel
        MaxiterationsSDEditField        matlab.ui.control.NumericEditField
        MaxiterationsSDEditFieldLabel   matlab.ui.control.Label
        RFMT0EditField                  matlab.ui.control.NumericEditField
        RFMT0EditFieldLabel             matlab.ui.control.Label
        RFMT0_LEditField                matlab.ui.control.NumericEditField
        RFMT0_LEditFieldLabel           matlab.ui.control.Label
        RFMT0_REditField                matlab.ui.control.NumericEditField
        RFMT0_REditFieldLabel           matlab.ui.control.Label
        MaxiterationsRFMEditField       matlab.ui.control.NumericEditField
        MaxiterationsRFMEditFieldLabel  matlab.ui.control.Label
        TolerancesPanel                 matlab.ui.container.Panel
        ShocksandDetonationsEditField   matlab.ui.control.NumericEditField
        ShocksandDetonationsEditFieldLabel  matlab.ui.control.Label
        RootFindingMethodEditField      matlab.ui.control.NumericEditField
        RootFindingMethodEditFieldLabel  matlab.ui.control.Label
        DisplaySpeciesEditField         matlab.ui.control.NumericEditField
        DisplaySpeciesEditFieldLabel    matlab.ui.control.Label
        TraceoptionEditField            matlab.ui.control.NumericEditField
        TraceoptionEditFieldLabel       matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        SelectionofspeciesTab           matlab.ui.container.Tab
        PeriodicTable_P_2               matlab.ui.control.Button
        listbox_LS                      matlab.ui.control.ListBox
        text_LS                         matlab.ui.control.Label
        RemoveButton1                   matlab.ui.control.Button
        AddButton1                      matlab.ui.control.Button
        listbox_LS_DB                   matlab.ui.control.ListBox
        text_LS_DB                      matlab.ui.control.Label
        edit_seeker_1                   matlab.ui.control.EditField
        text_list_species               matlab.ui.control.Label
        DisplayspeciesTab               matlab.ui.container.Tab
        text_LS_display                 matlab.ui.control.Label
        listbox_LS_display              matlab.ui.control.ListBox
        RemoveButton2                   matlab.ui.control.Button
        AddButton2                      matlab.ui.control.Button
        listbox_LS_2                    matlab.ui.control.ListBox
        text_LS_2                       matlab.ui.control.Label
        edit_seeker_2                   matlab.ui.control.EditField
        text_list_species_2             matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        Node_Results                    matlab.ui.container.TreeNode
        Tab_results                     matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        Panel_parameters                matlab.ui.container.Panel
        text_theta_2                    matlab.ui.control.NumericEditField
        text_beta_2                     matlab.ui.control.NumericEditField
        text_beta_min_2                 matlab.ui.control.NumericEditField
        text_theta                      matlab.ui.control.Label
        text_beta                       matlab.ui.control.Label
        text_beta_min                   matlab.ui.control.Label
        text_Isp_2                      matlab.ui.control.NumericEditField
        text_Ivac_2                     matlab.ui.control.NumericEditField
        text_Cstar_2                    matlab.ui.control.NumericEditField
        EpsilonmolesLabel_2             matlab.ui.control.Label
        text_error_problem              matlab.ui.control.NumericEditField
        Panel_extra_5                   matlab.ui.container.Panel
        text_Isp_5                      matlab.ui.control.NumericEditField
        text_Ivac_5                     matlab.ui.control.NumericEditField
        text_Cstar_5                    matlab.ui.control.NumericEditField
        text_Aratio_5                   matlab.ui.control.NumericEditField
        text_T_5                        matlab.ui.control.NumericEditField
        text_Products_5                 matlab.ui.control.Label
        text_p_5                        matlab.ui.control.NumericEditField
        text_r_5                        matlab.ui.control.NumericEditField
        text_h_5                        matlab.ui.control.NumericEditField
        text_cp_5                       matlab.ui.control.NumericEditField
        text_W_5                        matlab.ui.control.NumericEditField
        text_sound_5                    matlab.ui.control.NumericEditField
        text_e_5                        matlab.ui.control.NumericEditField
        text_u_5                        matlab.ui.control.NumericEditField
        text_M_5                        matlab.ui.control.NumericEditField
        text_gamma_5                    matlab.ui.control.NumericEditField
        text_s_5                        matlab.ui.control.NumericEditField
        Panel_extra_4                   matlab.ui.container.Panel
        text_Isp_4                      matlab.ui.control.NumericEditField
        text_Ivac_4                     matlab.ui.control.NumericEditField
        text_Cstar_4                    matlab.ui.control.NumericEditField
        text_Aratio_4                   matlab.ui.control.NumericEditField
        text_T_4                        matlab.ui.control.NumericEditField
        text_Products_4                 matlab.ui.control.Label
        text_p_4                        matlab.ui.control.NumericEditField
        text_r_4                        matlab.ui.control.NumericEditField
        text_h_4                        matlab.ui.control.NumericEditField
        text_cp_4                       matlab.ui.control.NumericEditField
        text_W_4                        matlab.ui.control.NumericEditField
        text_sound_4                    matlab.ui.control.NumericEditField
        text_e_4                        matlab.ui.control.NumericEditField
        text_u_4                        matlab.ui.control.NumericEditField
        text_M_4                        matlab.ui.control.NumericEditField
        text_gamma_4                    matlab.ui.control.NumericEditField
        text_s_4                        matlab.ui.control.NumericEditField
        Panel_extra_3                   matlab.ui.container.Panel
        text_Isp_3                      matlab.ui.control.NumericEditField
        text_Ivac_3                     matlab.ui.control.NumericEditField
        text_Cstar_3                    matlab.ui.control.NumericEditField
        text_Aratio_3                   matlab.ui.control.NumericEditField
        text_T_3                        matlab.ui.control.NumericEditField
        text_Products_3                 matlab.ui.control.Label
        text_p_3                        matlab.ui.control.NumericEditField
        text_r_3                        matlab.ui.control.NumericEditField
        text_h_3                        matlab.ui.control.NumericEditField
        text_cp_3                       matlab.ui.control.NumericEditField
        text_W_3                        matlab.ui.control.NumericEditField
        text_sound_3                    matlab.ui.control.NumericEditField
        text_e_3                        matlab.ui.control.NumericEditField
        text_u_3                        matlab.ui.control.NumericEditField
        text_M_3                        matlab.ui.control.NumericEditField
        text_gamma_3                    matlab.ui.control.NumericEditField
        text_s_3                        matlab.ui.control.NumericEditField
        text_Isp                        matlab.ui.control.Label
        text_Ivac                       matlab.ui.control.Label
        text_Cstar                      matlab.ui.control.Label
        text_Aratio_2                   matlab.ui.control.NumericEditField
        text_Aratio                     matlab.ui.control.Label
        text_M_1                        matlab.ui.control.NumericEditField
        text_u_1                        matlab.ui.control.NumericEditField
        text_T_2                        matlab.ui.control.NumericEditField
        text_p_1                        matlab.ui.control.NumericEditField
        text_T_1                        matlab.ui.control.NumericEditField
        text_T                          matlab.ui.control.Label
        text_Reactans                   matlab.ui.control.Label
        text_Products                   matlab.ui.control.Label
        text_p_2                        matlab.ui.control.NumericEditField
        text_p                          matlab.ui.control.Label
        text_r_2                        matlab.ui.control.NumericEditField
        text_r_1                        matlab.ui.control.NumericEditField
        text_r                          matlab.ui.control.Label
        text_h_2                        matlab.ui.control.NumericEditField
        text_h_1                        matlab.ui.control.NumericEditField
        text_h                          matlab.ui.control.Label
        text_cp_2                       matlab.ui.control.NumericEditField
        text_cp_1                       matlab.ui.control.NumericEditField
        text_cp                         matlab.ui.control.Label
        text_W_2                        matlab.ui.control.NumericEditField
        text_W_1                        matlab.ui.control.NumericEditField
        text_W                          matlab.ui.control.Label
        text_sound_2                    matlab.ui.control.NumericEditField
        text_sound_1                    matlab.ui.control.NumericEditField
        text_sound                      matlab.ui.control.Label
        text_e_2                        matlab.ui.control.NumericEditField
        text_e_1                        matlab.ui.control.NumericEditField
        text_e                          matlab.ui.control.Label
        text_u_2                        matlab.ui.control.NumericEditField
        text_u                          matlab.ui.control.Label
        text_M_2                        matlab.ui.control.NumericEditField
        text_M                          matlab.ui.control.Label
        text_gamma_2                    matlab.ui.control.NumericEditField
        text_gamma_1                    matlab.ui.control.NumericEditField
        text_gamma                      matlab.ui.control.Label
        text_s_2                        matlab.ui.control.NumericEditField
        text_s_1                        matlab.ui.control.NumericEditField
        text_s                          matlab.ui.control.Label
        text_phi_3                      matlab.ui.control.Label
        edit_phi3                       matlab.ui.control.EditField
        TurbulencestatisticsTab         matlab.ui.container.Tab
        Panel_parameters_2              matlab.ui.container.Panel
        edit_eta                        matlab.ui.control.EditField
        text_phi_6                      matlab.ui.control.Label
        text_phi_5                      matlab.ui.control.Label
        edit_etaVorticity               matlab.ui.control.EditField
        text_Isp_7                      matlab.ui.control.NumericEditField
        text_Ivac_7                     matlab.ui.control.NumericEditField
        text_Cstar_7                    matlab.ui.control.NumericEditField
        text_Isp_6                      matlab.ui.control.Label
        text_Ivac_6                     matlab.ui.control.Label
        text_Cstar_6                    matlab.ui.control.Label
        text_Aratio_7                   matlab.ui.control.NumericEditField
        text_Aratio_6                   matlab.ui.control.Label
        text_K_2                        matlab.ui.control.NumericEditField
        text_K                          matlab.ui.control.Label
        text_R11_2                      matlab.ui.control.NumericEditField
        text_R11                        matlab.ui.control.Label
        text_RTT_2                      matlab.ui.control.NumericEditField
        text_RTT                        matlab.ui.control.Label
        text_Enstrophy_2                matlab.ui.control.NumericEditField
        text_Enstrophy                  matlab.ui.control.Label
        text_R11a_2                     matlab.ui.control.NumericEditField
        text_R11a                       matlab.ui.control.Label
        text_Kr_2                       matlab.ui.control.NumericEditField
        text_Kr                         matlab.ui.control.Label
        text_R11r_2                     matlab.ui.control.NumericEditField
        text_R11r                       matlab.ui.control.Label
        text_EnstrophyTT_2              matlab.ui.control.NumericEditField
        text_EnstrophyTT                matlab.ui.control.Label
        text_RTTr_2                     matlab.ui.control.NumericEditField
        text_RTTr                       matlab.ui.control.Label
        text_Kolmogorov_2               matlab.ui.control.NumericEditField
        text_Kolmogorov                 matlab.ui.control.Label
        text_RTTa_2                     matlab.ui.control.NumericEditField
        text_RTTa                       matlab.ui.control.Label
        text_Ka_2                       matlab.ui.control.NumericEditField
        text_Ka                         matlab.ui.control.Label
        text_phi_4                      matlab.ui.control.Label
        edit_chi                        matlab.ui.control.EditField
        MixturecompositionTab           matlab.ui.container.Tab
        text_phi_2                      matlab.ui.control.Label
        edit_phi2                       matlab.ui.control.EditField
        text_R3                         matlab.ui.control.Label
        text_P3                         matlab.ui.control.Label
        UITable_P                       matlab.ui.control.Table
        UITable_R2                      matlab.ui.control.Table
        CustomfiguresTab                matlab.ui.container.Tab
        DefaultsettingsCheckBox         matlab.ui.control.CheckBox
        figure_settings                 matlab.ui.control.Button
        figure_size                     matlab.ui.control.Button
        figure_clear                    matlab.ui.control.Button
        figure_plot                     matlab.ui.control.Button
        Tree_variable_y                 matlab.ui.container.CheckBoxTree
        Variable_y                      matlab.ui.container.TreeNode
        Tree_variable_x                 matlab.ui.container.CheckBoxTree
        Variable_x                      matlab.ui.container.TreeNode
        Tree_mixtures                   matlab.ui.container.CheckBoxTree
        Mixtures                        matlab.ui.container.TreeNode
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu_CommandWindow       matlab.ui.container.ContextMenu
        MaximizeMenu                    matlab.ui.container.Menu
        MinimizeMenu                    matlab.ui.container.Menu
        ClearCommandWindowMenu          matlab.ui.container.Menu
        ContextMenu_UITree              matlab.ui.container.ContextMenu
        RemoveMenu                      matlab.ui.container.Menu
        ContextMenu_UIAxes              matlab.ui.container.ContextMenu
        property_inspector_menu         matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        ContextMenuUITable_R            matlab.ui.container.ContextMenu
        RemoveMenu_2                    matlab.ui.container.Menu
    end

    properties (Access = public)
        constants                % Constants object
        database                 % Class inhereted from Database superclass
        chemicalSystem           % Chemical system object
        mixture                  % Mixture object
        equilibriumSolver        % EquilibriumSolver object
        shockSolver              % ShockSolver object
        detonationSolver         % DetonationSolver object
        rocketSolver             % RocketSolver object
        shockTurbulenceSolver    % ShockTurbulenceSolver
        plotConfig               % PlotConfig object
        export                   % Export object
        appController            % AppController object
        statusPanel              % AppStatusPanel object
        consolePanel             % AppConsolePanel object
        displaySpecies           % Chemical species to be shown in plotComposition
        defaultLayout            % Default GUI component layout and data
        currentResults           % Last solved GUI result set
        maxRelativeError = 2e-2; % Relative error threshold to change color (2 %)
    end

    properties (Access = private)
        hiddenTabs                             % Hidden tabs
        splashColor = [0.5098, 0.6039, 0.6745] % Font color splash
        splashScreen                           % Startup splash screen
        welcomeMessage = 'Welcome to Combustion Toolbox %s --- A MATLAB-based framework for solving combustion and high-speed flow problems.'
        runtimeOptions = struct( ...
            'debugMode', false, ...
            'showSplash', true, ...
            'showFigure', true)                % Runtime startup options
    end
    
    properties (Dependent)
        NS_products  % Number of product species (computations)
        NS_display   % Number of display species (plots)
        LS_reactants % List of reactants
    end

    methods (Access = public)
        % Value changed function: Reactants
        function public_ReactantsValueChanged(app, event)
            app.appController.onReactantsChanged(event);
        end

        % Value changed function: Products
        function public_ProductsValueChanged(app)
            % Update Listbox (extended settings)
            app.listbox_LS.Items = app.listbox_Products.Items;
            % * Update Title with the number of items contained in the box
            app.ListofSpeciesPanel.Title = sprintf('List of Species - %d', app.NS_products);
            % Update Display species (extended settings)
            app.listbox_LS_2.Items = app.listbox_LS.Items;
            % Set Display species to all (extended settings)
            app.listbox_LS_display.Items = app.listbox_LS_2.Items;
            app.displaySpecies = app.listbox_LS_display.Items;
            % Update Text with the number of items contained in the box
            app.text_LS.Text = sprintf('List of Species - %d', app.NS_products);
            % Update Text with the number of items contained in the box
            app.text_LS_2.Text = sprintf('List of Species - %d', app.NS_products);
            % Update Text with the number of items contained in the box
            app.text_LS_display.Text = sprintf('Display Species - %d', app.NS_display);
        end

        function public_FLAG_IACValueChanged(app)
            app.appController.onRocketModelChanged();
        end

        function public_ClearButtonPushed(app, event)
            ClearButtonPushed(app, event)
        end
        
        function public_xlsMenuSelected(app, event)
            xlsMenuSelected(app, event);
        end

        function public_matMenuSelected(app, event)
            matMenuSelected(app, event);
        end

        function public_scriptMenuSelected(app, event)
            scriptMenuSelected(app, event);
        end

        function hideTab(app, tabObj)
            % Hide a tab by removing it from its parent TabGroup
            if isempty(tabObj) || ~isvalid(tabObj)
                return
            end
        
            % Store original parent and index so we can restore position later
            tg = tabObj.Parent;
            if isempty(tg) || ~isvalid(tg)
                return
            end
        
            key = matlab.lang.makeValidName(tabObj.Title);
        
            app.hiddenTabs.(key).Tab   = tabObj;
            app.hiddenTabs.(key).Group = tg;
        
            % store the order index
            children = tg.Children;
            app.hiddenTabs.(key).Index = find(children == tabObj, 1, 'first');
        
            % if currently selected, move selection away first
            if tg.SelectedTab == tabObj
                otherTabs = children(children ~= tabObj);
                if ~isempty(otherTabs)
                    tg.SelectedTab = otherTabs(1);
                end
            end
        
            % remove tab (this hides it)
            tabObj.Parent = [];
        end
        
        function showTab(app, tabTitle, positionMode)
            % showTab(app, tabTitle)
            % showTab(app, tabTitle, 'original')  -> restores original position (default)
            % showTab(app, tabTitle, 'end')       -> puts tab at the end
            
            if nargin < 3 || isempty(positionMode)
                positionMode = 'original';
            end

            positionMode = lower(string(positionMode));
        
            key = matlab.lang.makeValidName(tabTitle);
        
            if ~isfield(app.hiddenTabs, key)
                return
            end
        
            tabObj = app.hiddenTabs.(key).Tab;
            tg     = app.hiddenTabs.(key).Group;
            idx0   = app.hiddenTabs.(key).Index;
        
            if isempty(tabObj) || ~isvalid(tabObj) || isempty(tg) || ~isvalid(tg)
                return
            end
        
            % Restore parent (tab becomes part of tg.Children)
            tabObj.Parent = tg;
        
            ch = tg.Children;
            n  = numel(ch);
        
            % Find current index of the restored tab
            k = find(ch == tabObj, 1, 'first');
            if isempty(k)
                return
            end
        
            switch positionMode
                case 'original'
                    idx = min(max(idx0, 1), n);
        
                    if k ~= idx
                        % Remove and insert at original idx
                        ch(k) = [];
                        ch = [ch(1:idx-1); tabObj; ch(idx:end)];
                        tg.Children = ch;
                    end
        
                case 'end'
                    % "end" = last position in Children order
                    if k ~= n
                        ch(k) = [];
                        ch = [ch; tabObj];
                        tg.Children = ch;
                    end
        
                otherwise
                    error('showTab:InvalidPositionMode', ...
                          'positionMode must be "original" or "end".');
            end
        
            % Optional: select it (comment out if you want less flicker)
            tg.SelectedTab = tabObj;
        
            % Remove from hidden list
            app.hiddenTabs = rmfield(app.hiddenTabs, key);
        end

    end

    methods
        function value = get.NS_products(app)
            value = length(app.listbox_Products.Items);
        end

        function value = get.NS_display(app)
            value = length(app.displaySpecies);
        end

        function value = get.LS_reactants(app)
            data = app.UITable_R.Data;

            if isempty(data) || size(data, 2) < 1
                value = [];
                return
            end

            value = data(:, 1)';
            
        end

    end

    methods (Access = private)
        
        function installDatabase(app)
            % Check if running in standalone mode

            % Import packages
            import combustiontoolbox.databases.NasaDatabase

            if ~isdeployed
                % Get dir app
                app_info = matlab.apputil.getInstalledAppInfo;
                FLAG_ID = contains(struct2table(app_info).id, 'combustion_toolbox_app');
                dir_app =  app_info(FLAG_ID).location;
                dir_database_app = fullfile(dir_app, 'databases');
        
                % Generate database
                if ~exist(fullfile(dir_database_app, 'DB.mat'), 'file')
                    INSTALL('install', 'path');
                end
                
                return
            end

            % In standalone mode, use the ctfroot directory for application files
            appRoot = fullfile(ctfroot, 'CombustionTo');
            databaseFolder = fullfile(appRoot, 'databases');
            databasePath = fullfile(databaseFolder, 'DB.mat');
    
            % Return if file already exists
            if exist(databasePath, 'file')
                return
            end

            % Create the directory if it doesn’t exist
            if ~exist(databaseFolder, 'dir')
                mkdir(databaseFolder);
            end

            % Install database
            DB = NasaDatabase();
            DB.save('path', databaseFolder); % Not working when app is deployed (saved as obj in macOS)
            
            % Force save with proper naming
            % data.DB = DB;
            % databasePath = fullfile(pwd, 'DB.mat');
            % save(databasePath, '-struct', 'data');
            
            % Assign database
            app.database = DB;
        end

        
        function getDatabase(app)
            % Load database
            if ~isempty(app.database)
                return
            end

            OS = combustiontoolbox.utils.SystemUtils.getOS;

            if ~isdeployed
                app.database = combustiontoolbox.databases.NasaDatabase();
                return
            end

            switch OS
                case 'macOS'
                    app.database = combustiontoolbox.databases.NasaDatabase('filename', 'obj');
                otherwise
                    app.database = combustiontoolbox.databases.NasaDatabase();
            end

        end

        function closeSplash(app)
            % Close the startup splash screen
            if isempty(app.splashScreen)
                return
            end

            if isvalid(app.splashScreen)
                delete(app.splashScreen);
            end

            app.splashScreen = [];
        end

        function options = parseStartupOptions(app, varargin)
            % Parse app startup options
            options = app.runtimeOptions;

            if isempty(varargin)
                return
            end

            if isscalar(varargin) && (ischar(varargin{1}) || isstring(varargin{1}))
                optionName = strtrim(char(varargin{1}));

                if any(strcmpi(optionName, {'debug', 'debugmode', 'test', 'testing', 'testmode'}))
                    options.debugMode = true;
                    options.showSplash = false;
                    options.showFigure = false;
                    return
                end
            end

            if isscalar(varargin) && isstruct(varargin{1})
                settings = varargin{1};
                names = fieldnames(settings);

                for i = 1:numel(names)
                    options = app.setStartupOption(options, names{i}, settings.(names{i}));
                end

            else
                if mod(numel(varargin), 2) ~= 0
                    error('combustion_toolbox:InvalidStartupOptions', ...
                        'Startup options must be provided as name-value pairs');
                end

                for i = 1:2:numel(varargin)
                    options = app.setStartupOption(options, varargin{i}, varargin{i + 1});
                end
            end

            if options.debugMode
                options.showSplash = false;
                options.showFigure = false;
            end
        end

        function options = setStartupOption(app, options, name, value)
            % Set one startup option
            name = lower(strtrim(char(name)));

            switch name
                case {'debug', 'debugmode', 'test', 'testing', 'testmode'}
                    options.debugMode = app.toLogicalOption(value);
                case {'showsplash', 'splash', 'splashscreen'}
                    options.showSplash = app.toLogicalOption(value);
                case {'showfigure', 'showgui', 'visible'}
                    options.showFigure = app.toLogicalOption(value);
                otherwise
                    error('combustion_toolbox:InvalidStartupOption', ...
                        'Unknown startup option "%s"', char(name));
            end
        end

        function value = toLogicalOption(~, value)
            % Convert startup option values to logical scalars
            if islogical(value)
                value = value(1);
                return
            end

            if isnumeric(value)
                value = value(1) ~= 0;
                return
            end

            if isstring(value)
                value = char(value);
            end

            if ischar(value)
                switch lower(strtrim(value))
                    case {'true', 'on', 'yes', '1'}
                        value = true;
                    case {'false', 'off', 'no', '0'}
                        value = false;
                    otherwise
                        error('combustion_toolbox:InvalidStartupOptionValue', ...
                            'Startup option values must be true or false');
                end

                return
            end

            error('combustion_toolbox:InvalidStartupOptionValue', ...
                'Startup option values must be true or false');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load figure in the background
            app.UIFigure.Visible = 'off';
            % Splash
            app.splashScreen = [];

            if app.runtimeOptions.showSplash
                try
                    app.splashScreen = combustiontoolbox.utils.display.showSplashScreen('color', app.splashColor);
                catch
                    % Continue startup when the splash screen cannot be created
                    closeSplash(app);
                end
            end
            
            % Install database (only standalone versions)
            installDatabase(app);
            % Add additional components
            % createDynamicComponents(app); % Next release
            % Get screen position
            % position = splash_obj.ScreenPosition;
            position = combustiontoolbox.utils.display.getMonitorPositionsMATLAB();
            % Centering app
            app.UIFigure.Position(1) = position(1) + (position(3) - app.UIFigure.Position(3))/2;
            app.UIFigure.Position(2) = position(2) + (position(4) - app.UIFigure.Position(4))/2;
            % Check screen size
            if position(4) <= app.UIFigure.Position(4)
                app.UIFigure.Position(4) = 0.9 * position(4);
                app.UIFigure.Resize = true;
                app.UIFigure.Scrollable = true;
            end
            % Get Constants
            app.constants = combustiontoolbox.common.Constants;
            % Get Nasa's database
            getDatabase(app);
            % Initialize chemical system
            app.chemicalSystem = combustiontoolbox.core.ChemicalSystem(app.database);
            % Initialize mixture
            app.mixture = combustiontoolbox.core.Mixture(app.chemicalSystem);
            % Initialize plotConfig object
            app.plotConfig = combustiontoolbox.utils.display.PlotConfig;
            % Initialize solvers
            app.equilibriumSolver = combustiontoolbox.equilibrium.EquilibriumSolver('plotConfig', app.plotConfig);
            app.shockSolver = combustiontoolbox.shockdetonation.ShockSolver('plotConfig', app.plotConfig, 'equilibriumSolver', app.equilibriumSolver);
            app.detonationSolver = combustiontoolbox.shockdetonation.DetonationSolver('plotConfig', app.plotConfig, 'equilibriumSolver', app.equilibriumSolver);
            app.rocketSolver = combustiontoolbox.rocket.RocketSolver('plotConfig', app.plotConfig, 'equilibriumSolver', app.equilibriumSolver);
            app.shockTurbulenceSolver = combustiontoolbox.shockturbulence.ShockTurbulenceSolver('plotConfig', app.plotConfig, 'equilibriumSolver', app.equilibriumSolver, 'shockSolver', app.shockSolver);
            % Initialize export object
            app.export = combustiontoolbox.utils.Export();
            % Initialize status panel
            app.statusPanel = combustiontoolbox.gui.AppStatusPanel(app);
            % Initialize command window panel
            app.consolePanel = combustiontoolbox.gui.AppConsolePanel(app);
            % Initialize GUI controller
            app.appController = combustiontoolbox.gui.AppController(app);
            % Refresh problem dropdown from catalog metadata
            [problemItems, problemItemsData] = app.appController.problemDropdownData();
            app.ProblemType.Items = problemItems;
            app.ProblemType.ItemsData = problemItemsData;
            % Initialize List box species DataBase master
            app.listbox_LS_DB.Items = app.database.listSpecies;
            % Initialize table data
            app.UITable_R.ColumnFormat = {[] [] [] {'Fuel', 'Oxidizer', 'Inert'} []};
            % Save default GUI state
            app.defaultLayout.Panel_parameters.Position = app.Panel_parameters.Position;
            app.defaultLayout.data_UITable_R = app.UITable_R.Data;
            app.UITable_P.ColumnFormat(2:3)  = {'shortE'};
            % Initialize command window output and history
            app.consolePanel.initialize(sprintf(app.welcomeMessage, app.constants.release));
            % Apply startup visibility
            if app.runtimeOptions.showFigure
                app.UIFigure.Visible = 'on';
            else
                app.UIFigure.Visible = 'off';
            end
            % Hide Turbulence statistics tab
            app.hideTab(app.TurbulencestatisticsTab);
            % Delete splash
            closeSplash(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            uiabout();
        end

        % Value changed function: Console
        function ConsoleValueChanged(app, event)
            app.appController.onConsoleValueChanged(event);
        end

        % Value changed function: Console_text
        function Console_textValueChanged(app, event)
            app.consolePanel.restoreOutput(event.PreviousValue);
        end

        % Menu selected function: SnapshotMenu
        function SnapshotMenuSelected(app, event)
            app.appController.onSnapshotSelected();
        end

        % Menu selected function: MaximizeMenu
        function MaximizeMenuSelected(app, event)
            app.consolePanel.maximize();
        end

        % Menu selected function: MinimizeMenu
        function MinimizeMenuSelected(app, event)
            app.consolePanel.minimize();
        end

        % Menu selected function: ClearCommandWindowMenu
        function ClearCommandWindowMenuSelected(app, event)
            app.consolePanel.clearOutput();
        end

        % Button pushed function: Calculate
        function CalculateButtonPushed(app, event)
            app.appController.onCalculatePushed();
        end

        % Menu selected function: SendfeedbackMenu
        function SendfeedbackMenuSelected(app, event)
            run('uifeedback');
        end

        % Cell edit callback: UITable_R
        function UITable_RCellEdit(app, event)
            app.appController.onReactantsTableEdited(event);
        end

        % Value changed function: edit_phi
        function edit_phiValueChanged(app, event)
            app.appController.onEquivalenceRatioChanged(event);
        end

        % Value changed function: PR1
        function PR1ValueChanged(app, event)
            app.appController.onReactantsTemperatureChanged();
        end

        % Menu selected function: DiscussionsMenu
        function DiscussionsMenuSelected(app, event)
            % Open default web browser and redirect to Combustion Toolbox
            % discussion forum

            % Import packages
            import combustiontoolbox.utils.SystemUtils
        
            % Definitions
            url = SystemUtils.url.discussions;
            
            % Open website
            SystemUtils.openWebsite(url)
        end

        % Value changed function: ProblemType
        function ProblemTypeValueChanged(app, event)
            % Update GUI items for the selected problem
            app.appController.onProblemTypeChanged();
        end

        % Value changed function: PP2
        function PP2ValueChanged(app, event)
            app.appController.onProductPressureChanged(event);
        end

        % Value changed function: PR2
        function PR2ValueChanged(app, event)
            app.appController.onReactantPressureChanged(event);
        end

        % Value changed function: FrozenchemistryCheckBox
        function FrozenchemistryCheckBoxValueChanged(app, event)
            app.appController.onFrozenChemistryChanged();
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            combustiontoolbox.utils.SystemUtils.websiteCT;
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.appController.onClearSelected();
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.appController.onResultsTreeSelectionChanged();
        end

        % Value changed function: PR4
        function PR4ValueChanged(app, event)
            app.appController.onReactantMachChanged();
        end

        % Value changed function: PR3
        function PR3ValueChanged(app, event)
            app.appController.onReactantVelocityChanged();
        end

        % Menu selected function: xlsMenu
        function xlsMenuSelected(app, event)
            app.appController.onExportSelected('.xls');
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            app.appController.onExportSelected('.mat');
        end

        % Menu selected function: scriptMenu
        function scriptMenuSelected(app, event)
            app.appController.onExportProblemScriptSelected();
        end

        % Button pushed function: AddButton1
        function AddButton1Pushed(app, event)
            app.appController.onProductSpeciesAdded();
        end

        % Button pushed function: RemoveButton1
        function RemoveButton1Pushed(app, event)
            app.appController.onProductSpeciesRemoved();
        end

        % Menu selected function: ValidationsMenu
        function ValidationsMenuSelected(app, event)
            uivalidations();
        end

        % Menu selected function: RemoveMenu
        function RemoveMenuSelected(app, event)
            delete(app.Tree.SelectedNodes);
        end

        % Menu selected function: LicenseMenu
        function LicenseMenuSelected(app, event)
            uilicense();
        end

        % Button pushed function: figure_clear
        function figure_clearButtonPushed(app, event)
            ClearButtonPushed(app, event);
        end

        % Button pushed function: figure_plot
        function figure_plotButtonPushed(app, event)
            app.appController.onCustomFigurePlotPushed();
        end

        % Button pushed function: figure_size
        function figure_sizeButtonPushed(app, event)
            if contains(app.figure_size.Text, 'Max')
                % Save default values
                app.defaultLayout.UIFigure_position = app.UIFigure.Position;
                app.defaultLayout.UIAxes_position = app.UIAxes.Position;
                app.defaultLayout.Tab_results_position = app.Tab_results.Position;
                % Maximize UIFigure (width)
                app.UIFigure.Position([1,3]) = [0.5 * app.UIFigure.Position(1), 2 * app.UIFigure.Position(3)];
                % Delete UIAxes
                delete(app.UIAxes);
                % Create new UIAxes
                app.UIAxes = uiaxes(app.UIFigure, 'Position', [655,9,609,719]);
                % Change name button
                app.figure_size.Text = 'Minimize';
                % Replot
                figure_plotButtonPushed(app, event);
            else
                app.UIFigure.Position = app.defaultLayout.UIFigure_position;
                % Delete UIAxes
                delete(app.UIAxes);
                % Create new UIAxes
                app.UIAxes = uiaxes(app.CustomfiguresTab, 'Position', app.defaultLayout.UIAxes_position);
                % Change name button
                app.figure_size.Text = 'Maximize';
                % Replot
                figure_plotButtonPushed(app, event);
            end
        end

        % Menu selected function: CheckforupdatesMenu
        function CheckforupdatesMenuSelected(app, event)
            [~, message] = combustiontoolbox.utils.checkUpdate(app.UIFigure);
            app.consolePanel.setOutput(message);
        end

        % Button pushed function: PeriodicTable_R
        function PeriodicTable_RButtonPushed(app, event)
            uielements(app, event);
        end

        % Button pushed function: PeriodicTable_P, PeriodicTable_P_2
        function PeriodicTable_PButtonPushed(app, event)
            uielements(app, event);
        end

        % Value changed function: Reactants
        function ReactantsValueChanged(app, event)
            public_ReactantsValueChanged(app, event);
        end

        % Value changed function: Products
        function ProductsValueChanged(app, event)
            app.appController.onProductsChanged();
        end

        % Value changing function: edit_seeker_1
        function edit_seeker_1ValueChanging(app, event)
            app.appController.onDatabaseSpeciesSearchChanging(event);
        end

        % Value changing function: edit_seeker_2
        function edit_seeker_2ValueChanging(app, event)
            app.appController.onDisplaySpeciesSearchChanging(event);
        end

        % Button pushed function: AddButton2
        function AddButton2Pushed(app, event)
            app.appController.onDisplaySpeciesAdded();
        end

        % Button pushed function: RemoveButton2
        function RemoveButton2Pushed(app, event)
            app.appController.onDisplaySpeciesRemoved();
        end

        % Menu selected function: property_inspector_menu
        function property_inspector_menuSelected(app, event)
            % Set default settings to 'off'
            app.DefaultsettingsCheckBox.Value = false;
            % Show property inspector
            inspect(app.UIAxes)
        end

        % Button pushed function: figure_settings
        function figure_settingsButtonPushed(app, event)
            % Set default settings to 'off'
            app.DefaultsettingsCheckBox.Value = false;
            % Show property inspector
            inspect(app.UIAxes)
        end

        % Value changed function: FLAG_IAC
        function FLAG_IACValueChanged(app, event)
            public_FLAG_IACValueChanged(app);            
        end

        % Value changing function: PR3
        function PR3ValueChanging(app, event)
            app.appController.onRocketReactantAreaChanging();
        end

        % Value changing function: PP3
        function PP3ValueChanging(app, event)
            app.appController.onRocketProductAreaChanging();
        end

        % Callback function
        function PeriodicTable_displayButtonPushed(app, event)
            uielements(app, event);
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            uipreferences(app);
        end

        % Value changed function: TraceoptionEditField
        function TraceoptionEditFieldValueChanged(app, event)
            app.equilibriumSolver.tolGibbs = app.TraceoptionEditField.Value;
        end

        % Value changed function: RootFindingMethodEditField
        function RootFindingMethodEditFieldValueChanged(app, event)
            app.equilibriumSolver.tol0 = app.RootFindingMethodEditField.Value;
        end

        % Value changed function: ShocksandDetonationsEditField
        function ShocksandDetonationsEditFieldValueChanged(app, event)
            app.shockSolver.tol0 = app.ShocksandDetonationsEditField.Value;
            app.detonationSolver.tol0 = app.ShocksandDetonationsEditField.Value;
        end

        % Value changed function: DisplaySpeciesEditField
        function DisplaySpeciesEditFieldValueChanged(app, event)
            app.plotConfig.mintolDisplay = app.DisplaySpeciesEditField.Value;
        end

        % Value changed function: MaxiterationsRFMEditField
        function MaxiterationsRFMEditFieldValueChanged(app, event)
            app.equilibriumSolver.itMax = app.MaxiterationsRFMEditField.Value;
        end

        % Value changed function: MaxiterationsSDEditField
        function MaxiterationsSDEditFieldValueChanged(app, event)
            app.shockSolver.itMax = app.MaxiterationsSDEditField.Value;
            app.detonationSolver.itMax = app.MaxiterationsSDEditField.Value;
        end

        % Value changed function: RFMT0_LEditField
        function RFMT0_LEditFieldValueChanged(app, event)
            app.equilibriumSolver.root_T0_l = app.RFMT0_LEditField.Value;
        end

        % Value changed function: RFMT0_REditField
        function RFMT0_REditFieldValueChanged(app, event)
            app.equilibriumSolver.root_T0_r = app.RFMT0_REditField.Value;
        end

        % Value changed function: RFMT0EditField
        function RFMT0EditFieldValueChanged(app, event)
            app.equilibriumSolver.root_T0 = app.RFMT0EditField.Value;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Get key
            key = event.Key;

            % Get first active modifier when present
            modifier = '';

            if ~isempty(event.Modifier)
                modifier = event.Modifier;

                if iscell(modifier)
                    modifier = modifier{1};
                end
            end

            % Read action

            % Modifier used (shift + key press)
            if isequal(modifier,'shift')
                switch lower(key)
                    case 'f1'
                        event.Source.Tag = 'R';
                        PeriodicTable_RButtonPushed(app, event);
                    case 'f2'
                        event.Source.Tag = 'P';
                        PeriodicTable_PButtonPushed(app, event);
                end
                
                return

            % Modifier used (control + key press)
            elseif isequal(modifier, 'control')
                switch lower(key)
                    case 'n'
                        ClearButtonPushed(app, event);
                    case 'p'
                        PreferencesMenuSelected(app, event);
                    case 'q'
                        app.consolePanel.setOutput('Closing the Combustion Toolbox');
                        UIFigureCloseRequest(app, event);
                    case 's'
                        xlsMenuSelected(app, event);
                    case 'x'
                        app.consolePanel.setOutput('Taking snapshot...');
                        SnapshotMenuSelected(app, event);
                        app.consolePanel.setOutput('Taking snapshot... OK!');
                end

                return
            
            % No modifier used (one key press)
            else
                switch lower(key)
                    case {'uparrow', 'downarrow', 'rightarrow', 'leftarrow'}
                        app.consolePanel.selectHistory(key);
                    case 'return'
                        app.appController.onConsoleValueChanged(event);
                    case 'f1'
                        DocumentationMenuSelected(app, event);
                    case 'f5'
                        CalculateButtonPushed(app, event);
                        return
                    otherwise
                        return
                end
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            app.appController.onNewSelected();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            closeSplash(app);
            delete(app)
        end

        % Value changing function: PR4
        function PR4ValueChanging(app, event)
            app.appController.onDetonationReactantAngleChanging();
        end

        % Value changing function: PP4
        function PP4ValueChanging(app, event)
            app.appController.onDetonationProductAngleChanging();
        end

        % Value changing function: PR5
        function PR5ValueChanging(app, event)
            app.appController.onShockReactantAngleChanging();
        end

        % Value changing function: PP5
        function PP5ValueChanging(app, event)
            app.appController.onShockProductAngleChanging();
        end

        % Value changed function: IonizedspeciesCheckBox
        function IonizedspeciesCheckBoxValueChanged(app, event)
            app.appController.onIonizedSpeciesChanged();
        end

        % Callback function: Tree_variable_x
        function Tree_variable_xCheckedNodesChanged(app, event)
            % Do not allow selection of two or more variables in the x axis
            if isscalar(app.Tree_variable_x.CheckedNodes) || isempty(app.Tree_variable_x.CheckedNodes)
                return
            end

            if app.Tree_variable_x.CheckedNodes(1) == event.PreviousCheckedNodes
                app.Tree_variable_x.CheckedNodes(1) = [];
            else
                app.Tree_variable_x.CheckedNodes(2) = [];
            end
        end

        % Callback function: Tree_variable_y
        function Tree_variable_yCheckedNodesChanged(app, event)
            % If molar fraction or mass fraction is selected, do not allow
            % selection of two or more variables in the y axis
            
            if isscalar(app.Tree_variable_y.CheckedNodes) || isempty(app.Tree_variable_y.CheckedNodes)
                return
            end

            if sum(contains([app.Tree_variable_y.CheckedNodes.Text], {'Xi', 'Yi', 'molarFraction', 'massFraction'})) == 0
                return
            end
            
            if sum(contains([event.PreviousCheckedNodes.Text], {'Xi', 'Yi', 'molarFraction', 'massFraction'}))
                indexRemove = [];
                
                for i = 1:length(app.Tree_variable_y.CheckedNodes)
                    if any(strcmpi(app.Tree_variable_y.CheckedNodes(i).Text, {'Xi', 'Yi', 'molarFraction', 'massFraction'}))
                        indexRemove = [indexRemove, i];
                    end
                end

                app.Tree_variable_y.CheckedNodes(indexRemove) = [];
                return
            end

            if sum(contains([event.CheckedNodes.Text], {'Xi', 'Yi', 'molarFraction', 'massFraction'}))
                indexRemove = [];
                
                for i = 1:length(app.Tree_variable_y.CheckedNodes)
                    if ~strcmpi(app.Tree_variable_y.CheckedNodes(i).Text, {'Xi', 'Yi', 'molarFraction', 'massFraction'})
                        indexRemove = [indexRemove, i];
                    end
                end

                app.Tree_variable_y.CheckedNodes(indexRemove) = [];
            end
        end

        % Menu selected function: RemoveMenu_2
        function RemoveMenu_2Selected(app, event)
            % Checks
            if isempty(event.ContextObject.Selection)
                message = 'To remove species from the list of reactants, please select them first.';
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return
            end
            
            % Definitions
            FLAG_REMOVE = event.ContextObject.Selection(:, 1);

            % Remove selected species
            event.ContextObject.Data(FLAG_REMOVE, :) = [];
            
            % Update values of the UITable items with the changes made
            app.appController.onReactantsTableEdited(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Combustion Toolbox';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'logo_CT_noversion.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Enable = 'off';
            app.OpenMenu.Text = 'Open';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Separator = 'on';
            app.SaveasMenu.Text = 'Save as';
            app.xlsMenu = uimenu(app.SaveasMenu);
            app.xlsMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsMenuSelected, true);
            app.xlsMenu.Text = '*.xls';
            app.matMenu = uimenu(app.SaveasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '*.mat';
            app.scriptMenu = uimenu(app.SaveasMenu);
            app.scriptMenu.MenuSelectedFcn = createCallbackFcn(app, @scriptMenuSelected, true);
            app.scriptMenu.Text = '*.m';
            app.SnapshotMenu = uimenu(app.FileMenu);
            app.SnapshotMenu.MenuSelectedFcn = createCallbackFcn(app, @SnapshotMenuSelected, true);
            app.SnapshotMenu.Separator = 'on';
            app.SnapshotMenu.Text = 'Snapshot';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Separator = 'on';
            app.PreferencesMenu.Text = 'Preferences';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.TutorialMenu = uimenu(app.HelpMenu);
            app.TutorialMenu.Enable = 'off';
            app.TutorialMenu.Text = 'Tutorial';
            app.ExamplesMenu = uimenu(app.HelpMenu);
            app.ExamplesMenu.Enable = 'off';
            app.ExamplesMenu.Text = 'Examples';
            app.ValidationsMenu = uimenu(app.HelpMenu);
            app.ValidationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidationsMenuSelected, true);
            app.ValidationsMenu.Text = 'Validations';
            app.DiscussionsMenu = uimenu(app.HelpMenu);
            app.DiscussionsMenu.MenuSelectedFcn = createCallbackFcn(app, @DiscussionsMenuSelected, true);
            app.DiscussionsMenu.Text = 'Discussions';
            app.CheckforupdatesMenu = uimenu(app.HelpMenu);
            app.CheckforupdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforupdatesMenuSelected, true);
            app.CheckforupdatesMenu.Text = 'Check for updates';
            app.LicenseMenu = uimenu(app.HelpMenu);
            app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
            app.LicenseMenu.Separator = 'on';
            app.LicenseMenu.Text = 'License';
            app.SendfeedbackMenu = uimenu(app.HelpMenu);
            app.SendfeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @SendfeedbackMenuSelected, true);
            app.SendfeedbackMenu.Separator = 'on';
            app.SendfeedbackMenu.Text = 'Send feedback';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.Tab_lateral_bar = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.Tab_lateral_bar);
            app.SetupTab.Title = 'Setup';
            app.TabGroup2 = uitabgroup(app.SetupTab);
            app.InputsTab = uitab(app.TabGroup2);
            app.InputsTab.Title = 'Inputs';
            app.DefinereactantsandspeciestobeconsideredPanel = uipanel(app.InputsTab);
            app.DefinereactantsandspeciestobeconsideredPanel.Title = 'Define reactants and species to be considered';
            app.UITable_R = uitable(app.DefinereactantsandspeciestobeconsideredPanel);
            app.UITable_R.ColumnName = {'Species'; 'Nº moles'; 'Mole fraction'; 'Type'; 'Temperature [K]'};
            app.UITable_R.RowName = {};
            app.UITable_R.ColumnSortable = true;
            app.UITable_R.ColumnEditable = [true true false true true];
            app.UITable_R.CellEditCallback = createCallbackFcn(app, @UITable_RCellEdit, true);
            app.ReactantsPanel = uipanel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.ReactantsPanel.Title = 'Reactants';
            app.Reactants = uidropdown(app.ReactantsPanel);
            app.Reactants.Items = {'', 'Air', 'Methane + Air', 'Ethane + Air', 'Propane + Air', 'Acetylene + Air', 'Ethylene + Air', 'Bencene + Air', 'Iso-octane + Air', 'Hydrogen + Air', 'Carbon monoxide + Air', 'Methanol + Air', 'Ethanol + Air', 'Natural Gas + Air', 'Syngas + Air', 'LH2 + LOX', 'RP1 + LOX'};
            app.Reactants.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.Reactants.Editable = 'on';
            app.Reactants.ValueChangedFcn = createCallbackFcn(app, @ReactantsValueChanged, true);
            app.Reactants.Value = 1;
            app.ListofSpeciesPanel = uipanel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.ListofSpeciesPanel.Title = 'List of Species';
            app.listbox_Products = uilistbox(app.ListofSpeciesPanel);
            app.listbox_Products.Items = {};
            app.listbox_Products.Multiselect = 'on';
            app.listbox_Products.Value = {};
            app.OFEditFieldLabel = uilabel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.OFEditFieldLabel.Text = 'O/F';
            app.edit_OF = uieditfield(app.DefinereactantsandspeciestobeconsideredPanel, 'numeric');
            app.edit_phi = uieditfield(app.DefinereactantsandspeciestobeconsideredPanel, 'text');
            app.edit_phi.ValueChangedFcn = createCallbackFcn(app, @edit_phiValueChanged, true);
            app.edit_phi.Value = '-';
            app.text_phi = uilabel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.text_phi.Text = 'phi';
            app.FuelEditFieldLabel = uilabel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.FuelEditFieldLabel.Text = '% Fuel';
            app.ProductsPanel = uipanel(app.DefinereactantsandspeciestobeconsideredPanel);
            app.ProductsPanel.Title = 'Products';
            app.Products = uidropdown(app.ProductsPanel);
            app.Products.Items = {'', 'Complete reaction', 'HC/O2/N2', 'HC/O2/N2 extended', 'HC/O2/N2 rich', 'HC/O2/N2 Propellants', 'Soot formation', 'Soot formation Extended', 'Hydrogen', 'Hydrogen (L)', 'Hydrogen ions', 'Dissociated air', 'Air ions'};
            app.Products.Editable = 'on';
            app.Products.ValueChangedFcn = createCallbackFcn(app, @ProductsValueChanged, true);
            app.Products.Value = '';
            app.edit_F = uieditfield(app.DefinereactantsandspeciestobeconsideredPanel, 'numeric');
            app.PeriodicTable_R = uibutton(app.DefinereactantsandspeciestobeconsideredPanel, 'push');
            app.PeriodicTable_R.ButtonPushedFcn = createCallbackFcn(app, @PeriodicTable_RButtonPushed, true);
            app.PeriodicTable_R.Tag = 'R';
            app.PeriodicTable_R.Icon = fullfile(pathToMLAPP, 'assets', 'logo_uielements.svg');
            app.PeriodicTable_R.Tooltip = {''};
            app.PeriodicTable_R.Text = '';
            app.PeriodicTable_P = uibutton(app.DefinereactantsandspeciestobeconsideredPanel, 'push');
            app.PeriodicTable_P.ButtonPushedFcn = createCallbackFcn(app, @PeriodicTable_PButtonPushed, true);
            app.PeriodicTable_P.Tag = 'P';
            app.PeriodicTable_P.Icon = fullfile(pathToMLAPP, 'assets', 'logo_uielements.svg');
            app.PeriodicTable_P.Tooltip = {''};
            app.PeriodicTable_P.Text = '';
            app.DefinestateofreactantsandproductsPanel = uipanel(app.InputsTab);
            app.DefinestateofreactantsandproductsPanel.Title = 'Define state of reactants and products';
            app.PP2 = uieditfield(app.DefinestateofreactantsandproductsPanel, 'text');
            app.PP2.ValueChangedFcn = createCallbackFcn(app, @PP2ValueChanged, true);
            app.PP2.Value = '1';
            app.PP1 = uieditfield(app.DefinestateofreactantsandproductsPanel, 'text');
            app.PP1.Value = '2500';
            app.PR1 = uieditfield(app.DefinestateofreactantsandproductsPanel, 'text');
            app.PR1.ValueChangedFcn = createCallbackFcn(app, @PR1ValueChanged, true);
            app.PR1.Value = '300';
            app.text_RP1 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_RP1.Text = 'Temperature [K]';
            app.text_R1 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_R1.Text = 'Reactants';
            app.text_P1 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_P1.Text = 'Products';
            app.PR2 = uieditfield(app.DefinestateofreactantsandproductsPanel, 'text');
            app.PR2.ValueChangedFcn = createCallbackFcn(app, @PR2ValueChanged, true);
            app.PR2.Value = '1';
            app.text_RP2 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_RP2.Text = 'Pressure [bar]';
            app.text_RP1_2 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_RP1_2.Visible = 'off';
            app.text_RP1_2.Text = 'Area ratio A_c/A_t';
            app.text_RP2_2 = uilabel(app.DefinestateofreactantsandproductsPanel);
            app.text_RP2_2.Visible = 'off';
            app.text_RP2_2.Text = 'Mass flux [kg/s]';
            app.AdditionalconstraintsPanel = uipanel(app.InputsTab);
            app.AdditionalconstraintsPanel.Title = 'Additional constraints';
            app.AdditionalconstraintsPanel.Visible = 'off';
            app.text_R2 = uilabel(app.AdditionalconstraintsPanel);
            app.text_R2.Text = 'Reactants';
            app.text_RP = uilabel(app.AdditionalconstraintsPanel);
            app.text_RP.Text = 'Products';
            app.PR3 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PR3.ValueChangedFcn = createCallbackFcn(app, @PR3ValueChanged, true);
            app.PR3.ValueChangingFcn = createCallbackFcn(app, @PR3ValueChanging, true);
            app.PR3.Value = '500';
            app.text_RP3 = uilabel(app.AdditionalconstraintsPanel);
            app.text_RP3.Text = 'Constant Enthalpy: hP = hR';
            app.PR4 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PR4.ValueChangedFcn = createCallbackFcn(app, @PR4ValueChanged, true);
            app.PR4.ValueChangingFcn = createCallbackFcn(app, @PR4ValueChanging, true);
            app.PR4.Value = '1';
            app.text_RP4 = uilabel(app.AdditionalconstraintsPanel);
            app.text_RP4.Text = 'Pressure [bar]';
            app.PP4 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PP4.ValueChangingFcn = createCallbackFcn(app, @PP4ValueChanging, true);
            app.PP4.Value = '1';
            app.PP3 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PP3.ValueChangingFcn = createCallbackFcn(app, @PP3ValueChanging, true);
            app.PP3.Value = '2500';
            app.text_P2 = uilabel(app.AdditionalconstraintsPanel);
            app.text_P2.Text = 'Products';
            app.PR5 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PR5.ValueChangingFcn = createCallbackFcn(app, @PR5ValueChanging, true);
            app.PR5.Value = '1';
            app.text_RP5 = uilabel(app.AdditionalconstraintsPanel);
            app.text_RP5.Text = 'Wave/Deflection angle [deg]';
            app.PP5 = uieditfield(app.AdditionalconstraintsPanel, 'text');
            app.PP5.ValueChangingFcn = createCallbackFcn(app, @PP5ValueChanging, true);
            app.PP5.Value = '1';
            app.Clear = uibutton(app.InputsTab, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.Calculate = uibutton(app.InputsTab, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Calculate.Text = 'Calculate';
            app.SelectProblemTypePanel = uipanel(app.InputsTab);
            app.SelectProblemTypePanel.Title = 'Select Problem Type';
            app.ProblemType = uidropdown(app.SelectProblemTypePanel);
            app.ProblemType.Items = {'TP:  Equilibrium composition at defined T and P', 'HP: Adiabatic T and composition at constant P', 'SP:  Isentropic compression/expansion to a specified P', 'TV: Equilibrium composition at defined T and constant V', 'EV: Adiabatic T and composition at constant V', 'SV: Isentropic compresion/expansion to a specified V', 'SHOCK_I: Planar incident shock wave', 'SHOCK_R: Planar reflected shock wave', 'SHOCK_OBLIQUE: Oblique incident shock wave', 'SHOCK_OBLIQUE_R: Oblique reflected shock wave', 'SHOCK_POLAR: Polar shocks', 'SHOCK_POLAR_R: Polar shocks (regular reflections)', 'DET: Chapman-Jouguet Detonation', 'DET_OVERDRIVEN: Overdriven detonation', 'DET_UNDERDRIVEN: Underdriven detonation', 'DET_R: Reflected Chapman-Jouguet detonation', 'DET_OVERDRIVEN_R: Overdriven reflected detonation', 'DET_UNDERDRIVEN_R: Underdriven reflected detonation', 'DET_OBLIQUE: Oblique incident detonation', 'DET_POLAR: Polar detonations', 'ROCKET: Rocket propellant performance', 'SHOCKTURBULENCE_VORTICAL: Vortical shock-turbulence interaction', 'SHOCKTURBULENCE_VORTICAL_ENTROPIC: Vortical-entropic shock-turbulence interaction', 'SHOCKTURBULENCE_ACOUSTIC: Acoustic shock-turbulence interaction', 'SHOCKTURBULENCE_COMPRESSIBLE: Compressible shock-turbulence interaction'};
            app.ProblemType.ItemsData = {'TP', 'HP', 'SP', 'TV', 'EV', 'SV', 'SHOCK_I', 'SHOCK_R', 'SHOCK_OBLIQUE', 'SHOCK_OBLIQUE_R', 'SHOCK_POLAR', 'SHOCK_POLAR_R', 'DET', 'DET_OVERDRIVEN', 'DET_UNDERDRIVEN', 'DET_R', 'DET_OVERDRIVEN_R', 'DET_UNDERDRIVEN_R', 'DET_OBLIQUE', 'DET_POLAR', 'ROCKET', 'SHOCKTURBULENCE_VORTICAL', 'SHOCKTURBULENCE_VORTICAL_ENTROPIC', 'SHOCKTURBULENCE_ACOUSTIC', 'SHOCKTURBULENCE_COMPRESSIBLE'};
            app.ProblemType.ValueChangedFcn = createCallbackFcn(app, @ProblemTypeValueChanged, true);
            app.ProblemType.Value = 'TP';
            app.FrozenchemistryCheckBox = uicheckbox(app.SelectProblemTypePanel);
            app.FrozenchemistryCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrozenchemistryCheckBoxValueChanged, true);
            app.FrozenchemistryCheckBox.Text = 'Frozen chemistry';
            app.IonizedspeciesCheckBox = uicheckbox(app.SelectProblemTypePanel);
            app.IonizedspeciesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IonizedspeciesCheckBoxValueChanged, true);
            app.IonizedspeciesCheckBox.Text = 'Ionized species';
            app.FLAG_IAC = uicheckbox(app.SelectProblemTypePanel);
            app.FLAG_IAC.ValueChangedFcn = createCallbackFcn(app, @FLAG_IACValueChanged, true);
            app.FLAG_IAC.Visible = 'off';
            app.FLAG_IAC.Text = 'IAC model';
            app.FLAG_IAC.Value = true;
            app.QuicksettingsTab = uitab(app.TabGroup2);
            app.QuicksettingsTab.Title = 'Quick settings';
            app.TabGroup4 = uitabgroup(app.QuicksettingsTab);
            app.SelectionofspeciesTab = uitab(app.TabGroup4);
            app.SelectionofspeciesTab.Title = 'Selection of species';
            app.text_list_species = uilabel(app.SelectionofspeciesTab);
            app.text_list_species.Text = 'Search Species';
            app.edit_seeker_1 = uieditfield(app.SelectionofspeciesTab, 'text');
            app.edit_seeker_1.ValueChangingFcn = createCallbackFcn(app, @edit_seeker_1ValueChanging, true);
            app.text_LS_DB = uilabel(app.SelectionofspeciesTab);
            app.text_LS_DB.Text = 'List of species DB';
            app.listbox_LS_DB = uilistbox(app.SelectionofspeciesTab);
            app.listbox_LS_DB.Items = {};
            app.listbox_LS_DB.Multiselect = 'on';
            app.listbox_LS_DB.Value = {};
            app.AddButton1 = uibutton(app.SelectionofspeciesTab, 'push');
            app.AddButton1.ButtonPushedFcn = createCallbackFcn(app, @AddButton1Pushed, true);
            app.AddButton1.Text = 'Add >>';
            app.RemoveButton1 = uibutton(app.SelectionofspeciesTab, 'push');
            app.RemoveButton1.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton1Pushed, true);
            app.RemoveButton1.Text = 'Remove <<';
            app.text_LS = uilabel(app.SelectionofspeciesTab);
            app.text_LS.Text = 'List of species';
            app.listbox_LS = uilistbox(app.SelectionofspeciesTab);
            app.listbox_LS.Items = {};
            app.listbox_LS.Multiselect = 'on';
            app.listbox_LS.Value = {};
            app.PeriodicTable_P_2 = uibutton(app.SelectionofspeciesTab, 'push');
            app.PeriodicTable_P_2.ButtonPushedFcn = createCallbackFcn(app, @PeriodicTable_PButtonPushed, true);
            app.PeriodicTable_P_2.Tag = 'P';
            app.PeriodicTable_P_2.Icon = fullfile(pathToMLAPP, 'assets', 'logo_uielements.svg');
            app.PeriodicTable_P_2.Tooltip = {''};
            app.PeriodicTable_P_2.Text = '';
            app.DisplayspeciesTab = uitab(app.TabGroup4);
            app.DisplayspeciesTab.Title = 'Display species';
            app.text_list_species_2 = uilabel(app.DisplayspeciesTab);
            app.text_list_species_2.Text = 'Search Species';
            app.edit_seeker_2 = uieditfield(app.DisplayspeciesTab, 'text');
            app.edit_seeker_2.ValueChangingFcn = createCallbackFcn(app, @edit_seeker_2ValueChanging, true);
            app.text_LS_2 = uilabel(app.DisplayspeciesTab);
            app.text_LS_2.Text = 'List of species';
            app.listbox_LS_2 = uilistbox(app.DisplayspeciesTab);
            app.listbox_LS_2.Items = {};
            app.listbox_LS_2.Multiselect = 'on';
            app.listbox_LS_2.Value = {};
            app.AddButton2 = uibutton(app.DisplayspeciesTab, 'push');
            app.AddButton2.ButtonPushedFcn = createCallbackFcn(app, @AddButton2Pushed, true);
            app.AddButton2.Text = 'Add >>';
            app.RemoveButton2 = uibutton(app.DisplayspeciesTab, 'push');
            app.RemoveButton2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton2Pushed, true);
            app.RemoveButton2.Text = 'Remove <<';
            app.listbox_LS_display = uilistbox(app.DisplayspeciesTab);
            app.listbox_LS_display.Items = {};
            app.listbox_LS_display.Multiselect = 'on';
            app.listbox_LS_display.Value = {};
            app.text_LS_display = uilabel(app.DisplayspeciesTab);
            app.text_LS_display.Text = 'Display species';
            app.TolerancesPanel = uipanel(app.QuicksettingsTab);
            app.TolerancesPanel.Title = 'Tolerances';
            app.TraceoptionEditFieldLabel = uilabel(app.TolerancesPanel);
            app.TraceoptionEditFieldLabel.Text = 'Trace option';
            app.TraceoptionEditField = uieditfield(app.TolerancesPanel, 'numeric');
            app.TraceoptionEditField.Limits = [0 Inf];
            app.TraceoptionEditField.ValueDisplayFormat = '%.1e';
            app.TraceoptionEditField.ValueChangedFcn = createCallbackFcn(app, @TraceoptionEditFieldValueChanged, true);
            app.TraceoptionEditField.Value = 1e-14;
            app.DisplaySpeciesEditFieldLabel = uilabel(app.TolerancesPanel);
            app.DisplaySpeciesEditFieldLabel.Text = 'Display Species';
            app.DisplaySpeciesEditField = uieditfield(app.TolerancesPanel, 'numeric');
            app.DisplaySpeciesEditField.ValueDisplayFormat = '%.1e';
            app.DisplaySpeciesEditField.ValueChangedFcn = createCallbackFcn(app, @DisplaySpeciesEditFieldValueChanged, true);
            app.DisplaySpeciesEditField.Value = 1e-06;
            app.RootFindingMethodEditFieldLabel = uilabel(app.TolerancesPanel);
            app.RootFindingMethodEditFieldLabel.Text = 'Root Finding Method';
            app.RootFindingMethodEditField = uieditfield(app.TolerancesPanel, 'numeric');
            app.RootFindingMethodEditField.Limits = [0 Inf];
            app.RootFindingMethodEditField.ValueDisplayFormat = '%.1e';
            app.RootFindingMethodEditField.ValueChangedFcn = createCallbackFcn(app, @RootFindingMethodEditFieldValueChanged, true);
            app.RootFindingMethodEditField.Value = 0.001;
            app.ShocksandDetonationsEditFieldLabel = uilabel(app.TolerancesPanel);
            app.ShocksandDetonationsEditFieldLabel.Text = 'Shocks and Detonations';
            app.ShocksandDetonationsEditField = uieditfield(app.TolerancesPanel, 'numeric');
            app.ShocksandDetonationsEditField.Limits = [0 Inf];
            app.ShocksandDetonationsEditField.ValueDisplayFormat = '%.1e';
            app.ShocksandDetonationsEditField.ValueChangedFcn = createCallbackFcn(app, @ShocksandDetonationsEditFieldValueChanged, true);
            app.ShocksandDetonationsEditField.Value = 5e-05;
            app.TuningparametersPanel = uipanel(app.QuicksettingsTab);
            app.TuningparametersPanel.Title = 'Tuning parameters';
            app.MaxiterationsRFMEditFieldLabel = uilabel(app.TuningparametersPanel);
            app.MaxiterationsRFMEditFieldLabel.Text = 'Max iterations RFM';
            app.MaxiterationsRFMEditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.MaxiterationsRFMEditField.Limits = [1 Inf];
            app.MaxiterationsRFMEditField.ValueDisplayFormat = '%d';
            app.MaxiterationsRFMEditField.ValueChangedFcn = createCallbackFcn(app, @MaxiterationsRFMEditFieldValueChanged, true);
            app.MaxiterationsRFMEditField.Value = 30;
            app.RFMT0_REditFieldLabel = uilabel(app.TuningparametersPanel);
            app.RFMT0_REditFieldLabel.Text = 'RFM T0_R';
            app.RFMT0_REditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.RFMT0_REditField.Limits = [0 Inf];
            app.RFMT0_REditField.ValueDisplayFormat = '%d K';
            app.RFMT0_REditField.ValueChangedFcn = createCallbackFcn(app, @RFMT0_REditFieldValueChanged, true);
            app.RFMT0_REditField.Value = 3000;
            app.RFMT0_LEditFieldLabel = uilabel(app.TuningparametersPanel);
            app.RFMT0_LEditFieldLabel.Text = 'RFM T0_L';
            app.RFMT0_LEditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.RFMT0_LEditField.Limits = [0 Inf];
            app.RFMT0_LEditField.ValueDisplayFormat = '%d K';
            app.RFMT0_LEditField.ValueChangedFcn = createCallbackFcn(app, @RFMT0_LEditFieldValueChanged, true);
            app.RFMT0_LEditField.Value = 1000;
            app.RFMT0EditFieldLabel = uilabel(app.TuningparametersPanel);
            app.RFMT0EditFieldLabel.Text = 'RFM T0';
            app.RFMT0EditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.RFMT0EditField.Limits = [0 Inf];
            app.RFMT0EditField.ValueDisplayFormat = '%d K';
            app.RFMT0EditField.ValueChangedFcn = createCallbackFcn(app, @RFMT0EditFieldValueChanged, true);
            app.RFMT0EditField.Value = 3000;
            app.MaxiterationsSDEditFieldLabel = uilabel(app.TuningparametersPanel);
            app.MaxiterationsSDEditFieldLabel.Text = 'Max iterations S&D';
            app.MaxiterationsSDEditField = uieditfield(app.TuningparametersPanel, 'numeric');
            app.MaxiterationsSDEditField.Limits = [1 Inf];
            app.MaxiterationsSDEditField.ValueDisplayFormat = '%d';
            app.MaxiterationsSDEditField.ValueChangedFcn = createCallbackFcn(app, @MaxiterationsSDEditFieldValueChanged, true);
            app.MaxiterationsSDEditField.Value = 50;
            app.OthersPanel = uipanel(app.QuicksettingsTab);
            app.OthersPanel.Title = 'Others';
            app.IdealAirCheckBox = uicheckbox(app.OthersPanel);
            app.IdealAirCheckBox.Text = 'Ideal Air';
            app.ReportPanel = uipanel(app.QuicksettingsTab);
            app.ReportPanel.Title = 'Report';
            app.TypeDropDownLabel = uilabel(app.ReportPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.Report_type = uidropdown(app.ReportPanel);
            app.Report_type.Items = {'None', 'Auto'};
            app.Report_type.Value = 'Auto';
            app.PrintresultsCheckBox = uicheckbox(app.ReportPanel);
            app.PrintresultsCheckBox.Text = 'Print results';
            app.PP6 = uieditfield(app.SetupTab, 'text');
            app.PP6.Visible = 'off';
            app.PP6.Value = '0';
            app.text_RP3_2 = uilabel(app.SetupTab);
            app.text_RP3_2.Visible = 'off';
            app.text_RP3_2.Text = 'etaVorticity [-]';
            app.ResultsTab = uitab(app.Tab_lateral_bar);
            app.ResultsTab.Title = 'Results';
            app.Tab_results = uitabgroup(app.ResultsTab);
            app.ParametersTab = uitab(app.Tab_results);
            app.ParametersTab.Title = 'Parameters';
            app.Panel_parameters = uipanel(app.ParametersTab);
            app.edit_phi3 = uieditfield(app.Panel_parameters, 'text');
            app.edit_phi3.Editable = 'off';
            app.edit_phi3.Value = '-';
            app.text_phi_3 = uilabel(app.Panel_parameters);
            app.text_phi_3.Text = 'phi';
            app.text_s = uilabel(app.Panel_parameters);
            app.text_s.Text = 'Entropy [kJ/(kg K)]';
            app.text_s_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_s_1.ValueDisplayFormat = '%.4g';
            app.text_s_1.Editable = 'off';
            app.text_s_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_s_2.ValueDisplayFormat = '%.4g';
            app.text_s_2.Editable = 'off';
            app.text_gamma = uilabel(app.Panel_parameters);
            app.text_gamma.Text = 'Adiabatic index [-]';
            app.text_gamma_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_gamma_1.ValueDisplayFormat = '%.4g';
            app.text_gamma_1.Editable = 'off';
            app.text_gamma_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_gamma_2.ValueDisplayFormat = '%.4g';
            app.text_gamma_2.Editable = 'off';
            app.text_M = uilabel(app.Panel_parameters);
            app.text_M.Visible = 'off';
            app.text_M.Text = 'Mach number [-]';
            app.text_M_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_M_2.ValueDisplayFormat = '%.4g';
            app.text_M_2.Editable = 'off';
            app.text_M_2.Visible = 'off';
            app.text_u = uilabel(app.Panel_parameters);
            app.text_u.Visible = 'off';
            app.text_u.Text = 'Flow velocity [m/s]';
            app.text_u_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_u_2.ValueDisplayFormat = '%.4g';
            app.text_u_2.Editable = 'off';
            app.text_u_2.Visible = 'off';
            app.text_e = uilabel(app.Panel_parameters);
            app.text_e.Text = 'Internal energy [kJ/kg]';
            app.text_e_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_e_1.ValueDisplayFormat = '%.4g';
            app.text_e_1.Editable = 'off';
            app.text_e_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_e_2.ValueDisplayFormat = '%.4g';
            app.text_e_2.Editable = 'off';
            app.text_sound = uilabel(app.Panel_parameters);
            app.text_sound.Text = 'Sound speed [m/s]';
            app.text_sound_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_sound_1.ValueDisplayFormat = '%.4g';
            app.text_sound_1.Editable = 'off';
            app.text_sound_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_sound_2.ValueDisplayFormat = '%.4g';
            app.text_sound_2.Editable = 'off';
            app.text_W = uilabel(app.Panel_parameters);
            app.text_W.Text = 'Molecular Weight [g/mol]';
            app.text_W_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_W_1.ValueDisplayFormat = '%.4g';
            app.text_W_1.Editable = 'off';
            app.text_W_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_W_2.ValueDisplayFormat = '%.4g';
            app.text_W_2.Editable = 'off';
            app.text_cp = uilabel(app.Panel_parameters);
            app.text_cp.Text = 'Specific heat cp [kJ/(kg K)]';
            app.text_cp_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_cp_1.ValueDisplayFormat = '%.4g';
            app.text_cp_1.Editable = 'off';
            app.text_cp_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_cp_2.ValueDisplayFormat = '%.4g';
            app.text_cp_2.Editable = 'off';
            app.text_h = uilabel(app.Panel_parameters);
            app.text_h.Text = 'Enthalpy [kJ/kg]';
            app.text_h_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_h_1.ValueDisplayFormat = '%.4g';
            app.text_h_1.Editable = 'off';
            app.text_h_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_h_2.ValueDisplayFormat = '%.4g';
            app.text_h_2.Editable = 'off';
            app.text_r = uilabel(app.Panel_parameters);
            app.text_r.Text = 'Density [kg/m3]';
            app.text_r_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_r_1.ValueDisplayFormat = '%.4g';
            app.text_r_1.Editable = 'off';
            app.text_r_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_r_2.ValueDisplayFormat = '%.4g';
            app.text_r_2.Editable = 'off';
            app.text_p = uilabel(app.Panel_parameters);
            app.text_p.Text = 'Pressure [bar]';
            app.text_p_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_p_2.Limits = [0 Inf];
            app.text_p_2.ValueDisplayFormat = '%.4g';
            app.text_p_2.Editable = 'off';
            app.text_Products = uilabel(app.Panel_parameters);
            app.text_Products.Text = 'Products';
            app.text_Reactans = uilabel(app.Panel_parameters);
            app.text_Reactans.Text = 'Reactants';
            app.text_T = uilabel(app.Panel_parameters);
            app.text_T.Text = 'Temperature [K]';
            app.text_T_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_T_1.ValueDisplayFormat = '%.4g';
            app.text_T_1.Editable = 'off';
            app.text_p_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_p_1.ValueDisplayFormat = '%.4g';
            app.text_p_1.Editable = 'off';
            app.text_T_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_T_2.ValueDisplayFormat = '%.4g';
            app.text_T_2.Editable = 'off';
            app.text_u_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_u_1.ValueDisplayFormat = '%.4g';
            app.text_u_1.Editable = 'off';
            app.text_u_1.Visible = 'off';
            app.text_M_1 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_M_1.ValueDisplayFormat = '%.4g';
            app.text_M_1.Editable = 'off';
            app.text_M_1.Visible = 'off';
            app.text_Aratio = uilabel(app.Panel_parameters);
            app.text_Aratio.Visible = 'off';
            app.text_Aratio.Text = 'Area ratio A/A_t [-]';
            app.text_Aratio_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_Aratio_2.ValueDisplayFormat = '%.4g';
            app.text_Aratio_2.Editable = 'off';
            app.text_Aratio_2.Visible = 'off';
            app.text_Cstar = uilabel(app.Panel_parameters);
            app.text_Cstar.Visible = 'off';
            app.text_Cstar.Text = 'Charac. velocity [m/s]';
            app.text_Ivac = uilabel(app.Panel_parameters);
            app.text_Ivac.Visible = 'off';
            app.text_Ivac.Text = 'Specific impulse vac [s]';
            app.text_Isp = uilabel(app.Panel_parameters);
            app.text_Isp.Visible = 'off';
            app.text_Isp.Text = 'Specific impulse [s]';
            app.Panel_extra_3 = uipanel(app.Panel_parameters);
            app.Panel_extra_3.Visible = 'off';
            app.text_s_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_s_3.ValueDisplayFormat = '%.4g';
            app.text_s_3.Editable = 'off';
            app.text_gamma_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_gamma_3.ValueDisplayFormat = '%.4g';
            app.text_gamma_3.Editable = 'off';
            app.text_M_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_M_3.ValueDisplayFormat = '%.4g';
            app.text_M_3.Editable = 'off';
            app.text_u_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_u_3.ValueDisplayFormat = '%.4g';
            app.text_u_3.Editable = 'off';
            app.text_e_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_e_3.ValueDisplayFormat = '%.4g';
            app.text_e_3.Editable = 'off';
            app.text_sound_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_sound_3.ValueDisplayFormat = '%.4g';
            app.text_sound_3.Editable = 'off';
            app.text_W_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_W_3.ValueDisplayFormat = '%.4g';
            app.text_W_3.Editable = 'off';
            app.text_cp_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_cp_3.ValueDisplayFormat = '%.4g';
            app.text_cp_3.Editable = 'off';
            app.text_h_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_h_3.ValueDisplayFormat = '%.4g';
            app.text_h_3.Editable = 'off';
            app.text_r_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_r_3.ValueDisplayFormat = '%.4g';
            app.text_r_3.Editable = 'off';
            app.text_p_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_p_3.Limits = [0 Inf];
            app.text_p_3.ValueDisplayFormat = '%.4g';
            app.text_p_3.Editable = 'off';
            app.text_Products_3 = uilabel(app.Panel_extra_3);
            app.text_Products_3.Text = 'Throat';
            app.text_T_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_T_3.ValueDisplayFormat = '%.4g';
            app.text_T_3.Editable = 'off';
            app.text_Aratio_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_Aratio_3.ValueDisplayFormat = '%.4g';
            app.text_Aratio_3.Editable = 'off';
            app.text_Cstar_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_Cstar_3.ValueDisplayFormat = '%.4g';
            app.text_Cstar_3.Editable = 'off';
            app.text_Ivac_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_Ivac_3.ValueDisplayFormat = '%.4g';
            app.text_Ivac_3.Editable = 'off';
            app.text_Isp_3 = uieditfield(app.Panel_extra_3, 'numeric');
            app.text_Isp_3.ValueDisplayFormat = '%.4g';
            app.text_Isp_3.Editable = 'off';
            app.Panel_extra_4 = uipanel(app.Panel_parameters);
            app.Panel_extra_4.Visible = 'off';
            app.text_s_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_s_4.ValueDisplayFormat = '%.4g';
            app.text_s_4.Editable = 'off';
            app.text_gamma_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_gamma_4.ValueDisplayFormat = '%.4g';
            app.text_gamma_4.Editable = 'off';
            app.text_M_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_M_4.ValueDisplayFormat = '%.4g';
            app.text_M_4.Editable = 'off';
            app.text_u_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_u_4.ValueDisplayFormat = '%.4g';
            app.text_u_4.Editable = 'off';
            app.text_e_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_e_4.ValueDisplayFormat = '%.4g';
            app.text_e_4.Editable = 'off';
            app.text_sound_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_sound_4.ValueDisplayFormat = '%.4g';
            app.text_sound_4.Editable = 'off';
            app.text_W_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_W_4.ValueDisplayFormat = '%.4g';
            app.text_W_4.Editable = 'off';
            app.text_cp_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_cp_4.ValueDisplayFormat = '%.4g';
            app.text_cp_4.Editable = 'off';
            app.text_h_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_h_4.ValueDisplayFormat = '%.4g';
            app.text_h_4.Editable = 'off';
            app.text_r_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_r_4.ValueDisplayFormat = '%.4g';
            app.text_r_4.Editable = 'off';
            app.text_p_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_p_4.Limits = [0 Inf];
            app.text_p_4.ValueDisplayFormat = '%.4g';
            app.text_p_4.Editable = 'off';
            app.text_Products_4 = uilabel(app.Panel_extra_4);
            app.text_Products_4.Text = 'Exit';
            app.text_T_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_T_4.ValueDisplayFormat = '%.4g';
            app.text_T_4.Editable = 'off';
            app.text_Aratio_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_Aratio_4.ValueDisplayFormat = '%.4g';
            app.text_Aratio_4.Editable = 'off';
            app.text_Cstar_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_Cstar_4.ValueDisplayFormat = '%.4g';
            app.text_Cstar_4.Editable = 'off';
            app.text_Ivac_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_Ivac_4.ValueDisplayFormat = '%.4g';
            app.text_Ivac_4.Editable = 'off';
            app.text_Isp_4 = uieditfield(app.Panel_extra_4, 'numeric');
            app.text_Isp_4.ValueDisplayFormat = '%.4g';
            app.text_Isp_4.Editable = 'off';
            app.Panel_extra_5 = uipanel(app.Panel_parameters);
            app.Panel_extra_5.Visible = 'off';
            app.text_s_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_s_5.ValueDisplayFormat = '%.4g';
            app.text_s_5.Editable = 'off';
            app.text_gamma_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_gamma_5.ValueDisplayFormat = '%.4g';
            app.text_gamma_5.Editable = 'off';
            app.text_M_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_M_5.ValueDisplayFormat = '%.4g';
            app.text_M_5.Editable = 'off';
            app.text_u_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_u_5.ValueDisplayFormat = '%.4g';
            app.text_u_5.Editable = 'off';
            app.text_e_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_e_5.ValueDisplayFormat = '%.4g';
            app.text_e_5.Editable = 'off';
            app.text_sound_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_sound_5.ValueDisplayFormat = '%.4g';
            app.text_sound_5.Editable = 'off';
            app.text_W_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_W_5.ValueDisplayFormat = '%.4g';
            app.text_W_5.Editable = 'off';
            app.text_cp_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_cp_5.ValueDisplayFormat = '%.4g';
            app.text_cp_5.Editable = 'off';
            app.text_h_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_h_5.ValueDisplayFormat = '%.4g';
            app.text_h_5.Editable = 'off';
            app.text_r_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_r_5.ValueDisplayFormat = '%.4g';
            app.text_r_5.Editable = 'off';
            app.text_p_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_p_5.Limits = [0 Inf];
            app.text_p_5.ValueDisplayFormat = '%.4g';
            app.text_p_5.Editable = 'off';
            app.text_Products_5 = uilabel(app.Panel_extra_5);
            app.text_Products_5.Text = 'Exit';
            app.text_T_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_T_5.ValueDisplayFormat = '%.4g';
            app.text_T_5.Editable = 'off';
            app.text_Aratio_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_Aratio_5.ValueDisplayFormat = '%.4g';
            app.text_Aratio_5.Editable = 'off';
            app.text_Cstar_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_Cstar_5.ValueDisplayFormat = '%.4g';
            app.text_Cstar_5.Editable = 'off';
            app.text_Ivac_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_Ivac_5.ValueDisplayFormat = '%.4g';
            app.text_Ivac_5.Editable = 'off';
            app.text_Isp_5 = uieditfield(app.Panel_extra_5, 'numeric');
            app.text_Isp_5.ValueDisplayFormat = '%.4g';
            app.text_Isp_5.Editable = 'off';
            app.text_error_problem = uieditfield(app.Panel_parameters, 'numeric');
            app.text_error_problem.ValueDisplayFormat = '%11.4e';
            app.text_error_problem.Editable = 'off';
            app.EpsilonmolesLabel_2 = uilabel(app.Panel_parameters);
            app.EpsilonmolesLabel_2.Text = 'Epsilon (method)';
            app.text_Cstar_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_Cstar_2.ValueDisplayFormat = '%.4g';
            app.text_Cstar_2.Editable = 'off';
            app.text_Cstar_2.Visible = 'off';
            app.text_Ivac_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_Ivac_2.ValueDisplayFormat = '%.4g';
            app.text_Ivac_2.Editable = 'off';
            app.text_Ivac_2.Visible = 'off';
            app.text_Isp_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_Isp_2.ValueDisplayFormat = '%.4g';
            app.text_Isp_2.Editable = 'off';
            app.text_Isp_2.Visible = 'off';
            app.text_beta_min = uilabel(app.Panel_parameters);
            app.text_beta_min.Visible = 'off';
            app.text_beta_min.Text = 'Min wave angle [deg]';
            app.text_beta = uilabel(app.Panel_parameters);
            app.text_beta.Visible = 'off';
            app.text_beta.Text = 'Wave angle [deg]';
            app.text_theta = uilabel(app.Panel_parameters);
            app.text_theta.Visible = 'off';
            app.text_theta.Text = 'Deflection angle [deg]';
            app.text_beta_min_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_beta_min_2.ValueDisplayFormat = '%.4g';
            app.text_beta_min_2.Editable = 'off';
            app.text_beta_min_2.Visible = 'off';
            app.text_beta_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_beta_2.ValueDisplayFormat = '%.4g';
            app.text_beta_2.Editable = 'off';
            app.text_beta_2.Visible = 'off';
            app.text_theta_2 = uieditfield(app.Panel_parameters, 'numeric');
            app.text_theta_2.ValueDisplayFormat = '%.4g';
            app.text_theta_2.Editable = 'off';
            app.text_theta_2.Visible = 'off';
            app.TurbulencestatisticsTab = uitab(app.Tab_results);
            app.TurbulencestatisticsTab.Title = 'Turbulence statistics';
            app.Panel_parameters_2 = uipanel(app.TurbulencestatisticsTab);
            app.edit_chi = uieditfield(app.Panel_parameters_2, 'text');
            app.edit_chi.Editable = 'off';
            app.edit_chi.Value = '-';
            app.text_phi_4 = uilabel(app.Panel_parameters_2);
            app.text_phi_4.Text = 'chi';
            app.text_Ka = uilabel(app.Panel_parameters_2);
            app.text_Ka.Text = 'Acoustic TKE amplification ratio, Ka [-]';
            app.text_Ka_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_Ka_2.ValueDisplayFormat = '%.4g';
            app.text_Ka_2.Editable = 'off';
            app.text_RTTa = uilabel(app.Panel_parameters_2);
            app.text_RTTa.Text = 'Acoustic transverse TKE amplification ratio, RTTa [-]';
            app.text_RTTa_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_RTTa_2.ValueDisplayFormat = '%.4g';
            app.text_RTTa_2.Editable = 'off';
            app.text_Kolmogorov = uilabel(app.Panel_parameters_2);
            app.text_Kolmogorov.Text = 'Kolmogorov length scale ratio, L [-]';
            app.text_Kolmogorov_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_Kolmogorov_2.ValueDisplayFormat = '%.4g';
            app.text_Kolmogorov_2.Editable = 'off';
            app.text_RTTr = uilabel(app.Panel_parameters_2);
            app.text_RTTr.Text = 'Rotational transverse TKE amplification ratio, RTTr [-]';
            app.text_RTTr_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_RTTr_2.ValueDisplayFormat = '%.4g';
            app.text_RTTr_2.Editable = 'off';
            app.text_EnstrophyTT = uilabel(app.Panel_parameters_2);
            app.text_EnstrophyTT.Text = 'Transverse enstrophy amplification ratio, WTT [-]';
            app.text_EnstrophyTT_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_EnstrophyTT_2.ValueDisplayFormat = '%.4g';
            app.text_EnstrophyTT_2.Editable = 'off';
            app.text_R11r = uilabel(app.Panel_parameters_2);
            app.text_R11r.Text = 'Rotational streamwise TKE amplification ratio, R11r [-]';
            app.text_R11r_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_R11r_2.ValueDisplayFormat = '%.4g';
            app.text_R11r_2.Editable = 'off';
            app.text_Kr = uilabel(app.Panel_parameters_2);
            app.text_Kr.Text = 'Rotational TKE amplification ratio, Kr [-]';
            app.text_Kr_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_Kr_2.ValueDisplayFormat = '%.4g';
            app.text_Kr_2.Editable = 'off';
            app.text_R11a = uilabel(app.Panel_parameters_2);
            app.text_R11a.Text = 'Acoustic streamwise TKE amplification ratio, R11a [-]';
            app.text_R11a_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_R11a_2.ValueDisplayFormat = '%.4g';
            app.text_R11a_2.Editable = 'off';
            app.text_Enstrophy = uilabel(app.Panel_parameters_2);
            app.text_Enstrophy.Text = 'Enstrophy amplification ratio, W [-]';
            app.text_Enstrophy_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_Enstrophy_2.ValueDisplayFormat = '%.4g';
            app.text_Enstrophy_2.Editable = 'off';
            app.text_RTT = uilabel(app.Panel_parameters_2);
            app.text_RTT.Text = 'Transverse turbulent kinetic energy amplification ratio, RTT [-]';
            app.text_RTT_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_RTT_2.ValueDisplayFormat = '%.4g';
            app.text_RTT_2.Editable = 'off';
            app.text_R11 = uilabel(app.Panel_parameters_2);
            app.text_R11.Text = 'Streamwise turbulent kinetic energy amplification ratio, R11 [-]';
            app.text_R11_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_R11_2.Limits = [0 Inf];
            app.text_R11_2.ValueDisplayFormat = '%.4g';
            app.text_R11_2.Editable = 'off';
            app.text_K = uilabel(app.Panel_parameters_2);
            app.text_K.Text = 'Turbulent kinetic energy amplification ratio, K [-]';
            app.text_K_2 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_K_2.ValueDisplayFormat = '%.4g';
            app.text_K_2.Editable = 'off';
            app.text_Aratio_6 = uilabel(app.Panel_parameters_2);
            app.text_Aratio_6.Visible = 'off';
            app.text_Aratio_6.Text = 'Area ratio A/A_t [-]';
            app.text_Aratio_7 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_Aratio_7.ValueDisplayFormat = '%.4g';
            app.text_Aratio_7.Editable = 'off';
            app.text_Aratio_7.Visible = 'off';
            app.text_Cstar_6 = uilabel(app.Panel_parameters_2);
            app.text_Cstar_6.Visible = 'off';
            app.text_Cstar_6.Text = 'Charac. velocity [m/s]';
            app.text_Ivac_6 = uilabel(app.Panel_parameters_2);
            app.text_Ivac_6.Visible = 'off';
            app.text_Ivac_6.Text = 'Specific impulse vac [s]';
            app.text_Isp_6 = uilabel(app.Panel_parameters_2);
            app.text_Isp_6.Visible = 'off';
            app.text_Isp_6.Text = 'Specific impulse [s]';
            app.text_Cstar_7 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_Cstar_7.ValueDisplayFormat = '%.4g';
            app.text_Cstar_7.Editable = 'off';
            app.text_Cstar_7.Visible = 'off';
            app.text_Ivac_7 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_Ivac_7.ValueDisplayFormat = '%.4g';
            app.text_Ivac_7.Editable = 'off';
            app.text_Ivac_7.Visible = 'off';
            app.text_Isp_7 = uieditfield(app.Panel_parameters_2, 'numeric');
            app.text_Isp_7.ValueDisplayFormat = '%.4g';
            app.text_Isp_7.Editable = 'off';
            app.text_Isp_7.Visible = 'off';
            app.edit_etaVorticity = uieditfield(app.Panel_parameters_2, 'text');
            app.edit_etaVorticity.Editable = 'off';
            app.edit_etaVorticity.Value = '-';
            app.text_phi_5 = uilabel(app.Panel_parameters_2);
            app.text_phi_5.Text = 'etaVorticity';
            app.text_phi_6 = uilabel(app.Panel_parameters_2);
            app.text_phi_6.Text = 'eta';
            app.edit_eta = uieditfield(app.Panel_parameters_2, 'text');
            app.edit_eta.Editable = 'off';
            app.edit_eta.Value = '-';
            app.MixturecompositionTab = uitab(app.Tab_results);
            app.MixturecompositionTab.Title = 'Mixture composition';
            app.UITable_R2 = uitable(app.MixturecompositionTab);
            app.UITable_R2.ColumnName = {'Species'; 'Nº moles'; 'Mole fraction'};
            app.UITable_R2.RowName = {};
            app.UITable_P = uitable(app.MixturecompositionTab);
            app.UITable_P.ColumnName = {'Species'; 'Nº moles'; 'Mole fraction'};
            app.UITable_P.RowName = {};
            app.text_P3 = uilabel(app.MixturecompositionTab);
            app.text_P3.Text = 'Products';
            app.text_R3 = uilabel(app.MixturecompositionTab);
            app.text_R3.Text = 'Reactants';
            app.edit_phi2 = uieditfield(app.MixturecompositionTab, 'text');
            app.edit_phi2.Editable = 'off';
            app.edit_phi2.Value = '-';
            app.text_phi_2 = uilabel(app.MixturecompositionTab);
            app.text_phi_2.Text = 'phi';
            app.CustomfiguresTab = uitab(app.Tab_results);
            app.CustomfiguresTab.Title = 'Custom figures';
            app.UIAxes = uiaxes(app.CustomfiguresTab);
            zlabel(app.UIAxes, 'Z')
            app.Tree_mixtures = uitree(app.CustomfiguresTab, 'checkbox');
            app.Mixtures = uitreenode(app.Tree_mixtures);
            app.Mixtures.Text = 'Mixtures';
            app.Tree_variable_x = uitree(app.CustomfiguresTab, 'checkbox');
            app.Variable_x = uitreenode(app.Tree_variable_x);
            app.Variable_x.Text = 'Variable x';
            % Assign Checked Nodes
            app.Tree_variable_x.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_variable_xCheckedNodesChanged, true);
            app.Tree_variable_y = uitree(app.CustomfiguresTab, 'checkbox');
            app.Variable_y = uitreenode(app.Tree_variable_y);
            app.Variable_y.Text = 'Variable y';
            % Assign Checked Nodes
            app.Tree_variable_y.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_variable_yCheckedNodesChanged, true);
            app.figure_plot = uibutton(app.CustomfiguresTab, 'push');
            app.figure_plot.ButtonPushedFcn = createCallbackFcn(app, @figure_plotButtonPushed, true);
            app.figure_plot.Text = 'Plot';
            app.figure_clear = uibutton(app.CustomfiguresTab, 'push');
            app.figure_clear.ButtonPushedFcn = createCallbackFcn(app, @figure_clearButtonPushed, true);
            app.figure_clear.Text = 'Clear';
            app.figure_size = uibutton(app.CustomfiguresTab, 'push');
            app.figure_size.ButtonPushedFcn = createCallbackFcn(app, @figure_sizeButtonPushed, true);
            app.figure_size.Text = 'Maximize';
            app.figure_settings = uibutton(app.CustomfiguresTab, 'push');
            app.figure_settings.ButtonPushedFcn = createCallbackFcn(app, @figure_settingsButtonPushed, true);
            app.figure_settings.Text = 'Settings';
            app.DefaultsettingsCheckBox = uicheckbox(app.CustomfiguresTab);
            app.DefaultsettingsCheckBox.Text = 'Default settings';
            app.DefaultsettingsCheckBox.Value = true;
            app.Tree = uitree(app.ResultsTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node_Results = uitreenode(app.Tree);
            app.Node_Results.Text = 'Results';
            app.Console = uitextarea(app.UIFigure);
            app.Console.ValueChangedFcn = createCallbackFcn(app, @ConsoleValueChanged, true);
            app.Label_Console = uilabel(app.UIFigure);
            app.Label_Console.Text = '>>';
            app.Lamp = uilamp(app.UIFigure);
            app.Console_text = uitextarea(app.UIFigure);
            app.Console_text.ValueChangedFcn = createCallbackFcn(app, @Console_textValueChanged, true);
            app.Console_text.Value = {'Welcome to Combustion Toolbox vXX.XX.XX --- A MATLAB-based framework for solving combustion and high-speed flow problems'};
            app.ContextMenu_CommandWindow = uicontextmenu(app.UIFigure);
            app.MaximizeMenu = uimenu(app.ContextMenu_CommandWindow);
            app.MaximizeMenu.MenuSelectedFcn = createCallbackFcn(app, @MaximizeMenuSelected, true);
            app.MaximizeMenu.Text = 'Maximize';
            app.MinimizeMenu = uimenu(app.ContextMenu_CommandWindow);
            app.MinimizeMenu.MenuSelectedFcn = createCallbackFcn(app, @MinimizeMenuSelected, true);
            app.MinimizeMenu.Text = 'Minimize';
            app.ClearCommandWindowMenu = uimenu(app.ContextMenu_CommandWindow);
            app.ClearCommandWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCommandWindowMenuSelected, true);
            app.ClearCommandWindowMenu.Text = 'Clear Command Window';
            % Assign app.ContextMenu_CommandWindow
            app.Console.ContextMenu = app.ContextMenu_CommandWindow;
            app.Label_Console.ContextMenu = app.ContextMenu_CommandWindow;
            app.Lamp.ContextMenu = app.ContextMenu_CommandWindow;
            app.Console_text.ContextMenu = app.ContextMenu_CommandWindow;
            app.ContextMenu_UITree = uicontextmenu(app.UIFigure);
            app.RemoveMenu = uimenu(app.ContextMenu_UITree);
            app.RemoveMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveMenuSelected, true);
            app.RemoveMenu.Text = 'Remove';
            app.ContextMenu_UIAxes = uicontextmenu(app.UIFigure);
            app.property_inspector_menu = uimenu(app.ContextMenu_UIAxes);
            app.property_inspector_menu.MenuSelectedFcn = createCallbackFcn(app, @property_inspector_menuSelected, true);
            app.property_inspector_menu.Text = 'Property inspector';
            app.Menu = uimenu(app.ContextMenu_UIAxes);
            % Assign app.ContextMenu_UIAxes
            app.UIAxes.ContextMenu = app.ContextMenu_UIAxes;
            app.ContextMenuUITable_R = uicontextmenu(app.UIFigure);
            app.RemoveMenu_2 = uimenu(app.ContextMenuUITable_R);
            app.RemoveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RemoveMenu_2Selected, true);
            app.RemoveMenu_2.Text = 'Remove';
            % Assign app.ContextMenuUITable_R
            app.UITable_R.ContextMenu = app.ContextMenuUITable_R;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = combustion_toolbox_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end